classdef ConfigAutoDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HFOAutoDetectorsUIFigure        matlab.ui.Figure
        ChooseDetectorDropDownLabel     matlab.ui.control.Label
        DetectorDropDown                matlab.ui.control.DropDown
        HPanel                          matlab.ui.container.Panel
        EpochsEditFieldLabel            matlab.ui.control.Label
        HDEpochEditField                matlab.ui.control.NumericEditField
        SDThresholdLabel                matlab.ui.control.Label
        HDSDEditField                   matlab.ui.control.NumericEditField
        MinEventTimemsEditFieldLabel    matlab.ui.control.Label
        HDMinDurEditField               matlab.ui.control.NumericEditField
        RMSPanel                        matlab.ui.container.Panel
        EpochsEditField_2Label          matlab.ui.control.Label
        RMSEpochEditField               matlab.ui.control.NumericEditField
        RMS_WindowmsEditFieldLabel      matlab.ui.control.Label
        RMSWinEditField                 matlab.ui.control.NumericEditField
        MinWindowmsEditFieldLabel       matlab.ui.control.Label
        RMSMinWinEditField              matlab.ui.control.NumericEditField
        MinOscillationsEditFieldLabel   matlab.ui.control.Label
        RMSMinOscillEditField           matlab.ui.control.NumericEditField
        RMSSDThresholdEditFieldLabel    matlab.ui.control.Label
        RMSSDEditField                  matlab.ui.control.NumericEditField
        MinGapTimemsEditFieldLabel      matlab.ui.control.Label
        RMSMinGapditField               matlab.ui.control.NumericEditField
        PeakSDThreshldEditFieldLabel    matlab.ui.control.Label
        RMSPeakSDEditField              matlab.ui.control.NumericEditField
        SLLPanel                        matlab.ui.container.Panel
        SLLHFEqualCheckBox              matlab.ui.control.CheckBox
        EpochsEditField_3Label          matlab.ui.control.Label
        SLLEpochEditField               matlab.ui.control.NumericEditField
        FilterWindowmsLabel             matlab.ui.control.Label
        SLLFiltWinEditField             matlab.ui.control.NumericEditField
        ThresholdPercentilEditFieldLabel  matlab.ui.control.Label
        SLLThresholdPercentilEditField  matlab.ui.control.NumericEditField
        EventMinTimemsEditFieldLabel    matlab.ui.control.Label
        SLLMinDurEditField              matlab.ui.control.NumericEditField
        MNIPanel                        matlab.ui.container.Panel
        CHF_EpochsEditFieldLabel        matlab.ui.control.Label
        MNICHFEpochEditField            matlab.ui.control.NumericEditField
        CHF_PercentilEditFieldLabel     matlab.ui.control.Label
        MNICHFPercentillEditField       matlab.ui.control.NumericEditField
        MinWindowmsEditField_2Label     matlab.ui.control.Label
        MNIMinWinEditField              matlab.ui.control.NumericEditField
        MinGapTimemsEditField_2Label    matlab.ui.control.Label
        MNIMinGapEditField              matlab.ui.control.NumericEditField
        ThresholdPercentilEditField_2Label  matlab.ui.control.Label
        MNIThresholdPercentilEditField  matlab.ui.control.NumericEditField
        BaselinePanel                   matlab.ui.container.Panel
        SegmentmsLabel                  matlab.ui.control.Label
        MNIBSSegmentsEditField          matlab.ui.control.NumericEditField
        ShiftLabel                      matlab.ui.control.Label
        MNIBSShiftEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        MNIBSThresholdEditField         matlab.ui.control.NumericEditField
        minBaseLineSecminEditFieldLabel  matlab.ui.control.Label
        MNIBSMinBsEditField             matlab.ui.control.NumericEditField
        EpochsEditField_4Label          matlab.ui.control.Label
        MNIEpochEditField               matlab.ui.control.NumericEditField
        FrequencyRangeHzPanel           matlab.ui.container.Panel
        LowEditFieldLabel               matlab.ui.control.Label
        LFEditField                     matlab.ui.control.NumericEditField
        HighEditFieldLabel              matlab.ui.control.Label
        HFEditField                     matlab.ui.control.NumericEditField
        FiltertypeDropDownLabel         matlab.ui.control.Label
        BPFilterTypeDropDown            matlab.ui.control.DropDown
        EventTypeLabel                  matlab.ui.control.Label
        EventTypeDropDown               matlab.ui.control.DropDown
        AutomaticdetectorsimplementedinRippleLabLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        NotchFilterCheckBox             matlab.ui.control.CheckBox
        FrequencyLabel                  matlab.ui.control.Label
        NotchFreqDropDown               matlab.ui.control.DropDown
        FiltertypeDropDown_2Label       matlab.ui.control.Label
        NotchFilterTypeDropDown         matlab.ui.control.DropDown
        BandwidthHzEditFieldLabel       matlab.ui.control.Label
        NotchBandwidthEditField         matlab.ui.control.NumericEditField
        HilPanel                        matlab.ui.container.Panel
        EpochsEditFieldLabel_2          matlab.ui.control.Label
        HilEpochsEditField              matlab.ui.control.NumericEditField
        OnsetthresholdSDEditFieldLabel  matlab.ui.control.Label
        HilThreshOnsetEditField         matlab.ui.control.NumericEditField
        InclusionCriteriaSDEditFieldLabel  matlab.ui.control.Label
        HilThreshInclusionEditField     matlab.ui.control.NumericEditField
        NumberofCyclesEditFieldLabel    matlab.ui.control.Label
        HilThreshNumberCyclesEditField  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp        
    end    
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            if nargin < 2
                for k = 1 : length( app.HFOAutoDetectorsUIFigure.Children)
                    app.HFOAutoDetectorsUIFigure.Children( k).Visible = 'off';
                end

                return;
            end

            % Change figure postion
            nam = fieldnames( mainapp);
            fig_h = cellfun( @(x) strfind( x, 'UIFigure'), nam, 'UniformOutput', false);
            fig_h = find( cell2mat( fig_h));
            nam = nam{ fig_h(1) };
            
            p = mainapp.( nam).Position;
            fig_pos = app.HFOAutoDetectorsUIFigure.Position;            
            app.HFOAutoDetectorsUIFigure.Position( 1) = p(1) + p(3)/2 - fig_pos(3)/2;
            app.HFOAutoDetectorsUIFigure.Position( 2) = p(2) + p(4)/2 - fig_pos(4)/2;            
                        
            if isempty( mainapp.data)
                return;
            end
            
            app.CallingApp = mainapp;
            
            % Set edit fields limits and default values   
            
            % sampling rate
            srate = mainapp.data.srate;
            
            % Nyquist frequency
            nyquist_fs = srate / 2;
                   
            set( app.LFEditField,...
                'Value', 80,...
                'Limits', [0, nyquist_fs]);
            
            set( app.HFEditField,...
                'Value', 250,...
                'Limits', [0, nyquist_fs]);            
            
            % epoch length
            max_epoch_len = diff( mainapp.uidata.xrange) / srate;
            
            % Notch filter
            app.NotchFilterCheckBox.Value = 0;
            app.NotchBandwidthEditField.Enable = 'off';
            app.NotchFilterTypeDropDown.Enable = 'off';
            app.NotchFreqDropDown.Enable = 'off';            
            app.NotchBandwidthEditField.Limits = [0.1, nyquist_fs/2];
            
            
            %% HFO detectors default values setup
            app.DetectorDropDown.Value = 'Hilbert Detector (HFOApp)';
            app.EventTypeDropDown.Value = 'Ripple';
            
            % Hilbert detector
            % default whole time series
            app.HDEpochEditField.Limits = [0, max_epoch_len];
            app.HDEpochEditField.Value = max_epoch_len;
            app.HDSDEditField.Value = 5; % sd
            app.HDMinDurEditField.Value = 10; % in ms
            
            % HFOApp default Hilbert detector
            app.HilEpochsEditField.Limits = [0, max_epoch_len];
            app.HilEpochsEditField.Value = max_epoch_len;
            app.HilThreshOnsetEditField.Value = app.CallingApp.ThreshOnsetEditField.Value;
            app.HilThreshInclusionEditField.Value = app.CallingApp.ThreshInclusionEditField.Value;
            app.HilThreshNumberCyclesEditField.Value = app.CallingApp.ThreshNumberCyclesEditField.Value;           
            
            % RMS detector
            app.RMSEpochEditField.Limits = [0, max_epoch_len];
            app.RMSEpochEditField.Value = max_epoch_len;
            app.RMSWinEditField.Value = 3; % ms
            app.RMSMinWinEditField.Value = 6; % ms
            app.RMSMinGapditField.Value = 10; % ms
            app.RMSMinOscillEditField.Value = 6;
            app.RMSSDEditField.Value = 5;
            app.RMSPeakSDEditField.Value = 3;
            
            % SLL detector
            app.SLLEpochEditField.Limits = [0, max_epoch_len];
            app.SLLEpochEditField.Value = max_epoch_len;
            app.SLLFiltWinEditField.Value = 5; % ms
            app.SLLThresholdPercentilEditField.Limits = [0, 100];
            app.SLLThresholdPercentilEditField.Value = 97.5;
            app.SLLMinDurEditField.Value = 12; % ms
            app.SLLHFEqualCheckBox.Value = 0;
            
            % MNI detector            
            app.MNIEpochEditField.Limits = [0, max_epoch_len];
            app.MNIEpochEditField.Value = min( [10, max_epoch_len]);
            app.MNICHFEpochEditField.Limits = [0, max_epoch_len];
            app.MNICHFEpochEditField.Value = min( [60, max_epoch_len]);
            app.MNICHFPercentillEditField.Limits = [0, 100];
            app.MNICHFPercentillEditField.Value = 95;
            app.MNIMinWinEditField.Value = 10; % ms
            app.MNIMinGapEditField.Value = 10; % ms
            app.MNIThresholdPercentilEditField.Limits = [0, 100];
            app.MNIThresholdPercentilEditField.Value = 99.9999;
            app.MNIBSSegmentsEditField.Limits = [0, max_epoch_len];
            app.MNIBSSegmentsEditField.Value = min([125, max_epoch_len]);
            app.MNIBSShiftEditField.Value = 0.5;
            app.MNIBSThresholdEditField.Value = 0.67;
            app.MNIBSMinBsEditField.Limits = [0, max_epoch_len];
            app.MNIBSMinBsEditField.Value = min( [5, max_epoch_len]);
                        
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% EDIT Me
            % Set default values for parameters of your own detector
            % length of time series (in seconds): max_epoch_len
            % sampling rate (in Hz): srate


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            % Do not change this line below
            DetectorDropDownValueChanged(app, []);
        end

        % Value changed function: DetectorDropDown
        function DetectorDropDownValueChanged(app, event)
            % left and bottom position of the panels
            pos = [10, 50];
            
            % Add panel of your own detector to the list
            set( [app.HPanel,...
                app.HilPanel,...
                app.RMSPanel,...
                app.SLLPanel,...
                app.MNIPanel],...
                'visible', 'off');
            
            value = app.DetectorDropDown.Value;               
            if strcmp( value, 'Hilbert Detector (HFOApp)')
                app.HilPanel.Visible = 'on';
                app.HilPanel.Position( [1, 2]) = pos;
                
            elseif strcmp( value, 'Hilbert Detector (RippleLab)')
                app.HPanel.Visible = 'on';
                app.HPanel.Position( [1, 2]) = pos;
                
            elseif strcmp( value, 'Short Time Energy (RMS) Detector')
                app.RMSPanel.Visible = 'on';
                app.RMSPanel.Position( [1, 2]) = pos;
                
            elseif strcmp( value, 'Short Line Length Detector')
                app.SLLPanel.Visible = 'on';
                app.SLLPanel.Position( [1, 2]) = pos;
                
            elseif strcmp( value, 'MNI Detector')
                app.MNIPanel.Visible = 'on';
                app.MNIPanel.Position( [1, 2]) = pos;
                
            else
                % Add your own detector here
            end
            
        end

        % Value changed function: LFEditField
        function LFEditFieldValueChanged(app, event)
            if app.LFEditField.Value >= app.HFEditField.Value
                txt = 'Low frequency must be smaller than High frequency.';
                app.CallingApp.PUpdateStatus( txt, 'r');
                app.LFEditField.Value = event.PreviousValue;
                
            else
                txt = ['Low cutoff frequency was set to ', num2str( app.LFEditField.Value), ' Hz'];
                app.CallingApp.PUpdateStatus( txt, 'b');
            end            
        end

        % Value changed function: HFEditField
        function HFEditFieldValueChanged(app, event)
            if app.HFEditField.Value <= app.LFEditField.Value
                txt = 'High frequency must be greater than Low frequency.';
                app.CallingApp.PUpdateStatus( txt, 'r');
                app.HFEditField.Value = event.PreviousValue;
                
            else
                txt = ['High cutoff frequency was set to ', num2str( app.HFEditField.Value), ' Hz'];
                app.CallingApp.PUpdateStatus( txt, 'b');
            end            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete( app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            set( app.RunButton,...
                'Enable', 'off',...
                'Text', 'Running',...
                'FontColor', 'b');
            
            app.CancelButton.Enable = 'off';
            
            try
                app.CallingApp.PUpdateClustInfo( '', 'k');
                app.CallingApp.PUpdateStatus( 'Running automatic event detection ...', 'b');
                drawnow;
                                
                cfg = [];
                cfg.evtype = strrep( lower( app.EventTypeDropDown.Value), ' ', '');
                
                % filter options, fields required by HFOFiltData.m
                cfg.bpfreq = [app.LFEditField.Value, app.HFEditField.Value];
                cfg.bptype = lower( app.BPFilterTypeDropDown.Value);
                cfg.notch_bandwidth = app.NotchBandwidthEditField.Value;
                if app.NotchFilterCheckBox.Value == 1
                    cfg.notch = 'yes';
                    cfg.notch_freq = str2double( strtok( app.NotchFreqDropDown.Value, '&'));
                    cfg.notch_type = lower( app.NotchFilterTypeDropDown.Value);                    
                else
                    cfg.notch = 'no';
                    cfg.notch_freq = [];
                    cfg.notch_type = '';
                end

                % Run detection for all channels in "Display List box"
                [~, loc] = ismember( app.CallingApp.ChannelListBox.Items, app.CallingApp.data.labels);
                labels = app.CallingApp.data.labels( loc);
                    
                
                %%******************************************************
                %% Add customized detector here
                detec_method = app.DetectorDropDown.Value;     
                if strcmp( detec_method, 'Hilbert Detector (HFOApp)')
                    cfg.method = 'hil';                    
                    cfg.epoch = app.HilEpochsEditField.Value;
                    cfg.nocycle_thresh = app.HilThreshNumberCyclesEditField.Value;
                    cfg.z_thresh = app.HilThreshOnsetEditField.Value;
                    cfg.peak_thresh = app.HilThreshInclusionEditField.Value;
                    % Calcuate mean and stand devaiation using a moving time window
                    cfg.avg_method = 'mov';
                    % cfg.avg_method = 'epoch';
                    
                elseif strcmp( detec_method, 'Hilbert Detector (RippleLab)')
                    cfg.method = 'hil';                    
                    cfg.epoch = app.HDEpochEditField.Value;
                    cfg.dur_thresh = app.HDMinDurEditField.Value / 1000;
                    cfg.z_thresh = app.HDSDEditField.Value;
                    cfg.peak_thresh = app.HDSDEditField.Value; 
                    cfg.avg_method = 'epoch';

                elseif strcmp( detec_method, 'Short Time Energy (RMS) Detector')
                    cfg.method = 'ste';                    
                    cfg.epoch = app.HDEpochEditField.Value;
                    cfg.rs_win = app.RMSWinEditField.Value / 1000;
                    cfg.rs_thresh = app.RMSSDEditField.Value;
                    cfg.rs_min_dur = app.RMSMinWinEditField.Value / 1000;
                    cfg.min_dist = app.RMSMinGapditField.Value / 1000;
                    cfg.nocycle_thresh = app.RMSMinOscillEditField.Value;
                    cfg.peak_thresh = app.RMSPeakSDEditField.Value;
                    
                elseif strcmp( detec_method, 'Short Line Length Detector')
                    cfg.method = 'sll';                    
                    cfg.epoch = app.HDEpochEditField.Value;
                    cfg.win_len = app.SLLFiltWinEditField.Value / 1000;
                    cfg.percent = app.SLLThresholdPercentilEditField.Value / 100;
                    cfg.min_dur = app.SLLMinDurEditField.Value / 1000;
                    if app.SLLHFEqualCheckBox.Value == 1
                        cfg.do_equal = 'yes';
                    else
                        cfg.do_equal = 'no';
                    end                    
                    
                elseif strcmp( detec_method, 'MNI Detector')
                    cfg.method = 'mni';                    
                    cfg.epoch = app.MNIEpochEditField.Value;
                    cfg.hf_epcoh = app.MNICHFEpochEditField.Value;
                    cfg.hf_percent  = app.MNICHFPercentillEditField.Value / 100;
                    cfg.min_win = app.MNIMinWinEditField.Value / 1000;
                    cfg.min_gap = app.MNIMinGapEditField.Value / 1000;
                    cfg.thresh_percent = app.MNIThresholdPercentilEditField.Value / 100;
                    cfg.bs_win = app.MNIBSSegmentsEditField.Value / 1000;
                    cfg.bs_shift = app.MNIBSShiftEditField.Value;
                    cfg.bs_thresh = app.MNIBSThresholdEditField.Value;
                    cfg.bs_min = app.MNIBSMinBsEditField.Value;
                    
                else
                    % Add your own detector here
                end               
                
                %% End of customized detector
                %%******************************************************
                          
                xrange = app.CallingApp.uidata.xrange;
                evs = HFOAutoDetect( app.CallingApp.data.mat( loc, xrange(1) : xrange(2)),...
                    app.CallingApp.data.srate, labels, cfg); 
                
                if ~isempty( evs)
                    for k = 1 : length( evs)
                        evs(k).Location = evs(k).Location + xrange(1) - 1;
                    end
                end
                
                app.CallingApp.uidata.cur_events = evs;
                [status, cnt] = app.CallingApp.PAddEvent( evs, cfg.evtype);
                
                if status == 1
                    app.CallingApp.PUpdateStatus( ...
                        ['Automated detection done. ',...
                        num2str( cnt), ' new events were added.'],...
                        'b');
                else
                    app.CallingApp.PUpdateStatus( 'No events added.', 'b');
                end
            
            catch
                app.CallingApp.PUpdateStatus(...
                    'Error encountered in automated HFO detection.',...
                    'r');
            end
                
            set( app.RunButton,...
                'Enable', 'on',...
                'Text', 'Run',...
                'FontColor', 'k');
            
            app.CancelButton.Enable = 'on';
            
            drawnow;
        end

        % Value changed function: NotchFilterCheckBox
        function NotchFilterCheckBoxValueChanged(app, event)
            if app.NotchFilterCheckBox.Value == 1
                app.NotchBandwidthEditField.Enable = 'on';
                app.NotchFilterTypeDropDown.Enable = 'on';
                app.NotchFreqDropDown.Enable = 'on';
            else
                app.NotchBandwidthEditField.Enable = 'off';
                app.NotchFilterTypeDropDown.Enable = 'off';
                app.NotchFreqDropDown.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HFOAutoDetectorsUIFigure = uifigure('Visible', 'off');
            app.HFOAutoDetectorsUIFigure.Name = 'HFO Auto Detectors';
            app.ChooseDetectorDropDownLabel = uilabel(app.HFOAutoDetectorsUIFigure);
            app.ChooseDetectorDropDownLabel.Text = 'Choose Detector:';
            app.DetectorDropDown = uidropdown(app.HFOAutoDetectorsUIFigure);
            app.DetectorDropDown.Items = {'Hilbert Detector (HFOApp)', 'Hilbert Detector (RippleLab)', 'Short Time Energy (RMS) Detector', 'Short Line Length Detector', 'MNI Detector'};
            app.DetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorDropDownValueChanged, true);
            app.DetectorDropDown.Value = 'Hilbert Detector (HFOApp)';
            app.HPanel = uipanel(app.HFOAutoDetectorsUIFigure);
            app.HPanel.Title = 'Hilbert Detector (RippleLab implementation)';
            app.EpochsEditFieldLabel = uilabel(app.HPanel);
            app.EpochsEditFieldLabel.Text = 'Epoch (s):';
            app.HDEpochEditField = uieditfield(app.HPanel, 'numeric');
            app.SDThresholdLabel = uilabel(app.HPanel);
            app.SDThresholdLabel.Text = 'SD Threshold:';
            app.HDSDEditField = uieditfield(app.HPanel, 'numeric');
            app.HDSDEditField.Limits = [0 Inf];
            app.MinEventTimemsEditFieldLabel = uilabel(app.HPanel);
            app.MinEventTimemsEditFieldLabel.Text = 'Min EventTime (ms):';
            app.HDMinDurEditField = uieditfield(app.HPanel, 'numeric');
            app.HDMinDurEditField.Limits = [0 Inf];
            app.RMSPanel = uipanel(app.HFOAutoDetectorsUIFigure);
            app.RMSPanel.Title = 'Short Time Energy (RMS) Detector (RippleLab implementation)';
            app.EpochsEditField_2Label = uilabel(app.RMSPanel);
            app.EpochsEditField_2Label.Text = 'Epoch (s):';
            app.RMSEpochEditField = uieditfield(app.RMSPanel, 'numeric');
            app.RMSEpochEditField.Limits = [0 Inf];
            app.RMS_WindowmsEditFieldLabel = uilabel(app.RMSPanel);
            app.RMS_WindowmsEditFieldLabel.Text = 'RMS_Window (ms):';
            app.RMSWinEditField = uieditfield(app.RMSPanel, 'numeric');
            app.RMSWinEditField.Limits = [0 Inf];
            app.MinWindowmsEditFieldLabel = uilabel(app.RMSPanel);
            app.MinWindowmsEditFieldLabel.Text = 'MinWindow (ms)';
            app.RMSMinWinEditField = uieditfield(app.RMSPanel, 'numeric');
            app.RMSMinWinEditField.Limits = [0 Inf];
            app.MinOscillationsEditFieldLabel = uilabel(app.RMSPanel);
            app.MinOscillationsEditFieldLabel.Text = 'Min Oscillations:';
            app.RMSMinOscillEditField = uieditfield(app.RMSPanel, 'numeric');
            app.RMSMinOscillEditField.Limits = [0 Inf];
            app.RMSSDThresholdEditFieldLabel = uilabel(app.RMSPanel);
            app.RMSSDThresholdEditFieldLabel.Text = 'RMS SD Threshold:';
            app.RMSSDEditField = uieditfield(app.RMSPanel, 'numeric');
            app.RMSSDEditField.Limits = [0 Inf];
            app.MinGapTimemsEditFieldLabel = uilabel(app.RMSPanel);
            app.MinGapTimemsEditFieldLabel.Text = 'Min GapTime (ms):';
            app.RMSMinGapditField = uieditfield(app.RMSPanel, 'numeric');
            app.RMSMinGapditField.Limits = [0 Inf];
            app.PeakSDThreshldEditFieldLabel = uilabel(app.RMSPanel);
            app.PeakSDThreshldEditFieldLabel.Text = 'Peak SDThreshld:';
            app.RMSPeakSDEditField = uieditfield(app.RMSPanel, 'numeric');
            app.RMSPeakSDEditField.Limits = [0 Inf];
            app.SLLPanel = uipanel(app.HFOAutoDetectorsUIFigure);
            app.SLLPanel.Title = 'Short Line Length Detector (RippleLab implementation)';
            app.SLLHFEqualCheckBox = uicheckbox(app.SLLPanel);
            app.SLLHFEqualCheckBox.Text = 'HF Equalization';
            app.EpochsEditField_3Label = uilabel(app.SLLPanel);
            app.EpochsEditField_3Label.Text = 'Epoch (s):';
            app.SLLEpochEditField = uieditfield(app.SLLPanel, 'numeric');
            app.SLLEpochEditField.Limits = [0 Inf];
            app.FilterWindowmsLabel = uilabel(app.SLLPanel);
            app.FilterWindowmsLabel.Text = 'Filter Window (ms):';
            app.SLLFiltWinEditField = uieditfield(app.SLLPanel, 'numeric');
            app.SLLFiltWinEditField.Limits = [0 Inf];
            app.ThresholdPercentilEditFieldLabel = uilabel(app.SLLPanel);
            app.ThresholdPercentilEditFieldLabel.Text = 'Threshold Percentil:';
            app.SLLThresholdPercentilEditField = uieditfield(app.SLLPanel, 'numeric');
            app.SLLThresholdPercentilEditField.Limits = [0 100];
            app.SLLThresholdPercentilEditField.ValueDisplayFormat = '%3.4f';
            app.EventMinTimemsEditFieldLabel = uilabel(app.SLLPanel);
            app.EventMinTimemsEditFieldLabel.Text = 'Event MinTime (ms):';
            app.SLLMinDurEditField = uieditfield(app.SLLPanel, 'numeric');
            app.SLLMinDurEditField.Limits = [0 Inf];
            app.MNIPanel = uipanel(app.HFOAutoDetectorsUIFigure);
            app.MNIPanel.Title = 'MNI Detector (RippleLab implementation)';
            app.CHF_EpochsEditFieldLabel = uilabel(app.MNIPanel);
            app.CHF_EpochsEditFieldLabel.Text = 'CHF_Epoch (s):';
            app.MNICHFEpochEditField = uieditfield(app.MNIPanel, 'numeric');
            app.MNICHFEpochEditField.Limits = [0 Inf];
            app.CHF_PercentilEditFieldLabel = uilabel(app.MNIPanel);
            app.CHF_PercentilEditFieldLabel.Text = 'CHF_Percentil:';
            app.MNICHFPercentillEditField = uieditfield(app.MNIPanel, 'numeric');
            app.MNICHFPercentillEditField.Limits = [0 100];
            app.MNICHFPercentillEditField.ValueDisplayFormat = '%3.4f';
            app.MinWindowmsEditField_2Label = uilabel(app.MNIPanel);
            app.MinWindowmsEditField_2Label.Text = 'MinWindow (ms):';
            app.MNIMinWinEditField = uieditfield(app.MNIPanel, 'numeric');
            app.MinGapTimemsEditField_2Label = uilabel(app.MNIPanel);
            app.MinGapTimemsEditField_2Label.Text = 'Min GapTime (ms):';
            app.MNIMinGapEditField = uieditfield(app.MNIPanel, 'numeric');
            app.ThresholdPercentilEditField_2Label = uilabel(app.MNIPanel);
            app.ThresholdPercentilEditField_2Label.Text = 'Threshold Percentil';
            app.MNIThresholdPercentilEditField = uieditfield(app.MNIPanel, 'numeric');
            app.MNIThresholdPercentilEditField.Limits = [0 100];
            app.MNIThresholdPercentilEditField.ValueDisplayFormat = '%3.4f';
            app.BaselinePanel = uipanel(app.MNIPanel);
            app.BaselinePanel.Title = 'Baseline';
            app.SegmentmsLabel = uilabel(app.BaselinePanel);
            app.SegmentmsLabel.Text = 'Segment (ms):';
            app.MNIBSSegmentsEditField = uieditfield(app.BaselinePanel, 'numeric');
            app.MNIBSSegmentsEditField.Limits = [0 Inf];
            app.ShiftLabel = uilabel(app.BaselinePanel);
            app.ShiftLabel.Text = 'Shift:';
            app.MNIBSShiftEditField = uieditfield(app.BaselinePanel, 'numeric');
            app.MNIBSShiftEditField.Limits = [0 Inf];
            app.ThresholdEditFieldLabel = uilabel(app.BaselinePanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.MNIBSThresholdEditField = uieditfield(app.BaselinePanel, 'numeric');
            app.MNIBSThresholdEditField.Limits = [0 Inf];
            app.minBaseLineSecminEditFieldLabel = uilabel(app.BaselinePanel);
            app.minBaseLineSecminEditFieldLabel.Text = 'minBaseLine (Sec/min):';
            app.MNIBSMinBsEditField = uieditfield(app.BaselinePanel, 'numeric');
            app.MNIBSMinBsEditField.Limits = [0 Inf];
            app.EpochsEditField_4Label = uilabel(app.MNIPanel);
            app.EpochsEditField_4Label.Text = 'Epoch (s):';
            app.MNIEpochEditField = uieditfield(app.MNIPanel, 'numeric');
            app.MNIEpochEditField.Limits = [0 Inf];
            app.FrequencyRangeHzPanel = uipanel(app.HFOAutoDetectorsUIFigure);
            app.FrequencyRangeHzPanel.Title = 'Frequency Range (Hz)';
            app.LowEditFieldLabel = uilabel(app.FrequencyRangeHzPanel);
            app.LowEditFieldLabel.Text = 'Low';
            app.LFEditField = uieditfield(app.FrequencyRangeHzPanel, 'numeric');
            app.LFEditField.Limits = [0 Inf];
            app.LFEditField.ValueChangedFcn = createCallbackFcn(app, @LFEditFieldValueChanged, true);
            app.HighEditFieldLabel = uilabel(app.FrequencyRangeHzPanel);
            app.HighEditFieldLabel.Text = 'High';
            app.HFEditField = uieditfield(app.FrequencyRangeHzPanel, 'numeric');
            app.HFEditField.Limits = [0 Inf];
            app.HFEditField.ValueChangedFcn = createCallbackFcn(app, @HFEditFieldValueChanged, true);
            app.FiltertypeDropDownLabel = uilabel(app.FrequencyRangeHzPanel);
            app.FiltertypeDropDownLabel.Text = 'Filter type:';
            app.BPFilterTypeDropDown = uidropdown(app.FrequencyRangeHzPanel);
            app.BPFilterTypeDropDown.Items = {'FIR', 'IIR'};
            app.BPFilterTypeDropDown.Value = 'FIR';
            app.EventTypeLabel = uilabel(app.HFOAutoDetectorsUIFigure);
            app.EventTypeLabel.Text = 'Event Type:';
            app.EventTypeDropDown = uidropdown(app.HFOAutoDetectorsUIFigure);
            app.EventTypeDropDown.Items = {'Gamma', 'Ripple', 'Fast Ripple', 'Ultrafast', 'Spike'};
            app.EventTypeDropDown.Value = 'Ripple';
            app.AutomaticdetectorsimplementedinRippleLabLabel = uilabel(app.HFOAutoDetectorsUIFigure);
            app.AutomaticdetectorsimplementedinRippleLabLabel.Text = 'Automatic detectors implemented in RippleLab';
            app.RunButton = uibutton(app.HFOAutoDetectorsUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.HFOAutoDetectorsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.HFOAutoDetectorsUIFigure);
            app.NotchFilterCheckBox = uicheckbox(app.Panel);
            app.NotchFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterCheckBoxValueChanged, true);
            app.NotchFilterCheckBox.Text = 'Notch filter';
            app.FrequencyLabel = uilabel(app.Panel);
            app.FrequencyLabel.Text = 'Frequency:';
            app.NotchFreqDropDown = uidropdown(app.Panel);
            app.NotchFreqDropDown.Items = {'50 & Hamonics', '60 & Harmonics'};
            app.NotchFreqDropDown.Value = '60 & Harmonics';
            app.FiltertypeDropDown_2Label = uilabel(app.Panel);
            app.FiltertypeDropDown_2Label.Text = 'Filter type:';
            app.NotchFilterTypeDropDown = uidropdown(app.Panel);
            app.NotchFilterTypeDropDown.Items = {'FIR', 'IIR'};
            app.NotchFilterTypeDropDown.Value = 'FIR';
            app.BandwidthHzEditFieldLabel = uilabel(app.Panel);
            app.BandwidthHzEditFieldLabel.Text = 'Bandwidth (Hz):';
            app.NotchBandwidthEditField = uieditfield(app.Panel, 'numeric');
            app.NotchBandwidthEditField.Limits = [0 Inf];
            app.NotchBandwidthEditField.Value = 4;
            app.HilPanel = uipanel(app.HFOAutoDetectorsUIFigure);
            app.HilPanel.Title = 'Hilbert Detector (HFOApp implementation)';
            app.EpochsEditFieldLabel_2 = uilabel(app.HilPanel);
            app.EpochsEditFieldLabel_2.Text = 'Epoch (s):';
            app.HilEpochsEditField = uieditfield(app.HilPanel, 'numeric');
            app.HilEpochsEditField.Limits = [0 Inf];
            app.OnsetthresholdSDEditFieldLabel = uilabel(app.HilPanel);
            app.OnsetthresholdSDEditFieldLabel.Text = 'Onset threshold (SD):';
            app.HilThreshOnsetEditField = uieditfield(app.HilPanel, 'numeric');
            app.HilThreshOnsetEditField.Limits = [0 Inf];
            app.InclusionCriteriaSDEditFieldLabel = uilabel(app.HilPanel);
            app.InclusionCriteriaSDEditFieldLabel.Text = 'Inclusion Criteria (SD):';
            app.HilThreshInclusionEditField = uieditfield(app.HilPanel, 'numeric');
            app.HilThreshInclusionEditField.Limits = [0 Inf];
            app.NumberofCyclesEditFieldLabel = uilabel(app.HilPanel);
            app.NumberofCyclesEditFieldLabel.Text = 'Number of Cycles:';
            app.HilThreshNumberCyclesEditField = uieditfield(app.HilPanel, 'numeric');
            app.HilThreshNumberCyclesEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.HFOAutoDetectorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigAutoDetector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HFOAutoDetectorsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HFOAutoDetectorsUIFigure)
        end
    end
end