classdef ConfigFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterSetupUIFigure            matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        LowfrequencyHzEditFieldLabel   matlab.ui.control.Label
        BPLowFreqEditField             matlab.ui.control.NumericEditField
        HighfrequencyHzEditFieldLabel  matlab.ui.control.Label
        BPHighFreqEditField            matlab.ui.control.NumericEditField
        FiltertypeDropDownLabel        matlab.ui.control.Label
        BPFilterTypeDropDown           matlab.ui.control.DropDown
        Panel_2                        matlab.ui.container.Panel
        NotchFilterCheckBox            matlab.ui.control.CheckBox
        FrequencyLabel                 matlab.ui.control.Label
        NotchFreqDropDown              matlab.ui.control.DropDown
        FiltertypeDropDown_2Label      matlab.ui.control.Label
        NotchFilterTypeDropDown        matlab.ui.control.DropDown
        BandwidthHzEditFieldLabel      matlab.ui.control.Label
        NotchBandwidthEditField        matlab.ui.control.NumericEditField
        SetColorButton                 matlab.ui.control.Button
        ColorLabel                     matlab.ui.control.Label
        InfoLabel                      matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        NewwindowCheckBox              matlab.ui.control.CheckBox
        NameEditFieldLabel             matlab.ui.control.Label
        WinNameEditField               matlab.ui.control.EditField
    end


    properties (Access = private)
        CallingApp % HFO App
        data
    end
    
    methods (Access = private)
        
        function SetAutoWindowName(app)
            % Use band-pass frequency as window name
            app.WinNameEditField.Value = ['Freq_',...
                num2str( app.data.bpfreq(1)),...
                '-',...
                num2str( app.data.bpfreq(2)) '_Hz'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            if nargin < 2
                for k = 1 : length( app.FilterSetupUIFigure.Children)
                    app.FilterSetupUIFigure.Children( k).Visible = 'off';
                end
                return;
            end
            
            nam = fieldnames( mainapp);
            fig_h = cellfun( @(x) strfind( x, 'UIFigure'), nam, 'UniformOutput', false);
            fig_h = find( cell2mat( fig_h));
            nam = nam{ fig_h(1) };
            
            p = mainapp.( nam).Position;
            fig_pos = app.FilterSetupUIFigure.Position;            
            app.FilterSetupUIFigure.Position( 1) = p(1) + p(3)/2 - fig_pos(3)/2;
            app.FilterSetupUIFigure.Position( 2) = p(2) + p(4)/2 - fig_pos(4)/2;
            
            app.CallingApp = mainapp;            
            app.CallingApp.data.bpfilt.update_plot = 'no';
            app.data.srate = app.CallingApp.data.srate;
            app.data.nyquistfreq = app.data.srate/2;
            
            % Default values
            if app.data.srate > 500
                app.data.bpfreq = [80, 250];
                SetAutoWindowName(app);
            else
                app.data.bpfreq = round( 0.5 * app.data.srate * [0.15, 0.35]);
            end
            
            app.data.bptype = 'fir';
            app.data.notch = 'no';
            app.data.notch_freq = 60;
            app.data.notch_bandwidth = [];
            app.data.notch_type = 'fir';
            app.data.yscale = 100;
            
            app.data.win_name_set = 'no';

            freq_lim = [eps, app.data.nyquistfreq];
            app.BPLowFreqEditField.Limits = freq_lim;
            app.BPHighFreqEditField.Limits = freq_lim;
            app.NotchBandwidthEditField.Limits = [0.001, freq_lim(2)];
            
            % Objects values
            app.NotchFilterCheckBox.Value = 0;
            app.NotchFilterTypeDropDown.Enable = 'off';
            app.NotchFreqDropDown.Enable = 'off';
            app.NotchBandwidthEditField.Enable = 'off';
            app.BPLowFreqEditField.Value = app.data.bpfreq(1);
            app.BPHighFreqEditField.Value = app.data.bpfreq(2);
            app.NewwindowCheckBox.Value = 1;    
            app.ColorLabel.Text = '';
            app.InfoLabel.Text = '';
            app.ColorLabel.BackgroundColor = app.CallingApp.uidata.color_main;
                       
        end

        % Value changed function: BPLowFreqEditField
        function BPLowFreqEditFieldValueChanged(app, event)
            value = app.BPLowFreqEditField.Value;
            if value >= app.data.nyquistfreq || value <= 0 
                % This can be removed
                app.BPLowFreqEditField.Value = event.PreviousValue;
                app.BPLowFreqEditField.FontColor = 'r';
                app.InfoLabel.Text = 'Invalid low frequency. Restored to previous value.';
                app.InfoLabel.FontColor = 'r';
                
            else
                app.BPLowFreqEditField.FontColor = 'k';
                app.data.bpfreq(1) = value;
                app.InfoLabel.Text = 'Low-cutoff freqency set.';
                app.InfoLabel.FontColor = 'b';
                if strcmpi( app.data.win_name_set, 'no')
                    SetAutoWindowName(app);
                end
            end
        end

        % Value changed function: BPHighFreqEditField
        function BPHighFreqEditFieldValueChanged(app, event)
            value = app.BPHighFreqEditField.Value;
            if value >= app.data.nyquistfreq  || value <= 0 
                % This can be removed
                app.BPHighFreqEditField.Value = event.PreviousValue;
                app.BPHighFreqEditField.FontColor = 'r';                
                app.InfoLabel.Text = 'Invalid low frequency. Restored to previous value.';
                app.InfoLabel.FontColor = 'r';
                
            else
                app.data.bpfreq(2) = value;
                app.BPHighFreqEditField.FontColor = 'b';
                app.InfoLabel.Text = 'High-cutoff freqency set.';
                app.InfoLabel.FontColor = 'b';
                if strcmpi( app.data.win_name_set, 'no')
                    SetAutoWindowName(app);
                end
            end
        end

        % Value changed function: BPFilterTypeDropDown
        function BPFilterTypeDropDownValueChanged(app, event)
            value = app.BPFilterTypeDropDown.Value;
            app.data.bptype = lower( value);
        end

        % Value changed function: NotchFilterCheckBox
        function NotchFilterCheckBoxValueChanged(app, event)
            value = app.NotchFilterCheckBox.Value;
            if value == 1
                app.NotchFilterTypeDropDown.Enable = 'on';
                app.NotchFreqDropDown.Enable = 'on';
                app.NotchBandwidthEditField.Enable = 'on';
            else
                app.NotchFilterTypeDropDown.Enable = 'off';
                app.NotchFreqDropDown.Enable = 'off';
                app.NotchBandwidthEditField.Enable = 'off';
            end            
        end

        % Value changed function: NotchFreqDropDown
        function NotchFreqDropDownValueChanged(app, event)
            if contains(app.NotchFreqDropDown.Value, '50')
                app.data.notch_freq = 50;
            else
                app.data.notcht_freq = 60;
            end
        end

        % Value changed function: NotchFilterTypeDropDown
        function NotchFilterTypeDropDownValueChanged(app, event)
            app.data.notch_type = lower( app.NotchFilterTypeDropDown.Value);
        end

        % Value changed function: NotchBandwidthEditField
        function NotchBandwidthEditFieldValueChanged(app, event)
            value = app.NotchBandwidthEditField.Value;
            
            if (0.5 * value + app.data.notch_freq) >= app.data.nyquistfreq
                app.NotchBandwidthEditField.Value = event.PreviousValue;
                app.NotchBandwidthEditField.FontColor = 'r';                                
                app.InfoLabel.Text = 'Invalid notch bandwidth. Restored to previous value.';
                app.InfoLabel.FontColor = 'r';
               
            else
                app.data.notch_bandwidth = value;
                app.NotchBandwidthEditField.FontColor = 'k';                
                app.InfoLabel.Text = 'Notch filter bandwidth set.';
                app.InfoLabel.FontColor = 'k';
            end  
        end

        % Value changed function: WinNameEditField
        function WinNameEditFieldValueChanged(app, event)
            value = strtrim( app.WinNameEditField.Value);
            if isempty( value)
               app.InfoLabel.Text = 'Windown name must be set.';
               app.InfoLabel.FontColor = 'r';
               app.WinNameEditField.BackgroundColor = [0.1, 0, 0];               
            else
                app.data.win_name_set = 'yes';
            end  
        end

        % Value changing function: WinNameEditField
        function WinNameEditFieldValueChanging(app, event)
            app.InfoLabel.Text = '';
            app.InfoLabel.FontColor = 'k';
            app.WinNameEditField.BackgroundColor = 'w';
        end

        % Button pushed function: SetColorButton
        function SetColorButtonPushed(app, event)
            c = uisetcolor( app.ColorLabel.BackgroundColor, 'Please choose a color.');
            app.ColorLabel.BackgroundColor = c;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % switch low and high freq
            if app.BPHighFreqEditField.Value < app.BPLowFreqEditField.Value
                tmp = app.BPHighFreqEditField.Value;
                app.BPHighFreqEditField.Value = app.BPLowFreqEditField.Value;
                app.BPLowFreqEditField.Value = tmp;
                
                % Update name
                BPLowFreqEditFieldValueChanged( app);
            end

            if abs( app.BPLowFreqEditField.Value - app.BPHighFreqEditField.Value) < eps
                app.BPLowFreqEditField.FontColor = 'r';
                app.BPHighFreqEditField.FontColor = 'r';
                app.InfoLabel.Text = 'Low and high cutoff frequencies must be different.';
                app.InfoLabel.Text = 'r';
                return;
            end
            
            % window name must be set
            if isempty( app.WinNameEditField.Value)
                app.WinNameEditField.BackgroundColor = [0.8, 0, 0];
                app.InfoLabel.Text = 'Window name can not be empty.';
                app.InfoLabel.FontColor = 'r';
                return;
            end

            win_name = strtrim( app.WinNameEditField.Value);   
            param_win_name = cellfun( @(x) x.Name, app.CallingApp.data.bpfilt.param, 'UniformOutput', false);
            if ismember( win_name, param_win_name)    
                warndlg( 'Window exists already. Please cancel.', 'Cancel');
                app.CallingApp.data.bpfilt.update_plot = 'no';
                
            else
                % Pass all parameters to the MainApp
                N = length( app.CallingApp.data.bpfilt.mat) + 1;
                app.CallingApp.data.bpfilt.param{ N, 1} = app.data;               
                app.CallingApp.data.bpfilt.param{ N, 1}.Name  = win_name;
                
                % 1-open new window; 0-do not open new window
                app.CallingApp.data.bpfilt.param{ N, 1}.NewWin = int8( app.NewwindowCheckBox.Value);    
                app.CallingApp.data.bpfilt.param{ N, 1}.Color = app.ColorLabel.BackgroundColor;
                
                % set update_plot to 'yes', so the main app knows it has to update the plots
                app.CallingApp.data.bpfilt.update_plot = 'yes';
                CancelButtonPushed( app);
            end  
        end

        % Close request function: FilterSetupUIFigure
        function FilterSetupUIFigureCloseRequest(app, event)
            delete(app);            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete( app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterSetupUIFigure = uifigure('Visible', 'off');
            app.FilterSetupUIFigure.Name = 'Filter Setup';
            app.FilterSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @FilterSetupUIFigureCloseRequest, true);
            app.Panel = uipanel(app.FilterSetupUIFigure);
            app.LowfrequencyHzEditFieldLabel = uilabel(app.Panel);
            app.LowfrequencyHzEditFieldLabel.Text = 'Low frequency (Hz):';
            app.BPLowFreqEditField = uieditfield(app.Panel, 'numeric');
            app.BPLowFreqEditField.ValueChangedFcn = createCallbackFcn(app, @BPLowFreqEditFieldValueChanged, true);
            app.HighfrequencyHzEditFieldLabel = uilabel(app.Panel);
            app.HighfrequencyHzEditFieldLabel.Text = 'High frequency (Hz);';
            app.BPHighFreqEditField = uieditfield(app.Panel, 'numeric');
            app.BPHighFreqEditField.ValueChangedFcn = createCallbackFcn(app, @BPHighFreqEditFieldValueChanged, true);
            app.FiltertypeDropDownLabel = uilabel(app.Panel);
            app.FiltertypeDropDownLabel.Text = 'Filter type:';
            app.BPFilterTypeDropDown = uidropdown(app.Panel);
            app.BPFilterTypeDropDown.Items = {'FIR', 'IIR'};
            app.BPFilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BPFilterTypeDropDownValueChanged, true);
            app.BPFilterTypeDropDown.Value = 'FIR';
            app.Panel_2 = uipanel(app.FilterSetupUIFigure);
            app.NotchFilterCheckBox = uicheckbox(app.Panel_2);
            app.NotchFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterCheckBoxValueChanged, true);
            app.NotchFilterCheckBox.Text = 'Notch filter';
            app.FrequencyLabel = uilabel(app.Panel_2);
            app.FrequencyLabel.Text = 'Frequency:';
            app.NotchFreqDropDown = uidropdown(app.Panel_2);
            app.NotchFreqDropDown.Items = {'50 & Hamonics', '60 & Harmonics'};
            app.NotchFreqDropDown.ValueChangedFcn = createCallbackFcn(app, @NotchFreqDropDownValueChanged, true);
            app.NotchFreqDropDown.Value = '60 & Harmonics';
            app.FiltertypeDropDown_2Label = uilabel(app.Panel_2);
            app.FiltertypeDropDown_2Label.Text = 'Filter type:';
            app.NotchFilterTypeDropDown = uidropdown(app.Panel_2);
            app.NotchFilterTypeDropDown.Items = {'FIR', 'IIR'};
            app.NotchFilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NotchFilterTypeDropDownValueChanged, true);
            app.NotchFilterTypeDropDown.Value = 'FIR';
            app.BandwidthHzEditFieldLabel = uilabel(app.Panel_2);
            app.BandwidthHzEditFieldLabel.Text = 'Bandwidth (Hz):';
            app.NotchBandwidthEditField = uieditfield(app.Panel_2, 'numeric');
            app.NotchBandwidthEditField.ValueChangedFcn = createCallbackFcn(app, @NotchBandwidthEditFieldValueChanged, true);
            app.NotchBandwidthEditField.Value = 4;
            app.SetColorButton = uibutton(app.FilterSetupUIFigure, 'push');
            app.SetColorButton.ButtonPushedFcn = createCallbackFcn(app, @SetColorButtonPushed, true);
            app.SetColorButton.Text = 'Color';
            app.ColorLabel = uilabel(app.FilterSetupUIFigure);
            app.InfoLabel = uilabel(app.FilterSetupUIFigure);
            app.InfoLabel.Text = 'Label2';
            app.CancelButton = uibutton(app.FilterSetupUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.FilterSetupUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.NewwindowCheckBox = uicheckbox(app.FilterSetupUIFigure);
            app.NewwindowCheckBox.Text = 'Open a new window';
            app.NameEditFieldLabel = uilabel(app.FilterSetupUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.WinNameEditField = uieditfield(app.FilterSetupUIFigure, 'text');
            app.WinNameEditField.ValueChangedFcn = createCallbackFcn(app, @WinNameEditFieldValueChanged, true);
            app.WinNameEditField.ValueChangingFcn = createCallbackFcn(app, @WinNameEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.FilterSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigFilter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilterSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterSetupUIFigure)
        end
    end
end