classdef ConfigMontage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MontageUIFigure                matlab.ui.Figure
        RereferencemethodButtonGroup   matlab.ui.container.ButtonGroup
        UseBRButton                    matlab.ui.control.RadioButton
        UseCARButton                   matlab.ui.control.RadioButton
        ResetChannelButton             matlab.ui.control.RadioButton
        AllChannelListBox              matlab.ui.control.ListBox
        BRTable                        matlab.ui.control.Table
        AddButton                      matlab.ui.control.Button
        RemoveButton                   matlab.ui.control.Button
        CommonaveragerereferenceLabel  matlab.ui.control.Label
        CARListBox                     matlab.ui.control.ListBox
        RefTitleLabel                  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        AllchannelsLabel               matlab.ui.control.Label
        NbRowEditField                 matlab.ui.control.NumericEditField
        AddRowButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        table_cell_ind
        table_cur_cell
        auto_move
        ref_method
    end
    
    methods (Access = private)
        
        function DoReref(app)
            % Close all windows
            app.CallingApp.DeleteAllWins( 'Montage');            
            
            app.CallingApp.data.results_saved = 'no';            
            app.CallingApp.data.savefile = [];      
            
            min_val = min( app.CallingApp.data.mat( 1, :), [], 'all');
            max_val = max( app.CallingApp.data.mat( 1, :), [], 'all');
            app.CallingApp.data.raw_limit = [-1, 1] * max( abs( [min_val, max_val]));            
            app.CallingApp.data.limit = app.CallingApp.data.raw_limit;            
                        
            % Band-pass filtered data to be passed to other windows
            app.CallingApp.data.bpfilt.mat = {};
            % amplitude time series from Hilbert transform
            app.CallingApp.data.bpfilt.amp = {};
            % z score normalized using whole time series
            app.CallingApp.data.bpfilt.z = {};            
            app.CallingApp.data.bpfilt.param = {};
            app.CallingApp.data.bpfilt.labels = {};
            app.CallingApp.data.bpfilt.update_plot = 'no';
            
            % Spectrograms
            app.CallingApp.data.tf.mat = {};
            app.CallingApp.data.tf.param = {};
            app.CallingApp.data.tf.labels = {};              

            app.CallingApp.data.events = [];
            app.CallingApp.data.disp_events = [];
            
            % Initialize GUI
            chnl_labels = app.CallingApp.data.labels;
            app.CallingApp.AllChannelListBox.Items = chnl_labels;
            app.CallingApp.ChannelListBox.Items = chnl_labels( 1);
            app.CallingApp.ChannelListBox.Value = chnl_labels( 1);
            app.CallingApp.EventListBox.Items = {};
            
            app.CallingApp.uidata.current_channel = {chnl_labels(1), 0};
            
            app.CallingApp.PRedraw();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % This is an app called by HFO MainApp
            if nargin < 2 || isempty( mainapp.data)
                for k = 1 : length( app.MontageUIFigure.Children)
                    app.MontageUIFigure.Children( k).Visible = 'off';
                end                
                return;
            end
            
            app.CallingApp = mainapp;
            
            if isempty( app.CallingApp.data.raw)
                % Save the raw data before rereferencing
                if isempty( app.CallingApp.data.trldata)
                    app.CallingApp.data.raw.mat = app.CallingApp.data.mat;
                else
                    app.CallingApp.data.raw.mat = app.CallingApp.data.trldata;
                end
                
                app.CallingApp.data.raw.labels = app.CallingApp.data.labels;                
            end
                        
            % Bipolar refere table
            app.table_cell_ind = 1;
            app.table_cur_cell = [];
            % move throug the current item automatically
            app.auto_move = 'yes'; % 'yes' | 'no'
            app.ref_method = 'bp'; % 'car' | 'bp'
                        
            % initial number of row of the table
            nb_rows = 15;
            app.BRTable.Data = cell( nb_rows, 2);
            app.BRTable.RowName = num2cell( (1:nb_rows)');
            
            app.AllChannelListBox.Items = app.CallingApp.data.raw.labels;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if strcmpi( app.ref_method, 'bp')
                % bipolar reference
                % remove the entire row
                if ~isempty( app.table_cur_cell)
                    row_ind = unique( app.table_cur_cell( :, 1), 'stable');
                    
                    data = app.BRTable.Data;
                    data( row_ind, :) = [];
                    if ~isempty( data)
                        loc2keep = [];
                        for k = 1 : size( data, 1)
                            if ~isempty( data{ k, 1}) || ~isempty( data{ k, 2})
                                loc2keep = [loc2keep; k];
                            end
                        end
                        
                        app.BRTable.Data = cell( size( app.BRTable.Data, 1), 2);
                        app.BRTable.Data( 1:length( loc2keep), :) = data( loc2keep, :);
                        
                    else
                        app.BRTable.Data = cell( size( app.BRTable.Data, 1), 2);
                    end                    
                end
                
            else
                % common average reference
                val = app.CARListBox.Value;
                [~, loc] = ismember( val, app.CARListBox.Items);
                app.CARListBox.Items( loc) = [];
                if ~isempty( app.CARListBox.Items)
                    app.CARListBox.Value = app.CARListBox.Items(1);
                else
                    app.CARListBox.Value = {};
                end
                
                remain_label = app.AllChannelListBox.Items;
                remain_label = cat( 2, remain_label, val);
                if ~isempty( remain_label)
                    [~, loc] = ismember( remain_label, app.CallingApp.data.raw.labels);
                    [~, idx] = sort( loc);
                    app.AllChannelListBox.Items = remain_label( idx);
                    app.AllChannelListBox.Value = app.AllChannelListBox.Items(1);
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             delete( app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            if strcmpi( app.ref_method, 'bp')                
                % The table must not be empty
                ind = cellfun( @(x) ~isempty( x), app.BRTable.Data);
                is_ok = any( any( ind, 2));
                
                if ~is_ok
                    return;   
                    
                else
                    % make sure the combination are unique
                    good_ind = find( all( ind, 2));
                    label = cell( length( good_ind), 1);
                    for k = 1 : length( good_ind)
                        label{ k} = [app.BRTable.Data{ good_ind(k), 1}, '-', app.BRTable.Data{ good_ind(k), 2}];
                    end
                    
                    [label, loc] = unique( label, 'stable');
                    good_ind = good_ind( loc);                    
                    
                    N = length( good_ind);
                    nbsamples = size( app.CallingApp.data.raw.mat, 2);
                    nbtrls = size( app.CallingApp.data.raw.mat, 3);
                    if nbtrls > 1                       
                        app.CallingApp.data.trldata = zeros( N, nbsamples, nbtrls);
                        for k = 1 : N
                            [~, mat_loc] = ismember( app.BRTable.Data( good_ind(k), :), app.CallingApp.data.raw.labels);
                            app.CallingApp.data.trldata( k, :, :) = -1 * diff( app.CallingApp.data.raw.mat( mat_loc, :, :), [], 1);
                        end
                        
                        trl_idx = app.CallingApp.uidata.trial_index;
                        app.CallingApp.data.mat = app.CallingApp.data.trldata( :, :, trl_idx);
                        
                    else
                        app.CallingApp.data.mat = zeros( N, nbsamples);
                        for k = 1 : N
                            [~, mat_loc] = ismember( app.BRTable.Data( good_ind(k), :), app.CallingApp.data.raw.labels);
                            app.CallingApp.data.mat( k, :) = -1 * diff( app.CallingApp.data.raw.mat( mat_loc, :, :), [], 1);
                        end
                    end
                    
                    app.CallingApp.data.labels = label;
                    
                    DoReref(app);                    
                    CancelButtonPushed( app);
                end % do bipolar reference
                                
                
            elseif strcmpi( app.ref_method, 'car')     
                % The listbox must not be empty
                if length( app.CARListBox.Items) < 2
                    return;
                else
                    
                   app.CallingApp.data.labels = app.CARListBox.Items;
                   [~, loc] = ismember( app.CARListBox.Items, app.CallingApp.data.raw.labels);
                    
                   if ~isempty( app.CallingApp.data.trldata)
                       % multiple trials
                       app.CallingApp.data.trldata = bsxfun( @minus,...
                           app.CallingApp.data.raw.mat( loc, :, :),...
                           mean( app.CallingApp.data.raw.mat( loc, :, :), 1));
                       
                       trl_idx = app.CallingApp.uidata.trial_index;
                       app.CallingApp.data.mat = app.CallingApp.data.trldata( :, :, trl_idx);
                       
                   else
                       app.CallingApp.data.mat = bsxfun( @minus,...
                           app.CallingApp.data.raw.mat( loc, :, :),...
                           mean( app.CallingApp.data.raw.mat( loc, :, :), 1));
                   end

                   DoReref( app);
                   CancelButtonPushed( app);
                end     
                
            else
                % restore
                app.CallingApp.data.labels = app.CallingApp.data.raw.labels;
                if ~isempty( app.CallingApp.data.trldata)
                    app.CallingApp.data.trldata = app.CallingApp.data.raw.mat;
                    trl_idx = app.CallingApp.uidata.trial_index;
                    app.CallingApp.data.mat = app.CallingApp.data.trldata( :, :, trl_idx);
                    
                else
                    app.CallingApp.data.mat = app.CallingApp.data.raw.mat;
                end  
                
                DoReref(app);                    
                CancelButtonPushed( app);
            end
        end

        % Cell selection callback: BRTable
        function BRTableCellSelection(app, event)
            indices = event.Indices;
            app.table_cur_cell = indices;
            
            if length( indices) == 2
                app.table_cell_ind = (indices(1) - 1) * 2 + indices(2);
                
            else
                for k = 1 : size( app.BRTable.DisplayData, 1)
                    if isempty( app.BRTable.DisplayData{ k, 1}) && isempty( app.BRTable.DisplayData{ k, 2})
                        app.table_cell_ind = (k - 1) * 2 + 1;
                        break;
                    end
                end                
            end
        end

        % Selection changed function: RereferencemethodButtonGroup
        function RereferencemethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.RereferencemethodButtonGroup.SelectedObject;
            
            % restore all channel list
            app.AllChannelListBox.Items = app.CallingApp.data.raw.labels;
            app.AllChannelListBox.Value = app.CallingApp.data.raw.labels(1);
                
            % clear
            app.CARListBox.Items = {};
            app.BRTable.Data = cell( 64, 2);
            app.table_cell_ind = 1;
            
            app.AddButton.Visible = 'on';
            app.RemoveButton.Visible = 'on';
            app.AddRowButton.Visible = 'on';
            app.BRTable.Visible = 'on';
            app.CARListBox.Visible = 'on';
            app.RefTitleLabel.Visible = 'on';
            app.NbRowEditField.Visible = 'on';
            
            if selectedButton == app.UseBRButton
                %fprintf( 'Bipolar reference.\n');
                app.ref_method = 'bp';  
                
                app.BRTable.Visible = 'on';
                app.AddRowButton.Visible = 'on';
                app.NbRowEditField.Visible = 'on';
                app.CARListBox.Visible = 'off';
                app.RefTitleLabel.Text = 'Bipolar reference';
                app.RemoveButton.Text = 'Remove rows';
                
            elseif selectedButton == app.UseCARButton
                % fprintf( 'Common average reference.\n');
                app.ref_method = 'car';                
                
                app.RefTitleLabel.Text = 'Common average reference';
                
                app.BRTable.Visible = 'off';   
                app.AddRowButton.Visible = 'off';
                app.NbRowEditField.Visible = 'off';
                app.RemoveButton.Text = '<<';
                
                app.CARListBox.Visible = 'on';
                app.CARListBox.Position( [1, 3]) = app.BRTable.Position( [1, 3]);
                app.CARListBox.Position( 2) = app.AllChannelListBox.Position( 2);
                
            else
                app.ref_method = 'reset';
                
                app.AddButton.Visible = 'off';
                app.RemoveButton.Visible = 'off';
                app.AddRowButton.Visible = 'off';
                app.BRTable.Visible = 'off';
                app.CARListBox.Visible = 'off';
                app.RefTitleLabel.Visible = 'off';
                app.NbRowEditField.Visible = 'off';
            end
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if strcmpi( app.ref_method, 'bp')
                % Add rows if necessary
                if app.table_cell_ind >= 2 * size( app.BRTable.Data, 1)
                    app.AddRowButtonPushed( app);
                end
                
                
                % bipolar reference
                tmp = [2, 1];            
                row_ind = ceil( app.table_cell_ind / 2);
                col_ind = tmp( mod( app.table_cell_ind, 2) + 1);
                app.BRTable.Data{ row_ind, col_ind} = app.AllChannelListBox.Value;
                
                app.table_cell_ind = app.table_cell_ind + 1;  
                if strcmpi( app.auto_move, 'yes')
                    [~, loc] = ismember( app.AllChannelListBox.Value, app.AllChannelListBox.Items);
                    loc = min( [loc + 1, length( app.AllChannelListBox.Items)]);
                    app.AllChannelListBox.Value = app.AllChannelListBox.Items( loc);
                end
               
                
            else
               % common average reference 
               val = app.AllChannelListBox.Value;
               [~, loc] = ismember( val, app.AllChannelListBox.Items);
               app.AllChannelListBox.Items( loc) = [];
               if ~isempty( app.AllChannelListBox.Items)
                   app.AllChannelListBox.Value = app.AllChannelListBox.Items(1);
               else
                   app.AllChannelListBox.Value = {};
               end
               
               cur_label4ref = app.CARListBox.Items;
               cur_label4ref = cat( 2, cur_label4ref, val);               
               if ~isempty( cur_label4ref)
                   [~, loc] = ismember( cur_label4ref, app.CallingApp.data.raw.labels);
                   [~, idx] = sort( loc);
                   cur_label4ref = cur_label4ref( idx);
                   app.CARListBox.Items = cur_label4ref;
                   app.CARListBox.Value = app.CARListBox.Items(1);
               end
               
            end % ref method
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            N = app.NbRowEditField.Value;
            tmp = cell( N, 2);
            app.BRTable.Data = cat( 1, app.BRTable.Data, tmp);            
            M = size( app.BRTable.Data, 1);
            row_name = num2cell( ( M+1 : M +N)');
            app.BRTable.RowName = cat( 1, app.BRTable.RowName, row_name);
        end

        % Close request function: MontageUIFigure
        function MontageUIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MontageUIFigure = uifigure('Visible', 'off');
            app.MontageUIFigure.Name = 'Montage';
            app.MontageUIFigure.CloseRequestFcn = createCallbackFcn(app, @MontageUIFigureCloseRequest, true);
            app.RereferencemethodButtonGroup = uibuttongroup(app.MontageUIFigure);
            app.RereferencemethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RereferencemethodButtonGroupSelectionChanged, true);
            app.RereferencemethodButtonGroup.Title = 'Re-reference method';
            app.UseBRButton = uiradiobutton(app.RereferencemethodButtonGroup);
            app.UseBRButton.Text = 'Bipolar reference';
            app.UseBRButton.Value = true;
            app.UseCARButton = uiradiobutton(app.RereferencemethodButtonGroup);
            app.UseCARButton.Text = 'Common average reference';
            app.ResetChannelButton = uiradiobutton(app.RereferencemethodButtonGroup);
            app.ResetChannelButton.Text = 'Reset';
            app.AllChannelListBox = uilistbox(app.MontageUIFigure);
            app.BRTable = uitable(app.MontageUIFigure);
            app.BRTable.ColumnName = {'Column 1'; 'Column 2'};
            app.BRTable.RowName = {};
            app.BRTable.CellSelectionCallback = createCallbackFcn(app, @BRTableCellSelection, true);
            app.BRTable.Tooltip = {'Select a channel in the All channels listbox, then highlight a cell the table and press >>.'};
            app.AddButton = uibutton(app.MontageUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '>>';
            app.RemoveButton = uibutton(app.MontageUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<<';
            app.CommonaveragerereferenceLabel = uilabel(app.MontageUIFigure);
            app.CommonaveragerereferenceLabel.Text = 'Common average rereference';
            app.CARListBox = uilistbox(app.MontageUIFigure);
            app.RefTitleLabel = uilabel(app.MontageUIFigure);
            app.RefTitleLabel.Text = 'Bipolar reference';
            app.CancelButton = uibutton(app.MontageUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.MontageUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.AllchannelsLabel = uilabel(app.MontageUIFigure);
            app.AllchannelsLabel.Text = 'All channels';
            app.NbRowEditField = uieditfield(app.MontageUIFigure, 'numeric');
            app.NbRowEditField.Limits = [1 Inf];
            app.NbRowEditField.Tooltip = {'Specify the number of row to add. The rows can also be added automatically.'};
            app.NbRowEditField.Value = 1;
            app.AddRowButton = uibutton(app.MontageUIFigure, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add rows';
            % Show the figure after all components are created
            app.MontageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigMontage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MontageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MontageUIFigure)
        end
    end
end