classdef ConfigOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsUIFigure                 matlab.ui.Figure
        TimeseriesplotsnavigationPanel  matlab.ui.container.Panel
        MajorstepsizeEditFieldLabel     matlab.ui.control.Label
        MajorstepsizeEditField          matlab.ui.control.NumericEditField
        MinorstepsizeEditFieldLabel     matlab.ui.control.Label
        MinorstepsizeEditField          matlab.ui.control.NumericEditField
        HighlightcurrentchannelbackgroundCheckBox  matlab.ui.control.CheckBox
        HighlightBKColorButton          matlab.ui.control.Button
        BKColorLabel                    matlab.ui.control.Label
        ShowmainseparatorlineCheckBox   matlab.ui.control.CheckBox
        SetMainSepColorButton           matlab.ui.control.Button
        MainSepColorLabel               matlab.ui.control.Label
        ShowminorseparatrolineCheckBox  matlab.ui.control.CheckBox
        SetMinorSepButton               matlab.ui.control.Button
        MinorSepColorLabel              matlab.ui.control.Label
        TimedomainautomaticeventdetectionPanel  matlab.ui.container.Panel
        SearchwindowsLabel              matlab.ui.control.Label
        AutoSearchWinEditField          matlab.ui.control.NumericEditField
        AutoMergeEvsCheckBox            matlab.ui.control.CheckBox
        AutomergedurationthresholdsLabel  matlab.ui.control.Label
        AutoMergeEvsDurThreshEditField  matlab.ui.control.NumericEditField
        AutomergezscorethresholdsEditFieldLabel  matlab.ui.control.Label
        AutomergezscorethresholdsEditField  matlab.ui.control.NumericEditField
        TimewindowformanualadjustmentsLabel  matlab.ui.control.Label
        AutoAdjWin                      matlab.ui.control.NumericEditField
        EventcolorPanel                 matlab.ui.container.Panel
        GammaButton                     matlab.ui.control.Button
        RippleButton                    matlab.ui.control.Button
        FastrippleButton                matlab.ui.control.Button
        UltrafastButton                 matlab.ui.control.Button
        SpikeButton                     matlab.ui.control.Button
        EVColorGamma                    matlab.ui.control.Label
        EVColorRipple                   matlab.ui.control.Label
        EVColorFastRipple               matlab.ui.control.Label
        EVColorUltraFast                matlab.ui.control.Label
        EVColorSpike                    matlab.ui.control.Label
        EventlistsortmethodButtonGroup  matlab.ui.container.ButtonGroup
        SortEvChannelButton             matlab.ui.control.RadioButton
        SortEvLocationButton            matlab.ui.control.RadioButton
        FontsizeEditFieldLabel          matlab.ui.control.Label
        FontsizeEditField               matlab.ui.control.NumericEditField
        TSColorButton                   matlab.ui.control.Button
        TSColorLabel                    matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        TimeformatButtonGroup           matlab.ui.container.ButtonGroup
        ClockTypeClockButton            matlab.ui.control.RadioButton
        ClockTypeSecondsButton          matlab.ui.control.RadioButton
        SpectrogramcolormapDropDownLabel  matlab.ui.control.Label
        CmapDropDown                    matlab.ui.control.DropDown
        CursorlocationthresholdsEditFieldLabel  matlab.ui.control.Label
        AutoCursorDistLimitEditField    matlab.ui.control.NumericEditField
        NumberofxticksEditFieldLabel    matlab.ui.control.Label
        NumberofxticksEditField         matlab.ui.control.NumericEditField
        CurTSColorButton                matlab.ui.control.Button
        CurTSColorLabel                 matlab.ui.control.Label
        CursorFullRange                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Description
        uidata
        fnames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            if nargin < 2
                for k = 1 : length( app.OptionsUIFigure.Children)
                    app.OptionsUIFigure.Children( k).Visible = 'off';
                end
                
                return;
            end
            
     
            nam = fieldnames( mainapp);
            fig_h = cellfun( @(x) strfind( x, 'UIFigure'), nam, 'UniformOutput', false);
            fig_h = find( cell2mat( fig_h));
            nam = nam{ fig_h(1) };
            
            p = mainapp.( nam).Position;
            fig_pos = app.OptionsUIFigure.Position;            
            app.OptionsUIFigure.Position( 1) = p(1) + p(3)/2 - fig_pos(3)/2;
            app.OptionsUIFigure.Position( 2) = p(2) + p(4)/2 - fig_pos(4)/2;
            
            app.CallingApp = mainapp;
           
            app.fnames = {'win_major_step',...
                'win_minor_step',...
                'color_main',...
                'show_main_separator',...
                'show_minor_separator',...
                'color_main_separator',...
                'color_minor_separator',...
                'color_current_background',...
                'color_current_channel',...
                'cursor_full_range',...
                'event_sort_method',...
                'fontsize',...
                'all_event_color',...
                'autoev_search_len',...
                'autoev_distance_thresh',...
                'autoev_automerge',...
                'autoev_automerge_durthresh',...
                'manual_adj_win',...
                'autoev_automerge_zthresh',...
                'show_current_background',...
                'clock_type',...
                'nb_xticks',...
                'freq_colormap'};
            
            for k = 1 : length( app.fnames)
                app.uidata.( app.fnames{ k}) = app.CallingApp.uidata.( app.fnames{ k});
            end
            
            % step size when you press <</< and >>/>, relative to window
            % length, 0-1
            app.MajorstepsizeEditField.Value = app.uidata.win_major_step;
            app.MinorstepsizeEditField.Value = app.uidata.win_minor_step;
            
            app.TSColorLabel.BackgroundColor = app.uidata.color_main;
            
            app.CurTSColorLabel.Text = '';
            app.CurTSColorLabel.BackgroundColor = app.uidata.color_current_channel;
            if strcmpi( app.uidata.show_main_separator, 'yes')
                app.ShowmainseparatorlineCheckBox.Value = 1;
                app.SetMainSepColorButton.Enable = 'on';                
               
            else
                app.SetMainSepColorButton.Enable = 'off';
                app.ShowmainseparatorlineCheckBox.Value = 0;
            end            
            app.MainSepColorLabel.BackgroundColor = app.uidata.color_main_separator;
            app.MainSepColorLabel.Text = '';
            
            if strcmpi( app.uidata.show_minor_separator, 'yes')
                app.ShowminorseparatrolineCheckBox.Value = 1;
                app.SetMinorSepButton.Enable = 'on';
            else
                app.ShowminorseparatrolineCheckBox.Value = 0;
                app.SetMinorSepButton.Enable = 'off';
            end
            app.MinorSepColorLabel.BackgroundColor = app.uidata.color_minor_separator;
            app.MinorSepColorLabel.Text = '';
            
            if strcmpi( app.uidata.show_current_background, 'yes')
                app.HighlightcurrentchannelbackgroundCheckBox.Value = 1;
                app.HighlightBKColorButton.Enable = 'on';
            else
                app.HighlightcurrentchannelbackgroundCheckBox.Value = 0;
                app.HighlightBKColorButton.Enable = 'off';
            end
            app.BKColorLabel.BackgroundColor = app.uidata.color_current_background;
            app.BKColorLabel.Text = '';
            
            if strcmpi( app.uidata.cursor_full_range, 'yes')
                app.CursorFullRange.Value = 1;
            else
                app.CursorFullRange.Value = 0;
            end
            
            %'%' 'location' | 'channel'
            % app.uidata.event_sort_method = 'channel';
            if strcmpi( app.uidata.event_sort_method, 'channel')
                app.SortEvChannelButton.Value = 1;
            else
                app.SortEvLocationButton.Value = 1;
            end
            
            app.FontsizeEditField.Value = app.uidata.fontsize;      
            app.uidata.old_fontsize = app.uidata.fontsize;
            
            %app.uidata.all_event_color = {'r', 'b', 'g', [0.5, 0.7, 0.1], [0.3, 0.1, 0]};
            app.EVColorGamma.BackgroundColor = app.uidata.all_event_color{1};
            app.EVColorRipple.BackgroundColor = app.uidata.all_event_color{2};
            app.EVColorFastRipple.BackgroundColor = app.uidata.all_event_color{3};
            app.EVColorUltraFast.BackgroundColor = app.uidata.all_event_color{4};
            app.EVColorSpike.BackgroundColor = app.uidata.all_event_color{5};
            
             % If there's any data point within +/- 500 ms aroudn the cusrolor meet the criteria
            % cluster search time window
            app.AutoSearchWinEditField.Value = app.uidata.autoev_search_len;
            
            % cusor must be within the cluster, or within a cenrtain
            % distance from the cluster
            % app.uidata.autoev_distance_thresh = 0.4;
            app.AutoCursorDistLimitEditField.Value = app.uidata.autoev_distance_thresh;
            
            % merge detected events
            % If all z values between events are greater than 2
            % and the distance between them is less thann a threshold
            % is_merge = 'no';
            % app.uidata.autoev_automerge = 'no';
            if strcmpi( app.uidata.autoev_automerge, 'yes')
                app.AutoMergeEvsCheckBox.Value = 1;
                app.AutomergezscorethresholdsEditField.Enable = 'on';
                app.AutoMergeEvsDurThreshEditField.Enable = 'on';
            else
                app.AutomergezscorethresholdsEditField.Enable = 'off';
                app.AutoMergeEvsDurThreshEditField.Enable = 'off';
                app.AutoMergeEvsCheckBox.Value = 0;
            end
            
            % in seconds, any clusters within this distance will be mreged
            % merge_durthresh = 0.05;
            %app.uidata.autoev_automerge_durthresh = 0.05;
            app.AutoMergeEvsDurThreshEditField.Value = app.uidata.autoev_automerge_durthresh;
            
            % all samples between two clusters are greater than this threshold
            % app.uidata.autoev_automerge_zthresh = 2;
            app.AutomergezscorethresholdsEditField.Value = app.uidata.autoev_automerge_zthresh;
            
            app.AutoAdjWin.Value = app.uidata.manual_adj_win;
            
            if strcmpi( app.uidata.clock_type, 'clock')
                app.TimeformatButtonGroup.SelectedObject = app.ClockTypeClockButton;
            else
                app.TimeformatButtonGroup.SelectedObject = app.ClockTypeSecondsButton;
            end
            
            app.CmapDropDown.Value = app.uidata.freq_colormap;
            
            app.BKColorLabel.Text = '';
            app.MainSepColorLabel.Text = '';
            app.MinorSepColorLabel.Text = '';
            app.EVColorFastRipple.Text = '';
            app.EVColorGamma.Text = '';
            app.EVColorRipple.Text = '';
            app.EVColorSpike.Text = '';
            app.EVColorUltraFast.Text = '';
            app.TSColorLabel.Text = '';
            
            app.MajorstepsizeEditField.Limits = [0, 1];
            app.MinorstepsizeEditField.Limits = [0, 1];
            app.AutoSearchWinEditField.Limits = [0.00000001, Inf];
            app.AutoCursorDistLimitEditField.Limits = [0, Inf];
            app.AutomergezscorethresholdsEditField.Limits = [-Inf, Inf];
            app.AutoMergeEvsDurThreshEditField.Limits = [0, 10];
            app.FontsizeEditField.Limits = [6, 20];
        end

        % Value changed function: MajorstepsizeEditField
        function MajorstepsizeEditFieldValueChanged(app, event)
            value = app.MajorstepsizeEditField.Value;
            app.uidata.win_major_step = value;
        end

        % Value changed function: MinorstepsizeEditField
        function MinorstepsizeEditFieldValueChanged(app, event)
            value = app.MinorstepsizeEditField.Value;
            app.uidata.win_minor_step = value;
        end

        % Value changed function: 
        % HighlightcurrentchannelbackgroundCheckBox
        function HighlightcurrentchannelbackgroundCheckBoxValueChanged(app, event)
            value = app.HighlightcurrentchannelbackgroundCheckBox.Value;
            if value == 1
                app.uidata.show_current_background = 'yes';
                app.HighlightBKColorButton.Enable = 'on';
            else
                app.uidata.show_current_background = 'no';
                app.HighlightBKColorButton.Enable = 'off';
            end
        end

        % Button pushed function: HighlightBKColorButton
        function HighlightBKColorButtonPushed(app, event)
            c = uisetcolor( app.uidata.color_current_background, 'Set background color of current channel.');
            app.uidata.color_current_background = c;
            app.BKColorLabel.BackgroundColor = c;
        end

        % Value changed function: ShowmainseparatorlineCheckBox
        function ShowmainseparatorlineCheckBoxValueChanged(app, event)
            value = app.ShowmainseparatorlineCheckBox.Value;
            if value == 1
                app.SetMainSepColorButton.Enable = 'on';
                app.uidata.show_main_separator = 'yes';
            else
                app.SetMainSepColorButton.Enable = 'off';
                app.uidata.show_main_separator = 'no';
            end
        end

        % Value changed function: ShowminorseparatrolineCheckBox
        function ShowminorseparatrolineCheckBoxValueChanged(app, event)
            value = app.ShowminorseparatrolineCheckBox.Value;
            if value == 1
                app.SetMinorSepButton.Enable = 'on';
                app.uidata.show_minor_separator = 'yes';
            else
                app.SetMinorSepButton.Enable = 'off';
                app.uidata.show_minor_separator = 'no';
            end
        end

        % Button pushed function: SetMainSepColorButton
        function SetMainSepColorButtonPushed(app, event)
            c = uisetcolor( app.uidata.color_main_separator, 'Set main separator line color.');
            app.uidata.color_main_separator = c;
            app.MainSepColorLabel.BackgroundColor = c;
        end

        % Button pushed function: SetMinorSepButton
        function SetMinorSepButtonPushed(app, event)
            c = uisetcolor( app.uidata.color_minor_separator, 'Set main separator line color.');
            app.uidata.color_minor_separator = c;
            app.MinorSepColorLabel.BackgroundColor = c;
        end

        % Button pushed function: GammaButton
        function GammaButtonPushed(app, event)
            c = uisetcolor( app.uidata.all_event_color{1}, 'Set Gamma event color.');
            app.uidata.all_event_color{1} = c;
            app.EVColorGamma.BackgroundColor = c;
        end

        % Button pushed function: RippleButton
        function RippleButtonPushed(app, event)
            c = uisetcolor( app.uidata.all_event_color{2}, 'Set Ripple event color.');
            app.uidata.all_event_color{2} = c;
            app.EVColorRipple.BackgroundColor = c;  
        end

        % Button pushed function: FastrippleButton
        function FastrippleButtonPushed(app, event)
            c = uisetcolor( app.uidata.all_event_color{3}, 'Set Fast Ripple event color.');
            app.uidata.all_event_color{3} = c;
            app.EVColorFastRipple.BackgroundColor = c;
        end

        % Button pushed function: UltrafastButton
        function UltrafastButtonPushed(app, event)
            c = uisetcolor( app.uidata.all_event_color{4}, 'Set Ultra fast event color.');
            app.uidata.all_event_color{4} = c;
            app.EVColorUltraFast.BackgroundColor = c;
        end

        % Button pushed function: SpikeButton
        function SpikeButtonPushed(app, event)
            c = uisetcolor( app.uidata.all_event_color{5}, 'Set Spike event color.');
            app.uidata.all_event_color{5} = c;
            app.EVColorSpike.BackgroundColor = c;
        end

        % Value changed function: AutoSearchWinEditField
        function AutoSearchWinEditFieldValueChanged(app, event)
            value = app.AutoSearchWinEditField.Value;
            app.uidata.autoev_search_len = value;
        end

        % Value changed function: AutoCursorDistLimitEditField
        function AutoCursorDistLimitEditFieldValueChanged(app, event)
            value = app.AutoCursorDistLimitEditField.Value;
            app.uidata.autoev_distance_thresh = value;
        end

        % Value changed function: AutoMergeEvsCheckBox
        function AutoMergeEvsCheckBoxValueChanged(app, event)
            value = app.AutoMergeEvsCheckBox.Value;
            if value == 1
                app.uidata.autoev_automerge = 'yes';
                app.AutoMergeEvsDurThreshEditField.Enable = 'on';
                app.AutomergezscorethresholdsEditField.Enable = 'on';
                
            else
                app.uidata.autoev_automerge = 'no';
                app.AutoMergeEvsDurThreshEditField.Enable = 'off';
                app.AutomergezscorethresholdsEditField.Enable = 'off';
            end
        end

        % Value changed function: AutoMergeEvsDurThreshEditField
        function AutoMergeEvsDurThreshEditFieldValueChanged(app, event)
            value = app.AutoMergeEvsDurThreshEditField.Value;
            app.uidata.autoev_automerge_durthresh = value;
        end

        % Value changed function: AutomergezscorethresholdsEditField
        function AutomergezscorethresholdsEditFieldValueChanged(app, event)
            value = app.AutomergezscorethresholdsEditField.Value;
            app.uidata.autoev_automerge_zthresh = value;
        end

        % Selection changed function: EventlistsortmethodButtonGroup
        function EventlistsortmethodButtonGroupSelectionChanged(app, event)
            if app.SortEvChannelButton.Value == 1
                app.uidata.event_sort_method = 'channel';
            else
                app.uidata.event_sort_method = 'location';
            end
        end

        % Value changed function: FontsizeEditField
        function FontsizeEditFieldValueChanged(app, event)
            value = app.FontsizeEditField.Value;
            app.uidata.fontsize = value;
        end

        % Button pushed function: TSColorButton
        function TSColorButtonPushed(app, event)
            app.uidata.color_main = uisetcolor( ...
                app.uidata.color_main,...
                'Set color of raw time series.');
            
            app.TSColorLabel.BackgroundColor = app.uidata.color_main;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete( app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            for k = 1 : length( app.fnames)
                app.CallingApp.uidata.( app.fnames{ k}) = app.uidata.( app.fnames{ k});
            end
            
            if ~isequal( app.uidata.old_fontsize, app.uidata.fontsize)
                app.CallingApp.PResizeWindows( app.CallingApp.HFOAppUIFigure.Position, 'main');
            end
            
            app.CallingApp.PUpdateEventList();
            app.CallingApp.PRedraw();
            delete( app);
        end

        % Close request function: OptionsUIFigure
        function OptionsUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Selection changed function: TimeformatButtonGroup
        function TimeformatButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimeformatButtonGroup.SelectedObject;
            if selectedButton == app.ClockTypeClockButton
                app.uidata.clock_type = 'clock';
            else
                app.uidata.clock_type = 'seconds';
            end
        end

        % Value changed function: NumberofxticksEditField
        function NumberofxticksEditFieldValueChanged(app, event)
            app.uidata.nb_xticks = round( app.NumberofxticksEditField.Value);
        end

        % Value changed function: CmapDropDown
        function CmapDropDownValueChanged(app, event)
            app.uidata.freq_colormap = app.CmapDropDown.Value;
        end

        % Button pushed function: CurTSColorButton
        function CurTSColorButtonPushed(app, event)
            app.uidata.color_current_channel = uisetcolor( app.uidata.color_main, 'Set color of raw time series of current channel.');
            app.CurTSColorLabel.BackgroundColor = app.uidata.color_current_channel;
        end

        % Value changed function: CursorFullRange
        function CursorFullRangeValueChanged(app, event)
            if app.CursorFullRange.Value == 0
                app.uidata.cursor_full_range = 'no';
            else
                app.uidata.cursor_full_range = 'yes';
            end
        end

        % Value changed function: AutoAdjWin
        function AutoAdjWinValueChanged(app, event)
            app.uidata.manual_adj_win = app.AutoAdjWin.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsUIFigure = uifigure('Visible', 'off');
            app.OptionsUIFigure.Name = 'Options';
            app.OptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsUIFigureCloseRequest, true);
            app.TimeseriesplotsnavigationPanel = uipanel(app.OptionsUIFigure);
            app.TimeseriesplotsnavigationPanel.Title = 'Time series plots navigation';
            app.MajorstepsizeEditFieldLabel = uilabel(app.TimeseriesplotsnavigationPanel);
            app.MajorstepsizeEditFieldLabel.Text = 'Major step size (>> & <<):';
            app.MajorstepsizeEditField = uieditfield(app.TimeseriesplotsnavigationPanel, 'numeric');
            app.MajorstepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MajorstepsizeEditFieldValueChanged, true);
            app.MinorstepsizeEditFieldLabel = uilabel(app.TimeseriesplotsnavigationPanel);
            app.MinorstepsizeEditFieldLabel.Text = 'Minor stepsize (> & <):';
            app.MinorstepsizeEditField = uieditfield(app.TimeseriesplotsnavigationPanel, 'numeric');
            app.MinorstepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinorstepsizeEditFieldValueChanged, true);
            app.HighlightcurrentchannelbackgroundCheckBox = uicheckbox(app.TimeseriesplotsnavigationPanel);
            app.HighlightcurrentchannelbackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighlightcurrentchannelbackgroundCheckBoxValueChanged, true);
            app.HighlightcurrentchannelbackgroundCheckBox.Text = 'Highlight current channel background';
            app.HighlightBKColorButton = uibutton(app.TimeseriesplotsnavigationPanel, 'push');
            app.HighlightBKColorButton.ButtonPushedFcn = createCallbackFcn(app, @HighlightBKColorButtonPushed, true);
            app.HighlightBKColorButton.Text = 'Choose color';
            app.BKColorLabel = uilabel(app.TimeseriesplotsnavigationPanel);
            app.ShowmainseparatorlineCheckBox = uicheckbox(app.TimeseriesplotsnavigationPanel);
            app.ShowmainseparatorlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmainseparatorlineCheckBoxValueChanged, true);
            app.ShowmainseparatorlineCheckBox.Text = 'Show main separator line';
            app.SetMainSepColorButton = uibutton(app.TimeseriesplotsnavigationPanel, 'push');
            app.SetMainSepColorButton.ButtonPushedFcn = createCallbackFcn(app, @SetMainSepColorButtonPushed, true);
            app.SetMainSepColorButton.Text = 'Choose color';
            app.MainSepColorLabel = uilabel(app.TimeseriesplotsnavigationPanel);
            app.MainSepColorLabel.Text = 'Lab2';
            app.ShowminorseparatrolineCheckBox = uicheckbox(app.TimeseriesplotsnavigationPanel);
            app.ShowminorseparatrolineCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowminorseparatrolineCheckBoxValueChanged, true);
            app.ShowminorseparatrolineCheckBox.Text = 'Show minor separatro line';
            app.SetMinorSepButton = uibutton(app.TimeseriesplotsnavigationPanel, 'push');
            app.SetMinorSepButton.ButtonPushedFcn = createCallbackFcn(app, @SetMinorSepButtonPushed, true);
            app.SetMinorSepButton.Text = 'Choose color';
            app.MinorSepColorLabel = uilabel(app.TimeseriesplotsnavigationPanel);
            app.MinorSepColorLabel.Text = 'Lab';
            app.TimedomainautomaticeventdetectionPanel = uipanel(app.OptionsUIFigure);
            app.TimedomainautomaticeventdetectionPanel.Title = 'Time domain automatic event detection';
            app.SearchwindowsLabel = uilabel(app.TimedomainautomaticeventdetectionPanel);
            app.SearchwindowsLabel.Text = 'Search window (s):';
            app.AutoSearchWinEditField = uieditfield(app.TimedomainautomaticeventdetectionPanel, 'numeric');
            app.AutoSearchWinEditField.Limits = [0 Inf];
            app.AutoSearchWinEditField.ValueChangedFcn = createCallbackFcn(app, @AutoSearchWinEditFieldValueChanged, true);
            app.AutoSearchWinEditField.Tooltip = {'Search time window length centered at the cursor.'};
            app.AutoMergeEvsCheckBox = uicheckbox(app.TimedomainautomaticeventdetectionPanel);
            app.AutoMergeEvsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoMergeEvsCheckBoxValueChanged, true);
            app.AutoMergeEvsCheckBox.Text = 'Merge detected events automatically';
            app.AutomergedurationthresholdsLabel = uilabel(app.TimedomainautomaticeventdetectionPanel);
            app.AutomergedurationthresholdsLabel.Text = 'Auto merge duration threshold (s):';
            app.AutoMergeEvsDurThreshEditField = uieditfield(app.TimedomainautomaticeventdetectionPanel, 'numeric');
            app.AutoMergeEvsDurThreshEditField.ValueChangedFcn = createCallbackFcn(app, @AutoMergeEvsDurThreshEditFieldValueChanged, true);
            app.AutomergezscorethresholdsEditFieldLabel = uilabel(app.TimedomainautomaticeventdetectionPanel);
            app.AutomergezscorethresholdsEditFieldLabel.Text = 'Auto merge z score threshold (s):';
            app.AutomergezscorethresholdsEditField = uieditfield(app.TimedomainautomaticeventdetectionPanel, 'numeric');
            app.AutomergezscorethresholdsEditField.ValueChangedFcn = createCallbackFcn(app, @AutomergezscorethresholdsEditFieldValueChanged, true);
            app.TimewindowformanualadjustmentsLabel = uilabel(app.TimedomainautomaticeventdetectionPanel);
            app.TimewindowformanualadjustmentsLabel.Text = 'Time window for manual adjustment (s):';
            app.AutoAdjWin = uieditfield(app.TimedomainautomaticeventdetectionPanel, 'numeric');
            app.AutoAdjWin.Limits = [0.01 1];
            app.AutoAdjWin.ValueChangedFcn = createCallbackFcn(app, @AutoAdjWinValueChanged, true);
            app.AutoAdjWin.Tooltip = {'Time to extend beyond the cursors for the adjustment of manual events.'};
            app.AutoAdjWin.Value = 0.05;
            app.EventcolorPanel = uipanel(app.OptionsUIFigure);
            app.EventcolorPanel.Title = 'Event color';
            app.GammaButton = uibutton(app.EventcolorPanel, 'push');
            app.GammaButton.ButtonPushedFcn = createCallbackFcn(app, @GammaButtonPushed, true);
            app.GammaButton.Text = 'Gamma';
            app.RippleButton = uibutton(app.EventcolorPanel, 'push');
            app.RippleButton.ButtonPushedFcn = createCallbackFcn(app, @RippleButtonPushed, true);
            app.RippleButton.Text = 'Ripple';
            app.FastrippleButton = uibutton(app.EventcolorPanel, 'push');
            app.FastrippleButton.ButtonPushedFcn = createCallbackFcn(app, @FastrippleButtonPushed, true);
            app.FastrippleButton.Text = 'Fast ripple';
            app.UltrafastButton = uibutton(app.EventcolorPanel, 'push');
            app.UltrafastButton.ButtonPushedFcn = createCallbackFcn(app, @UltrafastButtonPushed, true);
            app.UltrafastButton.Text = 'Ultra fast';
            app.SpikeButton = uibutton(app.EventcolorPanel, 'push');
            app.SpikeButton.ButtonPushedFcn = createCallbackFcn(app, @SpikeButtonPushed, true);
            app.SpikeButton.Text = 'Spike';
            app.EVColorGamma = uilabel(app.EventcolorPanel);
            app.EVColorGamma.Text = 'Label5';
            app.EVColorRipple = uilabel(app.EventcolorPanel);
            app.EVColorRipple.Text = 'Label6';
            app.EVColorFastRipple = uilabel(app.EventcolorPanel);
            app.EVColorFastRipple.Text = 'Label7';
            app.EVColorUltraFast = uilabel(app.EventcolorPanel);
            app.EVColorUltraFast.Text = 'Label8';
            app.EVColorSpike = uilabel(app.EventcolorPanel);
            app.EVColorSpike.Text = 'Label9';
            app.EventlistsortmethodButtonGroup = uibuttongroup(app.OptionsUIFigure);
            app.EventlistsortmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventlistsortmethodButtonGroupSelectionChanged, true);
            app.EventlistsortmethodButtonGroup.Title = 'Event list sort method';
            app.SortEvChannelButton = uiradiobutton(app.EventlistsortmethodButtonGroup);
            app.SortEvChannelButton.Text = 'Channel';
            app.SortEvChannelButton.Value = true;
            app.SortEvLocationButton = uiradiobutton(app.EventlistsortmethodButtonGroup);
            app.SortEvLocationButton.Text = 'Location';
            app.FontsizeEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.FontsizeEditFieldLabel.Text = 'Fontsize:';
            app.FontsizeEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.FontsizeEditField.Limits = [6 24];
            app.FontsizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontsizeEditFieldValueChanged, true);
            app.FontsizeEditField.Value = 11;
            app.TSColorButton = uibutton(app.OptionsUIFigure, 'push');
            app.TSColorButton.ButtonPushedFcn = createCallbackFcn(app, @TSColorButtonPushed, true);
            app.TSColorButton.Text = 'Raw time series color';
            app.TSColorLabel = uilabel(app.OptionsUIFigure);
            app.TSColorLabel.Text = 'Label4';
            app.CancelButton = uibutton(app.OptionsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.OptionsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TimeformatButtonGroup = uibuttongroup(app.OptionsUIFigure);
            app.TimeformatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeformatButtonGroupSelectionChanged, true);
            app.TimeformatButtonGroup.Title = 'Time format';
            app.ClockTypeClockButton = uiradiobutton(app.TimeformatButtonGroup);
            app.ClockTypeClockButton.Text = 'Date time';
            app.ClockTypeClockButton.Value = true;
            app.ClockTypeSecondsButton = uiradiobutton(app.TimeformatButtonGroup);
            app.ClockTypeSecondsButton.Text = 'Seconds';
            app.SpectrogramcolormapDropDownLabel = uilabel(app.OptionsUIFigure);
            app.SpectrogramcolormapDropDownLabel.Text = 'Spectrogram colormap:';
            app.CmapDropDown = uidropdown(app.OptionsUIFigure);
            app.CmapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag'};
            app.CmapDropDown.ValueChangedFcn = createCallbackFcn(app, @CmapDropDownValueChanged, true);
            app.CmapDropDown.Value = 'jet';
            app.CursorlocationthresholdsEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.CursorlocationthresholdsEditFieldLabel.Text = 'Cursor location threshold (s):';
            app.AutoCursorDistLimitEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.AutoCursorDistLimitEditField.Limits = [0 Inf];
            app.AutoCursorDistLimitEditField.ValueChangedFcn = createCallbackFcn(app, @AutoCursorDistLimitEditFieldValueChanged, true);
            app.NumberofxticksEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.NumberofxticksEditFieldLabel.Text = 'Number of xticks:';
            app.NumberofxticksEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.NumberofxticksEditField.Limits = [2 20];
            app.NumberofxticksEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofxticksEditFieldValueChanged, true);
            app.NumberofxticksEditField.Value = 4;
            app.CurTSColorButton = uibutton(app.OptionsUIFigure, 'push');
            app.CurTSColorButton.ButtonPushedFcn = createCallbackFcn(app, @CurTSColorButtonPushed, true);
            app.CurTSColorButton.Text = 'Raw time series color for current channel';
            app.CurTSColorLabel = uilabel(app.OptionsUIFigure);
            app.CurTSColorLabel.Text = 'Label10';
            app.CursorFullRange = uicheckbox(app.OptionsUIFigure);
            app.CursorFullRange.ValueChangedFcn = createCallbackFcn(app, @CursorFullRangeValueChanged, true);
            app.CursorFullRange.Tooltip = {'Check this option to change the height of the cursor line.'};
            app.CursorFullRange.Text = 'Show cursor on all channels';
            % Show the figure after all components are created
            app.OptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsUIFigure)
        end
    end
end