classdef ConfigSpectrogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrogramConfigurationUIFigure  matlab.ui.Figure
        LowcutofffrequencyHzEditFieldLabel  matlab.ui.control.Label
        LoFreqEditField                matlab.ui.control.NumericEditField
        HighcutofffrequencyHzLabel     matlab.ui.control.Label
        HiFreqEditField                matlab.ui.control.NumericEditField
        NumberoffrequenciesEditFieldLabel  matlab.ui.control.Label
        NbFreqsEditField               matlab.ui.control.NumericEditField
        KeepallspectrogramButtonGroup  matlab.ui.container.ButtonGroup
        KAYesButton                    matlab.ui.control.RadioButton
        KANoButton                     matlab.ui.control.RadioButton
        WindowlengthButtonGroup        matlab.ui.container.ButtonGroup
        WLEntireButton                 matlab.ui.control.RadioButton
        WLCurrentButton                matlab.ui.control.RadioButton
        MultiplewindowsButtonGroup     matlab.ui.container.ButtonGroup
        MultiWinsYesButton             matlab.ui.control.RadioButton
        MultiWinsNoButton              matlab.ui.control.RadioButton
        ScaleAutoCheckBox              matlab.ui.control.CheckBox
        PadSizeEditField               matlab.ui.control.NumericEditField
        CancelButton                   matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        NumberoffrequencyticksLabel    matlab.ui.control.Label
        FreqticksEditField             matlab.ui.control.EditField
        PadsizesEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
       CallingApp % Description
       srate
        % time frequency computation parameters
%             %% Data structure
%             % tf for entire time series
%             app.uidata.tf_entire = 'no';
%             % keep the spectrogram of all calculated channels
%             app.uidata.tf_keepall = 'no';
% 
%             % calculate spectrogram for entire time series
%             % 'yes'
%             % 'no' - current window only
%             app.uidata.tf_entire = 'yes';
%             
%             % pad size for current window spectrogram calculation, in seconds
%             app.uidata.tf_padsize = 3;
%             
%             % calculate spectrogram and save it to data structure
%             % frequency range
%             app.uidata.tf_foi_range = [40, 250];
%             % number of linearly spaced of frequencies
%             app.uidata.tf_foi_len = 100;
%             % frequency tick
%             app.uidata.tf_ytick = [];
%             
%             % multiple spectrogram windows 'yes'|'no'
%             app.uidata.tf_multi_wins = 'no';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            if nargin < 2
                for k = 1 : length( app.SpectrogramConfigurationUIFigure.Children)
                    app.SpectrogramConfigurationUIFigure.Children( k).Visible = 'off';
                end                                
                return;
            end

            nam = fieldnames( mainapp);
            fig_h = cellfun( @(x) strfind( x, 'UIFigure'), nam, 'UniformOutput', false);
            fig_h = find( cell2mat( fig_h));
            nam = nam{ fig_h(1) };
            
            p = mainapp.( nam).Position;
            fig_pos = app.SpectrogramConfigurationUIFigure.Position;            
            app.SpectrogramConfigurationUIFigure.Position( 1) = p(1) + p(3)/2 - fig_pos(3)/2;
            app.SpectrogramConfigurationUIFigure.Position( 2) = p(2) + p(4)/2 - fig_pos(4)/2;            
            
            
            app.CallingApp = mainapp;
            
            if isempty( app.CallingApp.data)
                return;
            end
            
            app.srate = app.CallingApp.data.srate;            
            p_param = app.CallingApp.uidata;
            
            if strcmpi( p_param.tf_entire, 'yes')
                app.WLEntireButton.Value = 1;
            else
                app.WLCurrentButton.Value = 1;
            end
            
            if strcmpi( p_param.tf_keepall, 'yes')
                app.KAYesButton.Value = 1;
            else
                app.KANoButton.Value = 1;
            end

            if strcmpi( p_param.tf_multi_wins, 'yes')
                app.MultiWinsYesButton.Value = 1;
            else
                app.MultiWinsNoButton.Value = 1;
            end

            if strcmpi( p_param.tf_autoscale, 'yes')
                app.ScaleAutoCheckBox.Value = 1;
            else
                app.ScaleAutoCheckBox.Value = 0;
            end
            
            app.PadSizeEditField.Value = p_param.tf_padsize;
            app.LoFreqEditField.Value = p_param.tf_foi_range(1);
            app.HiFreqEditField.Value = p_param.tf_foi_range(2);
            app.NbFreqsEditField.Value = p_param.tf_foi_len;
            
            if isempty( p_param.tf_ytick)
                app.FreqticksEditField.Value = num2str( 8);
            else
                app.FreqticksEditField.Value = num2str( p_param.tf_ytick);
            end 
            
            
            %% Calculate spectrogram for current window
            win_spectro = 'off'; % 'on' | 'off'
            app.PadsizesEditFieldLabel.Visible = win_spectro;
            app.PadSizeEditField.Visible = win_spectro;
            app.WindowlengthButtonGroup.Visible = win_spectro;            
        end

        % Value changed function: LoFreqEditField
        function LoFreqEditFieldValueChanged(app, event)
            value = app.LoFreqEditField.Value;
            if value >= app.HiFreqEditField.Value || value > app.srate / 2
                app.LoFreqEditField.FontColor = 'r';
                app.LoFreqEditField.Value = event.PreviousValue;
            else
                app.LoFreqEditField.FontColor = 'k';
            end
        end

        % Value changed function: HiFreqEditField
        function HiFreqEditFieldValueChanged(app, event)
            value = app.HiFreqEditField.Value;
            if value <= app.LoFreqEditField.Value || value > app.srate / 2
                app.HiFreqEditField.FontColor = 'r';
                app.HiFreqEditField.Value = event.PreviousValue;
            else
                app.HiFreqEditField.FontColor = 'k';
            end
        end

        % Value changed function: FreqticksEditField
        function FreqticksEditFieldValueChanged(app, event)
            value = str2num( app.FreqticksEditField.Value);
            if isempty( value)
                app.FreqticksEditField.Value = event.PreviousValue;
            else
                if ~isscalar( value) && ...
                        ( any( value < app.LoFreqEditField.Value) || any( value > app.HiFreqEditField.Value))
                    app.FreqticksEditField.Value = event.PreviousValue;    
                end
            end
        end

        % Selection changed function: MultiplewindowsButtonGroup
        function MultiplewindowsButtonGroupSelectionChanged(app, event)
            selectedButton = app.MultiplewindowsButtonGroup.SelectedObject;
            if selectedButton == app.MultiWinsYesButton
                app.KAYesButton.Value = 1;
            end
        end

        % Selection changed function: KeepallspectrogramButtonGroup
        function KeepallspectrogramButtonGroupSelectionChanged(app, event)
            selectedButton = app.KeepallspectrogramButtonGroup.SelectedObject;

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             delete( app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.WLEntireButton.Value == 1
                app.CallingApp.uidata.tf_entire = 'yes';
            else
                app.CallingApp.uidata.tf_entire = 'no';
            end
            
            if app.KAYesButton.Value == 1
                app.CallingApp.uidata.tf_keepall = 'yes';
            else
                app.CallingApp.uidata.tf_keepall = 'no';
            end

            if app.MultiWinsYesButton.Value == 1
                app.CallingApp.uidata.tf_multi_wins = 'yes';
                app.CallingApp.uidata.tf_keepall = 'yes';
            else
                app.CallingApp.uidata.tf_multi_wins = 'no';
            end
            
            if app.ScaleAutoCheckBox.Value == 1
                app.CallingApp.uidata.tf_autoscale = 'yes';
            else
                app.CallingApp.uidata.tf_autoscale = 'no';
            end
           
            if app.WLCurrentButton.Value == 1
                app.CallingApp.uidata.tf_entire = 'no';
            else
                app.CallingApp.uidata.tf_entire = 'yes';
            end
            
            app.CallingApp.uidata.tf_padsize = app.PadSizeEditField.Value;
            app.CallingApp.uidata.tf_foi_range = [app.LoFreqEditField.Value, app.HiFreqEditField.Value];
            app.CallingApp.uidata.tf_foi_len = app.NbFreqsEditField.Value;                
            app.CallingApp.uidata.tf_ytick = str2num( app.FreqticksEditField.Value);
            
           
            % Close all windows
            for k = 1 : length( app.CallingApp.FreqWin)
                delete( app.CallingApp.FreqWin{ k});
            end
            app.CallingApp.FreqWin = {};
            app.CallingApp.data.tf.mat = {};
            app.CallingApp.data.tf.param = {};
            app.CallingApp.data.tf.labels = {};            
            
            delete( app);
        end

        % Close request function: SpectrogramConfigurationUIFigure
        function SpectrogramConfigurationUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: WindowlengthButtonGroup
        function WindowlengthButtonGroupSelectionChanged(app, event)
            selectedButton = app.WindowlengthButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrogramConfigurationUIFigure = uifigure('Visible', 'off');
            app.SpectrogramConfigurationUIFigure.Name = 'Spectrogram Configuration';
            app.SpectrogramConfigurationUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectrogramConfigurationUIFigureCloseRequest, true);
            app.LowcutofffrequencyHzEditFieldLabel = uilabel(app.SpectrogramConfigurationUIFigure);
            app.LowcutofffrequencyHzEditFieldLabel.Text = 'Low-cutoff frequency (Hz):';
            app.LoFreqEditField = uieditfield(app.SpectrogramConfigurationUIFigure, 'numeric');
            app.LoFreqEditField.ValueChangedFcn = createCallbackFcn(app, @LoFreqEditFieldValueChanged, true);
            app.HighcutofffrequencyHzLabel = uilabel(app.SpectrogramConfigurationUIFigure);
            app.HighcutofffrequencyHzLabel.Text = 'High-cutoff frequency (Hz):';
            app.HiFreqEditField = uieditfield(app.SpectrogramConfigurationUIFigure, 'numeric');
            app.HiFreqEditField.ValueChangedFcn = createCallbackFcn(app, @HiFreqEditFieldValueChanged, true);
            app.NumberoffrequenciesEditFieldLabel = uilabel(app.SpectrogramConfigurationUIFigure);
            app.NumberoffrequenciesEditFieldLabel.Text = 'Number of frequencies:';
            app.NbFreqsEditField = uieditfield(app.SpectrogramConfigurationUIFigure, 'numeric');
            app.NbFreqsEditField.Limits = [10 500];
            app.NbFreqsEditField.Value = 60;
            app.KeepallspectrogramButtonGroup = uibuttongroup(app.SpectrogramConfigurationUIFigure);
            app.KeepallspectrogramButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KeepallspectrogramButtonGroupSelectionChanged, true);
            app.KeepallspectrogramButtonGroup.Title = 'Keep all spectrogram';
            app.KAYesButton = uiradiobutton(app.KeepallspectrogramButtonGroup);
            app.KAYesButton.Text = 'Yes';
            app.KANoButton = uiradiobutton(app.KeepallspectrogramButtonGroup);
            app.KANoButton.Text = 'No';
            app.KANoButton.Value = true;
            app.WindowlengthButtonGroup = uibuttongroup(app.SpectrogramConfigurationUIFigure);
            app.WindowlengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowlengthButtonGroupSelectionChanged, true);
            app.WindowlengthButtonGroup.Title = 'Window length';
            app.WLEntireButton = uiradiobutton(app.WindowlengthButtonGroup);
            app.WLEntireButton.Text = 'Entire time series';
            app.WLEntireButton.Value = true;
            app.WLCurrentButton = uiradiobutton(app.WindowlengthButtonGroup);
            app.WLCurrentButton.Text = 'Current time window';
            app.MultiplewindowsButtonGroup = uibuttongroup(app.SpectrogramConfigurationUIFigure);
            app.MultiplewindowsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MultiplewindowsButtonGroupSelectionChanged, true);
            app.MultiplewindowsButtonGroup.Title = 'Multiple windows';
            app.MultiWinsYesButton = uiradiobutton(app.MultiplewindowsButtonGroup);
            app.MultiWinsYesButton.Text = 'Yes';
            app.MultiWinsNoButton = uiradiobutton(app.MultiplewindowsButtonGroup);
            app.MultiWinsNoButton.Text = 'No';
            app.MultiWinsNoButton.Value = true;
            app.ScaleAutoCheckBox = uicheckbox(app.SpectrogramConfigurationUIFigure);
            app.ScaleAutoCheckBox.Text = 'Auto scale';
            app.PadSizeEditField = uieditfield(app.SpectrogramConfigurationUIFigure, 'numeric');
            app.PadSizeEditField.Limits = [0 Inf];
            app.PadSizeEditField.Tooltip = {'Extend the current time window before calculating the spectrogram.'};
            app.CancelButton = uibutton(app.SpectrogramConfigurationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.SpectrogramConfigurationUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.NumberoffrequencyticksLabel = uilabel(app.SpectrogramConfigurationUIFigure);
            app.NumberoffrequencyticksLabel.Text = 'Number of frequency ticks:';
            app.FreqticksEditField = uieditfield(app.SpectrogramConfigurationUIFigure, 'text');
            app.FreqticksEditField.ValueChangedFcn = createCallbackFcn(app, @FreqticksEditFieldValueChanged, true);
            app.FreqticksEditField.Value = '6';
            app.PadsizesEditFieldLabel = uilabel(app.SpectrogramConfigurationUIFigure);
            app.PadsizesEditFieldLabel.Text = 'Pad size (s):';
            % Show the figure after all components are created
            app.SpectrogramConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigSpectrogram(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrogramConfigurationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrogramConfigurationUIFigure)
        end
    end
end