classdef ConfigWindows < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OrganizeFilterWindowsUIFigure  matlab.ui.Figure
        WindowsListBox                 matlab.ui.control.ListBox
        ListofWindowsLabel             matlab.ui.control.Label
        DeleteWinButton                matlab.ui.control.Button
        WindowtypeButtonGroup          matlab.ui.container.ButtonGroup
        EmbedWinButton                 matlab.ui.control.RadioButton
        FloatWinButton                 matlab.ui.control.RadioButton
        WinInfoTextArea                matlab.ui.control.TextArea
        SetColorButton                 matlab.ui.control.Button
        ColorLabel                     matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        InfoLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        data % band-pass filtered data   
        figname
    end
    
    methods (Access = private)
        
        function UpdateWindowsList(app)
            if sum( app.data.keep == 1) > 0               
                loc = find( app.data.keep == 1);                
                param_win_names = cellfun(@(x) x.Name,...
                    app.data.raw_param( loc, 1), 'UniformOutput',false);
                
                app.WindowsListBox.Items = param_win_names;
                app.WindowsListBox.Value = param_win_names(1);
                app.ColorLabel.BackgroundColor = app.data.raw_param{ loc(1), 1}.Color;
                if app.data.raw_param{ loc(1), 1}.NewWin == 1
                    app.FloatWinButton.Value = 1;
                else
                    app.EmbedWinButton.Value = 1;
                end
                
                N = length( app.data.keep);
                if N == 1
                    txt = '1 band-pass filter window is listed';   
                    
                else
                    txt = [num2str(N), ' band-pass filter windows is listed'];
                end
                
                app.InfoLabel.Text = txt;
                app.InfoLabel.FontSize = 12;
                app.InfoLabel.FontColor = 'b';
                
            else
                % No window list
                app.WindowsListBox.Value = {};
                app.WindowsListBox.Items = {};               
                app.ColorLabel.BackgroundColor = app.OrganizeFilterWindowsUIFigure.Color;
            end
            
            if isempty( app.WindowsListBox.Value) || isempty( app.WindowsListBox.Items)
                app.DeleteWinButton.Enable = 'off';
                app.EmbedWinButton.Enable = 'off';
                app.FloatWinButton.Enable = 'off';
                app.SetColorButton.Enable = 'off';
                app.WinInfoTextArea.Value = '';
                
            else
                ShowWinInfo( app);             
            end
                
        end % Update window name list
        
        
        function ShowWinInfo( app)
            param = app.data.raw_param;
            win_names = cellfun( @(x) x.Name, param, 'UniformOutput',false);
            [~, loc] = ismember( app.WindowsListBox.Value, win_names);
            
            app.WinInfoTextArea.Value = {...
                ['Bandpass frequency: [', num2str( param{ loc}.bpfreq), '] Hz'],...
                ['Bandpass filter type: ', param{ loc}.bptype],...
                ['Notch filter: ', param{ loc}.notch],...
                ['Notch freq: ', num2str( param{ loc}.notch_freq),  ' Hz'],...
                ['Notch filter type: ', param{ loc}.notch_type]};
        end
        
        
        function CloseFiltWin( app, win_name, flag)
            % flag, 1- close window
            %       0- clear data and close window
            
            % Clear band-pass filtered data
            if flag ~= 1
                param_win_names = cellfun( @(x) x.Name,...
                    app.CallingApp.data.bpfilt.param, 'UniformOutput', false);
                
                [is_exist, loc] = ismember( win_name, param_win_names);
                
                if is_exist
                    app.CallingApp.data.bpfilt.mat( loc) = [];
                    app.CallingApp.data.bpfilt.amp( loc) = [];
                    app.CallingApp.data.bpfilt.z( loc) = [];
                    app.CallingApp.data.bpfilt.param( loc) = [];
                    app.CallingApp.data.bpfilt.labels( loc) = [];
                end
            end
            
            % Close windows
            if ~isempty( app.CallingApp.TimeWin)
                all_win_names = cellfun( @(x) x.UIFigure.Name,...
                    app.CallingApp.TimeWin, 'UniformOutput', false);
                
                [is_exist, loc] = ismember( win_name, all_win_names);
                if is_exist
                    delete( app.CallingApp.TimeWin{ loc});
                    app.CallingApp.TimeWin( loc) = [];
                end
            end            
        end % close Filt window
        
        
    end % private methods    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            if nargin < 2
                for k = 1 : length( app.OrganizeFilterWindowsUIFigure.Children)
                    app.OrganizeFilterWindowsUIFigure.Children( k).Visible = 'off';
                end
                
                return;
            end
                        
            nam = fieldnames( mainapp);
            fig_h = cellfun( @(x) strfind( x, 'UIFigure'), nam, 'UniformOutput', false);
            fig_h = find( cell2mat( fig_h));
            nam = nam{ fig_h(1) };
            app.figname = nam;
            
            p_pos = mainapp.( app.figname).Position;
            fig_pos = app.OrganizeFilterWindowsUIFigure.Position;            
            app.OrganizeFilterWindowsUIFigure.Position( [1, 2]) = ...
               [p_pos(1) + p_pos(3)/2 - fig_pos(3)/2, ...
                p_pos(2) + p_pos(4)/2 - fig_pos(4)/2];
                       
            app.ColorLabel.Text = '';
            app.WindowsListBox.Items = {};
            app.WindowsListBox.Value = {};

            app.CallingApp = mainapp;
            app.data.raw_param = app.CallingApp.data.bpfilt.param;
            param_win_names = cellfun(@(x) x.Name, app.data.raw_param, 'UniformOutput',false);
            app.data.raw_param_win_names = param_win_names;
            app.data.keep = ones( length( app.data.raw_param), 1);
            app.data.update = ones( length( app.data.raw_param), 1);
            UpdateWindowsList( app);
            
            if isempty( app.data.keep)
                app.InfoLabel.Text = 'No band-pass filtered data found.';
                app.InfoLabel.FontColor = 'r';
                app.InfoLabel.Position(2) = app.OrganizeFilterWindowsUIFigure.Position(4)/2;
                
                app.WindowsListBox.Visible = 'off';
                app.ListofWindowsLabel.Visible = 'off';
                app.DeleteWinButton.Visible = 'off';
                app.WindowtypeButtonGroup.Visible = 'off';
                app.SetColorButton.Visible = 'off';
                app.ColorLabel.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ApplyButton.Enable = 'off';
                app.WinInfoTextArea.Visible = 'off';
            end  
        end

        % Close request function: OrganizeFilterWindowsUIFigure
        function OrganizeFilterWindowsUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: WindowsListBox
        function WindowsListBoxValueChanged(app, event)
             value = app.WindowsListBox.Value;
            if isempty( value)
                return;
            end
            
            win_names = cellfun(@(x) x.Name, app.data.raw_param, 'UniformOutput',false);
            [~, loc] = ismember( value, win_names);            
            if app.data.raw_param{ loc}.NewWin == 1
                app.FloatWinButton.Value = 1;
            else
                app.EmbedWinButton.Value = 1; 
            end
            
            app.ColorLabel.BackgroundColor = app.data.raw_param{ loc}.Color;            
            ShowWinInfo( app);
        end

        % Button pushed function: DeleteWinButton
        function DeleteWinButtonPushed(app, event)
            if isempty( app.WindowsListBox.Value)
                return;
            end

            val = app.WindowsListBox.Value;
            [~, loc] = ismember( val, app.data.raw_param_win_names);
            if loc ~= 0
                app.data.keep( loc) = 0;
                app.data.update( loc) = 0;
            end
            
            UpdateWindowsList( app); 
            app.InfoLabel.Text = [val, ' is going to be removed.'];
        end

        % Selection changed function: WindowtypeButtonGroup
        function WindowtypeButtonGroupSelectionChanged(app, event)
            if isempty( app.WindowsListBox.Value)
                return;
            end
            
            val = app.WindowsListBox.Value;
            [~, loc] = ismember( val, app.data.raw_param_win_names);
            if loc ~= 0
                if app.EmbedWinButton.Value == 1
                    app.data.raw_param{ loc, 1}.NewWin = 0;   
                    app.data.keep( loc) = 0;                    
                    app.InfoLabel.Text = [app.WindowsListBox.Value,...
                        ' will be embedded into main window.'];
                    
                else
                    app.data.raw_param{ loc, 1}.NewWin = 1;
                    app.data.keep( loc) = 1;
                    app.InfoLabel.Text = [app.WindowsListBox.Value,...
                        ' will be separated from main window.'];
                end
            end
        end

        % Button pushed function: SetColorButton
        function SetColorButtonPushed(app, event)
            if isempty( app.WindowsListBox.Value)
                return;
            end
            
            [~, loc] = ismember( app.WindowsListBox.Value, app.data.raw_param_win_names);
            c = uisetcolor( app.data.raw_param{ loc, 1}.Color, 'Please select color');
            app.data.raw_param{ loc, 1}.Color = c;
            app.ColorLabel.BackgroundColor = c;
            app.InfoLabel.Text = ['Window ', app.WindowsListBox.Value, ' color was updated'];
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete( app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.CallingApp.data.bpfilt.param = app.data.raw_param;
            
            % Delete windows by name
            win2rm = app.data.raw_param_win_names( app.data.keep ~= 1);
            f = app.data.update( app.data.keep ~= 1);
            for k = 1 : length( win2rm)
                CloseFiltWin( app, win2rm{ k}, f(k));
            end
            
            % Open new windows if necessary   
            ca_param = app.CallingApp.data.bpfilt.param;
            filt_mat_name = cellfun( @(x) x.Name, ca_param, 'uniformoutput', false);
            
            filt_win_names = cellfun( @(x) x.UIFigure.Name,...
                app.CallingApp.TimeWin, 'uniformoutput', false);
            
            for k = 1 : length( filt_mat_name)  
                win_name2add = filt_mat_name{ k};
                win_exist = ismember( win_name2add, filt_win_names);                
                if ~win_exist && ca_param{ k}.NewWin == 1
                    N = length( app.CallingApp.TimeWin);
                    app.CallingApp.TimeWin{ N+1} = TimeApp( app.CallingApp, win_name2add);
                end
            end
            
            % Update plots
            app.CallingApp.PRedraw();
            
            % Resize window
            app.CallingApp.PResizeWindows( app.CallingApp.( app.figname).Position, 'main');
            
            delete( app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OrganizeFilterWindowsUIFigure = uifigure('Visible', 'off');
            app.OrganizeFilterWindowsUIFigure.Name = 'Organize Filter Windows';
            app.OrganizeFilterWindowsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OrganizeFilterWindowsUIFigureCloseRequest, true);
            app.WindowsListBox = uilistbox(app.OrganizeFilterWindowsUIFigure);
            app.WindowsListBox.ValueChangedFcn = createCallbackFcn(app, @WindowsListBoxValueChanged, true);
            app.ListofWindowsLabel = uilabel(app.OrganizeFilterWindowsUIFigure);
            app.ListofWindowsLabel.Text = 'List of Windows';
            app.DeleteWinButton = uibutton(app.OrganizeFilterWindowsUIFigure, 'push');
            app.DeleteWinButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteWinButtonPushed, true);
            app.DeleteWinButton.Text = 'Delete selected';
            app.WindowtypeButtonGroup = uibuttongroup(app.OrganizeFilterWindowsUIFigure);
            app.WindowtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowtypeButtonGroupSelectionChanged, true);
            app.WindowtypeButtonGroup.Title = 'Display type';
            app.EmbedWinButton = uiradiobutton(app.WindowtypeButtonGroup);
            app.EmbedWinButton.Text = 'Embed';
            app.EmbedWinButton.Value = true;
            app.FloatWinButton = uiradiobutton(app.WindowtypeButtonGroup);
            app.FloatWinButton.Text = 'Float';
            app.WinInfoTextArea = uitextarea(app.OrganizeFilterWindowsUIFigure);
            app.SetColorButton = uibutton(app.OrganizeFilterWindowsUIFigure, 'push');
            app.SetColorButton.ButtonPushedFcn = createCallbackFcn(app, @SetColorButtonPushed, true);
            app.SetColorButton.Text = 'Set color';
            app.ColorLabel = uilabel(app.OrganizeFilterWindowsUIFigure);
            app.ColorLabel.Text = 'Label2';
            app.CancelButton = uibutton(app.OrganizeFilterWindowsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.OrganizeFilterWindowsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InfoLabel = uilabel(app.OrganizeFilterWindowsUIFigure);
            % Show the figure after all components are created
            app.OrganizeFilterWindowsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigWindows(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OrganizeFilterWindowsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OrganizeFilterWindowsUIFigure)
        end
    end
end