classdef ConfigYScale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        YScaleSetupUIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)
        CallingApp % Description
        uidata
    end
    
    methods (Access = private)
        
        function YScaleChange(app, src, event)
            param_win_names = cellfun( @(x) x.Name, app.CallingApp.data.bpfilt.param, 'UniformOutput',false);
            [is_exist, loc] = ismember( src.Tag, param_win_names);
            if is_exist
                app.CallingApp.data.bpfilt.param{ loc}.yscale = event.Value;
            end
            
            for k = 1 : length( app.CallingApp.TimeWin)
                if strcmpi( app.CallingApp.TimeWin{k}.UIFigure.Name, src.Tag)
                    app.CallingApp.TimeWin{k}.YScaleSpinner.Value = event.Value;
                end
            end
                        
            app.CallingApp.PRedraw();
        end % function
        
    end % private method
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = [];
            
            if nargin < 2
                return;
            end            
            
            app.CallingApp = mainapp;
            
            nam = fieldnames( mainapp);
            fig_h = cellfun( @(x) strfind( x, 'UIFigure'), nam, 'UniformOutput', false);
            fig_h = find( cell2mat( fig_h));
            nam = nam{ fig_h(1) };
                        
            % Move to the center of the calling app
            ca_pos = mainapp.( nam).Position;            
            fig_pos = app.YScaleSetupUIFigure.Position;
            app.YScaleSetupUIFigure.Position( [1, 2]) =...
               [ca_pos(1) + 0.5 * (ca_pos(3) - fig_pos(3)), ...
                ca_pos(2) + ca_pos(4)];
                        
            %% For all filtered time series
            win_idx = 1 : length( app.CallingApp.data.bpfilt.mat);
            nb_wins = length( win_idx);
            if nb_wins > 0
                height = 22;
                spinner_width = 100;
                label_width = 30;
                txt_width = 100;
                label_left = 10;
                
                max_height = height * (nb_wins + 4);
                max_height = max( [100, max_height]);
                
                app.YScaleSetupUIFigure.Position(3) = txt_width + label_width + spinner_width + label_left*2;
                app.YScaleSetupUIFigure.Position(4) = max_height;
                c = linspace( height, app.YScaleSetupUIFigure.Position(4)- 2 *height, nb_wins);
                
                for k = 1 : nb_wins
                    h = uilabel( app.YScaleSetupUIFigure,...
                        'Position', [label_left, c(k), txt_width, height]);
                    
                    h.Text = app.CallingApp.data.bpfilt.param{ win_idx(k)}.Name;
                    h.HorizontalAlignment = 'Left';
                    
                    h = uilabel( app.YScaleSetupUIFigure, ...
                        'Position', [label_left + txt_width +  1, c(k), label_width, height]);
                    
                    h.BackgroundColor = app.CallingApp.data.bpfilt.param{ win_idx(k)}.Color;
                    h.Text = '';
                    
                    xloc = label_left + txt_width + label_width + 2;
                    
                    app.uidata.YScaleSpinner = uispinner( app.YScaleSetupUIFigure,...
                        'Position', [xloc, c(k), spinner_width, height]);
                    
                    app.uidata.YScaleSpinner.ValueChangedFcn = @app.YScaleChange;
                    app.uidata.YScaleSpinner.Value = app.CallingApp.data.bpfilt.param{ win_idx(k)}.yscale;
                    app.uidata.YScaleSpinner.Tag = app.CallingApp.data.bpfilt.param{ win_idx(k)}.Name;
                    app.uidata.YScaleSpinner.Step = 10;
                    app.uidata.YScaleSpinner.Limits = [eps, Inf];
                end
                
            end % if any filtered data exist            

        end

        % Close request function: YScaleSetupUIFigure
        function YScaleSetupUIFigureCloseRequest(app, event)
            if ~isempty( app.CallingApp)
                app.CallingApp.HFloatYScale = [];
            end
            
            delete( app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.YScaleSetupUIFigure = uifigure('Visible', 'off');
            app.YScaleSetupUIFigure.Name = 'YScale Setup';
            app.YScaleSetupUIFigure.CloseRequestFcn = createCallbackFcn(app, @YScaleSetupUIFigureCloseRequest, true);
            % Show the figure after all components are created
            app.YScaleSetupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigYScale(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.YScaleSetupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.YScaleSetupUIFigure)
        end
    end
end