classdef FreqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        SaveFileMenu                    matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        EventMenu                       matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        ExportasMatlabFileMenu          matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        WindowsMenu                     matlab.ui.container.Menu
        AddbandpassfilterwindowMenu     matlab.ui.container.Menu
        AddtimefrequencywindowMenu      matlab.ui.container.Menu
        OrganizeWindowsMenu             matlab.ui.container.Menu
        MontageMenu                     matlab.ui.container.Menu
        ChangeMontageMenu               matlab.ui.container.Menu
        SpectrogramMenu                 matlab.ui.container.Menu
        ConfigureMenu                   matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        SetpreferencesMenu              matlab.ui.container.Menu
        AutomatedHFOdetectionMenu       matlab.ui.container.Menu
        SavesettingsasdefaultMenu       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        XScaleSpinner                   matlab.ui.control.Spinner
        YScaleLoSpinner                 matlab.ui.control.Spinner
        StartClockEditField             matlab.ui.control.EditField
        EndClockHEditField              matlab.ui.control.EditField
        ResetTimeButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ChannelTab                      matlab.ui.container.Tab
        ChannelListBox                  matlab.ui.control.ListBox
        AllChannelListBox               matlab.ui.control.ListBox
        LockCurrentChannelCheckBox      matlab.ui.control.CheckBox
        RemoveChannelButton             matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        AddAllChannel                   matlab.ui.control.Button
        AddChannel                      matlab.ui.control.Button
        RemoveChannel                   matlab.ui.control.Button
        RemoveAllChannel                matlab.ui.control.Button
        DisplayLabel                    matlab.ui.control.Label
        AllLabel                        matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        EventsTab                       matlab.ui.container.Tab
        EventListBox                    matlab.ui.control.ListBox
        RemoveEventButton               matlab.ui.control.Button
        UpdateEventButton               matlab.ui.control.Button
        TypePanel                       matlab.ui.container.Panel
        ETGammaCheckBox                 matlab.ui.control.CheckBox
        ETRippleCheckBox                matlab.ui.control.CheckBox
        ETFastRippleCheckBox            matlab.ui.control.CheckBox
        ETUltraFastCheckBox             matlab.ui.control.CheckBox
        ETSpikeCheckBox                 matlab.ui.control.CheckBox
        EventTypetoDisplayButtonGroup   matlab.ui.container.ButtonGroup
        ShowAllEventButton              matlab.ui.control.RadioButton
        ShowSelectedEventButton         matlab.ui.control.RadioButton
        EventChanneltoDisplayButtonGroup  matlab.ui.container.ButtonGroup
        ShowEventOnAllChannelButton     matlab.ui.control.RadioButton
        ShowEventOnSelectedChanelButton  matlab.ui.control.RadioButton
        MergeEventButton                matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        MultipleDetectionCheckBox       matlab.ui.control.CheckBox
        EventDetectionOptionsPanel      matlab.ui.container.Panel
        OnsetthresholdSDEditFieldLabel  matlab.ui.control.Label
        ThreshOnsetEditField            matlab.ui.control.NumericEditField
        InclusionCriteriaSDEditFieldLabel  matlab.ui.control.Label
        ThreshInclusionEditField        matlab.ui.control.NumericEditField
        NumberofCyclesEditFieldLabel    matlab.ui.control.Label
        ThreshNumberCyclesEditField     matlab.ui.control.NumericEditField
        BackgroundTimeWindowButtonGroup  matlab.ui.container.ButtonGroup
        BKWholeRB                       matlab.ui.control.RadioButton
        BKCurrentRB                     matlab.ui.control.RadioButton
        BKSpecifiedRB                   matlab.ui.control.RadioButton
        BKSpecifiedEF                   matlab.ui.control.NumericEditField
        DurationLabel                   matlab.ui.control.Label
        StepBackButton                  matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        StepForwardButton               matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        JumpToClockEditField            matlab.ui.control.EditField
        AddEventButton                  matlab.ui.control.Button
        StartEventButton                matlab.ui.control.Button
        EndEventButton                  matlab.ui.control.Button
        ManualEventAutoAdjCheckBox      matlab.ui.control.CheckBox
        ManualModeButton                matlab.ui.control.StateButton
        PSDPanel                        matlab.ui.container.Panel
        TFAutoDetectCheckBox            matlab.ui.control.CheckBox
        PeakfrequencyLabel              matlab.ui.control.Label
        TFAutoPeakFreqLabel             matlab.ui.control.Label
        NumberofcyclesLabel             matlab.ui.control.Label
        TFAutoNoCycleLabel              matlab.ui.control.Label
        TFAutoBandwidthEditField        matlab.ui.control.NumericEditField
        TFAutoTimeLenEditField          matlab.ui.control.NumericEditField
        BandwidthHzEditFieldLabel       matlab.ui.control.Label
        TimewidowsEditFieldLabel        matlab.ui.control.Label
        TFAutoUseAsPercentCheckBox      matlab.ui.control.CheckBox
        UIAxesAvg                       matlab.ui.control.UIAxes
        YScaleHiSpinner                 matlab.ui.control.Spinner
        JumptoLabel                     matlab.ui.control.Label
        TimesLabel                      matlab.ui.control.Label
        AmplitudeLabel                  matlab.ui.control.Label
        StartClockLabel                 matlab.ui.control.Label
        EndClockLabel                   matlab.ui.control.Label
        TLabel                          matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        MLabel                          matlab.ui.control.Label
        LLabel                          matlab.ui.control.Label
        RLabel                          matlab.ui.control.Label
        ToiTLabel                       matlab.ui.control.Label
        ToiBLabel                       matlab.ui.control.Label
        TrialLabel                      matlab.ui.control.Label
        CurTrialEditField               matlab.ui.control.NumericEditField
        PreTrialButton                  matlab.ui.control.Button
        NextTrialButton                 matlab.ui.control.Button
        TotalTrialLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
        data
        is_called
    end
    
    properties (Access = public)
        uidata 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, win_name)
            % This is an App that is called by HFOApp
            
            if nargin < 2
                for k = 1 : length( app.UIFigure.Children)
                    app.UIFigure.Children( k).Visible = 'off';
                end 
                
                app.is_called = false;
                return;
            end
            
            app.is_called = true;
            app.CallingApp = mainapp;
            
            % The windown name has to be unique
            app.UIFigure.Name = win_name;
                        
            app.uidata.chnl_loc = [];
            if ~isempty( app.CallingApp.data.tf.mat)
                [~, loc] = ismember( win_name, app.CallingApp.data.tf.labels);
                app.YScaleLoSpinner.Value = app.CallingApp.data.tf.param{ loc}.climit(1);
                app.YScaleHiSpinner.Value = app.CallingApp.data.tf.param{ loc}.climit(2);
                app.uidata.chnl_loc = loc;
                app.uidata.climit = [app.YScaleLoSpinner.Value, app.YScaleHiSpinner.Value];
                caxis( app.UIAxes, app.uidata.climit);
            end
            
            p_fig_pos = app.CallingApp.HFOAppUIFigure.Position;            
            xloc = linspace( p_fig_pos(1)/2, p_fig_pos(1), 10);
            yloc = linspace( p_fig_pos(2)/2, p_fig_pos(2), 10);
            app.UIFigure.Position(1) = randsample( xloc, 1);
            app.UIFigure.Position(2) = randsample( yloc, 1);
            
            app.TFAutoBandwidthEditField.Limits = [0, app.CallingApp.data.srate/2];
            app.TFAutoPeakFreqLabel.Text = '';
            app.TFAutoNoCycleLabel.Text = '';            
            
            % event box and cursor line handles
            app.uidata.boxh = [];
            app.uidata.cursor_h = [];

            
            % manual event lines and text handles
            app.uidata.mev_sh = gobjects(0);
            app.uidata.mev_stxth = gobjects(0);
            app.uidata.mev_eh = gobjects(0);
            app.uidata.mev_etxth  = gobjects(0);
            
            % Disable built-in axes interactions
            tb = axtoolbar( app.UIAxes);
            tb.Visible = 'off';   
            tb = axtoolbar( app.UIAxesAvg);
            tb.Visible = 'off';
            
            % Mirror tooltips from main window
            for k = 1 : length( app.CallingApp.tooltip_objs)
                obj_nam = app.CallingApp.tooltip_objs{ k};
            
                if isprop( app, obj_nam)
                    app.( obj_nam).Tooltip = app.CallingApp.( obj_nam).Tooltip;
                end
            end
            
            disableDefaultInteractivity( app.UIAxes);
            disableDefaultInteractivity( app.UIAxesAvg);
            
            app.MergeEventButton.Visible = app.CallingApp.MergeEventButton.Visible;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.CallingApp.POpenFileMenuSelected( event, app.UIFigure);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.CallingApp.PCloseFileMenuSelected( event, app.UIFigure);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.CallingApp.PQuitAppMenuSelected( event, app.UIFigure);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.CallingApp.PSaveMenuSelected( event);
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.CallingApp.PSaveasMenuSelected( event);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.CallingApp.PLoadeEventMenuSelected( event, app.UIFigure);
        end

        % Menu selected function: OrganizeWindowsMenu
        function OrganizeWindowsMenuSelected(app, event)
            app.CallingApp.POrganizeWindowsMenuSelected( event);
        end

        % Menu selected function: AddbandpassfilterwindowMenu
        function AddbandpassfilterwindowMenuSelected(app, event)
            app.CallingApp.PAddTimeWinButtonPushed();
        end

        % Menu selected function: AddtimefrequencywindowMenu
        function AddtimefrequencywindowMenuSelected(app, event)
            app.CallingApp.PAddFreqWinPushed();
        end

        % Menu selected function: ChangeMontageMenu
        function ChangeMontageMenuSelected(app, event)
            app.CallingApp.PChangeMontage( event);
        end

        % Menu selected function: ConfigureMenu
        function ConfigureMenuSelected(app, event)
            app.CallingApp.PSpectrogramMenu( event);
        end

        % Menu selected function: SetpreferencesMenu
        function SetpreferencesMenuSelected(app, event)
            app.CallingApp.PWinOptionsMenuSelected( event);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.CallingApp.PHelpMenuSelected( event);
        end

        % Value changed function: XScaleSpinner
        function XScaleSpinnerValueChanged(app, event)
            value = app.XScaleSpinner.Value;
            app.CallingApp.PUpdateXScale( value, event.PreviousValue);
        end

        % Value changed function: YScaleLoSpinner
        function YScaleLoSpinnerValueChanged(app, event)
            value = app.YScaleLoSpinner.Value;
            if value < app.YScaleHiSpinner.Value
                app.uidata.climit(1) = value;
                caxis( app.UIAxes, app.uidata.climit);
            else
                app.YScaleLoSpinner.Value = event.PreviousValue;
            end
        end

        % Callback function
        function FiltYScaleButtonPushed(app, event)
            app.CallingApp.PFiltYScaleButtonPushed();
        end

        % Value changed function: StartClockEditField
        function StartClockEditFieldValueChanged(app, event)
            value = app.StartClockEditField.Value;
            end_clock = app.EndClockHEditField.Value;
            app.CallingApp.PUpdateDataRange( value, event.PreviousValue, end_clock, end_clock);
        end

        % Value changed function: EndClockHEditField
        function EndClockHEditFieldValueChanged(app, event)
            value = app.EndClockHEditField.Value;
            start_clock = app.StartClockEditField.Value;
            app.CallingApp.PUpdateDataRange( start_clock, start_clock, value, event.PreviousValue);
        end

        % Value changed function: JumpToClockEditField
        function JumpToClockEditFieldValueChanged(app, event)
            value = app.JumpToClockEditField.Value;
            app.CallingApp.PJumpToClock( value);
        end

        % Button pushed function: ResetTimeButton
        function ResetTimeButtonPushed(app, event)
            app.CallingApp.PResetClock();
        end

        % Button pushed function: StepBackButton
        function StepBackButtonPushed(app, event)
            app.CallingApp.PStepBackButtonPushed();
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.CallingApp.PBackButtonPushed();
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.CallingApp.PForwardButtonPushed();
        end

        % Button pushed function: StepForwardButton
        function StepForwardButtonPushed(app, event)
            app.CallingApp.PStepForwardButtonPushed();
        end

        % Value changed function: ManualEventAutoAdjCheckBox
        function ManualEventAutoAdjCheckBoxValueChanged(app, event)
            value = app.ManualEventAutoAdjCheckBox.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ManualEventAutoAdjCheckBox', value);   
        end

        % Button pushed function: AddEventButton
        function AddEventButtonPushed(app, event)
            [~, evtype] = app.CallingApp.PGetEventType2Disp();
            app.CallingApp.PAddEvent( app.CallingApp.uidata.cur_event, evtype);             
            app.CallingApp.PRedraw();
        end

        % Callback function
        function ManualModeButtonPushed(app, event)
            value = app.ManualModeButton.Value;
            app.CallingApp.PManualEventButtonValueChanged( value);
        end

        % Button pushed function: StartEventButton
        function StartEventButtonPushed(app, event)
            app.CallingApp.PStartEventButtonPushed();
        end

        % Button pushed function: EndEventButton
        function EndEventButtonPushed(app, event)
             app.CallingApp.PEndEventButtonPushed();
        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)
            value = app.ChannelListBox.Value;
            app.CallingApp.PChannelListBoxValueChanged( value);
        end

        % Button pushed function: AddAllChannel
        function AddAllChannelButtonPushed(app, event)
            if ~isempty( app.AllChannelListBox.Items)
                app.AllChannelListBox.Value = app.AllChannelListBox.Items;
                app.CallingApp.PAddChannel( app.AllChannelListBox.Value);
            end
        end

        % Button pushed function: AddChannel
        function AddChannelButtonPushed(app, event)
            if ~isempty( app.AllChannelListBox.Value)
                app.CallingApp.PAddChannel( app.AllChannelListBox.Value);
            end
        end

        % Button pushed function: RemoveChannel
        function RemoveChannelButtonPushed(app, event)
            if ~isempty( app.ChannelListBox.Value)
                app.CallingApp.PRemoveChannel( app.ChannelListBox.Value);
            end
        end

        % Button pushed function: RemoveAllChannel
        function RemoveAllChannelButtonPushed(app, event)
            if ~isempty( app.ChannelListBox.Items)
                app.ChannelListBox.Value = app.ChannelListBox.Items;
                app.CallingApp.PRemoveChannel( app.ChannelListBox.Value);
            end
        end

        % Button pushed function: RemoveChannelButton
        function RemoveChannelButtonPushed2(app, event)
            if ~isempty( app.ChannelListBox.Value)
                app.CallingApp.PRemoveChannel( app.ChannelListBox.Value);
            end
        end

        % Value changed function: LockCurrentChannelCheckBox
        function LockCurrentChannelCheckBoxValueChanged(app, event)
            value = app.LockCurrentChannelCheckBox.Value;
            app.CallingApp.PLockCurrentChannelCheckBoxValueChanged( value);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
             if ~isempty( app.AllChannelListBox.Value)
                app.CallingApp.PAddChannel( app.AllChannelListBox.Value);
             end
        end

        % Value changed function: EventListBox
        function EventListBoxValueChanged(app, event)
            value = app.EventListBox.Value;
            app.CallingApp.PEventListBoxValueChanged( value);
        end

        % Value changed function: ETGammaCheckBox
        function ETGammaCheckBoxValueChanged(app, event)
            value = app.ETGammaCheckBox.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ETGammaCheckBox', value);
        end

        % Value changed function: ETRippleCheckBox
        function ETRippleCheckBoxValueChanged(app, event)
            value = app.ETRippleCheckBox.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ETRippleCheckBox', value);
        end

        % Value changed function: ETFastRippleCheckBox
        function ETFastRippleCheckBoxValueChanged(app, event)
            value = app.ETFastRippleCheckBox.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ETFastRippleCheckBox', value);
        end

        % Value changed function: ETUltraFastCheckBox
        function ETUltraFastCheckBoxValueChanged(app, event)
            value = app.ETUltraFastCheckBox.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ETUltraFastCheckBox', value);
        end

        % Value changed function: ETSpikeCheckBox
        function ETSpikeCheckBoxValueChanged(app, event)
            value = app.ETSpikeCheckBox.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ETSpikeCheckBox', value);            
        end

        % Selection changed function: EventTypetoDisplayButtonGroup
        function EventTypetoDisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.EventTypetoDisplayButtonGroup.SelectedObject;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'EventTypetoDisplayButtonGroup', selectedButton.Text);
        end

        % Selection changed function: 
        % EventChanneltoDisplayButtonGroup
        function EventChanneltoDisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.EventChanneltoDisplayButtonGroup.SelectedObject;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'EventChanneltoDisplayButtonGroup', selectedButton.Text);
        end

        % Button pushed function: RemoveEventButton
        function RemoveEventButtonPushed(app, event)
            app.CallingApp.PRemoveEvent( app.EventListBox.Value);
        end

        % Button pushed function: UpdateEventButton
        function UpdateEventButtonPushed(app, event)
             app.CallingApp.PUpdateCurrentEvent( app.EventListBox.Value);
        end

        % Callback function
        function AddTimeWinButtonPushed(app, event)
            app.CallingApp.PAddTimeWinButtonPushed();
        end

        % Callback function
        function AddFreqWinButtonPushed(app, event)
            app.CallingApp.PAddFreqWinPushed();
        end

        % Value changed function: ThreshOnsetEditField
        function ThreshOnsetEditFieldValueChanged(app, event)
            value = app.ThreshOnsetEditField.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ThreshOnsetEditField', value);
        end

        % Value changed function: ThreshInclusionEditField
        function ThreshInclusionEditFieldValueChanged(app, event)
            value = app.ThreshInclusionEditField.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ThreshInclusionEditField', value);
        end

        % Value changed function: ThreshNumberCyclesEditField
        function ThreshNumberCyclesEditFieldValueChanged(app, event)
            value = app.ThreshNumberCyclesEditField.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'ThreshNumberCyclesEditField', value);
        end

        % Value changed function: MultipleDetectionCheckBox
        function MultipleDetectionCheckBoxValueChanged(app, event)
            value = app.MultipleDetectionCheckBox.Value;
            
            app.CallingApp.PChangeObjectValue( event.Source.Type,...
                'MultipleDetectionCheckBox', value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           if app.is_called
                % modify data structure in main app
                for k = 1 : length( app.CallingApp.FreqWin)
                    if strcmpi( app.CallingApp.FreqWin{k}.UIFigure.Name, app.UIFigure.Name)
                        app.CallingApp.FreqWin(k) = [];        
                        break;
                    end
                end
                
                % clear spectrogram data
                if ~isempty( app.CallingApp.data.tf.mat)
                    [~, loc] = ismember( app.UIFigure.Name, app.CallingApp.data.tf.labels);
                    if loc ~= 0
                        app.CallingApp.data.tf.mat( loc) = [];
                        app.CallingApp.data.tf.param( loc) = []; % parameter for band-pass filtering
                        app.CallingApp.data.tf.labels( loc) = [];  
                        %app.CallingApp.uidata.current_channel = {};
                    end
                end
           end
                    
           delete(app);  
        end

        % Value changed function: YScaleHiSpinner
        function YScaleHiSpinnerValueChanged(app, event)
            value = app.YScaleHiSpinner.Value;
            if value > app.YScaleLoSpinner.Value
                app.uidata.climit(2) = value;
                caxis( app.UIAxes, app.uidata.climit);
            else
                app.YScaleHiSpinner.Value = event.PreviousValue;
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~app.is_called
                return;
            end
                        
            if isempty( app.CallingApp.data) || isempty( app.ChannelListBox.Items)
                return;
            end
                        
            %% Time series plot
            fig_name = app.UIFigure.Name;
            [~, loc] = ismember( fig_name, app.CallingApp.data.tf.labels);
            freq = app.CallingApp.data.tf.param{ loc}.freq( [1, end]);
            app.CallingApp.PWinButtonDown( 'freq', fig_name,...
                app.UIFigure.SelectionType, app.UIAxes.CurrentPoint( 1, 1:2),...
                app.CallingApp.uidata.data_range, freq);
            
            box_bound = [sum( app.LLabel.Position( [1, 3])),...
                app.RLabel.Position(1),...
                sum( app.BLabel.Position( [2, 4])),...
                app.TLabel.Position(2)];
            
            app.CallingApp.PShiftPlots( app.UIFigure.CurrentPoint( 1, 1:2), box_bound);
        end

        % Value changed function: ManualModeButton
        function ManualModeButtonValueChanged(app, event)
            value = app.ManualModeButton.Value;
            app.CallingApp.PManualEventButtonValueChanged( value);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if ~app.is_called
                return;
            end
            
            if strcmpi( event.Key, 'a')
                app.TabGroup.SelectedTab = app.EventsTab;
            end
            app.CallingApp.PWindowsKeyPress( event);
        end

        % Value changed function: AllChannelListBox
        function AllChannelListBoxValueChanged(app, event)
            value = app.AllChannelListBox.Value;
            app.CallingApp.PChangeObjectValue( event.Source.Type, 'AllChannelListBox', value);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.CallingApp.PClearButtonPushed( event, app.UIFigure);
        end

        % Menu selected function: ExportasMatlabFileMenu
        function ExportasMatlabFileMenuSelected(app, event)
            app.CallingApp.PExportasMatlabFileMenuSelected( []);
        end

        % Button pushed function: PreTrialButton
        function PreTrialButtonPushed(app, event)
            app.CallingApp.PPrevTrial();
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            app.CallingApp.PNextTrial();
        end

        % Value changed function: CurTrialEditField
        function CurTrialEditFieldValueChanged(app, event)
            value = app.CurTrialEditField.Value;
            app.CallingApp.PSetTrial( value, event.PreviousValue);
        end

        % Callback function
        function ADRippleLabMenuSelected(app, event)
            app.CallingApp.PADRippleLab();
        end

        % Menu selected function: SaveFileMenu
        function SaveFileMenuSelected(app, event)
            app.CallingApp.PSaveFileMenu();
        end

        % Value changed function: TFAutoUseAsPercentCheckBox
        function TFAutoUseAsPercentCheckBoxValueChanged(app, event)
            if app.TFAutoUseAsPercentCheckBox.Value == 1
                if app.TFAutoBandwidthEditField.Value < 0 || app.TFAutoBandwidthEditField.Value > 100
                    % default 50%
                    app.TFAutoBandwidthEditField.Value = 50;
                end
                
                app.TFAutoBandwidthEditField.Limits = [0, 100];
                
            else
                % default 20 Hz bandwidth
                app.TFAutoBandwidthEditField.Value = 20;
                app.TFAutoBandwidthEditField.Limits = [0, app.CallingApp.data.srate/2];
            end
        end

        % Menu selected function: AutomatedHFOdetectionMenu
        function AutomatedHFOdetectionMenuSelected(app, event)
            app.CallingApp.PAutoDetect();
        end

        % Button pushed function: MergeEventButton
        function MergeEventButtonPushed(app, event)
            app.CallingApp.PMergeEvent( app.EventListBox.Value);
        end

        % Menu selected function: SavesettingsasdefaultMenu
        function SavesettingsasdefaultMenuSelected(app, event)
            app.CallingApp.PSaveDefaultConfig();
        end

        % Callback function
        function BackgroundTimeWindowButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.BackgroundTimeWindowButtonGroup_2.SelectedObject;
            app.CallingAPP.PBKTimeChanged( selectedButton.Text);
        end

        % Selection changed function: 
        % BackgroundTimeWindowButtonGroup
        function BackgroundTimeWindowButtonGroupSelectionChanged(app, event)
            selectedButton = app.BackgroundTimeWindowButtonGroup.SelectedObject;
            app.CallingApp.PBKWinChange( selectedButton);
        end

        % Value changed function: BKSpecifiedEF
        function BKSpecifiedEFValueChanged(app, event)
            value = app.BKSpecifiedEF.Value;
            app.CallingApp.PChangeObjectValue( event.Source.Type, 'BKSpecifiedEF', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SaveFileMenu = uimenu(app.FileMenu);
            app.SaveFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFileMenuSelected, true);
            app.SaveFileMenu.Text = 'Save';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.EventMenu = uimenu(app.UIFigure);
            app.EventMenu.Text = 'Event';
            app.SaveMenu = uimenu(app.EventMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.EventMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ExportasMatlabFileMenu = uimenu(app.EventMenu);
            app.ExportasMatlabFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportasMatlabFileMenuSelected, true);
            app.ExportasMatlabFileMenu.Text = 'Export as Matlab File';
            app.LoadMenu = uimenu(app.EventMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.WindowsMenu = uimenu(app.UIFigure);
            app.WindowsMenu.Text = 'Windows';
            app.AddbandpassfilterwindowMenu = uimenu(app.WindowsMenu);
            app.AddbandpassfilterwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddbandpassfilterwindowMenuSelected, true);
            app.AddbandpassfilterwindowMenu.Text = 'Add bandpass filter window';
            app.AddtimefrequencywindowMenu = uimenu(app.WindowsMenu);
            app.AddtimefrequencywindowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddtimefrequencywindowMenuSelected, true);
            app.AddtimefrequencywindowMenu.Text = 'Add time-frequency window';
            app.OrganizeWindowsMenu = uimenu(app.WindowsMenu);
            app.OrganizeWindowsMenu.MenuSelectedFcn = createCallbackFcn(app, @OrganizeWindowsMenuSelected, true);
            app.OrganizeWindowsMenu.Text = 'Organize Windows';
            app.MontageMenu = uimenu(app.UIFigure);
            app.MontageMenu.Text = 'Montage';
            app.ChangeMontageMenu = uimenu(app.MontageMenu);
            app.ChangeMontageMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeMontageMenuSelected, true);
            app.ChangeMontageMenu.Text = 'Change Montage';
            app.SpectrogramMenu = uimenu(app.UIFigure);
            app.SpectrogramMenu.Text = 'Spectrogram';
            app.ConfigureMenu = uimenu(app.SpectrogramMenu);
            app.ConfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigureMenuSelected, true);
            app.ConfigureMenu.Text = 'Configure';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.SetpreferencesMenu = uimenu(app.OptionsMenu);
            app.SetpreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @SetpreferencesMenuSelected, true);
            app.SetpreferencesMenu.Text = 'Set preferences';
            app.AutomatedHFOdetectionMenu = uimenu(app.OptionsMenu);
            app.AutomatedHFOdetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomatedHFOdetectionMenuSelected, true);
            app.AutomatedHFOdetectionMenu.Text = 'Automated HFO detection';
            app.SavesettingsasdefaultMenu = uimenu(app.OptionsMenu);
            app.SavesettingsasdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesettingsasdefaultMenuSelected, true);
            app.SavesettingsasdefaultMenu.Text = 'Save settings as default';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.XScaleSpinner = uispinner(app.UIFigure);
            app.XScaleSpinner.Step = 0.2;
            app.XScaleSpinner.Limits = [2.22044604925031e-16 Inf];
            app.XScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @XScaleSpinnerValueChanged, true);
            app.XScaleSpinner.Tooltip = {'Time window length (in seconds).'};
            app.XScaleSpinner.Value = 2.22044604925031e-16;
            app.YScaleLoSpinner = uispinner(app.UIFigure);
            app.YScaleLoSpinner.Step = 20;
            app.YScaleLoSpinner.Limits = [0 Inf];
            app.YScaleLoSpinner.ValueChangedFcn = createCallbackFcn(app, @YScaleLoSpinnerValueChanged, true);
            app.YScaleLoSpinner.Tooltip = {'Low intensity of spectrogram.'};
            app.StartClockEditField = uieditfield(app.UIFigure, 'text');
            app.StartClockEditField.ValueChangedFcn = createCallbackFcn(app, @StartClockEditFieldValueChanged, true);
            app.StartClockEditField.Tooltip = {'Format: yyyy-MM-dd HH:mm:ss Or Scalar of sceonds'};
            app.EndClockHEditField = uieditfield(app.UIFigure, 'text');
            app.EndClockHEditField.ValueChangedFcn = createCallbackFcn(app, @EndClockHEditFieldValueChanged, true);
            app.EndClockHEditField.Tooltip = {'Format: yyyy-MM-dd HH:mm:ss Or Scalar of sceonds'};
            app.ResetTimeButton = uibutton(app.UIFigure, 'push');
            app.ResetTimeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimeButtonPushed, true);
            app.ResetTimeButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChannelTab = uitab(app.TabGroup);
            app.ChannelTab.Title = 'Channel';
            app.ChannelListBox = uilistbox(app.ChannelTab);
            app.ChannelListBox.Multiselect = 'on';
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Value = {'Item 1'};
            app.AllChannelListBox = uilistbox(app.ChannelTab);
            app.AllChannelListBox.Multiselect = 'on';
            app.AllChannelListBox.ValueChangedFcn = createCallbackFcn(app, @AllChannelListBoxValueChanged, true);
            app.AllChannelListBox.Value = {'Item 1'};
            app.LockCurrentChannelCheckBox = uicheckbox(app.ChannelTab);
            app.LockCurrentChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCurrentChannelCheckBoxValueChanged, true);
            app.LockCurrentChannelCheckBox.Text = 'Lock channel';
            app.RemoveChannelButton = uibutton(app.ChannelTab, 'push');
            app.RemoveChannelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelButtonPushed2, true);
            app.RemoveChannelButton.Text = 'Remove';
            app.AddButton = uibutton(app.ChannelTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddAllChannel = uibutton(app.ChannelTab, 'push');
            app.AddAllChannel.ButtonPushedFcn = createCallbackFcn(app, @AddAllChannelButtonPushed, true);
            app.AddAllChannel.Text = '<<';
            app.AddChannel = uibutton(app.ChannelTab, 'push');
            app.AddChannel.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannel.Text = '<';
            app.RemoveChannel = uibutton(app.ChannelTab, 'push');
            app.RemoveChannel.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelButtonPushed, true);
            app.RemoveChannel.Text = '>';
            app.RemoveAllChannel = uibutton(app.ChannelTab, 'push');
            app.RemoveAllChannel.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllChannelButtonPushed, true);
            app.RemoveAllChannel.Text = '>>';
            app.DisplayLabel = uilabel(app.ChannelTab);
            app.DisplayLabel.Text = 'Display';
            app.AllLabel = uilabel(app.ChannelTab);
            app.AllLabel.Text = 'All';
            app.ClearButton = uibutton(app.ChannelTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.EventsTab = uitab(app.TabGroup);
            app.EventsTab.Title = 'Events';
            app.EventListBox = uilistbox(app.EventsTab);
            app.EventListBox.Multiselect = 'on';
            app.EventListBox.ValueChangedFcn = createCallbackFcn(app, @EventListBoxValueChanged, true);
            app.EventListBox.Value = {'Item 1'};
            app.RemoveEventButton = uibutton(app.EventsTab, 'push');
            app.RemoveEventButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEventButtonPushed, true);
            app.RemoveEventButton.Text = 'Remove';
            app.UpdateEventButton = uibutton(app.EventsTab, 'push');
            app.UpdateEventButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventButtonPushed, true);
            app.UpdateEventButton.Text = 'Update';
            app.TypePanel = uipanel(app.EventsTab);
            app.TypePanel.Title = 'Type';
            app.ETGammaCheckBox = uicheckbox(app.TypePanel);
            app.ETGammaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETGammaCheckBoxValueChanged, true);
            app.ETGammaCheckBox.Text = 'Gamma';
            app.ETRippleCheckBox = uicheckbox(app.TypePanel);
            app.ETRippleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETRippleCheckBoxValueChanged, true);
            app.ETRippleCheckBox.Text = 'Ripple';
            app.ETFastRippleCheckBox = uicheckbox(app.TypePanel);
            app.ETFastRippleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETFastRippleCheckBoxValueChanged, true);
            app.ETFastRippleCheckBox.Text = 'Fast ripple';
            app.ETUltraFastCheckBox = uicheckbox(app.TypePanel);
            app.ETUltraFastCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETUltraFastCheckBoxValueChanged, true);
            app.ETUltraFastCheckBox.Text = 'Ultra fast';
            app.ETSpikeCheckBox = uicheckbox(app.TypePanel);
            app.ETSpikeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETSpikeCheckBoxValueChanged, true);
            app.ETSpikeCheckBox.Text = 'Spike';
            app.EventTypetoDisplayButtonGroup = uibuttongroup(app.EventsTab);
            app.EventTypetoDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventTypetoDisplayButtonGroupSelectionChanged, true);
            app.EventTypetoDisplayButtonGroup.Title = 'Type to display';
            app.ShowAllEventButton = uiradiobutton(app.EventTypetoDisplayButtonGroup);
            app.ShowAllEventButton.Text = 'All';
            app.ShowAllEventButton.Value = true;
            app.ShowSelectedEventButton = uiradiobutton(app.EventTypetoDisplayButtonGroup);
            app.ShowSelectedEventButton.Text = 'Selected';
            app.EventChanneltoDisplayButtonGroup = uibuttongroup(app.EventsTab);
            app.EventChanneltoDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventChanneltoDisplayButtonGroupSelectionChanged, true);
            app.EventChanneltoDisplayButtonGroup.Title = 'Channel to display';
            app.ShowEventOnAllChannelButton = uiradiobutton(app.EventChanneltoDisplayButtonGroup);
            app.ShowEventOnAllChannelButton.Text = 'All';
            app.ShowEventOnAllChannelButton.Value = true;
            app.ShowEventOnSelectedChanelButton = uiradiobutton(app.EventChanneltoDisplayButtonGroup);
            app.ShowEventOnSelectedChanelButton.Text = 'Selected';
            app.MergeEventButton = uibutton(app.EventsTab, 'push');
            app.MergeEventButton.ButtonPushedFcn = createCallbackFcn(app, @MergeEventButtonPushed, true);
            app.MergeEventButton.Text = 'Merge';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MultipleDetectionCheckBox = uicheckbox(app.SettingsTab);
            app.MultipleDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultipleDetectionCheckBoxValueChanged, true);
            app.MultipleDetectionCheckBox.Text = 'Enable multiple channel detection.';
            app.EventDetectionOptionsPanel = uipanel(app.SettingsTab);
            app.EventDetectionOptionsPanel.Title = 'Event Detection Options';
            app.OnsetthresholdSDEditFieldLabel = uilabel(app.EventDetectionOptionsPanel);
            app.OnsetthresholdSDEditFieldLabel.Text = 'Onset threshold (SD):';
            app.ThreshOnsetEditField = uieditfield(app.EventDetectionOptionsPanel, 'numeric');
            app.ThreshOnsetEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshOnsetEditFieldValueChanged, true);
            app.InclusionCriteriaSDEditFieldLabel = uilabel(app.EventDetectionOptionsPanel);
            app.InclusionCriteriaSDEditFieldLabel.Text = 'Inclusion Criteria (SD):';
            app.ThreshInclusionEditField = uieditfield(app.EventDetectionOptionsPanel, 'numeric');
            app.ThreshInclusionEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshInclusionEditFieldValueChanged, true);
            app.NumberofCyclesEditFieldLabel = uilabel(app.EventDetectionOptionsPanel);
            app.NumberofCyclesEditFieldLabel.Text = 'Number of Cycles:';
            app.ThreshNumberCyclesEditField = uieditfield(app.EventDetectionOptionsPanel, 'numeric');
            app.ThreshNumberCyclesEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshNumberCyclesEditFieldValueChanged, true);
            app.BackgroundTimeWindowButtonGroup = uibuttongroup(app.SettingsTab);
            app.BackgroundTimeWindowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundTimeWindowButtonGroupSelectionChanged, true);
            app.BackgroundTimeWindowButtonGroup.Title = 'Background Time Window';
            app.BKWholeRB = uiradiobutton(app.BackgroundTimeWindowButtonGroup);
            app.BKWholeRB.Text = 'Whole time series';
            app.BKWholeRB.Value = true;
            app.BKCurrentRB = uiradiobutton(app.BackgroundTimeWindowButtonGroup);
            app.BKCurrentRB.Text = 'Current Window';
            app.BKSpecifiedRB = uiradiobutton(app.BackgroundTimeWindowButtonGroup);
            app.BKSpecifiedRB.Text = 'Specified Window (s):';
            app.BKSpecifiedEF = uieditfield(app.SettingsTab, 'numeric');
            app.BKSpecifiedEF.Limits = [0.1 Inf];
            app.BKSpecifiedEF.ValueChangedFcn = createCallbackFcn(app, @BKSpecifiedEFValueChanged, true);
            app.BKSpecifiedEF.Value = 5;
            app.DurationLabel = uilabel(app.UIFigure);
            app.StepBackButton = uibutton(app.UIFigure, 'push');
            app.StepBackButton.ButtonPushedFcn = createCallbackFcn(app, @StepBackButtonPushed, true);
            app.StepBackButton.Text = '<<';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '<';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = '>';
            app.StepForwardButton = uibutton(app.UIFigure, 'push');
            app.StepForwardButton.ButtonPushedFcn = createCallbackFcn(app, @StepForwardButtonPushed, true);
            app.StepForwardButton.Text = '>>';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Label2';
            app.JumpToClockEditField = uieditfield(app.UIFigure, 'text');
            app.JumpToClockEditField.ValueChangedFcn = createCallbackFcn(app, @JumpToClockEditFieldValueChanged, true);
            app.AddEventButton = uibutton(app.UIFigure, 'push');
            app.AddEventButton.ButtonPushedFcn = createCallbackFcn(app, @AddEventButtonPushed, true);
            app.AddEventButton.Text = 'Add event';
            app.StartEventButton = uibutton(app.UIFigure, 'push');
            app.StartEventButton.ButtonPushedFcn = createCallbackFcn(app, @StartEventButtonPushed, true);
            app.StartEventButton.Enable = 'off';
            app.StartEventButton.Text = 'Start';
            app.EndEventButton = uibutton(app.UIFigure, 'push');
            app.EndEventButton.ButtonPushedFcn = createCallbackFcn(app, @EndEventButtonPushed, true);
            app.EndEventButton.Enable = 'off';
            app.EndEventButton.Text = 'End';
            app.ManualEventAutoAdjCheckBox = uicheckbox(app.UIFigure);
            app.ManualEventAutoAdjCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualEventAutoAdjCheckBoxValueChanged, true);
            app.ManualEventAutoAdjCheckBox.Enable = 'off';
            app.ManualEventAutoAdjCheckBox.Text = 'Manual auto adjustment';
            app.ManualEventAutoAdjCheckBox.Value = true;
            app.ManualModeButton = uibutton(app.UIFigure, 'state');
            app.ManualModeButton.ValueChangedFcn = createCallbackFcn(app, @ManualModeButtonValueChanged, true);
            app.ManualModeButton.Text = 'Manual';
            app.PSDPanel = uipanel(app.UIFigure);
            app.PSDPanel.Title = 'Event detection';
            app.TFAutoDetectCheckBox = uicheckbox(app.PSDPanel);
            app.TFAutoDetectCheckBox.Tooltip = {'Enable/Disable spectrogram guided HFO detection.'};
            app.TFAutoDetectCheckBox.Text = 'Event detect with spectrogram';
            app.PeakfrequencyLabel = uilabel(app.PSDPanel);
            app.PeakfrequencyLabel.Text = 'Peak frequency:';
            app.TFAutoPeakFreqLabel = uilabel(app.PSDPanel);
            app.TFAutoPeakFreqLabel.Text = 'Label4';
            app.NumberofcyclesLabel = uilabel(app.PSDPanel);
            app.NumberofcyclesLabel.Text = 'Number of cycles:';
            app.TFAutoNoCycleLabel = uilabel(app.PSDPanel);
            app.TFAutoNoCycleLabel.Text = 'Label6';
            app.TFAutoBandwidthEditField = uieditfield(app.PSDPanel, 'numeric');
            app.TFAutoBandwidthEditField.Limits = [0.1 100];
            app.TFAutoBandwidthEditField.Tooltip = {'Bandwidth for Hilbert detector.'};
            app.TFAutoBandwidthEditField.Value = 20;
            app.TFAutoTimeLenEditField = uieditfield(app.PSDPanel, 'numeric');
            app.TFAutoTimeLenEditField.Limits = [0 10];
            app.TFAutoTimeLenEditField.Tooltip = {'Time window for calculating the peak frequency.'};
            app.TFAutoTimeLenEditField.Value = 0.01;
            app.BandwidthHzEditFieldLabel = uilabel(app.PSDPanel);
            app.BandwidthHzEditFieldLabel.Text = 'Bandwidth (Hz):';
            app.TimewidowsEditFieldLabel = uilabel(app.PSDPanel);
            app.TimewidowsEditFieldLabel.Text = 'Time widow (s):';
            app.TFAutoUseAsPercentCheckBox = uicheckbox(app.PSDPanel);
            app.TFAutoUseAsPercentCheckBox.ValueChangedFcn = createCallbackFcn(app, @TFAutoUseAsPercentCheckBoxValueChanged, true);
            app.TFAutoUseAsPercentCheckBox.Tooltip = {'Check to interpret "Bandwidth (Hz)" as percentage of the peak.'};
            app.TFAutoUseAsPercentCheckBox.Text = 'Percentage of peak';
            app.UIAxesAvg = uiaxes(app.PSDPanel);
            xlabel(app.UIAxesAvg, 'Frequency (Hz)')
            ylabel(app.UIAxesAvg, 'Amplitude')
            zlabel(app.UIAxesAvg, 'Z')
            app.UIAxesAvg.XTick = [];
            app.UIAxesAvg.YTick = [];
            app.YScaleHiSpinner = uispinner(app.UIFigure);
            app.YScaleHiSpinner.Step = 10;
            app.YScaleHiSpinner.Limits = [2.22044604925031e-16 Inf];
            app.YScaleHiSpinner.ValueChangedFcn = createCallbackFcn(app, @YScaleHiSpinnerValueChanged, true);
            app.YScaleHiSpinner.Tooltip = {'High intensity of spectrogram.'};
            app.YScaleHiSpinner.Value = 2.22044604925031e-16;
            app.JumptoLabel = uilabel(app.UIFigure);
            app.JumptoLabel.Text = 'Go to time:';
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time (s):';
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Amplitude:';
            app.StartClockLabel = uilabel(app.UIFigure);
            app.StartClockLabel.Text = 'Start Time:';
            app.EndClockLabel = uilabel(app.UIFigure);
            app.EndClockLabel.Text = 'End Time:';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = '';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = '';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = '';
            app.LLabel = uilabel(app.UIFigure);
            app.LLabel.Text = '';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = '';
            app.ToiTLabel = uilabel(app.UIFigure);
            app.ToiBLabel = uilabel(app.UIFigure);
            app.ToiBLabel.Text = '';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial:';
            app.CurTrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurTrialEditField.ValueChangedFcn = createCallbackFcn(app, @CurTrialEditFieldValueChanged, true);
            app.CurTrialEditField.Value = 1;
            app.PreTrialButton = uibutton(app.UIFigure, 'push');
            app.PreTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreTrialButtonPushed, true);
            app.PreTrialButton.Text = '<';
            app.NextTrialButton = uibutton(app.UIFigure, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = '>';
            app.TotalTrialLabel = uilabel(app.UIFigure);
            app.TotalTrialLabel.Text = 'Label3';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'Frequency (Hz)')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FreqApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end