classdef HFOApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HFOAppUIFigure                  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        SaveFileMenu                    matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        EventMenu                       matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        ExportasMatlabFileMenu          matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        WindowsMenu                     matlab.ui.container.Menu
        AddbandpassfilterwindowMenu     matlab.ui.container.Menu
        AddtimefrequencywindowMenu      matlab.ui.container.Menu
        OrganizeWindowsMenu             matlab.ui.container.Menu
        MontageMenu                     matlab.ui.container.Menu
        ChangeMontageMenu               matlab.ui.container.Menu
        SpectrogramMenu                 matlab.ui.container.Menu
        ConfigureMenu                   matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        SetpreferencesMenu              matlab.ui.container.Menu
        AutomatedHFOdetectionMenu       matlab.ui.container.Menu
        SavesettingsasdefaultMenu       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TotalTrialLabel                 matlab.ui.control.Label
        NextTrialButton                 matlab.ui.control.Button
        PreTrialButton                  matlab.ui.control.Button
        CurTrialEditField               matlab.ui.control.NumericEditField
        TrialLabel                      matlab.ui.control.Label
        ToiBLabel                       matlab.ui.control.Label
        ToiTLabel                       matlab.ui.control.Label
        RLabel                          matlab.ui.control.Label
        LLabel                          matlab.ui.control.Label
        MLabel                          matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
        TLabel                          matlab.ui.control.Label
        JumptoLabel                     matlab.ui.control.Label
        EndClockLabel                   matlab.ui.control.Label
        StartClockLabel                 matlab.ui.control.Label
        AmplitudeLabel                  matlab.ui.control.Label
        TimesLabel                      matlab.ui.control.Label
        ManualModeButton                matlab.ui.control.StateButton
        ManualEventAutoAdjCheckBox      matlab.ui.control.CheckBox
        EndEventButton                  matlab.ui.control.Button
        StartEventButton                matlab.ui.control.Button
        AddEventButton                  matlab.ui.control.Button
        JumpToClockEditField            matlab.ui.control.EditField
        StatusLabel                     matlab.ui.control.Label
        StepForwardButton               matlab.ui.control.Button
        ForwardButton                   matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        StepBackButton                  matlab.ui.control.Button
        DurationLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ChannelTab                      matlab.ui.container.Tab
        ClearButton                     matlab.ui.control.Button
        AllLabel                        matlab.ui.control.Label
        DisplayLabel                    matlab.ui.control.Label
        RemoveAllChannel                matlab.ui.control.Button
        RemoveChannel                   matlab.ui.control.Button
        AddChannel                      matlab.ui.control.Button
        AddAllChannel                   matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        RemoveChannelButton             matlab.ui.control.Button
        LockCurrentChannelCheckBox      matlab.ui.control.CheckBox
        AllChannelListBox               matlab.ui.control.ListBox
        ChannelListBox                  matlab.ui.control.ListBox
        EventsTab                       matlab.ui.container.Tab
        MergeEventButton                matlab.ui.control.Button
        EventChanneltoDisplayButtonGroup  matlab.ui.container.ButtonGroup
        ShowEventOnSelectedChanelButton  matlab.ui.control.RadioButton
        ShowEventOnAllChannelButton     matlab.ui.control.RadioButton
        EventTypetoDisplayButtonGroup   matlab.ui.container.ButtonGroup
        ShowSelectedEventButton         matlab.ui.control.RadioButton
        ShowAllEventButton              matlab.ui.control.RadioButton
        TypePanel                       matlab.ui.container.Panel
        ETSpikeCheckBox                 matlab.ui.control.CheckBox
        ETUltraFastCheckBox             matlab.ui.control.CheckBox
        ETFastRippleCheckBox            matlab.ui.control.CheckBox
        ETRippleCheckBox                matlab.ui.control.CheckBox
        ETGammaCheckBox                 matlab.ui.control.CheckBox
        UpdateEventButton               matlab.ui.control.Button
        RemoveEventButton               matlab.ui.control.Button
        EventListBox                    matlab.ui.control.ListBox
        SettingsTab                     matlab.ui.container.Tab
        BKSpecifiedEF                   matlab.ui.control.NumericEditField
        BackgroundTimeWindowButtonGroup  matlab.ui.container.ButtonGroup
        BKSpecifiedRB                   matlab.ui.control.RadioButton
        BKCurrentRB                     matlab.ui.control.RadioButton
        BKWholeRB                       matlab.ui.control.RadioButton
        EventDetectionOptionsPanel      matlab.ui.container.Panel
        ThreshNumberCyclesEditField     matlab.ui.control.NumericEditField
        NumberofCyclesEditFieldLabel    matlab.ui.control.Label
        ThreshInclusionEditField        matlab.ui.control.NumericEditField
        InclusionCriteriaSDEditFieldLabel  matlab.ui.control.Label
        ThreshOnsetEditField            matlab.ui.control.NumericEditField
        OnsetthresholdSDEditFieldLabel  matlab.ui.control.Label
        MultipleDetectionCheckBox       matlab.ui.control.CheckBox
        ResetTimeButton                 matlab.ui.control.Button
        EndClockHEditField              matlab.ui.control.EditField
        StartClockEditField             matlab.ui.control.EditField
        FiltYScaleButton                matlab.ui.control.Button
        YScaleSpinner                   matlab.ui.control.Spinner
        XScaleSpinner                   matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % All time series data
        data
        
        % gui data
        uidata
        
        % Band-pass filtered window handles, Nx1 cell array
        TimeWin
        
        % Spectrogram window handes, Nx1 cell array
        FreqWin
        
        % Window handle. Y scale of embeded bandpass filter time series
        HFloatYScale
        
        % Preference setup window handle
        HMiscOption
        
        % Tooltip objects
        tooltip_objs
        
    end % public properties
    
    
    methods (Access = public)
        
        %% Main draw function
        function PRedraw( app)
            Redraw(app);
        end
        
        
        %% Move the time series plot backward with a small step
        function PBackButtonPushed( app)
            BackButtonPushed( app);
        end
        
        
        %% Move the time series plot backward with a large step
        function PStepBackButtonPushed( app)
            StepBackButtonPushed( app);
        end
        
        
        %% Move the time series plot forward with a small step
        function PForwardButtonPushed(app)
            ForwardButtonPushed( app);
        end
        
        
        %% Move the time series plot forward with a large step
        function PStepForwardButtonPushed(app)
            StepForwardButtonPushed( app);
        end
        
        
        %% Add channel to plot
        function PAddChannel(app, items)
            app.AllChannelListBox.Value = items;
            AddChannelButtonPushed( app);
        end
        
        
        %% Remove channel from plot
        function PRemoveChannel( app, items)
            app.ChannelListBox.Value = items;
            RemoveChannelButtonPushed( app);
        end
        
        
        %% Update the status bar
        function PUpdateStatus( app, txt, fc)
            % txt, string
            % fc, font color, [r, g, b] | color code 
            
            % font size
            fz = app.uidata.fontsize + 2;
            
            set( app.StatusLabel,...
                'Text', txt,...
                'FontColor', fc,...
                'FontSize', fz);

            cellfun( @(x) ...
                set( x.StatusLabel,...
                'Text', txt,...
                'FontColor', fc,...
                'FontSize', fz),...
                app.TimeWin);
            
            cellfun( @(x)...
                set( x.StatusLabel,...
                'Text', txt,...
                'FontColor', fc,...
                'FontSize', fz),...
                app.FreqWin);
        end
        
        
        %% Display event detection/marking results
        function PUpdateClustInfo( app, txt, fc)
            % txt, string
            % fc, font color [r, g, b] | color code    
            
            fz = app.uidata.fontsize + 2;
            
            set( app.DurationLabel,...
                'Text', txt,...
                'FontColor', fc,...
                'FontSize', fz);
            
            cellfun( @(x) ...
                set( x.DurationLabel,...
                'Text', txt,...
                'FontColor', fc,...
                'FontSize', fz), ...
                app.TimeWin);
            
            cellfun( @(x) ...
                set( x.DurationLabel,...
                'Text', txt,...
                'FontColor', fc,...
                'FontSize', fz), ...
                app.FreqWin);
        end
        
        
        %% Time window change
        function PUpdateXScale( app, new_val, prev_val)
            % new_val, new value
            % prev_val, previous value      
            
            app.XScaleSpinner.Value = new_val;
            
            cellfun( @(x) ...
                set( x.XScaleSpinner, 'Value', new_val),...
                app.TimeWin);
            
            cellfun( @(x) ...
                set( x.XScaleSpinner, 'Value', new_val),...
                app.FreqWin);
            
            % Validate and update
            UpdateXScale( app, new_val, prev_val);
        end
        
        
        %% Time-of-interet
        function PUpdateDataRange(app, sclk, prev_sclk, eclk, prev_eclk) 
            % sclk, start clock string
            % prev_sclk, previous start clock string
            % eclk, end clock string
            % prev_eclk, previous end clock string
            
            if app.uidata.debug
                fprintf( 'PUpdateDataRange called.\n');
            end
            
            srate = app.data.srate;            
            if strcmpi( app.uidata.clock_type, 'clock')
                clock_format = 'yyyy-MM-dd HH:mm:ss';
                
                % Update time window of interest
                try
                    tmp_sclock = datetime( sclk, 'InputFormat', clock_format);
                    app.StartClockEditField.Value = sclk;
                    UpdateClock( app);
                    
                catch
                    PUpdateStatus( app, 'Invalid start clock format.', 'r');
                    app.StartClockEditField.FontColor = 'r';
                    app.StartClockEditField.Value = prev_sclk;
                    UpdateClock( app);
                    return;
                end            
                
                try
                    tmp_eclock = datetime( eclk, 'InputFormat', clock_format);
                    app.EndClockHEditField.Value = eclk;
                    UpdateClock( app);
                    
                catch
                    PUpdateStatus( app, 'Invalid end clock format.', 'r');
                    app.EndClockHEditField.FontColor = 'r';
                    app.EndClockHEditField.Value = prev_eclk;
                    UpdateClock( app);
                    return;
                end
                                
                app.StartClockEditField.FontColor = 'k';
                app.EndClockHEditField.FontColor = 'k';
                UpdateClock( app);
                
                start_clock = app.data.start_clock;
                end_clock = app.data.end_clock;
                
                % difference between start clock and new start clock
                d_s_tmps = TimeDiff( app, start_clock, tmp_sclock);
                
                % difference between new start clock and end clock
                d_tmps_e = TimeDiff( app, tmp_sclock, end_clock);
                
                % start and timp end clock
                d_s_tmpe = TimeDiff( app, start_clock, tmp_eclock);
                
                d_tmpe_e = TimeDiff( app, tmp_eclock, end_clock);
                
                d_tmps_tmpe = TimeDiff( app, tmp_sclock, tmp_eclock);
                
                if d_s_tmps < 0  || d_tmps_e < 0
                    % start clock time is out of range
                    app.StartClockEditField.FontColor = 'r';
                    txt = strcat( string( sclk), ' was invalid start clock time.');
                    PUpdateStatus( app, txt, 'r');
                    
                    app.StartClockEditField.Value = prev_sclk;
                    UpdateClock( app);
                    
                elseif d_s_tmpe  < 0  || d_tmpe_e  < 0
                    % stop clock time is out of range
                    app.EndClockHEditField.FontColor = 'r';
                    txt = strcat( string( eclk), ' was invalid stop clock time.');
                    PUpdateStatus( app, txt, 'r');
                    
                    app.EndClockHEditField.Value = prev_eclk;
                    UpdateClock( app);
                    
                elseif d_tmps_tmpe < 0
                    % start and end time clock in wrong order
                    app.EndClockHEditField.FontColor = 'r';
                    app.StartClockEditField.FontColor = 'r';
                    txt = 'Invalid start and stop clock time. Time range has not been changed.';
                    PUpdateStatus( app, txt, 'r');
                    
                    app.StartClockEditField.Value = prev_sclk;
                    app.EndClockHEditField.Value = prev_eclk;
                    UpdateClock( app);
                    
                else
                    % update time window
                    start_loc = floor( srate * d_s_tmps);
                    start_loc = max( [1, start_loc]);
                    end_loc = floor( srate * d_s_tmpe);
                    end_loc = min( [end_loc, app.data.nbsamples]);
                    app.uidata.xrange = [start_loc, end_loc];
                    app.uidata.start_loc = start_loc;
                    Redraw( app);
                end

            else
                % Input in seconds   
                time_range = app.data.time([1, end]);
                tmp_sclock = str2double( sclk);  
                
                % tolerence for the comparison between floating numbers
                t_tol = 0.1 / srate;     
                t_out_rng = time_range(1) > tmp_sclock || time_range(2) + t_tol < tmp_sclock;
                if ~isscalar( tmp_sclock) || t_out_rng
                    PUpdateStatus( app, 'Invalid start time.', 'r');
                    app.StartClockEditField.FontColor = 'r';
                    app.StartClockEditField.Value = prev_sclk;
                    UpdateClock( app);
                    return;
                end
                
                app.StartClockEditField.Value = sclk;                
        
                tmp_eclock = str2double( eclk);  
                t_out_rng = time_range(1) > tmp_eclock || time_range(2) + t_tol < tmp_eclock;
                if ~isscalar( tmp_eclock) || t_out_rng
                    PUpdateStatus( app, 'Invalid end time.', 'r');
                    app.EndClockHEditField.FontColor = 'r';
                    app.EndClockHEditField.Value = prev_eclk;
                    UpdateClock( app);
                    return;
                end

                app.EndClockHEditField.Value = eclk;                  
                
                if tmp_sclock >= tmp_eclock
                    app.StartClockEditField.FontColor = 'r';
                    app.EndClockHEditField.FontColor = 'r';
                    app.StartClockEditField.Value = prev_sclk;
                    app.EndClockHEditField.Value = prev_eclk;
                    UpdateClock( app);
                    return;
                end
                
                app.StartClockEditField.FontColor = 'k';
                app.EndClockHEditField.FontColor = 'k';
                UpdateClock( app);                
                
                % update time window
                start_loc = floor( srate * (tmp_sclock - time_range(1)) + 1);
                start_loc = max( [1, start_loc]);
                
                end_loc = floor( srate * (tmp_eclock - time_range(1)) + 1);
                end_loc = min( [end_loc, app.data.nbsamples]);
                
                app.uidata.xrange = [start_loc, end_loc];
                app.uidata.start_loc = start_loc;
                Redraw( app);                
            end      
            
            PUpdateStatus( app, 'Time range updated.', app.uidata.msg_fc);  
            
        end % PUpdateDataRange
        
        
        %% Reset time-of-interest to entire time series
        function PResetClock( app)
            ResetTimeButtonPushed( app);
        end
        
        
        %% Jump to clock time
        function PJumpToClock( app, value)
            app.JumpToClockEditField.Value = value;
            JumpToClockEditFieldValueChanged( app, []);
        end
    
        
        %% Synchronize the values of objects across Time and Spectrogram windows
        function PChangeObjectValue( app, ev_type, name, value)
            if nargin > 1
                if strcmpi( ev_type, 'uibuttongroup')
                    btn_grp = app.( name).Children;
                    
                    child_nam = arrayfun( @(x)...
                        btn_grp( x).Text,...
                        (1 : length( btn_grp))',...
                        'Uniformoutput', false);
                    
                    [is_exist, loc] = ismember( value, child_nam);
                    if is_exist 
                        app.( name).Children( loc).Value = 1;
                    end
                else
                    app.(name).Value = value;
                end
            end

            SyncTimeWin( app);
            SyncFreqWin( app);
            
            % default configurations
            app.uidata.thresh_inclusion = app.ThreshInclusionEditField.Value;
            app.uidata.thresh_onset = app.ThreshOnsetEditField.Value;
            app.uidata.thresh_nbcycle = app.ThreshNumberCyclesEditField.Value;
        end
        
        
        %% Time window for z score calculation
        function PBKWinChange( app, select_btn)
            
            if strcmpi( select_btn.Text, 'Whole time series')
                app.BKWholeRB.Value = 1;
                
            elseif strcmpi( select_btn.Text, 'Current Window')
                app.BKCurrentRB.Value = 1;
                
            else
                app.BKSpecifiedRB.Value = 1;
            end
            
            BackgroundTimeWindowButtonGroupSelectionChanged( app, []);
        end
        
        
        %% Manual event start button pushed
        function PStartEventButtonPushed(app)
            StartEventButtonPushed(app);
        end
        
        
        %% Manual event end button pushed
        function PEndEventButtonPushed(app)
            EndEventButtonPushed(app);
        end
        
        
        %% Set manual event
        function msg = PSetManualEventRange( app, btn)
            if app.uidata.debug
                fprintf( 'PSetManualEventRange called.\n');
            end
            
            % btn, 'start' | 'end' to indicate which button was pressed
            if isempty( app.data) 
                return;
            end
            
            if app.ManualModeButton.Value == 0
                return;
            end
        
            % Current working channel must be set
            if isempty( app.uidata.current_channel)
                msg = 'Current channel has not been set.';
                return;
            end

            % Cursor location must be set
            if isempty( app.uidata.cursor_loc)
                msg = 'Cursor location was not set';
                return;
            end
            
            cursor_loc = round( app.uidata.cursor_loc);

            % manual event location
            ev_loc = [];
            
            % start and end location
            sloc = app.uidata.manual_start;
            eloc = app.uidata.manual_end;
            if strcmpi( btn, 'start')
                app.uidata.manual_start = cursor_loc;
                app.StartEventButton.FontColor = 'b';
                if ~isempty( eloc) && eloc > cursor_loc
                    ev_loc = [cursor_loc, eloc];
                    
                elseif ~isempty( eloc) && ~(eloc > cursor_loc)
                    ev_loc = [];                    
                    % app.uidata.manual_start = [];                    
                    % app.StartEventButton.FontColor = 'k';                    
                    app.uidata.manual_end = [];                    
                    app.EndEventButton.FontColor = 'k';                    
                else
                    % do nothing
                end                
                
            elseif strcmpi( btn, 'end')
                app.uidata.manual_end = cursor_loc;
                app.EndEventButton.FontColor = 'b';                
                if ~isempty( sloc) && cursor_loc > sloc
                    ev_loc = [sloc, cursor_loc];
                    
                elseif ~isempty( sloc) && ~(cursor_loc > sloc)
                    ev_loc = [];                    
                    % app.uidata.manual_end = [];                    
                    % app.EndEventButton.FontColor = 'k';
                    app.uidata.manual_start = [];                    
                    app.StartEventButton.FontColor = 'k';
                else 
                    % do nothing
                end
                
            else
                return;
            end % which button was pressed
            
            
            cur_chnl = app.uidata.current_channel{1};
            cidx = PRetriveFiltCellIndex( app, app.uidata.current_channel{2});            
            % cidx = 0 if raw time series was clicked
            % cidx > 0 if bandpass filtered time series was clicked
            if cidx == 0
                msg = 'Manual event using raw data.';
                
            else                
                msg = ['Manual event. Filter Window: ',...
                    app.data.bpfilt.param{ cidx}.Name,...
                    '; Channel: ', cur_chnl,...
                    '; Matrix index: ', num2str( cidx), '\n'];
            end
            
            if app.uidata.debug
                fprintf( msg);
            end
            
            % Estimate nubmer of cycles etc
            srate = app.data.srate;
            if cidx > 0 && ~isempty( ev_loc)
                % Manual marking on bandpass filtered data
                [~, bp_ts, z, drng] = PRetriveTs( app, cur_chnl, cidx, ev_loc);
                ts_ind = drng >= ev_loc( 1) & drng <= ev_loc( 2);
                [nb_cycles, avg_freq] = GetNbcycles( app, bp_ts( ts_ind), srate);
                peakz = max( z( ts_ind)); 
                bpfreq = app.data.bpfilt.param{ cidx}.bpfreq;
                
            elseif cidx == 0 && ~isempty( ev_loc)
                % Manual marking on raw data
                avg_freq = 0;
                nb_cycles = 0;                
                peakz = 0;
                bpfreq = [0, app.data.srate/2];

            else
                app.uidata.cur_event = [];
                Redraw( app);
                
                PUpdateClustInfo( app, 'Manual event was not set.', app.uidata.msg_fc);
                return;
            end
            
            % Current event data structure
            app.uidata.cur_event = [];
            app.uidata.cur_event.label = cur_chnl;
            app.uidata.cur_event.Location = ev_loc;
            app.uidata.cur_event.info.Location = ev_loc;
            app.uidata.cur_event.info.AvgFreq = avg_freq;
            app.uidata.cur_event.info.PeakZScore = peakz; 
            app.uidata.cur_event.info.NoCycles = nb_cycles;
            app.uidata.cur_event.info.BPFreq = bpfreq;
            
            % Adjust manual event
            txt = 'raw manual events';
            if app.ManualEventAutoAdjCheckBox.Value == 1 && cidx > 0                
                if app.uidata.debug
                    fprintf( 'Manual event location before adjust: [%d %d]\n',...
                        ev_loc( 1),...
                        ev_loc( 2));
                end
                
                [~, bp_ts, z, data_range] = PRetriveTs( app, cur_chnl, cidx, ev_loc);
                loc_ind = data_range >= ev_loc( 1) & data_range <= ev_loc( 2);
                orig_sloc = find( loc_ind, 1, 'first');
                orig_eloc = find( loc_ind, 1, 'last');
             
                onset_thresh = app.ThreshOnsetEditField.Value;
                if any( z( loc_ind) >= onset_thresh)                    
                    N = round( app.data.srate * app.uidata.manual_adj_win);
                    
                    new_eloc = ev_loc;
                    if z( orig_sloc) >= onset_thresh
                        new_eloc(1) = max( ev_loc(1) - N, data_range(1));
                    end

                    if z( orig_eloc) >= onset_thresh
                        new_eloc(2) = min( ev_loc(2) + N, data_range(end));
                    end
                    
                    xloc = data_range >= new_eloc( 1) & data_range <= new_eloc( 2);
                    new_sloc = find( xloc, 1, 'first');
                    [sloc, eloc] = FindOnset( app, z( xloc) >= onset_thresh);
                    adj_loc = [];
                    for k = 1 : length( sloc)
                        if RangeOverlap( app, [sloc(k), eloc(k)] + new_sloc - 1, [orig_sloc, orig_eloc])
                            adj_loc = cat( 1, adj_loc, [sloc(k), eloc(k)]);                            
                        end
                    end
                                          
                    % Merege events if the distance and minimal z meets the criteria
                    if strcmpi( app.uidata.autoev_automerge, 'yes') && size( adj_loc, 1) > 1
                        merge_durthresh = app.uidata.autoev_automerge_durthresh;
                        merge_zthresh = app.uidata.autoev_automerge_zthresh;                        
                        merge_evs = transpose( adj_loc);
                        merge_evs = merge_evs(:);
                        tmp_N = size( adj_loc, 1);
                        loc2rm = [];
                        %loc2rm = nan( 2*(tmp_N - 1), 1);
                        %cnt = 0;
                        for k = 2 : tmp_N
                            dur = (adj_loc( k, 1) - adj_loc( k-1, 2)) / srate;
                            val_ok = all( z( new_sloc + adj_loc( k-1, 2) - 1 : new_sloc + adj_loc( k, 1) - 1) >= merge_zthresh);
                            if dur < merge_durthresh && val_ok
                                loc2rm = [loc2rm; (k-1)*2; (k-1)*2 + 1];
                                %cnt = cnt + 1;
                                %loc2rm( 2*cnt - 1 : 2*cnt) =  [(k-1)*2; (k-1)*2 + 1];
                            end
                        end
                        
                        %loc2rm( 2*cnt+1 : end) = [];
                        
                        if ~isempty( loc2rm)
                            merge_evs( loc2rm) = [];
                            sz = [2, length( merge_evs) / 2];
                            adj_loc = transpose( reshape( merge_evs, sz));
                        end
                    end
                    
                    % All clusters that survive onset threshold
                    app.uidata.cur_event = [];
                    app.uidata.cur_event.label = cur_chnl;
                    txt = 'adjusted manual events';
                    tmp_evinfo = [];
                    for k = 1 : size( adj_loc, 1)
                        ts_ind = new_sloc + adj_loc( k, 1) - 1 : new_sloc + adj_loc( k, 2) - 1;
                        [nb_cycles, avg_freq] = GetNbcycles( app, bp_ts( ts_ind), srate);
                        
                        tmp_evinfo.Location( k, :) = new_sloc + adj_loc( k, :) + data_range(1) - 1;
                        tmp_evinfo.AvgFreq( k, 1) = avg_freq;
                        tmp_evinfo.PeakZScore( k, 1) = max( z(ts_ind));
                        tmp_evinfo.NoCycles( k, 1) = nb_cycles;
                        tmp_evinfo.BPFreq( k, :) = app.data.bpfilt.param{ cidx}.bpfreq;
                    end
                    
                    app.uidata.cur_event.Location = tmp_evinfo.Location;
                    app.uidata.cur_event.info = tmp_evinfo;
                end                
               
            end % auto adjustment of the event
              
            
            Redraw( app);            

            [~, dur, peakz, nb_cycle] = EventInfo( app, app.uidata.cur_event, srate);
            tmp_txt = EventInfoStr( app, dur, peakz, nb_cycle);      
            
            if length( peakz) == 1
                txt = strrep( txt, 'events', 'event');
            end
                        
            txt = [num2str( length( peakz)), txt, '. ', tmp_txt];               
            PUpdateClustInfo( app, txt, app.uidata.msg_fc);            
        end % PSetManualEventRange
        
        
        % Test if (x, y) is within a rectangle defined by xrange and yrange
        function s = PPointInRange( ~, x, xrange, y, yrange)
            % xrange, [lo, hi]
            % yrange, [lo, hi]
            s = x >= xrange(1) && ...
                x <= xrange(2) && ...
                y > yrange(1) && ...
                y < yrange(2);
        end
        
        
        % Main event detection function
        function [evinfo, cursor_zval, msg, result] = ...
                PDetectEvent( app, src, cur_chnl, cursor_loc, filt_cell_idx, to_detect, default_search)
            % src, 'time' | 'freq'
            % cur_chanl, channel label
            % cursor_loc, cursor location, scalar if src is time and [time, freq] if src is freq
            % filt_cell_idx, cell index in bpfilt.mat
            % to_detect, 'yes' | 'no', detect event or just get z score at cursor
            % default_search, true | false, use small search window when this is called within window button press
            %
            % evinfo is a data structure contains the following fields:
            %       evinfo.Location, N x 2 location, [lo, hi].
            %       evinfo.NoCycles, N x 1 vector number of cycles
            %       evinfo.AvgFreq, N x 1 vector, average frequency.
            %       evinfo.PeakZScore, N x 1 vector, maximal z score.
            %       evinfo.BPFreq, N x 2 bandpass frequency, [lo, hi]
            % 
            % msg, message.
            %
            % cursor_zval, z score at cursor location
            %
            % result, misc. results for spectrogram detection
                 
            
            if app.uidata.debug
                fprintf( 'PDetectEvent called.\n');
            end
            
            if app.uidata.debug
                tic;
            end
            
            evinfo = [];
            cursor_zval = [];
            msg = '';
            result = [];
            
            if ~ismember( src, {'freq', 'time'})
                return;
            end            
           
            % channel label and cursor location must be set 
            if isempty( cur_chnl) || isempty( cursor_loc)
                msg = 'No channel was set for event detection';
                if app.uidata.debug
                    fprintf( [msg, '.\n']);
                end
                
                return;
            end
            
            if ~default_search
                % Detection with keypress
                search_len = app.uidata.autoev_search_len;
                % clust_dist_thresh =  app.uidata.autoev_distance_thresh;
                
            else
                % Detection with button press
                search_len = app.uidata.default_autoev_search_len; % 1 s
                % clust_dist_thresh = app.uidata.default_autoev_distance_thresh; % 0.5s
            end
           
            % Cursor location disabled
            clust_dist_thresh = search_len;            
            
            if strcmpi( src, 'time')
                % event detection using bandpass filtered data
                
                if filt_cell_idx < 1 % main window raw time series
                    msg = 'No bandpass filtered data specified for event detection';
                    
                    if app.uidata.debug
                        fprintf( [msg, '.\n']);
                    end
                    
                    return;
                end                
                
                if app.uidata.debug                    
                    msg = ['Event detection. Window: ', app.data.bpfilt.param{ filt_cell_idx}.Name,...
                        '. Channel: ', cur_chnl,...
                        '. Index: ', num2str( filt_cell_idx)];
                    
                    fprintf( [msg, '.\n']);
                end
                                
                if ~app.uidata.debug
                    [~, bp_ts, z, data_range] = PRetriveTs( app, cur_chnl, filt_cell_idx, cursor_loc);
                else
                    [raw_ts, bp_ts, z, data_range] = PRetriveTs( app, cur_chnl, filt_cell_idx, cursor_loc);
                end               
                
                % z score at cursor
                win_cursor_loc = cursor_loc - data_range(1) + 1;                
                if win_cursor_loc < 1 || win_cursor_loc > length( z)
                    return;
                end
                
                cursor_zval = z( win_cursor_loc);

                % Just return the z score
                if ~strcmpi( to_detect, 'yes')
                    return;
                end
                
                len = diff( data_range( [1, end]));
                if len < 1
                    msg = 'Time window too short for event detection';
                    if app.uidata.debug
                        fprintf( ['\n', msg, '.\n\n']);
                    end
                    return;
                end
                
                % bandpass filter frequency
                bpfreq = app.data.bpfilt.param{ filt_cell_idx}.bpfreq;

                % Limit the search to curren time window
                N = round( app.data.srate * search_len / 2); 
                sloc = max( [1, win_cursor_loc - N]);
                eloc = min( [sloc + 2*N, length( data_range)]);

                sample_loc = sloc : eloc;
                tmp_z = z( sample_loc);
                tmp_filt_ts = bp_ts( sample_loc);
                % cursor location in search window
                tmp_cursor_loc = cursor_loc - data_range( sloc) + 1;

                evinfo = [];
                ev_cnt = 0;
                % location in current detection window (not the plotting window)
                dr = [];                
                onset_thresh = app.ThreshOnsetEditField.Value;
                peak_thresh = app.ThreshInclusionEditField.Value;
                nbc_thresh = app.ThreshNumberCyclesEditField.Value;
                cur_dis_limit = app.data.srate * clust_dist_thresh;
                if any( tmp_z >= onset_thresh)
                    
                    [onsets, offsets] = FindOnset( app, tmp_z >= onset_thresh);

                    for clust_idx = 1 : length( onsets)
                        c = [onsets( clust_idx), offsets( clust_idx)];

                        % location in entire time series
                        c_in_raw = c + sloc + data_range( 1) - 2;

                        % if exist in results already
                        [~, ev_type] = GetEventType2Disp( app);
                        is_exist = PIsEventExist( app, c_in_raw, cur_chnl, ev_type);
                        
                        if is_exist
                            % event already exists
                            continue;
                        end                        
                        
                        % number of cycles in filtered data
                        tmp_ind = c(1) : c(2);
                        v = tmp_filt_ts( tmp_ind);
                        [nb_cycle, avg_freq] = GetNbcycles( app, v, app.data.srate);
                        
                        % cursor within the cluster
                        is_within_clust = prod( tmp_cursor_loc - c) < 0;
                        % cursor within a distance of the cluster
                        is_within_range = min(abs( c - tmp_cursor_loc)) <= cur_dis_limit;
                        cur_dist_ok = is_within_clust || is_within_range;
                        
                        nb_cycle_ok = nb_cycle >= nbc_thresh;
                        
                        % maximal z value meet the criteris
                        peak_zscore = max( tmp_z( tmp_ind));
                        peak_ok = peak_zscore >= peak_thresh;
                        
                        if peak_ok && nb_cycle_ok && cur_dist_ok
                            ev_cnt = ev_cnt + 1;
                            dr( ev_cnt, :) = c; %#ok<AGROW> 
                            evinfo.Location( ev_cnt, :) = c_in_raw;
                            evinfo.NoCycles( ev_cnt, 1) = nb_cycle;
                            evinfo.PeakZScore( ev_cnt, 1) = peak_zscore;
                            evinfo.AvgFreq( ev_cnt, 1) = avg_freq;
                            evinfo.BPFreq( ev_cnt, :) = bpfreq;
                        end
                    end
                end
               
                % Merge two adjacent events if
                % 1) all z values between two events are greater than merge_zthresh and
                % 2) the distance between them is less than merge_durthresh
                if strcmpi( app.uidata.autoev_automerge, 'yes') && ev_cnt > 1
                    merge_durthresh = app.uidata.autoev_automerge_durthresh;
                    merge_zthresh = app.uidata.autoev_automerge_zthresh;
                
                    merge_evs = transpose( evinfo.Location);
                    merge_evs = merge_evs(:);
                    loc2rm = [];
                    %loc2rm = nan( 2*( ev_cnt - 1), 1);
                    %tmp_cnt = 0;
                    for k = 2 : ev_cnt
                        dur = (dr( k, 1) - dr( k-1, 2)) / app.data.srate;
                        val_ok = all( tmp_z( dr( k-1, 2) : dr( k, 1)) > merge_zthresh);
                        if dur < merge_durthresh && val_ok
                            loc2rm = [loc2rm; (k-1)*2; (k-1)*2 + 1];
                            %tmp_cnt = tmp_cnt + 1;
                            %loc2rm( 2*tmp_cnt - 1 : 2*tmp_cnt) = [2*(k-1); 2*(k-1) + 1];
                        end
                    end
                    
                    %loc2rm( 2*tmp_cnt + 1 : end) = [];
                    
                    if ~isempty( loc2rm)
                        merge_evs( loc2rm) = [];
                        sz = [2, length( merge_evs) / 2];
                        merge_evs = transpose( reshape( merge_evs, sz));
                        
                        [~, chnl_loc] = ismember( cur_chnl, app.data.bpfilt.labels{ filt_cell_idx});
                        evinfo = [];
                        N = size( merge_evs, 1);
                        ev_cycle = zeros( N, 1);
                        avg_freq = zeros( N, 1);
                        peakz = zeros( N, 1);
                        for ev_idx = 1 : N
                            tmp_ind = merge_evs( ev_idx, 1) : merge_evs( ev_idx, 2);
                            v = app.data.bpfilt.mat{ filt_cell_idx}( chnl_loc, tmp_ind);
                            [ev_cycle( ev_idx), avg_freq( ev_idx)] = GetNbcycles( app, v, app.data.srate);
                            tmp_ind = data_range >= tmp_ind(1) & data_range <= tmp_ind( end);
                            peakz( ev_idx) = max( z( tmp_ind));
                        end
                        
                        evinfo.Location = merge_evs;
                        evinfo.NoCycles = ev_cycle;
                        evinfo.AvgFreq = avg_freq;
                        evinfo.PeakZScore = peakz;
                        evinfo.BPFreq = repmat( bpfreq, [N, 1]);
                    end
                end % merge events
                

                if isempty( evinfo)
                    msg = ['No event detected for ', cur_chnl];
                else
                    msg = ['Event detecteded for ', cur_chnl];
                end
                
                
                if app.uidata.debug 
                    % plot detection                    
                    fig_h = findobj( 'Name', 'HFOApp_EventDetect');
                    
                    if isempty( fig_h)
                        figure('Name', 'HFOApp_EventDetect');
                    else
                        clf( fig_h);
                    end
                    
                    subplot( 211);
                    plot( gca, data_range, raw_ts, 'color', 0.5*ones( 1, 3));
                    hold on;
                    plot( gca, data_range, bp_ts, 'color', [0.6, 0.4, 0.9]);
                    amp = abs( hilbert( bp_ts));
                    plot( gca, data_range, amp, app.uidata.msg_fc);
                    xlim = get( gca, 'ylim');
                    plot( cursor_loc * [1, 1], xlim, 'r');
                    title( 'Raw and filtered time series');
                    
                    if ~isempty( evinfo)
                        hold on;
                        for k = 1 : size( evinfo.Location, 1)
                            loc = evinfo.Location( k, 1) : evinfo.Location( k, 2);
                            tmp_loc = dr( k, 1) : dr( k, 2);
                            plot( loc, tmp_filt_ts( tmp_loc), 'r', 'linewidth', 1);
                        end
                    end
                    
                    set( gca, 'xlim', data_range( [1, end]));
                    
                    subplot( 212);
                    hold on;
                    plot( gca, data_range, z, 'k');
                    xlim = get( gca, 'ylim');
                    plot( cursor_loc * [1, 1], xlim, 'r');
                    refline( 0, app.ThreshOnsetEditField.Value);
                    title( 'z score');
                    
                    if ~isempty( evinfo)
                        hold on;
                        for k = 1 : size( evinfo.Location, 1)
                            loc = evinfo.Location( k, 1) : evinfo.Location( k, 2);
                            tmp_loc = dr( k, 1) : dr( k, 2);
                            plot( loc, tmp_z( tmp_loc), 'r', 'linewidth', 1);
                        end
                    end
                    set( gca, 'xlim', data_range( [1, end]));
                end % debug plot                
                
            else
                % spectrogram guided event detection
                
                % cursor_loc has to be [time, freq]
                if length( cursor_loc) ~= 2
                    msg = 'Incorrect spectrogram event detection parameters';
                    return;
                end
                
                % location of the current channel in the spectrogram cell array
                [is_exist, fw_loc] = ismember( cur_chnl, app.data.tf.labels);
                if ~is_exist
                    msg = ['Spectrogram not found for ', cur_chnl];
                    return;
                end                
                
                % frequency must be within range
                f = app.data.tf.param{ fw_loc}.freq;
                if cursor_loc(2) < f(1) || cursor_loc(2) > f(end)
                    msg = 'Ivalid frequency';
                    return;
                end
                
                tmp = app.uidata.data_range( [1, end]);
                if cursor_loc(1) < tmp(1) || cursor_loc(1) > tmp(2)
                    msg = 'Invalid cursor location';
                    return;
                end

                % get auto detection param from the spectrogram window
                freq_win_names = cellfun( @(x) x.UIFigure.Name, app.FreqWin, 'UniformOutput', false);
                [~, freq_win_ind] = ismember( cur_chnl, freq_win_names);
                
                if fw_loc == 0 || freq_win_ind == 0
                    msg = 'Invalid spectrogram window settings';
                    return;
                end
                                
                time_win = app.FreqWin{ freq_win_ind}.TFAutoTimeLenEditField.Value;
                bandwidth = app.FreqWin{ freq_win_ind}.TFAutoBandwidthEditField.Value;
                srate = app.data.srate;

                % Average the spectrogram over a small time window
                len = round( app.data.srate * time_win);
                max_marg = min( abs( app.uidata.data_range - cursor_loc(1)));
                len = min( [len, max_marg]);
                len = max( [0, len]);
                tmp_xrng = cursor_loc( 1) + [-1, 1] * len;
                % spectrogram not calcualted for entire time series
                freq_xrange = app.data.tf.param{ fw_loc}.xrange;
                [~, ~, overlap_rng] = RangeOverlap( app, tmp_xrng, freq_xrange);
                
                freq_ind = freq_xrange( 1) : freq_xrange( 2);
                tmp_ind = freq_ind >= overlap_rng(1) & freq_ind <= overlap_rng(2);
                
                if ~any( tmp_ind)
                    msg = 'No spectrogram data avaiable';
                    return;
                end
                                
                avg_s = mean( app.data.tf.mat{ fw_loc}( :, tmp_ind), 2);
                
                % maximal frequency index
                [~, pf_loc] = min( abs( cursor_loc(2) - f));
                
                % local peak frequency
                if app.FreqWin{ freq_win_ind}.TFAutoUseAsPercentCheckBox.Value == 1
                    [pk_loc, pk_range] = FindPeakRange( app, avg_s, pf_loc, bandwidth/100, 0);
                    if length( unique( pk_range)) == 1
                        % at least three frequency points
                        lo = max( [1, pk_loc - 1]);
                        hi = min( [lo+3, length( f)]);
                        pk_range = [lo, hi];
                    end                    
                    bpfreq = f( pk_range);
                    
                else
                    pk_loc = FindPeakRange( app, avg_s, pf_loc, 0.5, 0);                                    
                    bpfreq = f( pk_loc) + 0.5 * [-1, 1] * bandwidth;
                    bpfreq = sort( bpfreq);
                    
                    % make sure bandpass frequency is valid
                    bpfreq( 1) = max( [1, bpfreq(1)]);
                    bpfreq( 2) = min( [bpfreq(1) + bandwidth, app.data.srate/2 - 1]);
                end
                
                %% Set breakpoint here to make example plots
                
                % Peak frequency
                pk_f = f( pk_loc);
               
                % Print detected peak frequency info 
                if app.uidata.debug
                    fprintf( '\nSpectro event detection: Peak frequency: %.1f Hz, %.2f - %.2f Hz.\n',...
                        pk_f, bpfreq(1), bpfreq(2));
                end                
                
                % Aerage over frequency, then find the peak location in time                
                pk_range = f >= bpfreq(1) & f <= bpfreq(2);                
                [~, ~, xrng] = RangeOverlap( app, app.uidata.data_range, freq_xrange);
                tmp_ind = freq_ind >= xrng(1) & freq_ind <= xrng(2);
                
                if ~any( tmp_ind)
                    msg = 'No spectrogram data avaiable';
                    return;
                end
                
                v = mean( app.data.tf.mat{ fw_loc}( pk_range, tmp_ind), 1);
                xrng_cursor_loc = cursor_loc(1) - xrng(1) + 1;
                
                % peak location in time, all entire time seris
                t_pk_loc = FindPeakRange( app, v, xrng_cursor_loc, 0.5, 0);
                t_pk_loc = t_pk_loc + xrng(1) - 1;                
                
                % pad current time window before filtering
                % cursolor location +/- pad size
                % pad_size = round( app.data.srate * app.uidata.tf_padsize);
                pad_size = round( app.data.srate * 6);
                
                % location in entire time series
                lo = max( [1, t_pk_loc - pad_size]);
                hi = min( [app.data.nbsamples, t_pk_loc + pad_size]);
                pad_xrng = lo : hi;
                
                param = [];
                param.bpfreq = bpfreq;
                if ~isempty( app.data.bpfilt.mat)
                    param.bptype = app.data.bpfilt.param{1}.bptype;
                else
                    param.bptype = 'fir';
                end                
                param.notch = 'no';
                [~, chnl_loc] = ismember( cur_chnl, app.data.labels);
                v = app.data.mat( chnl_loc, pad_xrng);
                [filt_v, amp] = HFOFiltData( v, srate, param);
                z = ( amp - mean( amp)) / std( amp);
                
                % Cluster which contains the time peak that survive the onset threshold
                onset_thresh = app.ThreshOnsetEditField.Value;
                pad_cursor_loc = find( pad_xrng == t_pk_loc);
                [tmp_pk_loc, pk_rng] = FindPeakRange( app, z, pad_cursor_loc, onset_thresh, 1);
                
                if isempty( pk_rng)
                    % nothing survive onset_thresh
                    max_z = z( tmp_pk_loc);
                    nb_cycle = 0;
                    loc = [];
                    dur = 0;
                    
                else
                    loc = pk_rng  + pad_xrng( 1) - 1;
                    dur = round( 100000 * diff( pk_rng, [], 2) / srate) / 100;
                    pk_rng = pk_rng(1) : pk_rng(end);                    
                    max_z = max( z( pk_rng));
                    [nb_cycle, avg_freq] = GetNbcycles( app, filt_v( pk_rng), srate);
                end
               
                peakz_ok = max_z >= app.ThreshInclusionEditField.Value - eps;
                nc_ok = nb_cycle >= app.ThreshNumberCyclesEditField.Value - eps; 
                if peakz_ok && nc_ok 
                    evinfo.Location = loc;
                    evinfo.NoCycles = nb_cycle;
                    evinfo.AvgFreq = avg_freq;
                    evinfo.PeakZScore = max_z;
                    evinfo.BPFreq = bpfreq;
                end
                
                result = [];
                result.peak_freq = pk_f;
                result.nb_cycle = nb_cycle;
                result.avg_s = avg_s;
                result.freq = app.data.tf.param{ fw_loc}.freq;
                result.cell_ind = fw_loc;
                result.win_ind = freq_win_ind;
                result.dur = dur;
                result.NoCycles = nb_cycle;
                result.PeakZScore = max_z;
                result.BPFreq = bpfreq;
            end % 'time' | 'freq' detection
            
            if app.uidata.debug
                t = toc;
                fprintf( 'Event detection took %.5f s\n', t);
            end
        end % PDetectEvent
        
        
        %% Manage bandpass filter windows
        function POrganizeWindows( app)
            waitfor( ConfigFiltWindows( app));
        end
        
        
        %% Outline detected events and show cursor location
        function PUpdateClustBox( app, mtrack_info)
            if app.uidata.debug
                fprintf( 'PUpdateClustBox called.\n');
            end
            
            %% reactange plot specifying current identified events
            %% line style and line width of the rectangle
            box_linecolor = app.uidata.box_linecolor;
            box_linestyle = app.uidata.box_linestyle;
            box_linewidth = app.uidata.box_linewidth;
            
            % line color of the cursor
            c_linecolor = app.uidata.cursor_linecolor;
            % cursor color on spectrogram plot
            c_linecolor_freq = app.uidata.cursor_linecolor_freq;
            c_linewidth = app.uidata.cursor_linewidth;
            
            % y point of the cursor, 1 x 2
            cursor_ypnt = app.uidata.cursor_ypnt;
            if ~isempty( app.uidata.current_channel)
                cur_chnl_loc = ismember( mtrack_info.real_label, app.uidata.current_channel{1});
                cursor_ypnt = mtrack_info.marg_range( cur_chnl_loc, :);
                cursor_ypnt= [min( cursor_ypnt, [], 'all'), max( cursor_ypnt, [], 'all')];
            end
            
            %% vertical line to indicate the cursor location
            if ~isempty( app.uidata.cursor_loc)
                if strcmpi( app.uidata.cursor_full_range, 'yes')
                    % show cursor position on all channels
                    tmp_cursor_ypnt = [0, 1];
                else
                    % show cursor postion on current channel
                    tmp_cursor_ypnt = cursor_ypnt;
                end
                
                xpnt = app.uidata.cursor_loc * [1, 1];
                if isempty( app.uidata.cursor_h) || ~isvalid( app.uidata.cursor_h)                    
                    hold( app.UIAxes, 'on');
                    app.uidata.cursor_h = line( app.UIAxes, xpnt, tmp_cursor_ypnt);                    
                    set( app.uidata.cursor_h,...
                        'LineWidth', c_linewidth,...
                        'Color', c_linecolor);
                    
                else
                    set( app.uidata.cursor_h,...
                        'XData', xpnt,...
                        'YData', tmp_cursor_ypnt);
                end
                
                % time series windows
                for win_idx = 1 : length( app.TimeWin)
                    h = app.TimeWin{win_idx}.uidata.cursor_h;
                    if isempty( h) || ~isvalid( h)
                        hold( app.TimeWin{win_idx}.UIAxes, 'on');
                        
                        h = line( app.TimeWin{win_idx}.UIAxes, xpnt, tmp_cursor_ypnt);                        
                        set( h, 'LineWidth', c_linewidth,...
                            'Color', c_linecolor);
                        
                        app.TimeWin{win_idx}.uidata.cursor_h = h;
                        
                    else
                        set( h, 'XData', xpnt,...
                            'YData', tmp_cursor_ypnt);
                    end
                end
                
                for win_idx = 1 : length( app.FreqWin)
                    [~, chnl_loc] = ismember( app.FreqWin{ win_idx}.UIFigure.Name, app.data.tf.labels);                    
                    f = app.data.tf.param{ chnl_loc}.freq( [1, end]);                    
                    h = app.FreqWin{ win_idx}.uidata.cursor_h;
                    if isempty( h) || ~isvalid( h)
                        hold( app.FreqWin{ win_idx}.UIAxes, 'on');
                        h = line( app.FreqWin{win_idx}.UIAxes, xpnt, f( [1 2]));                        
                        set( h, 'LineWidth', c_linewidth, 'Color', 'k');                        
                        app.FreqWin{ win_idx}.uidata.cursor_h = h;
                        
                    else
                        set( h, 'XData', xpnt,...
                            'YData', f( [1 2]),...
                            'Color', c_linecolor_freq);
                    end
                end                
            end % cursor location
            
            
            %% Rectangle box to indicate current event    
            clust_ypnt = cursor_ypnt( [1, 1, 2, 2, 1]);
            
            cur_event = app.uidata.cur_event;
            xpnt_ind = [1, 2, 2, 1, 1];
            if ~isempty( cur_event) && ~isstruct( cur_event)
                %% To remove. app.uidata. cur_event will be a data structure
                
                nb_evs = size( cur_event, 1);
                boxh = app.uidata.boxh;
                for h_idx = 1 : length( boxh)
                    if ~isempty( boxh( h_idx)) && isvalid( boxh( h_idx))
                        delete( boxh( h_idx));
                    end
                end
                
                app.uidata.boxh = gobjects( nb_evs, 1);
                for ev_idx = 1 : nb_evs
                    xpnt = cur_event( ev_idx, xpnt_ind);
                    app.uidata.boxh( ev_idx) = line( app.UIAxes, xpnt, clust_ypnt);
                    set( app.uidata.boxh( ev_idx),...
                        'LineStyle', box_linestyle,...
                        'Color', box_linecolor,...
                        'LineWidth', box_linewidth);
                end
                
                % Time series windows
                for win_idx = 1 : length( app.TimeWin)
                    h = app.TimeWin{win_idx}.uidata.boxh;
                    for h_idx = 1 : length( h)
                        if ~isempty( h( h_idx)) && isvalid( h( h_idx))
                            delete( h( h_idx));
                        end
                    end
                    
                    app.TimeWin{win_idx}.uidata.boxh = gobjects( nb_evs, 1);
                    for ev_idx = 1 : nb_evs
                        xpnt = cur_event( ev_idx, xpnt_ind);
                        h = line( app.TimeWin{win_idx}.UIAxes, xpnt, clust_ypnt);                        
                        set( h, 'LineStyle', box_linestyle,...
                            'Color', box_linecolor,...
                            'LineWidth', box_linewidth);
                        
                        app.TimeWin{win_idx}.uidata.boxh( ev_idx) = h;
                    end
                end   % time series window
                
                % Spectrogram windows
                for win_idx = 1 : length( app.FreqWin)
                    h = app.FreqWin{win_idx}.uidata.boxh;
                    for h_idx = 1 : length( h)
                        if ~isempty( h( h_idx)) && isvalid( h( h_idx))
                            delete( h( h_idx));
                        end
                    end
                    
                    [~, chnl_loc] = ismember( app.FreqWin{ win_idx}.UIFigure.Name, app.data.tf.labels);
                    f = app.data.tf.param{ chnl_loc}.freq( [1, end]);
                    f = f( [1, 1, 2, 2, 1]);
                    
                    app.FreqWin{win_idx}.uidata.boxh = gobjects( nb_evs, 1);
                    hold( app.FreqWin{win_idx}.UIAxes, 'on');
                    for ev_idx = 1 : nb_evs
                        xpnt = cur_event( ev_idx, xpnt_ind);
                        h = line( app.FreqWin{win_idx}.UIAxes, xpnt, f);
                        set( h, 'LineStyle', box_linestyle,...
                            'Color', box_linecolor,...
                            'LineWidth', box_linewidth);  
                        
                        app.FreqWin{ win_idx}.uidata.boxh( ev_idx) = h;
                    end
                end % spectrogram window
                
                
            elseif ~isempty( cur_event) && isstruct( cur_event)
                % multiple events cur_event is a data structure
                
                boxh = app.uidata.boxh;
                for h_idx = 1 : length( boxh)
                    if ~isempty( boxh( h_idx)) && isvalid( boxh( h_idx))
                        delete( boxh( h_idx));
                    end
                end
                
                nb_labels = length( cur_event);                
                ind = 1 : nb_labels;
                nb_evs = arrayfun( @(x) size( cur_event( x).Location, 1), ind, 'uniformoutput', false);
                nb_evs = sum( cell2mat( nb_evs));                
                cur_ev_label = arrayfun( @(x) cur_event( x).label, ind, 'uniformoutput', false);
                
                app.uidata.boxh = gobjects( nb_evs, 1);
                cnt = 0;
                for chnl_idx = 1 : nb_labels
                    cur_label = cur_event( chnl_idx).label;
                    cur_chnl_loc = ismember( mtrack_info.real_label, cur_label);
                    
                    % y point of the clusters
                    cursor_ypnt = mtrack_info.marg_range( cur_chnl_loc, :);
                    cursor_ypnt= [min( cursor_ypnt(:)), max( (cursor_ypnt(:)))];
                    clust_ypnt = cursor_ypnt( [1, 1, 2, 2, 1]);
                    
                    ev_loc = cur_event( chnl_idx).Location;
                    for ev_idx = 1 : size( ev_loc, 1)
                        xpnt = ev_loc( ev_idx, xpnt_ind);
                        cnt = cnt + 1;
                        app.uidata.boxh( cnt) = line( app.UIAxes, xpnt, clust_ypnt);
                        set( app.uidata.boxh( cnt),...
                            'LineStyle', box_linestyle,...
                            'Color', box_linecolor,...
                            'LineWidth', box_linewidth);
                    end
                end
                
                
                % Time series windows
                for win_idx = 1 : length( app.TimeWin)
                    h = app.TimeWin{win_idx}.uidata.boxh;
                    for h_idx = 1 : length( h)
                        if ~isempty( h( h_idx)) && isvalid( h( h_idx))
                            delete( h( h_idx));
                        end
                    end
                    
                    app.TimeWin{win_idx}.uidata.boxh = gobjects( nb_evs, 1);
                    
                    cnt = 0;
                    for chnl_idx = 1 : nb_labels
                        cur_label = cur_event( chnl_idx).label;
                        cur_chnl_loc = ismember( mtrack_info.real_label, cur_label);
                        
                        cursor_ypnt = mtrack_info.marg_range( cur_chnl_loc, :);
                        cursor_ypnt= [min( cursor_ypnt(:)), max( (cursor_ypnt(:)))];
                        clust_ypnt = cursor_ypnt( [1, 1, 2, 2, 1]);
                        
                        ev_loc = cur_event( chnl_idx).Location;
                        for ev_idx = 1 : size( ev_loc, 1)
                            xpnt = ev_loc( ev_idx, xpnt_ind);                            
                            h = line( app.TimeWin{win_idx}.UIAxes, xpnt, clust_ypnt);
                            set( h, 'LineStyle', box_linestyle,...
                                'Color', box_linecolor,...
                                'LineWidth', box_linewidth);
                            
                            cnt = cnt + 1;
                            app.TimeWin{win_idx}.uidata.boxh( cnt) = h;
                        end
                    end                    
                end % time series window
                
                
                % Spectrogram windows
                for win_idx = 1 : length( app.FreqWin)
                    h = app.FreqWin{win_idx}.uidata.boxh;
                    for h_idx = 1 : length( h)
                        if ~isempty( h( h_idx)) && isvalid( h( h_idx))
                            delete( h( h_idx));
                        end
                    end
                    
                    freq_wname = app.FreqWin{ win_idx}.UIFigure.Name;
                    [is_exist, loc] = ismember( freq_wname, cur_ev_label);
                    if ~is_exist
                        continue;
                    end
                    
                    [~, chnl_loc] = ismember( freq_wname, app.data.tf.labels);
                    f = app.data.tf.param{ chnl_loc}.freq( [1, end]);
                    f = f( [1, 1, 2, 2, 1]);
                    
                    tmp_evs = cur_event( loc).Location;
                    nb_evs= size( tmp_evs, 1);
                    app.FreqWin{win_idx}.uidata.boxh = gobjects( nb_evs, 1);
                    hold( app.FreqWin{win_idx}.UIAxes, 'on');
                    for ev_idx = 1 : nb_evs
                        xpnt = tmp_evs( ev_idx, xpnt_ind);
                        h = line( app.FreqWin{win_idx}.UIAxes, xpnt, f);
                        set( h, 'LineStyle', box_linestyle,...
                            'Color', box_linecolor,...
                            'LineWidth', box_linewidth);
                        
                        app.FreqWin{ win_idx}.uidata.boxh( ev_idx) = h;
                    end
                    
                end % spectrogram window
                                
            else
                % do nothing                
            end % automaticaly identified clusters
            
        end % function PUpdateClustBox
        

        %% Turn on/off manual marking
        function PManualEventButtonValueChanged(app, value)
            app.ManualModeButton.Value = value;
            ManualModeButtonValueChanged( app);
        end
        
        
        % Update event list box
        function PUpdateEventList( app)
            if app.uidata.debug
                fprintf( 'PUpdateEventList called.\n');
            end
            
            % A list of events for plotted channels
            app.data.disp_events = [];
            if ~isempty( app.data.events) && ~isempty( app.ChannelListBox.Items)
                
                % events for all channels in Display                
                if strcmpi( app.uidata.clock_type, 'clock')
                    start_time = app.data.start_clock;
                else
                    start_time = app.data.time(1);
                end
                
                evs = PFormatEvents( app, app.data.events, start_time, app.data.srate);
                chnl_loc = ismember( evs( :, 2), app.ChannelListBox.Items);
                evs = evs( chnl_loc, :);
                
                % Sort events, this affects how it looks in the event listbox
                % 'location' | 'channel'
                sort_meth = app.uidata.event_sort_method;
                if strcmpi( sort_meth, 'location')
                    % Sort events by starting location
                    ev_locs = cell2mat( evs( :, 3));
                    [~, idx] = sort( ev_locs( :, 1));
                    
                elseif strcmpi( sort_meth, 'channel')
                    % Sort events by channel label
                    [~, label_loc] = ismember( evs( :, 2), app.AllChannelListBox.Items);
                    [~, idx] = sort( label_loc);
                    
                else
                    idx = 1 : cnt;
                end
                
                app.data.disp_events = evs( idx, :);
            end
            
            %% Eventlist box
            if ~isempty( app.data.disp_events)
                [~, loc] = ismember( app.EventListBox.Value, app.EventListBox.Items);
                loc = max( [1, loc]);
                app.EventListBox.Items = app.data.disp_events( :, 1);
                loc = dsearchn( (1 : length( app.EventListBox.Items))', loc);
                app.EventListBox.Value = app.EventListBox.Items( loc);
                app.RemoveEventButton.Enable = 'on';
                
            else
                app.EventListBox.Items = {};
                app.EventListBox.Value = {};
                app.RemoveEventButton.Enable = 'off';
            end
            
            for k = 1 : length( app.TimeWin)
                app.TimeWin{ k}.EventListBox.Items = app.EventListBox.Items;
                app.TimeWin{ k}.EventListBox.Value = app.EventListBox.Value;
                app.TimeWin{ k}.RemoveEventButton.Enable = app.RemoveEventButton.Enable;
            end
            
            for k = 1 : length( app.FreqWin)
                app.FreqWin{ k}.EventListBox.Items = app.EventListBox.Items;
                app.FreqWin{ k}.EventListBox.Value = app.EventListBox.Value;
                app.FreqWin{ k}.RemoveEventButton.Enable = app.RemoveEventButton.Enable;
            end
            
            % trl data
            if ~isempty( app.uidata.trial_index)
                app.data.trlevents{ app.uidata.trial_index} = app.data.events;
            end
            
        end % function P Update EventList
        
        
        %% Retrive events in a formatted way
        function evs = PFormatEvents( ~, dat_evs, start_clock, srate, is_debug)
            % data_evs, app.data.events or subset of app.data.events
            % start_clock = app.data.start_clock;
            % srate = app.data.srate;
            %
            % Output 
            %   evs - organized event format
            %       { event_label, channel_name, [start, end], event_type, ...}
            %
            % event_label must be in the format of 
            % 'channel_label [index]: start_time - end_time', 
            % which is going to be used to retrieve channel and index by
            % other functions.
            
            evs = {};
            
            if isempty( dat_evs)
                return;
            end
            
            if nargin < 5
                is_debug = false;                
            end
            
            if is_debug
                tic;
            end
            
            nb_evchnls = length( dat_evs);
            N = (1 : nb_evchnls)';
            nb_evs = arrayfun( @(x) size( dat_evs(x).info, 1), N, 'UniformOutput', false);
            M = cell2mat( nb_evs);
            nb_evs = sum( M);
            
            ev_loc = arrayfun( @(x) cell2mat( dat_evs(x).info( :, 1) ), N, 'UniformOutput', false);
            ev_loc = cell2mat( ev_loc);
            
            ev_type = arrayfun( @(x) dat_evs(x).info( :, 2), N, 'UniformOutput', false);
            ev_type = cat( 1, ev_type{:});
            
            % Event index for each channel 
            label = cell( nb_evs, 1);
            ev_idx = zeros( nb_evs, 1);
            for k = 1 : nb_evchnls
                tmp_k = sum( M( 1:k-1)) + 1 : sum( M( 1: k));
                label( tmp_k) = repmat( {dat_evs(k).label}, [M(k), 1]);
                ev_idx( tmp_k) = 1 : M(k);
            end
           
            if isdatetime( start_clock)           
                start_clock.Format = 'HH:mm:ss.SSSS';
                tmp = seconds( ev_loc / srate);
                c_1 = string( start_clock + tmp( :, 1));
                c_2 = string( start_clock + tmp( :, 2));
                
            else % start_clock is in seconds
                ev_time = start_clock + ev_loc / srate;
                ev_time = round( 1000 * ev_time) / 1000;
                c_1 = compose( '%.3f', ev_time( :, 1));
                c_2 = compose( '%.3f', ev_time( :, 2));
            end
            
            arr_ind = (1 : nb_evs)';
            ev_label = arrayfun( @(x) char( strcat( label( x),...
                ' [', num2str( ev_idx( x)), ']:',...
                {' '}, c_1(x), {' - '}, c_2(x), '.') ),...
                arr_ind, 'UniformOutput', false);
            
            ev_loc = mat2cell( ev_loc, ones( nb_evs, 1), 2);
            
            % average frequeny
            evinfo = arrayfun( @(x) dat_evs(x).info( :, 3), N, 'UniformOutput', false);
            evinfo = cat( 1, evinfo{:});
            avg_freq = cellfun( @(x) x.AvgFreq, evinfo, 'UniformOutput', false);
            
            % peak z score
            peakz = cellfun( @(x) x.PeakZScore, evinfo, 'UniformOutput', false);
            
            % number of cycles
            nb_c = cellfun( @(x) x.NoCycles, evinfo, 'UniformOutput', false);
            
            % bp freq
            bpfreq = cellfun( @(x) x.BPFreq, evinfo, 'UniformOutput', false);
            
            evs = [ev_label, label, ev_loc, ev_type, avg_freq, peakz, nb_c, bpfreq];
            
            if is_debug
                t = toc;
                fprintf( 'Format events took %f s\n', t);
            end           
        end % function p format events
        
        
        %% Add events to result
        function [status, cnt] = PAddEvent( app, ev2add, evtypes)
            % 
            % ev2add is a structure array contains following fields:
            %       label
            %       location
            %       info, which is a data strucutre (PDetectEvent)
            %             Location, NoCycles, PeakZscore, AvgFreq, BPFreq
            % 
            % evtypes, event types
            %
            % status = 0, failed to add
            % status = 1, succeed to add     
            % cnt, number of events added
            
            if app.uidata.debug
                fprintf( 'PAddEvent called.\n');
            end
                        
            status = 0;
            
            % number of events added
            cnt = 0;
            
            if isempty( ev2add)
                PUpdateClustInfo( app, 'No event to add.', 'r');
                return;
            end
                        
            if isempty( evtypes)
                PUpdateClustInfo( app, 'Please set event type.', 'r');
                return;
            end
            
            evtypes = ReorderEventType( app, evtypes);

            for chnl_idx = 1 : length( ev2add)
                label = ev2add( chnl_idx).label;
                evs = ev2add( chnl_idx).Location;
                
                %% Add current events to app.data.events (a structural array)
                [evs, sort_ind] = sortrows( evs);
                
                % sort other fields
                ev2add_info = ev2add( chnl_idx).info;
                fnames = fieldnames( ev2add_info);
                for k = 1 : length( fnames)
                    ev2add_info.( fnames{ k}) = ev2add_info.( fnames{ k})( sort_ind, :);
                end
                
                tmp = struct();
                nb_evs = size( evs, 1);
                for ev_idx = 1 : nb_evs
                    for k = 1 : length( fnames)
                        tmp( ev_idx).( fnames{ k}) = ev2add_info.( fnames{ k})( ev_idx, :);
                    end
                end
                
                ev2add_info = tmp;
                
                ev_chnls = arrayfun( @(x) x.label, app.data.events, 'uniformoutput', false);
                [is_exist, loc] = ismember( label, ev_chnls);
                if is_exist
                    for ev_idx = 1 : nb_evs
                        ev_loc = evs( ev_idx, :);
                        ev_info = app.data.events( loc).info;
                        
                        % Test if event exist, or overlap with existing ones
                        for k = 1 : size( app.data.events( loc).info, 1)
                            is_overlap = RangeOverlap( app, ev_loc, ev_info{ k, 1});                            
                            tmp = ReorderEventType( app, ev_info{ k, 2});
                            evtype_same = isequal( evtypes, tmp);
                            is_overlap = is_overlap && evtype_same;
                            
                            if is_overlap && evtype_same
                                loc2insert = k;
                                break;
                            end
                        end
                        
                        if is_overlap
                            % merge location if two events overlap in
                            % location and have same event types.
                            %
                            % % The PeakZscore, AvgFreq etc might not be accurate.
                            
                            tmp_loc = [ev_info{ loc2insert, 1}(:); ev_loc'];
                            tmp_loc = [min( tmp_loc), max( tmp_loc)];
                            app.data.events( loc).info{ loc2insert, 1} = tmp_loc;
                            
                            tmp_evtype = cat( 1, ev_info{ loc2insert, 2}, evtypes);
                            app.data.events( loc).info{ loc2insert, 2} = ReorderEventType( app, tmp_evtype);
                            
                            % The merged avg_freq might not be accurate
                            tmp_evinfo = app.data.events( loc).info{ loc2insert, 3};
                            
                            %% location, nbcycles, peakz, avgfreq
                            %tmp_evinfo.Location = tmp_loc;
                            
                            n1 = diff( ev_info{ loc2insert, 1}(:));
                            % n2 = diff( ev2add_info( ev_idx).Location);
                            n2 = diff( tmp_loc);
                            f1 = ev_info{ loc2insert, 3}.AvgFreq;
                            f2 = ev2add_info( ev_idx).AvgFreq;
                            
                            N = diff( tmp_loc);
                            d = [n1/f1, n2/f2];
                            d( isinf( d)) = []; 
                            
                            if isempty( d)
                                avg_freq = 0;
                            else
                                avg_freq = N / mean( d);
                            end                             
                            
                            c1 = ev_info{ loc2insert, 3}.NoCycles;
                            c2 = ev2add_info( ev_idx).NoCycles;
                            c = [n1 / c1, n2 / c2];
                            c( isinf(c)) = [];
                            if isempty( c)
                                tmp_c = 0;
                            else
                                tmp_c = N / mean( c);
                            end
                            
                            tmp_evinfo.NoCycles = tmp_c;                            
                            tmp_evinfo.PeakZScore = max( [tmp_evinfo.PeakZScore, ev2add_info( ev_idx).PeakZScore]);
                            tmp_evinfo.AvgFreq = avg_freq;
                            
                            f1 = ev_info{ loc2insert, 3}.BPFreq;
                            f2 = ev2add_info( ev_idx).BPFreq;
                            bpfreq = sort( [f1(:); f2(:)]);
                            tmp_evinfo.BPFreq = bpfreq( [1, end])';
                            
                            app.data.events( loc).info{ loc2insert, 3} = tmp_evinfo;
                            
                        else
                            cnt = cnt + 1;
                            % append to info list and sort
                            N = size( app.data.events( loc).info, 1);
                            app.data.events( loc).info{ N+1, 1} = ev_loc;
                            app.data.events( loc).info{ N+1, 2} = evtypes;
                            app.data.events( loc).info{ N+1, 3} = rmfield( ev2add_info( ev_idx), 'Location');
                            
                            tmp = cell2mat( app.data.events( loc).info( :, 1));
                            [~, idx] = sort( tmp( :, 1));
                            app.data.events( loc).info  = app.data.events( loc).info( idx(:), :);
                        end
                        
                    end % event list to add
                    
                else   % add a new channel to the events structure                    
                    N = length( app.data.events);
                    app.data.events( N+1).label = label;
                    for ev_idx = 1 : nb_evs
                        cnt = cnt + 1;
                        app.data.events( N+1).info{ ev_idx, 1} =  evs( ev_idx, :);
                        app.data.events( N+1).info{ ev_idx, 2} =  evtypes;
                        app.data.events( N+1).info{ ev_idx, 3} =  rmfield( ev2add_info( ev_idx), 'Location');
                    end
                end % add a new label to events
                
            end % channel loop
            
            % sort by channel label
            ev_chnls = arrayfun( @(x) x.label, app.data.events, 'uniformoutput', false);
            [~, loc_in_raw] = ismember( ev_chnls, app.AllChannelListBox.Items);
            [~, idx] = sort( loc_in_raw);
            app.data.events = app.data.events( idx);
            
            PUpdateEventList( app);
            app.uidata.cur_event = [];
            Redraw( app);
            status = 1;
            
            nb_chnl = length( ev2add);
            nb_evs = arrayfun( @(x) size( ev2add(x).Location, 1), (1:nb_chnl), 'uniformoutput', false);
            nb_evs = sum( cell2mat( nb_evs));   
            if nb_chnl == 1
                if nb_evs == 1
                    txt = ['1 event added for ', ev2add(1).label, '.'];
                else
                    txt = [num2str( nb_evs), ' events added for ', ev2add(1).label, '.'];
                end
                
            else
                if nb_evs == 1
                    txt = ['1 event added for ', num2str( nb_chnl), ' channels.'];
                else
                    txt = [num2str( nb_evs), ' events added for ',...
                        num2str( nb_chnl), ' channels.'];
                end
            end
            
            PUpdateClustInfo( app, txt, app.uidata.msg_fc);
            
            if ~isempty( app.data.savefile)
                SaveResults( app);
                PUpdateStatus( app, 'Saved results were updated.', app.uidata.msg_fc);
            end
            
        end % function PAddEvent
        
        
        %% Remove currently detected events using right mouse click
        function RemoveCurEvent( app, cur_chnl, cursor_loc)
            %
            % cur_chnl, current channel
            % cursor_loc, cursor location
            %
            % app.uidata.cur_event is a data structure contains fields
            %  label, location, info (Location, PeakZscore, AvgFreq, NoCycles, BPFreq) 
            %
            % app.uidata.cur_event could be changed after call to this func
            
            if app.uidata.debug
                fprintf( 'RemoveCurEvent called.\n');
            end
            
            cur_ev = app.uidata.cur_event;            
            if isempty( cur_ev) || isempty( cur_chnl) || isempty( cursor_loc) 
                return;
            end
                        
            if isstruct( cur_ev)
                ind = (1 : length( cur_ev))';
                chnl_names = arrayfun( @(x) cur_ev( x).label, ind, 'UniformOutput', false);
                [chnl_exist, chnl_loc] = ismember( cur_chnl, chnl_names);                
                if ~chnl_exist
                    return;
                end

                ev_loc = cur_ev( chnl_loc).Location;
                ind2rm = ev_loc( :, 1) <= cursor_loc & ev_loc( :, 2) >= cursor_loc;
                
                cur_ev( chnl_loc).Location( ind2rm == 1, :) = [];
                % info
                fnames = fieldnames( cur_ev( chnl_loc).info);
                for k = 1 : length( fnames)
                    nam = fnames{ k};
                    cur_ev( chnl_loc).info.( nam)( ind2rm == 1, :) = [];
                end

                % remove current channel if all events are removed
                if isempty( cur_ev( chnl_loc).Location)
                    cur_ev( chnl_loc) = [];
                end

                app.uidata.cur_event = cur_ev;
                
            else
                % Becasue of the new app.uidata.cur_event data structure,
                % this statement will never be reached. To be removed in future.
                ind = cur_ev( :, 1) <= cursor_loc & cur_ev( :, 2) >= cursor_loc;
                cur_ev( ind, :) = [];
                app.uidata.cur_event = cur_ev;
            end
        end % update app.uidata.cur_event
        
        
        %% Remove selected event in the event list box from results
        function PRemoveEvent( app, ev_label)
            if app.uidata.debug
                fprintf( 'PRemoveEvent called.\n');
            end
            
            if isempty( ev_label)
                PUpdateStatus( app, 'No event to remove.', 'r');
                return;
            end
            
            [label2ch, ev_idx] = RetrieveChannel( app, ev_label);
            u_label2rm = unique( label2ch, 'stable');
            for k = 1 : length( u_label2rm)
                loc = ismember( label2ch, u_label2rm{ k});                
                ev_chnls = arrayfun( @(x) x.label, app.data.events, 'uniformoutput', false);
                [~, label_loc] = ismember( u_label2rm{ k}, ev_chnls);
                app.data.events( label_loc).info( ev_idx( loc), :) = [];
                
                if isempty( app.data.events( label_loc).info)
                    app.data.events( label_loc) = [];
                end
            end
            
            if length( ev_label) == 1
                txt = '1 event was removed.';
            else
                txt = [num2str( length( ev_label)), ' events were removed.'];
            end
            
            PUpdateClustInfo( app, txt, 'r');
            PUpdateEventList( app);
            Redraw( app);
            
            if ~isempty( app.data.savefile)
                SaveResults( app);
                PUpdateStatus( app, 'Saved results were updated.', app.uidata.msg_fc);
            end
        end % function PRemoveEvent
        
        
        function PMergeEvent( app, ev_label)
            % selecte_event_label, cell array of event label
            if app.uidata.debug
                fprintf( 'PMergeEvent called.\n');
            end
            
            if length( ev_label) ~= 2
                PUpdateStatus( app, 'You can only merge two events.', 'r');
                return;
            end
            
            [cur_label, ev_loc] = RetrieveChannel( app, ev_label);
            u_chnls = unique( cur_label, 'stable');
            if length( u_chnls) ~= 1
                txt = 'Only two events of the same channel can be merged.';
                PUpdateStatus( app, txt, 'r');
                return;
            end
            
            ev_chnls = arrayfun( @(x) x.label, app.data.events, 'uniformoutput', false);
            [~, label_loc] = ismember( u_chnls, ev_chnls);
            chnl_ev_info = app.data.events( label_loc).info;
            
            % N x 2 event
            cur_ev = cell2mat( chnl_ev_info( ev_loc, 1));
            
            %% only events within this distance can be merged
            %merge_threshold = 3;
            %if (cur_ev(2, 1) - cur_ev( 1, 2)) > app.data.srate * merge_threshold
            %    txt = 'Events are too far away to be merged.';
            %    PUpdateStatus( app, txt, 'r');
            %    return;
            %end
            
            % event type
            evtype = cat( 1,...
                chnl_ev_info{ ev_loc(1), 2}(:),...
                chnl_ev_info{ ev_loc(2), 2}(:) );
            
            evtype = ReorderEventType( app, evtype);
            
            app.data.events( label_loc).info{ ev_loc(1), 1} = cur_ev( [1, 4]);
            app.data.events( label_loc).info{ ev_loc(1), 2} = evtype;
                        
            e1 = app.data.events( label_loc).info{ ev_loc(1), 3};
            e2 = app.data.events( label_loc).info{ ev_loc(2), 3};            

            n1 = diff( app.data.events( label_loc).info{ ev_loc(1), 1});
            n2 = diff( app.data.events( label_loc).info{ ev_loc(2), 1});
            f1 = e1.AvgFreq;
            f2 = e2.AvgFreq;
            
            N = diff( cur_ev( [1, 4]));
            d = [n1/f1, n2/f2];
            d( isinf( d)) = [];
            
            if isempty( d)
                avg_freq = 0;
            else
                avg_freq = N / mean( d);
            end
            
            c1 = e1.NoCycles;
            c2 = e2.NoCycles;
            c = [n1 / c1, n2 / c2];
            c( isinf(c)) = [];
            if isempty( c)
                tmp_c = 0;
            else
                tmp_c = N / mean( c);
            end
            
            bpfreq = sort( [e1.BPFreq(:), e2.BPFreq(:)]);
            bpfreq = bpfreq( [1, end]);            
                                    
            tmpev = [];
            tmpev.Location = cur_ev( [1, 4]);
            tmpev.AvgFreq = avg_freq;
            tmpev.NoCycles = tmp_c;            
            tmpev.PeakZScore = max( [e1.PeakZScore, e2.PeakZScore]);    
            tmpev.BPFreq = bpfreq;
            
            app.data.events( label_loc).info{ ev_loc(1), 3} = tmpev;            
            
            % set the second event to empty
            app.data.events( label_loc).info( ev_loc(2), :) = [];
            
            PUpdateEventList( app);
            
            app.MergeEventButton.Enable = 'off';
            
            for k = 1 : length( app.TimeWin)
                if isprop( app.TimeWin{ k}, 'MergeEventButton')
                    app.TimeWin{ k}.MergeEventButton.Enable = app.MergeEventButton.Enable;
                end
            end
            
            for k = 1 : length( app.FreqWin)
                if isprop( app.FreqWin{ k}, 'MergeEventButton')
                    app.FreqWin{ k}.MergeEventButton.Enable = app.MergeEventButton.Enable;
                end
            end

            Redraw( app);
            
            if ~isempty( app.data.savefile)
                SaveResults( app);
                PUpdateStatus( app, 'Saved results were updated.', app.uidata.msg_fc);
            end            
        end
        
        
        function evtype = ReorderEventType( app, evtype)
            % Make evtype the same order as they appear in app.uidata.all_event_type
            %%*** This is becoming confusing, revision needed.
            if isempty( evtype)
                return;
            end
            
            if ~iscell( evtype)
                evtype = {evtype};
            end
            
            if iscell( evtype{1})
                evtype = cat( 1, evtype{:});
            end
            
            evtype = unique( evtype, 'stable');
            [~, loc] = ismember( evtype, app.uidata.all_event_types);
            if ~any( loc == 0)
                evtype = app.uidata.all_event_types( loc);
            end
        end % function ReorderEventType
        
        
        %% Test if an event exists
        function [exist_in_curlabel, dur, evtype, ev_label] = PIsEventExist( app, ev_loc, cur_label, dest_ev_type)
            % Input
            %   ev_loc, scalar or [lo, hi]
            %   cur_label, channel label
            %   dest_ev_type, event type
            %
            % Output
            %   exist_in_curlabel, true|false, whether the event exist in cur label
            %   dur, duration of the event
            %   evtype, event type
            %   ev_label, label shown in the event list box
            %
            
            if app.uidata.debug
                fprintf( 'PIsEventExist called.\n');
            end            
            
            if nargin < 4
                dest_ev_type = [];
            else
                dest_ev_type = ReorderEventType( app, dest_ev_type);
            end
            
            % event exist in the current channel cur_label
            exist_in_curlabel = false;
            
            ev_label = {}; % string shown in the event listbox
            dur = [];
            evtype = {};
            
            if isempty( app.data.events) || isempty( ev_loc)
                return;
            end
            
            chnls = arrayfun( @(x)...
                app.data.events(x).label, (1 : length( app.data.events))', ...
                'UniformOutput', false);
            
            [label_exist, chnl_loc] = ismember( cur_label, chnls);
            if ~any( label_exist)
                return;
            end
            
            if strcmpi( app.uidata.clock_type, 'clock')
                start_time = app.data.start_clock;
            else
                start_time = app.data.time(1);
            end
                
            nb_evs = size( app.data.events( chnl_loc).info, 1);
            cnt = 0;
            dur = zeros( nb_evs, 1);
            evtype = cell( nb_evs, 1);
            ev_label = cell( nb_evs, 1);
            for ev_idx = 1 : nb_evs
                exist_ev_loc = app.data.events( chnl_loc).info{ ev_idx, 1};
                is_overlap = RangeOverlap( app, ev_loc, exist_ev_loc);
                
                if ~isempty( dest_ev_type)
                    % source event type
                    tmp = app.data.events( chnl_loc).info{ ev_idx, 2};                    
                    same_evtype = isequal( dest_ev_type, tmp);
                    is_overlap = is_overlap && same_evtype;
                end
                
                if ~is_overlap
                    continue;
                end
                
                exist_in_curlabel = true;
                
                %{ event_label, channel_name, [start, end], event_type, ...}
                s_evs = app.data.events( chnl_loc);
                s_evs.info = s_evs.info( ev_idx, :);
                
                evs = PFormatEvents( app, s_evs, start_time, app.data.srate);
                
                [~, ~, tmp_ev_label] = RetrieveChannel( app, evs(1), ev_idx);
                tmp_ev_label = tmp_ev_label{1};                
                
                tmp_dur = diff( evs{ 3}) / app.data.srate;
                tmp_dur = round( 100000 * tmp_dur) / 100;
                
                cnt = cnt + 1;
                dur( cnt, 1) = tmp_dur;
                evtype{ cnt, 1} = strjoin( evs{4}, ', ');
                ev_label{ cnt, 1} = tmp_ev_label;
            end
            
            dur( cnt+1 : end) = [];
            evtype( cnt+1 : end) = [];
            ev_label( cnt + 1 : end) = [];            
        end % function if event exists in current channel        
        
        
        function PEventListBoxValueChanged( app, value)
            app.EventListBox.Value = value;
            EventListBoxValueChanged( app);
        end
        
        
        %% Update event type of selected events in the event listbox
        function PUpdateCurrentEvent( app, ev_label)
            % ev_label, event label define in PFormatEvents

            if app.uidata.debug
                fprintf( 'PUpdateCurrentEvent called.\n');
            end
            
            if isempty( ev_label)
                PUpdateStatus( app, 'No event to update.', 'r');
                return;
            end
            
            [~, ev_select] = GetEventType2Disp( app);
            if isempty( ev_select)
                PUpdateStatus( app, 'Please select event type to update.', 'r');
                return;
            end
            
            % all channel labels in the events data structure
            ev_chnls = arrayfun( @(x) x.label, app.data.events, 'uniformoutput', false);
            [label2ch, ev_loc] = RetrieveChannel( app, ev_label);

            nb_lables = length( ev_label);
            ch_s = cell( nb_lables, 1);
            ch_ind = zeros( nb_lables, 1);
            for k = 1 : nb_lables 
                cur_chnl = label2ch{ k};

                [~, label_loc] = ismember( cur_chnl, ev_chnls);
                ind = app.data.events( label_loc).info{ ev_loc(k), 1};
                is_exist = PIsEventExist( app, ind, cur_chnl, ev_select);

                s = [label2ch{k}, ' [', num2str( ev_loc(k)), ']'];
                ch_s{ k} = s;

                if is_exist
                    % location overlaps and same event type
                    txt = [s, ' was not updated.'];
                else
                    ch_ind( k) = 1;
                    app.data.events( label_loc).info{ ev_loc( k), 2} = ev_select;
                    txt = [s, ' was updated.'];
                end
               
                if app.uidata.debug
                    fprintf( [txt, '\n']); 
                end
            end
            
            PUpdateEventList( app);
            Redraw( app);
            
            % Summary of changes
            txt = '';
            if any( ch_ind == 0)
                txt = strjoin( ch_s( ch_ind == 0), ' | ');
                txt = [txt, ' was not updated; '];
            end

            if any( ch_ind == 1)
                txt = [txt, strjoin( ch_s( ch_ind == 1), ' | ')];
                txt = [txt, ' was updated'];
            end

            PUpdateClustInfo( app, txt, 'r'); 

            if ~isempty( app.data.savefile)
                SaveResults( app);
                
                PUpdateStatus( app,...
                    'Saved results were updated.',...
                    app.uidata.msg_fc);
            end
        end % PUpdateCurrentEvent
        
        
        %% Lock/Unlock current channel
        function PLockCurrentChannelCheckBoxValueChanged( app, value)
            app.LockCurrentChannelCheckBox.Value = value;
            LockCurrentChannelCheckBoxValueChanged( app);
        end
        
        

        %% Figure out which bandpass filtered time sries to work with
        function [filt_cell_idx] = PRetriveFiltCellIndex( app, filt_cell_idx)
            if app.uidata.debug
                fprintf( 'PRetriveFiltCellIndex called.\n');
            end
            
            if filt_cell_idx == 0 && ~isempty( app.data.bpfilt.mat)                
                % Click within the main winbdow, or current channel was not set
                filt_win = cellfun( @(x) int8( x.NewWin),...
                    app.data.bpfilt.param, 'UniformOutput', false);
                
                filt_win = cell2mat( filt_win);
                
                if length( filt_win) == 1
                    % One bandpass filtered signal only
                    filt_cell_idx = 1;
                    
                else
                    if sum( filt_win == 0) == 1
                        % one window is embeded within the main window
                        filt_cell_idx = find( filt_win == 0);
                    end
                end
            end
        end % PRetriveFiltCellIndex
        
        
        function [raw_ts, bp_ts, z, data_range] = PRetriveTs( app, cur_chnl, filt_cell_idx, cursor_loc)
            % Retrieve raw, band-pass filtered data, amplitude and z score
            %
            % Baseline can be
            %   whole time series
            %   current time window
            %   specified time length centered around cursor_loc (default)
            
            if app.uidata.debug
                fprintf( 'PRetriveTs called.\n');
            end
            
            raw_ts = [];
            bp_ts = [];
            z = [];
            data_range = [];
            
            if isempty( cur_chnl)
                return;
            end
            
            [~, raw_chnl_loc] = ismember( cur_chnl, app.data.labels);
            data_range = app.uidata.data_range(1) : app.uidata.data_range( end);
            raw_ts = app.data.mat( raw_chnl_loc, data_range);
            
            if filt_cell_idx == 0
                return;
            end
            
            [~, chnl_loc] = ismember( cur_chnl, app.data.bpfilt.labels{ filt_cell_idx});
            bp_ts = app.data.bpfilt.mat{ filt_cell_idx}( chnl_loc, data_range);
            
            if app.BKWholeRB.Value == 1
                z = app.data.bpfilt.z{ filt_cell_idx}( chnl_loc, data_range);
                
            elseif app.BKCurrentRB.Value == 1
                if ~exist( 'zscore', 'file')
                    avg = mean( app.data.bpfilt.amp{ filt_cell_idx}( chnl_loc, data_range));
                    sd = std( app.data.bpfilt.amp{ filt_cell_idx}( chnl_loc, data_range));
                    z = (app.data.bpfilt.amp{ filt_cell_idx}( chnl_loc, data_range) - avg) / sd;
                else
                    z = zscore( app.data.bpfilt.amp{ filt_cell_idx}( chnl_loc, data_range));
                end
                
            else
                % Default option
                if isempty( cursor_loc)
                    cursor_loc = round( mean( app.uidata.data_range));
                end
                
                win_len = round( 0.5 * app.data.srate * app.BKSpecifiedEF.Value);
                sloc = max( [app.uidata.xrange(1), round( cursor_loc) - win_len]);
                eloc = min( [sloc + 2*win_len, app.uidata.xrange( end)]);                
                avg = mean( app.data.bpfilt.amp{ filt_cell_idx}( chnl_loc, sloc : eloc));
                sd = std( app.data.bpfilt.amp{ filt_cell_idx}( chnl_loc, sloc : eloc));
                z = (app.data.bpfilt.amp{ filt_cell_idx}( chnl_loc, data_range) - avg) / sd;
            end
            
        end %function PRetriveTs
        
        
        function [ev_types2disp, ev_select] = PGetEventType2Disp( app)
            [ev_types2disp, ev_select] = GetEventType2Disp( app);
        end % function GetEventType2disp
        
        
        %% Add bandpass filter time window
        function PAddTimeWinButtonPushed( app)
            if app.uidata.debug
                fprintf( 'PAddTimeWinButtonPushed called.\n');
            end
            
            chnl2plot = app.ChannelListBox.Items;
            if isempty( chnl2plot)
                app.StatusLabel.Text = 'Please select at least one channel to band-pass filter.';
                app.StatusLabel.FontColor = 'r';
                return;
            end
            
            % Open a new window
            h = findall( 0, 'type', 'figure', 'name', 'Filter Setup');
            if ~isempty( h) && isvalid( h)
                figure( h);
                return;
            end
            
            waitfor( ConfigFilter( app));
                        
            if strcmpi( app.data.bpfilt.update_plot, 'yes')
                % Preprocess the channels in the list box
                [~, chnl_loc] = ismember( chnl2plot, app.data.labels);
                nbchnls = length( chnl_loc);
                
                N = length( app.data.bpfilt.param);
                app.data.bpfilt.mat{ N} = app.data.mat( chnl_loc, :);
                app.data.bpfilt.amp{ N} = app.data.mat( chnl_loc, :);
                app.data.bpfilt.z{ N} = app.data.mat( chnl_loc, :);
                for chnl_idx = 1 : nbchnls
                    txt = ['Processing channel ', num2str( chnl_idx), '/', num2str( nbchnls), '.'];
                    PUpdateStatus( app, txt, app.uidata.msg_fc);
                    drawnow;
                    
                    cur_chnl = chnl_loc( chnl_idx);                    
                    [app.data.bpfilt.mat{ N}( chnl_idx, :), app.data.bpfilt.amp{ N}( chnl_idx, :)] = ...
                        HFOFiltData( app.data.mat( cur_chnl, :), app.data.srate, app.data.bpfilt.param{ N});
                    
                    if exist( 'zscore', 'file')
                        app.data.bpfilt.z{ N}( chnl_idx, :) = zscore( app.data.bpfilt.amp{ N}( chnl_idx, :));
                    else
                        avg = mean( app.data.bpfilt.amp{ N}( chnl_idx, :));
                        sd = std( app.data.bpfilt.amp{ N}( chnl_idx, :));
                        app.data.bpfilt.z{ N}( chnl_idx, :) = (app.data.bpfilt.amp{ N}( chnl_idx, :) - avg ) / sd;
                    end
                end
                
                app.data.bpfilt.labels{ N} = chnl2plot(:);
                
                % intensity range
                xrng = app.uidata.xrange( [1, end]);
                lim = [min( app.data.bpfilt.mat{N}( :, xrng(1) : xrng(2)), [], 'all'),...
                    max( app.data.bpfilt.mat{N}( :, xrng(1) : xrng(2)), [], 'all')];
                
                lim = [-1, 1] * max( abs( lim));
                app.data.bpfilt.param{ N, 1}.raw_limit = lim;
                app.data.bpfilt.param{ N, 1}.limit = lim;
                app.data.bpfilt.param{ N, 1}.yscale = 100;
                
                if app.data.bpfilt.param{ N, 1}.NewWin == 1
                    nb_wins = length( app.TimeWin);
                    app.TimeWin{ nb_wins+1} = TimeApp( app, app.data.bpfilt.param{ N, 1}.Name);
                end
                
                txt = 'Ready';
                PUpdateStatus( app, txt, app.uidata.msg_fc);
                
                if ~isempty( app.HFloatYScale)
                    FiltYScaleButtonPushed( app);
                end
                
                PResizeWindows( app, app.HFOAppUIFigure.Position, 'main');
                Redraw( app);
            end
            
            % Close Organize bandpass filter window
            h = findall( 0, 'type', 'figure', 'name', 'Organize Filter Windows');
            if ~isempty( h) && isvalid( h)
                delete( h);
            end            
            
        end % add new bandpass filter window
        
        
         %% Add spectrogram window
        function PAddFreqWinPushed( app)
            if app.uidata.debug
                fprintf( 'PAddFreqWinPushed called.\n');
            end
           
            if isempty( app.uidata.current_channel)
                PUpdateStatus( app,...
                    'Please set the current channel to run spectrogram.', 'r');
                return;
            end

            %% spectrogram data structure
            %app.data.tf.mat = {}; % sample x frequency
            %app.data.tf.param = {}; % param.freq-frequency
            %app.data.tf.labels = {}; % Nx1 cell array of channel labels
                
            cur_chnl = app.uidata.current_channel{1};
            if iscell( cur_chnl)
                cur_chnl = cur_chnl{1};
            end
            
            PUpdateStatus( app,...
                ['Calculating spectrogram for ', cur_chnl],...
                app.uidata.msg_fc);
            
            PUpdateClustInfo( app,...
                [cur_chnl, ' is busy, please wait ...'],...
                app.uidata.msg_fc);
            
            drawnow;
            
            % calculate spectrogram
            PUpdateSpectrogram( app, cur_chnl);

            % spectrogram window
            PUpdateStatus( app,...
               'Plotting spectrogram, please wait ...',...
               app.uidata.msg_fc);
            
            drawnow;
            
            win_ind = PGetFreqWinIndex( app, cur_chnl);

            if win_ind < 1
                PUpdateStatus( app,...
                    'Something went wrong in opening spectrogram window.',...
                    'r');
                
                return;
            end 
            
            if strcmpi( app.uidata.entire_tf_plotted, 'yes')
                [~, chnloc] = ismember( cur_chnl, app.data.tf.labels);

                imagesc( app.FreqWin{ win_ind}.UIAxes,...
                    1 : app.data.nbsamples,...
                    app.data.tf.param{ chnloc}.freq,...
                    app.data.tf.mat{ chnloc});
            end            
            
            PResizeWindows( app, app.HFOAppUIFigure.Position, 'main');
            Redraw( app);
            
            txt = [cur_chnl, ' spectrogram is ready.'];
            PUpdateClustInfo( app, txt, app.uidata.msg_fc);
        end % add new freq window
        
        
        function txt = PDynamicCursorInfo( app, xloc, yloc)
            % Information to show when the cursor moves with left mouse click            
            %%%%% click point in the time series axes           
            % xloc = floor( app.UIAxes.CurrentPoint( 1, 1));
            % yloc = app.UIAxes.CurrentPoint( 1, 2);
            
            if app.uidata.debug
                fprintf( 'PDynamicCursorInfo called.\n');
            end
                        
            click_in_axes = PPointInRange( app, xloc, app.uidata.data_range, yloc, [0, 1]);
            if ~click_in_axes || isempty( app.uidata.current_channel)
                txt = ['Cursor at ', num2str( app.uidata.cursor_loc), '.'];
                return;
            end
            
            filt_cell_idx = PRetriveFiltCellIndex( app, app.uidata.current_channel{2});    
            if filt_cell_idx > 0
                % click on bandpass filtered time series
                [~, cursor_zval] = PDetectEvent( app, 'time', ...
                    app.uidata.current_channel{ 1}, xloc, filt_cell_idx, 'no', true);
                
                win_name = app.data.bpfilt.param{ filt_cell_idx}.Name;
                % [~, mat_idx] = ismember( cur_chnl, app.data.bpfilt.labels{ filt_cell_idx});
                % filt_info = sprintf( 'Name: %s; WinInd %d; MatInd: %d', win_name,  filt_cell_idx ', mat_idx);
                if ~isempty( cursor_zval)
                    txt = sprintf( '%s. Cursor Z: %.2f. Bandpass window: %s',...
                        app.uidata.current_channel{ 1},...
                        cursor_zval,...
                        win_name);
                else
                    txt = sprintf( '%s. Cursor at %d. Bandpass window: %s',...
                        app.uidata.current_channel{ 1},...
                        xloc,...
                        win_name);
                end
                
            else
                txt = ['Current channel: ', app.uidata.current_channel{1}, '.'];
            end

        end % function PDynamicCursorInfo
        

        function win_ind = PGetFreqWinIndex( app, cur_chnl)
            % Retrieve index in app.FreqWin for cur_chnl
            % cur_chnl, channel label
            % The spectrogram window name is same to channel label.
            
            if app.uidata.debug
                fprintf( 'PGetFreqWinIndex called.\n');
            end
            
            %% Spectrogram window names
            freq_win_names = cellfun( @(x) x.UIFigure.Name, app.FreqWin, 'UniformOutput', false);
            [win_exist, win_ind] = ismember( cur_chnl, freq_win_names);
            
            if strcmpi( app.uidata.tf_multi_wins, 'no')
                % Close all the other windows
                loc2rm = zeros( length( app.FreqWin), 1);
                for k = 1 : length( app.FreqWin)
                    if k == win_ind
                        continue;
                    end
                    
                    delete( app.FreqWin{k});
                    loc2rm( k) = 1;
                end
                
                app.FreqWin( loc2rm == 1) = [];
            end
            
            % open new window if necessary
            if ~win_exist
                if strcmpi( app.uidata.tf_multi_wins, 'yes')
                    N = length( app.FreqWin);
                    win_ind = N + 1;
                else
                    app.FreqWin = {};
                    win_ind = 1;
                end
                app.FreqWin{ win_ind} = FreqApp( app, cur_chnl);
            end
        end % function PUpdateFreqWin
        
        
        function [tf, freq_axis, orig_data_range] = PUpdateSpectrogram( app, cur_chnl, force_update)
            % app.data.tf.mat = {}; % sample x frequency
            % app.data.tf.param = {}; % param.freq-frequency
            % app.data.tf.labels = {}; % Nx1 cell array of channel labels
            
            if app.uidata.debug
                fprintf( 'PUpdateSpectrogram called.\n');
            end
            
            tf = [];
            freq_axis = [];
            orig_data_range = [];
            
            if isempty( cur_chnl)
                return;
            end
            
            if nargin < 3
                force_update = false;
            end
            
            if iscell( cur_chnl)
                cur_chnl = cur_chnl{1};
            end
            
            % clear all other channels' spectrogram data
            if strcmpi( app.uidata.tf_keepall, 'no') && strcmpi( app.uidata.tf_entire, 'yes')                
                loc2rm = ~ismember( app.data.tf.labels, cur_chnl);
                app.data.tf.mat( loc2rm) = [];
                app.data.tf.param( loc2rm) = [];
                app.data.tf.labels( loc2rm) = [];
            end
            
            [chnl_exist, chnl_loc] = ismember( cur_chnl, app.data.tf.labels);
            if ~chnl_exist
                force_update = true;
            end
            
            orig_data_range = app.uidata.data_range(1) : app.uidata.data_range(2);
            is_within = false;
            if chnl_exist
                % if orig_data_range is within app.data.tf.param{ N+1}.xrange
                [~, is_within] = RangeOverlap( app, orig_data_range, app.data.tf.param{ chnl_loc}.xrange);
            end
            
            if chnl_exist && is_within && ~force_update
                % retrieve the spectrom for curren time window
                yrng = app.data.tf.param{ chnl_loc}.xrange;
                [~, ~, ib]= intersect( orig_data_range, yrng(1) : yrng(2));
                tf = app.data.tf.mat{ chnl_loc}( :, ib);
                freq_axis = app.data.tf.param{ chnl_loc}.freq;
                
            else % calculate the spectrogram
                if strcmpi( app.uidata.tf_entire, 'yes')
                    % calculate for the entire time series
                    data_range = 1 : app.data.nbsamples;
                    ib = 1 : app.data.nbsamples;
                    freq_xrange = [1, app.data.nbsamples];
                    
                else
                    % calcualte for current time window
                    N = round( app.data.srate * app.uidata.tf_padsize);
                    lo = max( [app.uidata.xrange(1), orig_data_range(1) - N]);
                    hi = min( [orig_data_range(end) + N, app.uidata.xrange(2)]);
                    data_range = lo : hi;
                    [com_rng, ~, ib]= intersect( orig_data_range, data_range);
                    freq_xrange = com_rng( [1, end]);
                end
                
                [~, chnl_locin_raw] = ismember( cur_chnl, app.data.labels);
                foi = app.uidata.tf_foi_range;
                foi = linspace( foi(1), foi(2), app.uidata.tf_foi_len);
                [tf, ~, freq_axis] = HFOSpectrogram( app.data.mat( chnl_locin_raw, data_range),...
                    app.data.srate, 'foi', foi);
                tf = abs( tf( :, ib));
                
                if strcmpi( app.uidata.tf_keepall, 'yes')
                    if ~chnl_exist
                        % add to data structure
                        N = length( app.data.tf.mat) + 1;
                    else
                        % update
                        N = chnl_loc;
                    end
                    
                else
                    % only save the entire spectrogram of the current channel
                    app.data.tf.mat = {};
                    app.data.tf.param = {};
                    app.data.tf.labels = {};
                    N = 1;
                end
                
                app.data.tf.mat{ N} = tf;
                app.data.tf.param{ N}.freq = freq_axis;
                app.data.tf.param{ N}.climit = [0, prctile( tf(:), 99.9)];
                app.data.tf.param{ N}.Name = cur_chnl;
                app.data.tf.param{ N}.xrange = freq_xrange;
                app.data.tf.labels{ N} = cur_chnl;
            end % if spectrogram of the current label exists            
        end % function PUpdateSpectrogram
        
        
        function [disp_ev_loc, dis_ev_color] = GetCurrentEvents2Show( app)
            % app.uidata.data_range;
            % app.ChannelListBox.Items(:);            
            if app.uidata.debug
                fprintf( 'GetCurrentEvents2Show called.\n');
            end
            
            if isempty( app.ChannelListBox.Items)
                disp_ev_loc = {};
                dis_ev_color = {};
                return;
            end
            
            nbchnls = length( app.ChannelListBox.Items);
            disp_ev_loc = cell( nbchnls, 1);
            dis_ev_color = cell( nbchnls, 1);
            if isempty( app.data.events)
                return;
            end
                       
            ev_chnls = arrayfun( @(x) x.label, app.data.events, 'uniformoutput', false);
            % Which type of event to display
            ev_types2disp = GetEventType2Disp( app);
            for chnl_idx = 1 : nbchnls
                cur_label = app.ChannelListBox.Items{ chnl_idx};
                
                % Plot events on current selected channel only
                if app.ShowEventOnAllChannelButton.Value == 0 && ...
                        ( isempty( app.uidata.current_channel) || ... 
                        ~ismember( cur_label, app.uidata.current_channel( :, 1)))
                    continue;
                end
                
                % Position of the current channel in the events data structure
                [chnl_exist, chnl_in_evs] = ismember( cur_label, ev_chnls);
                if ~chnl_exist
                    % current channel has no events
                    continue;
                end                
                
                cnt = 0;
                for ev_idx = 1 : size( app.data.events( chnl_in_evs).info, 1)
                    % current event type
                    cur_ev_type = app.data.events( chnl_in_evs).info{ ev_idx, 2};
                    show_cur_ev = intersect( cur_ev_type, ev_types2disp);
                    
                    % use the last event type's color
                    [~, evtype_loc_in_all] = ismember( cur_ev_type, app.uidata.all_event_types);
                    evtype_loc_in_all = evtype_loc_in_all( end);
                    
                    cur_ev = app.data.events( chnl_in_evs).info{ ev_idx, 1};
                    [cur_ev_in_win, ~, overlap_rng] = RangeOverlap( app, cur_ev, app.uidata.data_range) ;
                    if cur_ev_in_win && ~isempty( show_cur_ev)
                        cnt = cnt + 1;
                        disp_ev_loc{ chnl_idx}{ cnt} = overlap_rng;
                        dis_ev_color{ chnl_idx}{ cnt} = app.uidata.all_event_color{ evtype_loc_in_all};
                    end
                end
                    
            end % channel oop
        end % function
        
        
        %% Retrive misc. information of plotted channels
        function [mtrack_info, ftrack_info] = PGetTrackInfo( app)
            if app.uidata.debug
                fprintf( 'PGetTrackInfo called.\n');
            end
                        
            % Get re-scale range, ytick etc in for main and band-pass filter windows
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            chnl2plot = app.ChannelListBox.Items(:);
            nbchnls = length( chnl2plot);
            
            % whether bandpass filtered window is embeded in main window
            embed_win = cellfun( @(x) int8( x.NewWin) == 0, app.data.bpfilt.param, 'uniformoutput', false);
            embed_win = cell2mat( embed_win);
            nb_embed_win = sum( embed_win);
            nb_float_win = sum( ~embed_win);
            
            % Info of each time series in the main window
            mtrack_info = [];
            mtrack_info.real_label = {}; % N x 1 cell array of real channel label2rgb
            mtrack_info.label_location = [];
            mtrack_info.scale_factor = []; % N x 1 scale factor [0, 1]
            mtrack_info.ylimit = []; % Nx2 matrix of data amplitude limits
            mtrack_info.Color = {}; % line color
            mtrack_info.filt_cell_ind = []; % Nx1 of the cell index in bpfilt.mat 0-raw data2fun
            mtrack_info.filt_win_ind = []; %
            mtrack_info.ytick = [];
            mtrack_info.yticklabel = {}; % N x 1 cell array of ytick label2rgb
            mtrack_info.plot_ytick = [];
            mtrack_info.plot_yticklabel = {};
            
            % Info of each time series in floated time series
            ftrack_info = cell( nb_float_win, 1);
            %ftrack_info.real_label = {}; % N x 1 cell array of real channel label2rgb
            %ftrack_info.label_location = [];
            %ftrack_info.yticklabel = {}; % N x 1 cell array of ytick label2rgb
            %ftrack_info.scale_factor = []; % N x 1 scale factor [0, 1]
            %ftrack_info.ylimit = []; % Nx2 matrix of data amplitude limits
            %ftrack_info.Color = {}; % line color
            %ftrack_info.filt_cell_ind = []; % Nx1 of the cell index in bpfilt.mat 0-raw data2fun
            
            cnt = 0;
            for chnl_idx = 1 : nbchnls
                cur_chnl = chnl2plot{ chnl_idx};
                [~, chnl_loc] = ismember( cur_chnl, app.data.labels);
                
                cnt = cnt + 1;
                mtrack_info.real_label{ cnt, 1} = cur_chnl;
                mtrack_info.label_location( cnt, 1) = chnl_loc;
                mtrack_info.yticklabel{ cnt, 1} = cur_chnl;
                mtrack_info.scale_factor( cnt, 1) = app.YScaleSpinner.Value/100;
                mtrack_info.ylimit( cnt, :) = app.data.raw_limit;
                mtrack_info.Color{ cnt, 1} = app.uidata.color_main;
                mtrack_info.filt_cell_ind( cnt, 1) = 0;
                mtrack_info.filt_win_ind( cnt, 1) = 0;
                
                % band-pass filter data to be plot in the main window
                for fidx = 1 : length( app.data.bpfilt.mat)
                    if app.data.bpfilt.param{ fidx}.NewWin == 0
                        cnt = cnt + 1;
                        [~, f_chnl_loc] = ismember( cur_chnl, app.data.bpfilt.labels{ fidx});
                        mtrack_info.real_label{ cnt, 1} = cur_chnl;
                        mtrack_info.label_location( cnt, 1) = f_chnl_loc;
                        mtrack_info.yticklabel{ cnt, 1} = ['f_', cur_chnl];
                        mtrack_info.scale_factor( cnt, 1) = app.data.bpfilt.param{ fidx}.yscale / 100;
                        mtrack_info.ylimit( cnt, :) = app.data.bpfilt.param{ fidx}.raw_limit;
                        mtrack_info.Color{ cnt, 1} = app.data.bpfilt.param{ fidx}.Color;
                        mtrack_info.filt_cell_ind( cnt, 1) = fidx;
                        mtrack_info.filt_win_ind( cnt, 1) = 0;
                    end
                end
            end
                                    
            N = nbchnls * (1 + nb_embed_win);
            b = linspace( 0, 1, N + 1)';
            mtrack_info.ytick = (b( 1 : end-1) + b( 2:end))/2;
            
            range_lim_label = {};
            % range_lim = [];
            range_lim = nan( 2*(nb_embed_win + 1), 1);
            for k = 1 : N
                [rescale_range, tmp] = UpdateYScaleRange( app, b( [k, k+1]), 0.1, mtrack_info.scale_factor( k));
                mtrack_info.rescale_range( k, :) = rescale_range;
                mtrack_info.marg_range( k, :) = tmp;
                
                if k <= nb_embed_win + 1
                    %range_lim =[range_lim; tmp(:)];
                    range_lim ( 2*k - 1 : 2*k) = tmp(:);
                    tmp = mtrack_info.ylimit( k, :) / mtrack_info.scale_factor(k);                    
                    tmp = string( RoundData( app, tmp));
                    range_lim_label = cat( 1, range_lim_label, {tmp(1); tmp(2)} );
                end
            end
            
            new_ytick = [mtrack_info.ytick(:); range_lim];
            new_yticklabel = cat( 1, mtrack_info.yticklabel, range_lim_label);
            [ytick, idx] = sort( new_ytick);
            mtrack_info.plot_ytick = ytick;
            mtrack_info.plot_yticklabel = new_yticklabel(idx);
            mtrack_info.yrange = [b(1:end-1), b(2:end)];
            
            
            filt_win_names = cellfun( @(x) x.UIFigure.Name, app.TimeWin, 'uniformoutput', false);
            f_b = linspace( 0, 1, nbchnls+1)';
            cnt = 0;
            for fidx = 1 : length( app.data.bpfilt.mat)
                if app.data.bpfilt.param{ fidx}.NewWin ~= 0
                    cnt = cnt + 1;
                    
                    win_name = app.data.bpfilt.param{ fidx}.Name;
                    [~, win_ind] = ismember( win_name, filt_win_names);
                    app.data.bpfilt.param{ fidx}.yscale = app.TimeWin{ win_ind}.YScaleSpinner.Value;
                    scale_fact = app.TimeWin{ win_ind}.YScaleSpinner.Value / 100;
                    
                    % N x 1 cell array of real channel label2rgb
                    ftrack_info{ cnt}.real_label = chnl2plot; 
                    [~, tmp_loc] = ismember( chnl2plot, app.data.bpfilt.labels{ fidx});
                    ftrack_info{ cnt}.label_location = tmp_loc;
                    % N x 1 cell array of ytick label2rgb
                    ftrack_info{ cnt}.yticklabel = chnl2plot; 
                    ftrack_info{ cnt}.ytick = (f_b( 1:end-1) + f_b( 2:end)) / 2;
                    % N x 1 scale factor [0, 1]
                    ftrack_info{ cnt}.scale_factor = scale_fact; 
                    % Nx2 matrix of data amplitude limits
                    ftrack_info{ cnt}.ylimit = app.data.bpfilt.param{ fidx}.raw_limit;
                    % line color
                    ftrack_info{ cnt}.Color = app.uidata.color_main;
                    % Nx1 of the cell index in bpfilt.mat 0-raw data2fun
                    ftrack_info{ cnt}.filt_cell_ind = fidx; 
                    ftrack_info{ cnt}.filt_win_ind = win_ind;
                    
                    for chnl_idx = 1 : length( chnl2plot)
                        [rescale_range, tmp] = UpdateYScaleRange( app,...
                            f_b( [chnl_idx, chnl_idx + 1]), 0.1, scale_fact);
                        
                        ftrack_info{ cnt}.rescale_range( chnl_idx, :) = rescale_range;
                        ftrack_info{ cnt}.marg_range( chnl_idx, :) = tmp;
                        
                        if chnl_idx == 1
                            range_lim = tmp(:);
                            tmp = app.data.bpfilt.param{ fidx}.raw_limit / scale_fact;
                            tmp = string( RoundData( app, tmp));
                            range_lim_label = {tmp(1); tmp(2)};
                        end
                    end
                    
                    new_yticklabel = cat( 1, chnl2plot, range_lim_label);
                    [f_ytick, idx] = sort( [ftrack_info{ cnt}.ytick(:); range_lim]);
                    
                    ftrack_info{ cnt}.plot_ytick = f_ytick;
                    ftrack_info{ cnt}.plot_yticklabel = new_yticklabel(idx);
                    ftrack_info{ cnt}.yrange = [f_b(1:end-1), f_b(2:end)];
                end
            end
        end % function
        
        
        function mat = RoundData(~, mat)
            % Adjust the number of digits
            if min( abs(mat)) < 1
                mat = round( mat * 1000) / 1000;
                
            elseif min( abs(mat)) < 10
                mat = round( mat * 100) / 100;
                
            elseif min( abs(mat)) < 100
                mat = round( mat * 10) / 10;
                
            else
                mat = round( mat);
            end
        end
        
        
        % Click on the time series or spectrograms
        function PWinButtonDown( app, src, src_win_name, click_type, coord, xrange, yrange)
            % src: 'main' | 'time' | 'freq'
            % % main window, bandpass filtered window or spectrogram window
            % src_win_name, source window name
            % click_type, 'normal' | 'open' 
            % click coordinates, [x, y]
            % xrange, app.uidata.data_range
            % yrange, [0, 1] or [lo_freq, hi_freq]
            
            if app.uidata.debug
                fprintf( 'PWinButtonDown called.\n');
            end
            
            % Location in time
            xloc = floor( coord(1));
            
            % frequency if src is 'freq'
            yloc = coord( 2);
            
            % Whether the click was within specified range
            click_in_axes = PPointInRange( app, xloc, xrange, yloc, yrange);            
            
            app.uidata.cursor_loc = [];
            
            % Which time series was clicked
            if click_in_axes
                app.uidata.cursor_loc = max( [1; xloc(:)]);
                
                if strcmpi( src, 'main') || strcmpi( src, 'time')                    
                    cur_chnl_set = 'no';
                    [mtrack_info, ftrack_info] = PGetTrackInfo( app);
                    
                    if strcmpi( src, 'main')                        
                        if ~isempty( mtrack_info)
                            yrange = mtrack_info.yrange;
                            idx = yrange( :, 1) <= yloc & yrange( :, 2) > yloc;
                            src_cur_chnl = mtrack_info.real_label{ idx};
                            fc_idx = mtrack_info.filt_cell_ind( idx);
                            cur_chnl_set = 'yes';
                        end
                        
                    else                         
                        for k = 1 : length( ftrack_info)
                            idx = ftrack_info{ k}.filt_cell_ind;                            
                            filt_win_name = app.data.bpfilt.param{ idx}.Name;
                            if ~strcmpi( filt_win_name, src_win_name)
                                continue;
                            end
                            
                            fc_idx = idx;
                            yrange = ftrack_info{ k}.yrange;
                            idx = yrange( :, 1) <= yloc & yrange( :, 2) > yloc;
                            src_cur_chnl = ftrack_info{ k}.real_label{ idx};
                            cur_chnl_set = 'yes';
                            break;                            
                        end                        
                    end
                    
                    if app.LockCurrentChannelCheckBox.Value == 0
                        % Update current_channel if it's not locked
                        app.uidata.current_channel = {};                        
                        if strcmpi( cur_chnl_set, 'yes')
                            app.uidata.current_channel = {src_cur_chnl, fc_idx};                            
                            msg = ['PWinButtonDown: Set CurChnl: ', src_cur_chnl, ...
                                ', FiltWin: ', num2str( fc_idx), '.\n'];
                        else
                            msg = 'PWinButtonDown: Failed to set current channel.\n';
                        end
                    end
                    
                    
                elseif strcmpi( src, 'freq')
                    % set current channel to the spectrogram plot channel                    
                    if app.LockCurrentChannelCheckBox.Value == 0
                        msg = 'PWinButtonDown: Current channel updated by FreqApp.\n';
                        
                        fc_idx = 0;
                        if ~isempty( app.uidata.current_channel)
                            fc_idx = app.uidata.current_channel{ 2};
                        end
                        
                        % src_win_name is a real channel label
                        app.uidata.current_channel = {src_win_name, fc_idx};
                        
                    else
                        msg = 'PWinButtonDown: Current channel locked.\n';
                    end
                    
                else
                    % Undefined source
                    return;
                end % switch which window is calling                
                
                % channel list box
                if ~isempty( app.uidata.current_channel)
                    app.ChannelListBox.Value = app.uidata.current_channel{ 1};
                end
                
                if app.uidata.debug
                    fprintf( msg);
                end                
            end % click within the axes
            
            
            if  click_in_axes && strcmpi( click_type, 'normal')  
                % Left mouse click to detect events                
                app.uidata.cur_event = [];
                
                if app.ManualModeButton.Value == 1
                    % Show the cursor information if it's in manual mode                    
                    txt = PDynamicCursorInfo( app, xloc, yloc);
                    
                else                    
                    % Automatic event detection
                    
                    % If click on spectrogram and THE checkbox is on
                    app.uidata.autoev_use_spec = false;
                    if strcmpi( src, 'freq')
                        widx = cellfun( @(x) ...
                            strcmpi( src_win_name, x.UIFigure.Name), app.FreqWin,...
                            'UniformOutput', false);
                        
                        widx = find( cell2mat( widx));
                        
                        freq_detect_set = app.FreqWin{ widx}.TFAutoDetectCheckBox.Value == 1;
                        if ~isempty( widx) && freq_detect_set
                            app.uidata.autoev_use_spec = true;
                        end
                    end
                    
                   txt = PGoDetectEvent( app, true, yloc);
                end
                
                Redraw( app);
                PUpdateClustInfo( app, txt, app.uidata.msg_fc);                
                
            elseif click_in_axes && strcmpi( click_type, 'open')
                % Double click on left and right side to move forward or backward
                
                win_rng = app.uidata.data_range;
                xrange = app.uidata.xrange( [1, end]);                
                margin_val = win_rng(1) + [0.95, 0.05] * diff( win_rng);
                N = round( app.data.srate * app.XScaleSpinner.Value / 4);
                
                if xloc > margin_val(1)
                    app.uidata.start_loc = min( [xrange(2), win_rng(1) + N]);
                    Redraw( app);
                    
                elseif xloc < margin_val(2)
                    app.uidata.start_loc = max( [xrange(1), win_rng(1) - N]);
                    Redraw( app);
                    
                else
                    %do nothing
                end
                
            elseif click_in_axes && strcmpi( click_type, 'alt')
                % right click to remove currently identified events
                
                current_channel = app.uidata.current_channel;
                cursor_loc = app.uidata.cursor_loc;
                if ~isempty( current_channel) && ~isempty( cursor_loc)
                    RemoveCurEvent( app, current_channel{ 1}, cursor_loc);
                    Redraw( app);
                end
                
            else % do nothing                
            end  %%%%% click point in the time series axes            
            
        end % PWinButtonDown
        
        
        % Do the event detection for left mouse click and shutcut "d"
        function txt = PGoDetectEvent( app, default_search, yloc)
            % The search wind will be limit to 1s around the cusror if this
            % function is called within the WindowButtondown callback
            %
            % User-specified serach range will be used if this function is
            % called by by "d" button press. default_search = false
            %
            % yloc, y coordinate of the cursor
            
            if app.uidata.debug
                fprintf( 'PGoDetectEvent called.\n');
            end
            
            
            if isempty( app.uidata.cursor_loc)
                txt = 'Left mouse click on the plots to start event detection.';
                return;
            end
            
            if isempty( app.uidata.current_channel)
                txt = 'Click on time series plot or spectrogram to begin event detection.';
                PUpdateStatus( app, 'No current channel was set.', 'r');
                return;
            end
            
            % app.uidata.evdetect.use_spec true | false
            % value changed by left mouse click
            
            % make sure it knows which bandpass filter data to use
            if ~app.uidata.autoev_use_spec
                fcidx = app.uidata.current_channel{ 2};
                fcidx = PRetriveFiltCellIndex( app, fcidx);
                if fcidx < 1 && isempty( app.data.bpfilt.mat) 
                    txt = 'No bandpass filter window for event detection.';
                    return;
                    
                elseif fcidx < 1 && ~isempty( app.data.bpfilt.mat) 
                    txt = 'Click on bandpass filtered data to detect events.';
                    return;
                    
                else
                    % do nothing
                end
            end            
            
            app.uidata.cur_event = [];
            srate = app.data.srate;
            cursor_loc = app.uidata.cursor_loc;
            
            if ~app.uidata.autoev_use_spec
                % detect using bandpass filtered time series
                
                [~, ev_type] = GetEventType2Disp( app);
                
                if app.MultipleDetectionCheckBox.Value == 1 && ~default_search
                    % multiple channel detection
                    
                    cnt = 0;
                    disp_chnls = app.ChannelListBox.Items;
                    nb_chnls = length( disp_chnls);
                    for chnl_idx = 1 : nb_chnls
                        cur_chnl = disp_chnls{ chnl_idx};
                        
                        tmp = ['Detect HFOs for ', cur_chnl,...
                            ', ', num2str( chnl_idx),...
                            '/', num2str( nb_chnls)];
                        if app.uidata.debug                            
                            fprintf( tmp);
                        end
                        PUpdateStatus( app, tmp, app.uidata.msg_fc);
                        drawnow;
                        
                        evinfo = PDetectEvent( app, 'time', cur_chnl,...
                            cursor_loc, fcidx, 'yes', default_search);
                        
                        % Remove locations if they are already in the results                         
                        evinfo = CleanCurEvent( app, cur_chnl, evinfo, ev_type); 

                        if isempty( evinfo)
                            continue;
                        end
                      
                        cnt = cnt + 1;
                        app.uidata.cur_event( cnt, 1).label = cur_chnl;
                        app.uidata.cur_event( cnt, 1).Location = evinfo.Location;
                        app.uidata.cur_event( cnt, 1).info = evinfo;
                    end
                    
                    txt = EventInfo( app, app.uidata.cur_event, srate);
                    
                else % detection for current channel
                    cur_chnl = app.uidata.current_channel{ 1};
                    tmp = [ 'Detect HFOs for ', cur_chnl,  ' ...'];
                    PUpdateStatus( app, tmp, app.uidata.msg_fc);
                    drawnow;
                    
                    [evinfo, cursor_zval, msg] = PDetectEvent( app, 'time', cur_chnl,...
                        app.uidata.cursor_loc, fcidx, 'yes', default_search);
                   
                    evinfo = CleanCurEvent( app, cur_chnl, evinfo, ev_type); 
                    if ~isempty( evinfo)
                        app.uidata.cur_event.label = cur_chnl;
                        app.uidata.cur_event.Location = evinfo.Location;
                        app.uidata.cur_event.info = evinfo;
                    end
                    
                    % Detected events information
                    if ~isempty( app.uidata.cur_event)  
                        txt = EventInfo( app, app.uidata.cur_event, app.data.srate);
                        
                    else
                        % show the z score at current cursor
                        if isempty( cursor_zval)
                            cursor_zval = 'NaN';
                        else
                            cursor_zval = round( 100 * cursor_zval) / 100;
                            cursor_zval = num2str( cursor_zval);
                        end
                        txt = [msg '. Cursor z score: ', cursor_zval];
                    end
                end % single or multi-channel detection
                                                
            else  % event detect using spectrom
                cur_chnl = app.uidata.current_channel{1};
                tmp = ['Detect HFOs for ', cur_chnl, ' ...'];
                PUpdateStatus( app, tmp, app.uidata.msg_fc);
                drawnow;
                    
                [evinfo, ~, ~, result] = PDetectEvent( app, 'freq',...
                    cur_chnl, [cursor_loc, yloc], [], 'yes', true);
                
                if ~isempty( evinfo)
                    [~, ev_type] = GetEventType2Disp( app);
                    is_exist = PIsEventExist( app, evinfo.Location, cur_chnl, ev_type);
                    if ~is_exist
                        tmpev = [];
                        tmpev.label = cur_chnl;
                        tmpev.Location = evinfo.Location;
                        tmpev.info = evinfo;
                        
                        app.uidata.cur_event = tmpev;
                        
                        txt = EventInfo( app, tmpev, app.data.srate);                        
                        txt = ['Spctrogram. ', txt];                    
                    else
                        txt = 'Spctrogram event detected but it already exists.';
                    end
                    
                else
                    if ~isempty( result)
                        txt = sprintf( ['No spctrogram event detected.',...
                               'Duration: %.0f ms; ',...
                               'No cycles: %.1f; ',...
                               'Max z: %.1f.'],...
                               result.dur, result.NoCycles, result.PeakZScore);
                    else
                        txt = 'No event found.';
                    end
                end
                
                if ~isempty( result)
                    freq_widx = result.win_ind;
                    fw_loc = result.cell_ind;
                    
                    % peak_freq = [num2str( result.peak_freq), ' Hz'];
                    peak_freq = sprintf( '%.1f Hz', result.peak_freq);
                    set( app.FreqWin{ freq_widx}.TFAutoPeakFreqLabel,...
                        'Text', peak_freq);
                    
                    % nb_cycles = num2str( round( 10 * result.nb_cycle) / 10);
                    set( app.FreqWin{ freq_widx}.TFAutoNoCycleLabel,...
                        'Text', sprintf( '%.1f', result.nb_cycle));
                    
                    cla( app.FreqWin{ freq_widx}.UIAxesAvg);
                    
                    plot( app.FreqWin{ freq_widx}.UIAxesAvg,...
                        app.data.tf.param{ fw_loc}.freq,...
                        result.avg_s,...
                        'linewidth', 1,...
                        'Color', [143, 30, 6]/255);
                    
                    set( app.FreqWin{ freq_widx}.UIAxesAvg,...
                        'xlim', app.data.tf.param{ fw_loc}.freq( [1, end]),...
                        'xtick', app.FreqWin{ freq_widx}.UIAxes.YTick,...
                        'fontsize', 10);
                end
                
            end % spectrogram auto detect
            
            PUpdateStatus( app, 'HFO detection completed.', app.uidata.msg_fc);
            drawnow;            
        end % function run event detection
      
                
        %% Menu callbacks
        function POpenFileMenuSelected( app, ~, parent_fig)
            if app.uidata.debug
                fprintf( 'POpenFileMenuSelected called.\n');
            end
            
            initial_uidata = 'yes';
            
            % Close current file
            if ~isempty( app.data)
                CloseMenuSelected( app, []);
                if strcmpi( app.uidata.previous_file_closed, 'no')
                    % Ask for confirmation to close curren file
                    return;
                end
                initial_uidata = 'no';
            end
                        
            app.StatusLabel.Visible = 'on';
            PUpdateStatus( app, 'Loading data ...', 'k');           
            [data_mat, status] = HFOLoadData();
            
            % data_mat must be a structure contain the following fields:
            %               mat,  channel x samples matrix
            %             srate,  scalar sampling rate in Hz
            %            labels,  Nx1 cell array of channel labels
            %       start_clock,  clock time of start, Matlab datetime, 'yyyy-MM-dd HH:mm:ss'
            %              file,  full-path-to-file 
            %              time,  vector of time (optional)
            if ~isstruct( data_mat)
                status = 0;                
            else
                valid_fields = {'mat', 'srate', 'labels', 'start_clock', 'file', 'time'};
                fnames = fieldnames( data_mat);
                is_exist = ismember( valid_fields( 1:4), fnames);
                if ~all( is_exist)      
                    status = 0;
                    
                else                    
                    % .mat is a matrix or channel x sample x trial
                    srate = data_mat.srate;
                    is_mat = ismatrix( data_mat.mat) || ndims( data_mat.mat) == 3;
                    ok_srate = isscalar( srate) && srate > 0;
                    tmp_label = data_mat.labels;
                    ok_label = iscell( tmp_label) && isvector( tmp_label) &&...
                        size( data_mat.mat, 1) == length( tmp_label);
                    ok_clock = isdatetime( data_mat.start_clock);
                    
                    if ~is_mat || ~ok_srate || ~ok_label || ~ok_clock
                        status = 0;
                    end
                end
            end
            
            
            if status == 0
                PUpdateStatus( app, 'Failed to load data.', 'r');
                return;
            else
                txt = [data_mat.file, 'was loaded successfully.'];
                PUpdateStatus( app, txt, 'k');
            end
            
            % initialize data
            if strcmpi( initial_uidata, 'yes')
                InitializeUidata( app);
            end

            app.uidata.previous_file_closed = 'no';
            
            % mat is a structure containing the fields: mat, labels,
            % srate, file, start_clock
            app.data = [];
            app.data = data_mat;
            clear data_mat;
            
            % length of data
            [~, nbsamples, nbtrls] = size( app.data.mat);
           
            %% Trial data, app.data.mat is a 3d matrix
            if nbtrls > 1
                app.data.trldata = app.data.mat;
                app.data.mat = app.data.mat( :, :, 1);
                app.uidata.trial_index = 1; 
            else
                app.data.trldata = [];
                app.uidata.trial_index = [];
            end
            
            % add more fields
            app.data.raw = []; % for montage 
            
            app.data.nbsamples = nbsamples;
            mat_len_s = (nbsamples - 1) / srate;
            app.data.end_clock = app.data.start_clock + seconds( mat_len_s);
            app.data.results_saved = 'no';
            % text file to save all events
            app.data.savefile = '';
            % text file to save a summary of all events
            app.data.statsfile = '';            
            
            
            t = [];
            if isfield( app.data, 'time')
                t = app.data.time;
                if ~isvector( t) || ~issorted( t) || length( t) ~= nbsamples
                    t = [];
                end
            end
            
            
            if isempty( t)
                app.data.time = linspace( 0, (nbsamples - 1) / double( app.data.srate), nbsamples);
                clear t;
            end
            
            app.data.raw_limit = [min( app.data.mat( 1, :)),  max( app.data.mat( 1, :))];
            app.data.raw_limit = [-1, 1] * max( abs( app.data.raw_limit));
            app.data.limit = app.data.raw_limit;            
            
            % Do not calcualte spectrogram for entire time series by default
            % if data is longer than 3 minutes
            % if mat_len_s > 181
            %    app.uidata.tf_entire = 'no';
            % end
            
            % Band-pass filtered data to be passed to other windows
            app.data.bpfilt.mat = {};
            % amplitude time series from Hilbert transform
            app.data.bpfilt.amp = {};
            % z score normalized using whole time series
            app.data.bpfilt.z = {};
            
            % parameter is a N x 3 cell array,
            % The first cell is a structure with fields:
            % 1)bpfreq, [lo, hi], frequency in Hz
            % 2)bptype, 'fir' | 'iir', filter type
            % 3)notch, 'no' | 'yes', notch filter
            % 4)notch_freq, [], notch frequency
            % 5)notch_type, 'fir' | 'iir', notch filter type
            % 6)name, window name
            % Second cell is 1|0 to indinate whether a new window is needed
            % Third cell is rgb color[r, g, b];
            app.data.bpfilt.param = {}; % parameter for band-pass filtering
            app.data.bpfilt.labels = {};
            app.data.bpfilt.update_plot = 'no';
            
            % Spectrograms
            app.data.tf.mat = {};
            % param is a cell array of sctures contains fields:
            %   freq, frequency vector
            %   xrange, time in continuous samples, [lo hi]
            %   Name, channel label ' '
            %   climit, colorbar limits [lo, hi]
            app.data.tf.param = {};
            app.data.tf.labels = {};
            
            
            % structure array containing the fields:
            %   label
            %   info
            % .label = 'channel_name';
            % .info{ 1, 1} = [start_loc, end_loc];
            % .info{ 1, 2} = {'gamma', 'spike'};
            % .info{ 1, 3} = data structure contains fields: AvgFreq, NoCycles, BPFreq, Location
            app.data.events = [];
            
            % multple trials, cell array of structure array of app.data.events
            app.data.trlevents = {};
            
            % event label to disaply in the listbox
            app.data.disp_events = [];            
            
            % Initialize GUI
            app.AllChannelListBox.Items = app.data.labels;
            
            lbl = app.data.labels( 1);
            app.ChannelListBox.Items = lbl;
            app.ChannelListBox.Value = lbl;
            app.EventListBox.Items = {};
            app.TabGroup.SelectedTab = app.ChannelTab;
            
            % starting point
            app.uidata.xrange = [1, nbsamples];
            app.uidata.current_channel = {lbl{ 1}, 0};
            
            ChangeClockType( app);
                                    
            % change default frequency ranges
            if any( app.uidata.tf_foi_range >= srate/2)
                if srate > 500
                    app.uidata.tf_foi_range = [50, 250];
                else
                    app.uidata.tf_foi_range = [srate/6, srate/3];
                end
            end
            
            % Other file specific uidata setting
            
                
            % Make all objects visible
            ChangeVisibility( app, 'on');
            if ~isempty( app.data.trldata)
                app.TrialLabel.Visible = 'on';
                app.PreTrialButton.Visible = 'on';
                app.NextTrialButton.Visible = 'on';
                app.CurTrialEditField.Visible = 'on';
                app.TotalTrialLabel.Visible = 'on';                
                set( app.CurTrialEditField, 'Visible', 'on',...
                    'Value', 1);
                
                set( app.TotalTrialLabel, 'Visible', 'on',...
                    'Text', ['/ ', num2str( nbtrls)]); 
                
                % app.MontageMenu.Enable = 'off';
                
            else
                app.TrialLabel.Visible = 'off';
                app.PreTrialButton.Visible = 'off';
                app.NextTrialButton.Visible = 'off';
                app.CurTrialEditField.Visible = 'off';
                app.TotalTrialLabel.Visible = 'off';                
                %  app.MontageMenu.Enable = 'on';
            end
            
            Redraw( app);            
            
            % Auto saving
            answer = uiconfirm( parent_fig,...
                'Enable auto-saving?',...
                'Auto-save',...
                'Options', {'Yes', 'No'});
            
            if strcmpi( answer, 'yes')
                SaveasMenuSelected( app, []);
            end
            
        end % Open file menu
        
        
        function PCloseFileMenuSelected( app, event, parent_fig) %#ok<INUSL> 
            if app.uidata.debug
                fprintf( 'PCloseFileMenuSelected called.\n');
            end
                        
            answer = 'ok';            
            
            % Ask for confirmation
            if ~isempty( app.data)
                ev_detected = ~isempty( app.data.events) || ~isempty( app.data.trlevents);            
                if ev_detected && ~strcmpi( app.data.results_saved, 'yes')
                    answer = uiconfirm( parent_fig,...
                        'Close without saving results?',...
                        'Warning', ...
                        'Icon',...
                        'warning');
                end
            end
                
            if ~strcmpi( answer, 'ok')
                PUpdateStatus( app, 'Failed to close file.', 'r');
                app.uidata.previous_file_closed = 'no';
                return;
            end
            
            app.uidata.previous_file_closed = 'yes';
                        
            % clear data structure
            if ~isempty( app.uidata)
                fnames = fieldnames( app.uidata);
                for k = 1 : length( fnames)
                    h = app.uidata.( fnames{k});
                    for obj_idx = 1 : length( h)
                        if isobject( h( obj_idx)) && isvalid( h( obj_idx))
                            delete( h( obj_idx));
                        end
                    end
                end
            end
            
            DeleteAllWins( app);
            
            app.data = [];
            app.uidata.current_channel = {};
            app.uidata.cur_event = [];            
            app.uidata.xrange = [];            
            app.uidata.data_range = [];       
            app.uidata.start_loc = 1;           
            app.uidata.cursor_loc = [];            
            
            app.StartClockEditField.Value = '';
            app.EndClockHEditField.Value = '';
            app.ChannelListBox.Items = {};
            app.EventListBox.Items = {};
            app.JumpToClockEditField.Value = '';
            app.AllChannelListBox.Items = {};
            
            ChangeVisibility( app, 'off');
            Redraw( app);            
        end % close file menu
        
        
        %% Quit program
        function PQuitAppMenuSelected( app, event, parent_fig)           %#ok<INUSL> 
            if app.uidata.debug
                fprintf( 'PQuitAppMenuSelected called.\n');
            end
            
            answer = 'ok';

            if ~isempty( app.data)
                ev_detected = ~isempty( app.data.events) || ~isempty( app.data.trlevents);
                results_saved = strcmpi( app.data.results_saved, 'yes');
                
                if ev_detected && ~results_saved
                    answer = uiconfirm( ...
                        parent_fig,...
                        'Quit without saving results?',...
                        'Warning',...
                        'Icon',...
                        'warning');
                end
            end
            
            if strcmpi( answer, 'ok')
                DeleteAllWins( app);
                delete( app);
            end % close windows
            
        end % quit program
        
        
        %% Specify a file to save the results
        function PSaveasMenuSelected( app, event) %#ok<INUSD> 
            if app.uidata.debug
                fprintf( 'PSaveasMenuSelected called.\n');
            end
            
            if isempty( app.data)
                PUpdateStatus( app, 'No data loaded. Nothing to save', 'r');
                return;
            end
            
            [~, fname_noext] = fileparts( app.data.file);
            fname_noext = [app.uidata.savename_prefix, fname_noext];            
            [filename, path] = uiputfile( {'.txt'}, 'Save results', [fname_noext, '.txt']);
            
            if isequal( filename, 0) || isequal( path, 0)                
                PUpdateStatus( app, 'Result saving was cancelled.', 'r');
                drawnow;
                
            else
                app.data.savefile = fullfile( path, filename);
                
                txt = sprintf( 'Saving events as %s', app.data.savefile);
                PUpdateStatus( app, txt, app.uidata.msg_fc);
                drawnow;               
                
                [~, filename, ext] = fileparts( app.data.savefile);       
                stats_file = fullfile( path, [filename, '_summary', ext]);
                s = GetClockSurfix( app);
                if exist( stats_file, 'file')
                   stats_file = fullfile( path, [filename, '_summary_', s, ext]);
                end
                app.data.statsfile = stats_file;
                
                SaveResults( app);
                
                txt = sprintf( 'Events were saved as %s', app.data.savefile);
                PUpdateStatus( app, txt, app.uidata.msg_fc);
                drawnow;    
            end
        end % save as funciton
        
        
        function PSaveMenuSelected( app, event)
            if isempty( app.data.savefile)
                PSaveasMenuSelected( app, event);
            else
                SaveResults( app);
            end
        end
        
        
        function PExportasMatlabFileMenuSelected( app, event) %#ok<INUSD> 
            ExportasMatlabFileMenuSelected( app, []);
        end
        
        
        function PLoadeEventMenuSelected( app, event, parent_fig)            %#ok<INUSL> 
            if app.uidata.debug
                fprintf( 'PLoadeEventMenuSelected called.\n');
            end
            
            % update app.data.events
            if isempty( app.data)
                return;
            end
               
            
            % Overwrite current event warning
            answer = 'ok';
            if ~isempty( app.data.events) && strcmpi( app.data.results_saved, 'no')
                answer = uiconfirm( parent_fig, ...
                    'Overwrite current events?',...
                    'Warning',...
                    'Icon', 'warning');
            end
            
            if ~strcmpi( answer, 'ok')
                PUpdateStatus( app, 'Event loading canceled.', app.uidata.msg_fc);
                return;
            end
            
            % Load events
            txt = 'Please select an event file (not the summary file).';
            [evfile, path] = uigetfile( {'*.txt'; '*.mat'}, txt);
            srcfile = fullfile( path, evfile);
                                    
            try
                PUpdateStatus( app, 'Loading events ...', 'k');
                [~, ~, ext] = fileparts( srcfile);
                
                evs = [];
                if strcmpi( ext, '.txt')
                    fid = fopen( srcfile);
                    if fid == 0
                        txt = ['Failed to open ', srcfile];                        
                        PUpdateStatus( app, txt, 'r');
                        return;
                    end
                    
                    % Check the first and second line to
                    % 1) make sure it's a file created by this app
                    % 2) get the number of header lines
                    tline = fgetl( fid);
                    if ~ischar( tline) || ~contains( tline, app.uidata.app_name)
                        txt = sprintf( '%s was not a file created by %s.',...
                            srcfile , app.uidata.app_name);
                        PUpdateStatus( app, txt, app.uidata.msg_fc);
                        return;
                    end
                    
                    % number of lines to skip
                    tline = fgetl( fid);
                    txt = split( tline, ':');
                    N = str2double( txt{ end});
                    txt = split( fgetl( fid), ':');
                    nbtrls = str2num( txt{ end}); %#ok<ST2NM> 
                    fclose( fid);
                    
                    txt = 'Incorrect number of trials in the event file. Event loading cancelled.';
                    if isempty( app.data.trldata)
                        if abs( nbtrls - 1) > eps                            
                            PUpdateStatus( app, txt, 'r');
                            return;
                        end
                        
                    else
                        if nbtrls > size( app.data.trldata, 3)
                            PUpdateStatus( app, txt, 'r');
                            return;
                        end
                    end
                    
                    % load with readtable
                    s = readtable( srcfile, 'headerlines', N, 'delimiter', ',');
                    if isempty( s)
                        return;
                    end
                    
                    all_trl_index = s.Var1;
                    label_in_file = s.Var2;
                    
                    trl_ind = unique( all_trl_index, 'stable');
                    
                    evs = cell( length( trl_ind), 1);
                    for trl_idx = 1 : length( trl_ind)
                        trl_loc = all_trl_index == trl_ind( trl_idx);
                        
                        cur_label = label_in_file( trl_loc);
                        label = unique( cur_label, 'stable');
                        
                        cur_ev_loc = s.Var3( trl_loc);
                        cur_ev_type = s.Var4( trl_loc);
                        cur_avgfreq = s.Var5( trl_loc);
                        cur_avg_peakz = s.Var6( trl_loc);
                        cur_no_cycles = s.Var7( trl_loc);
                        cur_bpfreq = s.Var8( trl_loc);
                        
                        nb_labels = length( label);
                        trl_evs = struct( 'label', cell( nb_labels, 1),...
                            'info', cell( nb_labels, 1));
                        
                        for chnl_idx = 1 : length( label)
                            trl_evs( chnl_idx).label = label{ chnl_idx};
                            loc = find( ismember( cur_label, label{ chnl_idx}));
                            for ev_idx = 1 : length( loc)
                                ind = loc( ev_idx);
                                trl_evs( chnl_idx).info{ ev_idx, 1} = round( str2num( cur_ev_loc{ ind})); %#ok<ST2NM> 
                                tmp_ev_type = split( cur_ev_type{ ind}, '; ');
                                trl_evs( chnl_idx).info{ ev_idx, 2} = cellfun( @(x) strtrim(x), tmp_ev_type, 'UniformOutput', false);
                                
                                % Channel, EventLocation(sample), EventType, AverageFreq(Hz), PeakZscore, NoCycles, BPFreq\n');
                                trl_evs( chnl_idx).info{ ev_idx, 3}.AvgFreq = cur_avgfreq( ind);
                                trl_evs( chnl_idx).info{ ev_idx, 3}.PeakZScore = cur_avg_peakz( ind);
                                trl_evs( chnl_idx).info{ ev_idx, 3}.NoCycles =  cur_no_cycles( ind);
                                trl_evs( chnl_idx).info{ ev_idx, 3}.Location = trl_evs( chnl_idx).info{ ev_idx, 1};
                                trl_evs( chnl_idx).info{ ev_idx, 3}.BPFreq = str2num( cur_bpfreq{ ind}); %#ok<ST2NM> 
                            end
                        end
                        
                        [trl_evs, msg] = ValidateEvents( app, trl_evs,...
                            app.data.labels, app.uidata.all_event_types, app.uidata.xrange);
                        
                        evs{ trl_ind( trl_idx), 1} = trl_evs;
                    end % trial loop
                    
                    
                elseif strcmpi( ext, '.mat')
                    mat = load( srcfile);
                    fnames = fieldnames( mat);
                    
                    if ~all( ismember( fnames, {'evs', 'nbtrls'}))
                        msg = 'Invalid event format';
                        nbtrls = 1;
                        evs = [];
                        
                    else
                        nbtrls = mat.nbtrls;
                        txt = 'Incorrect number of trials in the event file. Event loading cancelled.';
                        if isempty( app.data.trldata)
                            if abs( nbtrls - 1) > eps
                                PUpdateStatus( app, txt, 'r');
                                return;
                            end
                            
                        else
                            if nbtrls > size( app.data.trldata, 3)
                                PUpdateStatus( app, txt, 'r');
                                return;
                            end
                        end
                        
                        if isempty( mat.evs)
                             txt = 'Empty event, nothing was loaded.';
                            PUpdateStatus( app, txt, 'r');
                            return;
                        end
                        
                        all_ev_types = app.uidata.all_event_types;
                        xrange = app.uidata.xrange;
                        if nbtrls == 1
                            [tmp_evs, msg] = ValidateEvents( app, mat.evs,...
                                app.data.labels, all_ev_types, xrange);
                            evs{ 1} = tmp_evs;
                            
                        else
                            evs = cell( length( mat.evs), 1);
                            for trl_idx = 1 : length( mat.evs)
                                [tmp_evs, msg] = ValidateEvents( app, mat.evs{ trl_idx},...
                                    app.data.labels, all_ev_types, xrange);
                                evs{ trl_idx, 1} = tmp_evs;
                            end
                        end
                    end
                    
                    
                else
                    msg = 'Unsupported event format.';
                    PUpdateStatus( app, msg, 'r');
                    return;
                end % event file type
                
                
                if nbtrls == 1
                    app.data.events = evs{ 1};
                    
                else
                    app.data.trlevents = evs;
                    if app.uidata.trial_index <= length( evs)
                        app.data.events = evs{ app.uidata.trial_index};
                    else
                        app.data.events = [];
                    end
                end
                
                app.TabGroup.SelectedTab = app.EventsTab;
                PUpdateEventList( app);
                Redraw( app);
                
                if ~isempty( app.data.savefile)
                    msg = [msg, ' Go to Event -> Save to save loaded events to file.'];
                    PUpdateStatus( app, msg, app.uidata.msg_fc);
                else
                    PUpdateStatus( app, msg, app.uidata.msg_fc);
                end
                
            catch
                app.data.events = [];
                Redraw( app);
                
                txt = 'Failed to load events for unknown reasons.';
                PUpdateStatus( app, txt, 'r')
            end
            
        end % load event function
        
        
        %% Organized bandpass filtered windows
        function POrganizeWindowsMenuSelected( app, event) %#ok<INUSD> 
            h = findall( 0, 'type', 'figure', 'name', 'Organize Filter Windows');
            if ~isempty( h) && isvalid( h)
                close( h);
            end
                        
            waitfor( ConfigWindows( app));
        end
        
        
        function PHelpMenuSelected( app, event) %#ok<INUSD> 
            HelpMenuSelected( app);
        end
        
        
        %% Setup parameters for spectrogram
        function PSpectrogramMenu(app, event) %#ok<INUSD> 
            h = findall( 0, 'type', 'figure', 'name', 'Spectrogram Configuration');
            if ~isempty( h) && isvalid( h)
                close( h);
            end            
            
            waitfor( ConfigSpectrogram( app));
        end
        
        
        %% Misc options. Line color etc.
        function PWinOptionsMenuSelected( app, event) %#ok<INUSD> 
            if ~isempty( app.HMiscOption) && isvalid( app.HMiscOption)
                % window already exist, do nothing    
                figure( app.HMiscOption.OptionsUIFigure);
            else
                app.HMiscOption = ConfigOptions( app);
            end 
        end
        
        
        %% Montage setup
        function PChangeMontage( app, event) %#ok<INUSD> 
            h = findall( 0, 'type', 'figure', 'name', 'Montage');
            if ~isempty( h) && isvalid( h)
                close( h);
            end            
                        
            waitfor( ConfigMontage( app));
            
            % Disable "Clear" data button if montage has changed
            if ~isempty( app.data.raw)
                app.ClearButton.Enable = 'off';
                
                SyncFreqWin( app);
                SyncTimeWin( app);
            end
        end
        
        
        function PChannelListBoxValueChanged( app, value)
            app.ChannelListBox.Value = value;
            ChannelListBoxValueChanged( app);
        end
        
        
        %% Close all windows except the main window
        function DeleteAllWins( app, varargin)
            if app.uidata.debug
                fprintf( 'DeleteAllWins called.\n');
            end
            
            % A list of names of pop-up windows
            fig_nam = {'HFOApp_EventDetect',...
                'HFO Auto Detectors',...
                'Filter Setup',...
                'Organize Filter Windows',...
                'Spectrogram Configuration',...
                'Montage'}; 
            
            % Do not close varargin{1}
            if nargin > 1
                win_name = varargin{1};
            else
                win_name = '';
            end
            
            % Band-pass filter window
            for k = 1 : length( app.TimeWin)
                if isvalid( app.TimeWin{ k})
                    delete( app.TimeWin{ k});
                end
            end
            
            app.TimeWin = {};
            
            % Spectrogram window
            for k = 1 : length( app.FreqWin)
                if isvalid( app.FreqWin{ k})
                    delete( app.FreqWin{ k});
                end
            end
                       
            app.FreqWin = {};
            
            % Bandpass filter window amplitude scale
            if ~isempty( app.HFloatYScale) && isvalid( app.HFloatYScale)
                delete( app.HFloatYScale);
            end
            
            app.HFloatYScale = [];
            
            % Preference window
            if ~isempty( app.HMiscOption) && isvalid( app.HMiscOption)
                delete( app.HMiscOption);
            end
            
            app.HMiscOption = [];                      
            
            for k = 1 : length( fig_nam)
                fig_h = findall( 0, 'Type', 'figure', 'Name', fig_nam{ k});                
                if ~isempty( fig_h) && isvalid( fig_h) && ~strcmpi( win_name, fig_nam{k})
                    for x = 1 : length( fig_h)
                        delete( fig_h( x));
                    end
                end
            end
            
        end % function DeleteAllWins
        
        
        function PFiltYScaleButtonPushed( app)
            if isempty( app.data) || isempty( app.data.bpfilt.mat)
                return;
            end
            
            if ~isempty( app.HFloatYScale) && isvalid( app.HFloatYScale)
                delete( app.HFloatYScale);
            end
            
            app.HFloatYScale = ConfigYScale(app);
        end
        
        
        %% When the size of the main window changes
        function PResizeWindows( app, pos, win_type)
            % win_type, 'time' | 'freq' | 'main'
            % minimal main and time window size: 700 x 600
            % minimal freq window size 900 x 600   
                 
            try
                fontsize = app.uidata.fontsize;
            catch
                fontsize = 12;
            end            
            
            if strcmpi( win_type, 'main') || strcmpi( win_type, 'time')
                w = max( [700, pos(3)]);                
            else
                w = max( [700, pos(3) - 200]);
            end
            
            h = max( [600, pos(4)]);
            
            % Update figure width and height            
            app.HFOAppUIFigure.Position( [3, 4]) = [w, h];
            
            % The width should be related to the fontsize
            fontsize = min( [fontsize, 24]);
            
            if fontsize <= 12
                w1 = 50;                
                % component height, this should be related to the fontsize
                ch = 22;
                
            else
                ch = 22 * fontsize / 12;
                ch = min( [ch, 40]);
                w1 = 25 * fontsize / 6;
                w1 = min( [w1, w*50/700]);
            end
            
            ch = 22;
            w1 = 50;
            
            % Status bar position
            app.StatusLabel.Position = [3, 0, w-20, ch];
            
            % Time window, scale etc.
            % Bottom position
            b = h - ch - 1;            
                                   
            app.TimesLabel.Position = [3,  b, w1, ch];
            app.TimesLabel.HorizontalAlignment = 'Right';
            app.XScaleSpinner.Position = [w1 + 3, b, w1, ch];
            
            app.AmplitudeLabel.Position = [2*w1 + 13, b, 1.6*w1, ch];
            app.AmplitudeLabel.HorizontalAlignment = 'Right';            
            app.YScaleSpinner.Position = [3.6*w1 + 13, b, 1.2*w1, ch];            
            app.FiltYScaleButton.Position = [4.8*w1 + 13, b, 1.6*w1, ch];            
            
            app.StartClockLabel.Position = [6.4*w1 + 28, b, 1.2*w1, ch];
            app.StartClockLabel.HorizontalAlignment = 'right';
            app.StartClockEditField.Position = [7.6*w1 + 28, b, 1.6*w1, ch];
            app.EndClockLabel.Position = [9.2*w1 + 28, b, 1.2*w1, ch];
            app.EndClockLabel.HorizontalAlignment = 'right';
            app.EndClockHEditField.Position = [10.4*w1 + 32, b, 1.6*w1, ch];
            app.ResetTimeButton.Position = [12*w1 + 35, b, w1-2, ch];
                        
            tab_width = 230;
            add_evbtn_w = 62;
             
            % tab_width = max( [230, 4*w1 + 30]);
            % add_evbtn_w = 1.2*w1 + 2;
            
            ax_width = w - tab_width - 3;            
            b = h - 2*( ch + 1);
            
            app.DurationLabel.Position = [10, b, ax_width-10, ch];
            
            app.ManualEventAutoAdjCheckBox.Position = ...
                [ax_width + add_evbtn_w + 1,...
                b,...
                tab_width - add_evbtn_w - 1,...
                ch];
            
            b = h - 3*( ch + 1);
            x = ax_width + 1;
            app.AddEventButton.Position = [x, b, add_evbtn_w, ch];
            app.ManualModeButton.Position = [x + add_evbtn_w + 1, b, 1.2*w1, ch];
            app.StartEventButton.Position = [x + add_evbtn_w + 2 + 1.2*w1, b, w1, ch];
            app.EndEventButton.Position = [x + add_evbtn_w + 3 + 2.2*w1, b, 50, ch];
            
            tab_height = h - 30 - 6.5*ch;
            app.TabGroup.Position = [x, 4*ch, tab_width, tab_height];
            
            app.DisplayLabel.Position = [5, tab_height - 2*ch, 1.6*w1, ch];
            app.ChannelListBox.Position = [5, 3.5*ch, 1.6*w1, tab_height - 5.5*ch];
            app.RemoveChannelButton.Position = [5, 2 * ch, 1.6*w1, ch];
            app.LockCurrentChannelCheckBox.Position = [5, 0.5*ch, 2*w1, ch];
            
            % Channel tab
            app.AllLabel.Position = [tab_width - 1.6*w1 - 5, tab_height-2*ch, 1.6*w1, ch];
            app.AllChannelListBox.Position = [tab_width - 1.6*w1 - 5, 3.5*ch, 1.6*w1, tab_height - 5.5*ch];
            app.AddButton.Position = [tab_width - 1.6*w1 - 5, 2 * ch, 1.6*w1, ch];
            app.ClearButton.Position = [tab_width - 1.6*w1 - 5, 0.5 * ch, 1.6*w1, ch];
                
            % Event tab
            app.EventListBox.Position = [5, 270, tab_width - 10, tab_height - 270 - ch - 8];
            app.TypePanel.Position = [5, 76, 2*w1 + 10, 192];
            app.EventTypetoDisplayButtonGroup.Position = [3, 2, 2*w1 + 10, 71];
            app.EventChanneltoDisplayButtonGroup.Position = [2*w1 + 15, 2, 2*w1 + 13, 71];
            app.UpdateEventButton.Position = [2*w1 + 20,  270 - ch - 5, 1.8*w1, ch];
            app.RemoveEventButton.Position = [2*w1 + 20, 270 - 2*ch - 10, 1.8*w1, ch];
            app.MergeEventButton.Position = [2*w1 + 20, 270 - 3*ch - 15, 1.8*w1, ch];
            
            % Settings tab
            app.EventDetectionOptionsPanel.Position = [5, tab_height - 1.5*ch - 100, tab_width - 10, 100];
            app.BackgroundTimeWindowButtonGroup.Position = [5, tab_height - 2.5*ch - 100 - 119, tab_width - 60, 100];
            app.BKSpecifiedEF.Position = [tab_width - 54, tab_height - 2.5*ch - 100 - 119, 50, ch];                         
            app.MultipleDetectionCheckBox.Position = [5, 10, 220, ch];                        
            c = 3.5*ch + (tab_height - 5.5*ch)/2;
            m = tab_width/2 - 25/2;
            app.AddAllChannel.Position = [m, c+2.5*ch, 25, ch];
            app.AddChannel.Position = [m, c+1.4*ch, 25, ch];
            app.RemoveChannel.Position = [m, c - 1.4*ch, 25, ch];
            app.RemoveAllChannel.Position = [m, c-2.5*ch, 25, ch];
            
            % Navigation bar
            b = 25;
            app.BackButton.Position = [50, b, 15, ch];
            app.StepBackButton.Position = [25, b, 25, ch];
            app.LLabel.Position( [1, 2]) = [66, b];
            bar_len = w - tab_width - 25*5;
            pp = (app.MLabel.Position(1) - app.LLabel.Position(1)) / ( app.RLabel.Position(1) - app.LLabel.Position(1));
            
            v = version;
            if contains( v, 'R2019')
                app.TLabel.Position = [66, b + ch-2, bar_len, 6];
                app.ToiTLabel.Position = [66, b + ch - 2, bar_len, 6];
                app.BLabel.Position = [66, b-2, bar_len, 6];
                app.ToiBLabel.Position = [66, b-2, bar_len, 6];
            else
                app.TLabel.Position( [1, 2, 3]) = [66, b + ch-1, bar_len];
                app.ToiTLabel.Position( [1, 2, 3]) = [66, b + ch - 2, bar_len];
                app.BLabel.Position( [1, 2, 3]) = [66, b, bar_len];
                app.ToiBLabel.Position( [1, 2, 3]) = [66, b, bar_len];
            end            
            
            app.RLabel.Position( [1, 2]) = [ 66 + bar_len - 1, b];
            app.MLabel.Position( 1) = pp * ( app.RLabel.Position(1) - app.LLabel.Position(1)) + app.LLabel.Position(1);
            app.MLabel.Position( 2) = b;
            
            
            xx = x + 30;
            bb = 2.6*ch;
            app.TrialLabel.Position = [xx, bb, 30, ch];
            app.PreTrialButton.Position = [xx + 30, bb, 20, ch];
            app.NextTrialButton.Position = [xx + 50, bb, 20, ch];
            app.CurTrialEditField.Position = [xx + 73, bb, 38, ch];
            app.TotalTrialLabel.Position = [xx + 112, bb, 90, ch];            
            
            lpos = 66 + bar_len + 1;
            app.ForwardButton.Position = [lpos, b, 15, ch];
            app.StepForwardButton.Position = [lpos+15, b, 25, ch];
            app.JumptoLabel.Position = [lpos+50, b, 80, ch];
            app.JumptoLabel.HorizontalAlignment = 'right';
            app.JumpToClockEditField.Position = [lpos + 130, b, 80, ch];
            
            app.UIAxes.Position = [1, 3*ch + 5, ax_width, h - (ch + 2)*5];            
            
            objs = {'StatusLabel',...
                'TimesLabel',...
                'XScaleSpinner',...
                'AmplitudeLabel',...
                'YScaleSpinner',...
                'FiltYScaleButton',...
                'StartClockLabel',...
                'StartClockEditField',...
                'EndClockLabel',...
                'EndClockHEditField',...
                'ResetTimeButton',...
                'DurationLabel',...
                'ManualEventAutoAdjCheckBox',...
                'AddEventButton',...
                'ManualModeButton',...
                'StartEventButton',...
                'EndEventButton',...
                'TabGroup',...
                'DisplayLabel',...
                'ChannelListBox',...
                'RemoveChannelButton',...
                'LockCurrentChannelCheckBox',...
                'AllLabel',...
                'AllChannelListBox',...
                'AddButton',...
                'ClearButton',...
                'EventListBox',...
                'TypePanel',...
                'EventTypetoDisplayButtonGroup',...
                'EventChanneltoDisplayButtonGroup',...
                'UpdateEventButton',...
                'RemoveEventButton',...
                'MergeEventButton',...
                'EventDetectionOptionsPanel',...
                'BackgroundTimeWindowButtonGroup',...
                'BKSpecifiedEF',...
                'MultipleDetectionCheckBox',...
                'AddAllChannel',...
                'AddChannel',...
                'RemoveChannel',...
                'RemoveAllChannel',...
                'BackButton',...
                'StepBackButton',...
                'ForwardButton',...
                'StepForwardButton',...
                'JumptoLabel',...
                'JumptoLabel',...
                'JumpToClockEditField',...
                'UIAxes',...
                'MLabel',...
                'LLabel',...
                'RLabel',...
                'TLabel',...
                'BLabel',...
                'ToiTLabel',...
                'ToiBLabel',...
                'TrialLabel',...
                'PreTrialButton',...
                'NextTrialButton',...
                'CurTrialEditField',...
                'TotalTrialLabel' };
                        
            align_objs = {'TimesLabel',...
                'EndClockLabel',...
                'JumptoLabel',...
                'StartClockLabel'};
            
            for obj_idx = 1 : length( objs)
                obj_name = objs{ obj_idx};
                if isprop( app.( obj_name), 'FontSize')
                    app.( obj_name).FontSize = fontsize;
                end
            end
            
            % Resize bandpass filter windows
            for k = 1 : length( app.TimeWin)
                for obj_idx = 1 : length( objs)
                    if ~isprop( app.TimeWin{k}, objs{ obj_idx})
                        continue;
                    end
                    
                    obj_name = objs{ obj_idx};
                    app.TimeWin{ k}.( obj_name).Position = app.( obj_name).Position;
                    
                    if isprop( app.TimeWin{ k}.( obj_name), 'FontSize')
                        app.TimeWin{ k}.( obj_name).FontSize = fontsize;
                    end
                end
                
                for obj_idx = 1 : length( align_objs)
                    obj_name = align_objs{ obj_idx};
                    app.TimeWin{ k}.( obj_name).HorizontalAlignment = app.( obj_name).HorizontalAlignment;
                end
                
                app.TimeWin{k}.UIFigure.Position( [3, 4]) = [w, h];
            end % resize bandpass filter window
            
            % Resize spectrogram windows
            for k = 1 : length( app.FreqWin)
                for obj_idx = 1 : length( objs)
                    obj_name = objs{ obj_idx};
                    if strcmpi( obj_name, 'FiltYScaleButton')
                        app.FreqWin{ k}.YScaleHiSpinner.Position = app.( obj_name).Position;
                        app.FreqWin{ k}.YScaleHiSpinner.FontSize = fontsize;
                        
                    elseif strcmpi( obj_name, 'YScaleSpinner')
                        app.FreqWin{ k}.YScaleLoSpinner.Position = app.( obj_name).Position;
                        app.FreqWin{ k}.YScaleLoSpinner.FontSize = fontsize;
                        
                    elseif ~isprop( app.FreqWin{k}, objs{ obj_idx})
                        continue;
                        
                    else                        
                        app.FreqWin{ k}.( obj_name).Position = app.( obj_name).Position;
                        if isprop( app.FreqWin{ k}.( obj_name), 'FontSize')
                            app.FreqWin{ k}.( obj_name).FontSize = fontsize;
                        end
                    end
                end
                
                for obj_idx = 1 : length( align_objs)
                    obj_name = align_objs{ obj_idx};
                    app.FreqWin{ k}.( obj_name).HorizontalAlignment = app.( obj_name).HorizontalAlignment;
                end
                
                app.FreqWin{k}.PSDPanel.Position = [w - 2,...
                    app.FreqWin{k}.TabGroup.Position(2),...
                    195,...
                    app.FreqWin{k}.TabGroup.Position(4)];
                
                app.FreqWin{k}.TFAutoDetectCheckBox.Position = [5, tab_height - 2*ch, 186, ch];
                
                app.FreqWin{k}.UIAxesAvg.Position = [1, tab_height - 3*ch - 185, 194, 185];
                
                set( app.FreqWin{k}.PeakfrequencyLabel,...
                    'Position', [5, 5.6*ch, 110, ch], ...
                    'HorizontalAlignment', 'Right',...
                    'FontSize', fontsize);
                app.FreqWin{k}.TFAutoPeakFreqLabel.Position = [117, 5.6*ch, 90, ch];
                
                set( app.FreqWin{k}.NumberofcyclesLabel,...
                    'Position', [5, 4.5*ch, 110, ch], ...
                    'HorizontalAlignment', 'Right', ...
                    'FontSize', fontsize);
                app.FreqWin{k}.TFAutoNoCycleLabel.Position = [117, 4.5*ch, 90, ch];
                
                app.FreqWin{k}.BandwidthHzEditFieldLabel.Position = [5, 3*ch, 90, ch];
                app.FreqWin{k}.BandwidthHzEditFieldLabel.HorizontalAlignment = 'Right';
                app.FreqWin{k}.BandwidthHzEditFieldLabel.FontSize = fontsize;
                app.FreqWin{k}.TFAutoBandwidthEditField.Position = [96, 3*ch, 90, ch];
                app.FreqWin{k}.TFAutoBandwidthEditField.FontSize = fontsize;
                
                app.FreqWin{k}.TFAutoUseAsPercentCheckBox.Position = [5, 1.9*ch, 200, ch];
                app.FreqWin{k}.TFAutoUseAsPercentCheckBox.FontSize = fontsize;
                
                app.FreqWin{k}.TimewidowsEditFieldLabel.Position = [5, 0.8*ch, 90, ch];
                app.FreqWin{k}.TimewidowsEditFieldLabel.HorizontalAlignment = 'Right';
                app.FreqWin{k}.TimewidowsEditFieldLabel.FontSize = fontsize;
                app.FreqWin{k}.TFAutoTimeLenEditField.Position = [96, 0.8*ch, 90, ch];
                app.FreqWin{k}.TFAutoTimeLenEditField.FontSize = fontsize;
                
                app.FreqWin{k}.UIFigure.Position( [3, 4]) = [w+200, h];                
            end  % resize spectrogram window
            
        end % Resize windows
        
        
        function PShiftPlots( app, coord, bd)
            % bd = [sum( app.LLabel.Position( [1, 3])),...
            %    app.RLabel.Position(1),...
            %    sum( app.BLabel.Position( [2, 4])),...
            %    app.TLabel.Position(2)];
            %
            % xloc = app.HFOAppUIFigure.CurrentPoint( 1, 1);
            % yloc = app.HFOAppUIFigure.CurrentPoint( 1, 2);
            
            if app.uidata.debug
                fprintf( 'PShiftPlots called.\n');
            end
                     
            click_within = PPointInRange( app, coord(1), bd(1:2), coord(2), bd(3:4));
            if ~click_within
                return;
            end
            
            w = diff( bd( [1, 2]));
            x_sample_loc =  round( 1 + (app.data.nbsamples - 1) * (coord(1) - bd(1)) / w );
            x_sample_loc = max( [1, x_sample_loc]);
            win_len = floor( app.data.srate * app.uidata.win_len);
            
            if x_sample_loc <= app.uidata.start_loc
                app.uidata.start_loc = max( [app.uidata.xrange(1), x_sample_loc]);
            else
                tmp = max( [1, app.uidata.xrange(2) - win_len - 1]);
                app.uidata.start_loc = min( [tmp, x_sample_loc]);
            end
            
            % Change position of app.MLabel in Redraw
            Redraw( app);

        end % move time series
        
        
        %% Windows keypress
        function PWindowsKeyPress( app, event)
            if app.uidata.debug
                fprintf( 'PWindowsKeyPress called.\n');
            end
            
            key = event.Key;
            
            switch lower( key)
                case 'a'
                    AddEventButtonPushed( app);
                    
                case 's'
                    % mark start of cluster
                    StartEventButtonPushed( app);
                    
                case 'e'
                    % mark end of cluster
                    EndEventButtonPushed( app);
                    
                case 'f'
                    StepForwardButtonPushed( app);
                    
                case 'b'
                    BackButtonPushed( app);
                    
                case 'd'
                    PUpdateClustInfo( app, '', app.uidata.msg_fc);
                    PUpdateStatus( app,...
                        'Running auto detection, please wait ...',...
                        app.uidata.msg_fc);
                    
                    drawnow;
                    
                    txt = PGoDetectEvent( app, false, []);
                    Redraw( app);
                    
                    if isempty( app.uidata.cur_event)
                        PUpdateStatus( app, 'Auto detection done.', app.uidata.msg_fc);
                        
                        if app.uidata.autoev_search_len < 2*app.XScaleSpinner.Value && ...
                                ~isempty( app.TimeWin)
                        
                            PUpdateStatus( app, ...
                                ['Auto detection done. ',...
                                'The Search window might be too small. ',...
                                'Go to Options -> Set preference to change.'],...
                                app.uidata.msg_fc);
                        end
                        
                    else
                        PUpdateStatus( app,...
                            ['Auto detection done. Press "Add event" button or ',...
                            ' "a" on keyboard to add to results.'], app.uidata.msg_fc); 
                    end
                    
                    PUpdateClustInfo( app, txt, app.uidata.msg_fc);
                    drawnow;
                    
                case 'w'
                    % Turn on/off Manual marking
                    app.ManualModeButton.Value = ~app.ManualModeButton.Value;
                    ManualModeButtonValueChanged( app, []);
                    
                otherwise
                    % do nothing
                    
            end % switch key
            
        end % key press callback
        
        

        %% Clear selected channels
        function PClearButtonPushed( app, event, parent_fig) %#ok<INUSL> 
            % parent_fig, parent figure handle            
            
            if app.uidata.debug
                fprintf( 'PClearButtonPushed called.\n');
            end
            
            
            chnl2rm = app.AllChannelListBox.Value;
            all_chnls = app.AllChannelListBox.Items;
            
            if isempty( chnl2rm)
                txt = 'No channel to clear.';
                PUpdateStatus( app, txt, app.uidata.msg_fc);
                return;
            end
            
            if isequal( chnl2rm, app.AllChannelListBox.Items)
                txt = 'You can not clear all channels.';
                PUpdateStatus( app, txt, 'r');
                return;
            end
            
            % Ask for confirmation
            txt = 'Are you sure you want to clear selected channels?';
            answer = uiconfirm( parent_fig,...
                txt,...
                'Warning',...
                'Icon', 'warning');
            
            if ~strcmpi( answer, 'ok')
                txt = 'Clear data cancelled.';
                PUpdateStatus( app, txt, app.uidata.msg_fc);
                return;
            end
            
            % set current channel to empty
            app.uidata.current_channel = {};
            
            app.uidata.cur_event = [];
            
            % Remove channels from Display list box
            is_exist = ismember( app.ChannelListBox.Items, chnl2rm);
            if any( is_exist)
                PRemoveChannel( app, app.ChannelListBox.Items( is_exist));
            end
            
            [~, loc2rm] = ismember( chnl2rm, app.data.labels);
            app.data.mat( loc2rm, :) = [];
            app.data.labels( loc2rm) = [];
            
            % trial data
            if ~isempty( app.data.trldata)
                app.data.trldata( loc2rm, :, :) = [];
            end
            
            % Raw data
            if ~isempty( app.data.raw)
                [~, loc2rm] = ismember( chnl2rm, app.data.raw.labels);
                app.data.raw.mat( loc2rm, :) = [];
                app.data.raw.labels( loc2rm) = [];
            end
            
            app.data.bpfilt.mat = {};
            app.data.bpfilt.amp = {};
            app.data.bpfilt.z = {};
            app.data.bpfilt.param = {};
            app.data.bpfilt.labels = {};
            app.data.bpfilt.update_plot = 'no';
            
            app.data.tf.mat = {};
            app.data.tf.param = {};
            app.data.tf.labels = {};
            
            [~, loc] = ismember( chnl2rm, all_chnls);
            all_chnls( loc( loc ~= 0)) = [];
            app.AllChannelListBox.Items = all_chnls;
            app.AllChannelListBox.Value = all_chnls(1);
            
            % clear events
            ev_chnls = arrayfun( @(x) x.label,...
                app.data.events, 'uniformoutput', false);
            
            [is_exist, loc] = ismember( chnl2rm, ev_chnls);
            if any( is_exist)
                loc = loc( is_exist);
                app.data.events( loc) = [];
            end
                        
            % Close all bandpass filter windows and spectrogram windows
            DeleteAllWins( app);
            
            PUpdateEventList( app);
            Redraw( app);
        end % remove channel from loaded data
        
        
        function PPrevTrial(app)
            if isempty( app.data)
                return;
            end
            
            idx = app.uidata.trial_index;
            if idx > 1
                app.uidata.trial_index = idx - 1;
                TrialChanges( app);
            end
        end
        
        function PNextTrial( app)
            % app.uidata.trial_index
            if isempty( app.data)
                return;
            end
            
            nb_trls = size( app.data.trldata, 3);
            idx = app.uidata.trial_index;
            if idx < nb_trls
                app.uidata.trial_index = idx + 1;
                TrialChanges( app);
            end 
        end
    
        %% Change trial
        function PSetTrial( app, value, previous_value)
            value = round( value);
            
            nb_trls = size( app.data.trldata, 3);
            if value >= 1 && value <= nb_trls
                app.uidata.trial_index = value;
                app.CurTrialEditField.Value = value;
                TrialChanges( app);                
            else
                app.CurTrialEditField.Value = previous_value;
                Redraw( app);
            end            
        end
        
        
        function PAutoDetect( app)
            h = findall( 0, 'type', 'figure', 'Name', 'HFO Auto Detectors');
            if ~isempty( h) && isvalid( h)
                delete( h);
            end            
            
            ConfigAutoDetector( app);
        end
        
        
        % save loaded file as .mat
        function PSaveFileMenu( app)
            if isempty( app.data)
                PUpdateStatus( app, 'No data loaded. Nothing to save', 'r');
                return;
            end
            
            [~, fname_noext] = fileparts( app.data.file);
            fname_noext = [app.uidata.savename_prefix, fname_noext]; 
            default_fname = sprintf( 'HFOApp_%s.mat', fname_noext);
            [filename, path] = uiputfile( {'.mat'},...
                'Save data as Matlab file',...
                default_fname);
            
            if isequal( filename, 0) || isequal( path, 0)                
                PUpdateStatus( app, 'Data saving was cancelled.', 'r');
                
            else
                PUpdateStatus( app,...
                    'Saving data, plesase wait ...',...
                    app.uidata.msg_fc);
                drawnow;
                
                savefile = fullfile( path, filename);
                file = app.data.file;
                labels = app.data.labels;
                if ~isempty( app.data.trldata)
                    mat = app.data.trldata;
                else
                    mat = app.data.mat;
                end
                
                srate = app.data.srate;
                start_clock = app.data.start_clock;
                time = app.data.time;
                
                save( savefile, 'file', 'labels', 'mat',...
                    'srate', 'start_clock', 'time', '-v7.3');
                
                clear mat time
                
                PUpdateStatus( app,...
                    'Data were saved successfully.',...
                    app.uidata.msg_fc);
                drawnow;
            end             
        end % save file as .mat
        
        
        function PSaveDefaultConfig( app)
            p = fileparts( mfilename( 'fullpath'));
            cfg_file = fullfile( p, 'default_config.mat');
            if ~exist( cfg_file, 'file')
                try
                    HFOAppDefaultConfig;
                catch
                    PUpdateStatus( app,...
                        'Default configuration script was not found.',...
                        'r');
                    return;
                end
            end
            
            try 
                cfg = load( cfg_file);
                cfg = cfg.cfg;
                nam = fieldnames( cfg);
                for k = 1 : length( nam)
                    cfg.( nam{k}) = app.uidata.( nam{k});
                end
                
                save( cfg_file, 'cfg');
                PUpdateStatus( app, 'Default configuration was saved.', 'b');
                
            catch
                PUpdateStatus( app,...
                    'Failed to save default configuration.',...
                    'r');
            end
            
            drawnow;
        end % save default configurations
                        
    end % public methods
    
        
    methods (Access = private)        
        
        % Main Plotting function
        function Redraw(app)
            if app.uidata.debug
                fprintf( '\nRedraw called.\n\n');
            end

            %% Clear axes
            cla( app.UIAxes);
            for k = 1 : length( app.TimeWin)
                cla( app.TimeWin{ k}.UIAxes);
            end
            
            %% Return if the channel list box is empty
            if isempty( app.ChannelListBox.Items)
                msg_fontsize = 24;
                msg_fontcolor = app.uidata.msg_fc;
                txt_xloc = 0.1;
                txt_yloc = 0.5;
                                
                if isempty( app.data)
                    msg = 'Please load data to continue.';
                else
                    msg = 'Please select at least one channel.';
                end
                
                t = text( app.UIAxes, txt_xloc, txt_yloc, msg);
                set( t, ...
                    'FontSize', msg_fontsize,...
                    'Color', msg_fontcolor);
                
                set( app.UIAxes,...
                    'xtick', [],...
                    'xlim', [0, 1],...
                    'ytick', [],...
                    'ylim', [0, 1]);
                
                for k = 1 : length( app.TimeWin)
                    t = text( app.TimeWin{k}.UIAxes, txt_xloc, txt_yloc, msg);
                    set( t, ...
                        'FontSize', msg_fontsize,...
                        'Color', msg_fontcolor);
                    
                    set( app.TimeWin{k}.UIAxes,...
                        'xtick', [],...
                        'xlim', [0, 1],...
                        'ytick', [],...
                        'ylim', [0, 1]);
                end
                
                PUpdateStatus( app, 'Ready.', 'k');
                return;
            end % Channel list box is empty
            
            PUpdateClustInfo( app, '', 'k');
            
            % axes xlim that is determined by time window length
            win_len = floor( app.uidata.win_len * app.data.srate);
            if win_len < 2
                PUpdateStatus( app, 'Time window too small', 'r');
                return;
            end
            
            % axes xlimi
            start_loc = app.uidata.start_loc;
            xlim = [start_loc, start_loc + win_len];
            
            % real data range
            data_range = [start_loc, min( [start_loc + win_len, app.uidata.xrange(2)])];
            if data_range(1) >= data_range(2)
                data_range(2) = max( [data_range(2) - win_len, app.uidata.xrange(1)]);
            end
            
            app.uidata.data_range = data_range;
            
            % Make sure cursor location is within current time window
            tmp = app.uidata.cursor_loc;
            if ~isempty( tmp) && prod( tmp - data_range) < 0
                % do nothing
            else
                app.uidata.cursor_loc = round( mean( data_range));
            end
            
            ChangeClockType( app);
            
            nb_xticks = min( [diff( data_range) + 1, app.uidata.nb_xticks]);
            xtick = linspace( data_range(1), data_range( 2), nb_xticks);
            xtick = round( xtick);
            if strcmpi( app.uidata.clock_type, 'clock')
                % show clock label
                tmp_sclock = app.data.start_clock;
                if app.uidata.win_len < 5
                    tmp_sclock.Format = 'HH:mm:ss.SSSS';
                else
                    tmp_sclock.Format = 'HH:mm:ss';
                end
                
                srate = app.data.srate;
                tick_ind = ( 1 : length(xtick) )';
                xticklabel = arrayfun( @(x) ...
                    string( tmp_sclock + seconds( xtick(x) / srate)),...
                    tick_ind,...
                    'UniformOutput', false);
                
            else
                xtick_t = app.data.time( xtick); 
                if app.uidata.win_len <=1
                    xtick_t = round( xtick_t * 1000) / 1000;
                    
                elseif app.uidata.win_len <= 10
                    xtick_t = round( xtick_t * 10) / 10;
                    
                else
                    xtick_t = round( xtick_t);
                end
                
                xtick_t = num2cell( xtick_t);
                xticklabel = cellfun( @(x) num2str( x),...
                    xtick_t, 'UniformOutput', false);
            end
                        
            % events to overlay on the time series plot
            [disp_ev_loc, dis_ev_color] = GetCurrentEvents2Show( app);
            
            %% #################################################
            % Main window plot
            hold( app.UIAxes, 'on');
            chnl2plot = app.ChannelListBox.Items(:);
            
            [mtrack_info, ftrack_info] = PGetTrackInfo( app);
            nb_embed_wins = sum( mtrack_info.filt_cell_ind ~= 0);
            
            % find index of current selected channels
            cur_chnl = app.uidata.current_channel;
            cur_chnl_loc = [];
            if ~isempty( cur_chnl)
                cur_chnl_loc = find( ismember( mtrack_info.real_label, cur_chnl{1}));
            end
            
            p_xlim = xlim;
            if diff( xlim) > 50
                p_xlim(1) = p_xlim(1) + 10;
                p_xlim(2) = p_xlim(2) - 10;
            end
            p_xlim = p_xlim( [1, 2, 2, 1, 1]);     
            
            data_loc = data_range(1) : data_range(2);
            for chnl_idx = 1 : length( mtrack_info.real_label)
                % background of current channel
                if any( chnl_idx == cur_chnl_loc) && strcmpi( app.uidata.show_current_background, 'yes')
                
                    patch( app.UIAxes, p_xlim,...
                        mtrack_info.marg_range( chnl_idx, [1, 1, 2, 2, 1]),...
                        app.uidata.color_current_background,...
                        'linestyle', 'none',...
                        'facealpha', 0.7);
                end
                
                fc_idx = mtrack_info.filt_cell_ind( chnl_idx);
                ts_loc = mtrack_info.label_location( chnl_idx);
                
                % line color
                ck = mtrack_info.Color{ chnl_idx};
                if fc_idx ~= 0
                    v = app.data.bpfilt.mat{ fc_idx}( ts_loc, data_loc);                    
                else
                    v = app.data.mat( ts_loc, data_loc);
                    if ~isempty( cur_chnl) && strcmp( mtrack_info.real_label{ chnl_idx}, cur_chnl{1})
                        ck = app.uidata.color_current_channel;
                    end
                end                
                
                chnl_mat_limit = mtrack_info.ylimit( chnl_idx, :);
                chnl_mat_range = diff( chnl_mat_limit);
                if abs( chnl_mat_range) > eps
                    v = (v - chnl_mat_limit(1)) / chnl_mat_range;
                else
                    v = 0.5 * ones( 1, length( v));
                end
                
                % re-scale input to specified range
                rescale_range = mtrack_info.rescale_range( chnl_idx, :);
                v = v * diff(rescale_range) + rescale_range(1);
                
                % Plot rescale time series                
                plot( app.UIAxes, data_loc, v,...
                    'Color', ck,...
                    'linewidth', app.uidata.ts_linewidth);
                
                % Overlay events
                [~, tmp_chnloc] = ismember( mtrack_info.real_label{ chnl_idx}, chnl2plot);
                for ev_idx = 1 : length( disp_ev_loc{ tmp_chnloc})
                    tmp = disp_ev_loc{ tmp_chnloc}{ ev_idx};
                    [tmp_loc, ia] = intersect( data_loc, tmp(1) : tmp(2));
                    
                    plot( app.UIAxes, tmp_loc, v( ia),...
                        'color', dis_ev_color{ tmp_chnloc}{ ev_idx},...
                        'linewidth', app.uidata.ts_linewidth);
                end                
                
            end % main window time series plot            
          
            
            % length of the vertical line that indicates cursor location
            main_ypnt = app.uidata.cursor_ypnt;
            if ~isempty( cur_chnl_loc)
                main_ypnt = mtrack_info.yrange( cur_chnl_loc, :);
                main_ypnt= [min( main_ypnt(:)), max( (main_ypnt(:)))];
            end
            
            %% Add a separator between channels
            main_sep = nb_embed_wins + 1 : nb_embed_wins + 1: length( mtrack_info.real_label) - 1;
            minor_sep = 1 : length( mtrack_info.real_label) - 1;
            minor_sep( ismember( minor_sep, main_sep)) = [];
            if strcmpi( app.uidata.show_main_separator, 'yes') ...
                    || strcmpi( app.uidata.show_minor_separator, 'yes')
                tmp_x = xlim(1) : xlim(2);
                tmp_y = ones( 1, diff(xlim)+1);
            end
            
            if strcmpi( app.uidata.show_main_separator, 'yes')
                for k = main_sep
                    plot( app.UIAxes, tmp_x, mtrack_info.yrange( k, 2) * tmp_y, ...
                        'Color', app.uidata.color_main_separator);
                end
            end
            
            if strcmpi( app.uidata.show_minor_separator, 'yes')
                for k = minor_sep
                    plot( app.UIAxes, tmp_x, mtrack_info.yrange( k, 2) * tmp_y,...
                        'Color', app.uidata.color_minor_separator);
                end
            end
            
            
            %% #################################################
            % Floating filtered time series plot
            for fidx = 1 : length( ftrack_info)
                % index in app.TimeApp
                win_ind = ftrack_info{ fidx}.filt_win_ind;
                hold( app.TimeWin{ win_ind}.UIAxes, 'on');
                
                % index in bpfilt.mat
                mat_ind = ftrack_info{ fidx}.filt_cell_ind;
                
                for chnl_idx = 1 : length( ftrack_info{ fidx}.real_label)
                    % background of current channel
                    ftrack_label = ftrack_info{ fidx}.real_label{ chnl_idx};
                    tmp_ind = [1, 1, 2, 2, 1];
                    if ~isempty( cur_chnl) && strcmp( ftrack_label, cur_chnl{1}) &&...
                            strcmpi( app.uidata.show_current_background, 'yes')
                    
                        patch( app.TimeWin{ win_ind}.UIAxes, p_xlim,...
                            ftrack_info{ fidx}.marg_range( chnl_idx, tmp_ind),...
                            app.uidata.color_current_background,...
                            'linestyle', 'none',...
                            'facealpha', 0.7);
                    end
                    
                    tmp_loc = ftrack_info{ fidx}.label_location( chnl_idx);
                    v = app.data.bpfilt.mat{ mat_ind}( tmp_loc, data_loc);
                    filt_mat_limit = ftrack_info{ fidx}.ylimit;
                    filt_mat_range = diff( filt_mat_limit);
                    if abs( filt_mat_range) > eps
                        v = (v - filt_mat_limit(1)) / filt_mat_range;
                    else
                        v = 0.5 * ones( 1, length( v));
                    end
                    
                    % re-scale input to specified range
                    rescale_range = ftrack_info{ fidx}.rescale_range( chnl_idx, :);
                    v = v * diff(rescale_range) + rescale_range(1);
                    
                    plot( app.TimeWin{ win_ind}.UIAxes, data_loc, v,...
                        'Color', app.data.bpfilt.param{ mat_ind}.Color,...
                        'linewidth', app.uidata.ts_linewidth);
                    
                    [~, tmp_chnloc] = ismember( ftrack_label, chnl2plot);
                    for ev_idx = 1 : length( disp_ev_loc{ tmp_chnloc})
                        tmp = disp_ev_loc{ tmp_chnloc}{ ev_idx};
                        [tmp_loc, ia] = intersect( data_loc, tmp(1) : tmp(2));
                        
                        plot( app.TimeWin{ win_ind}.UIAxes, tmp_loc, v( ia),...
                            'color', dis_ev_color{ tmp_chnloc}{ ev_idx},...
                            'linewidth', app.uidata.ts_linewidth);
                    end
                    
                end % channel plot
                
                % Add a separator between channels
                if strcmpi( app.uidata.show_main_separator, 'yes')
                    for chnl_idx = 1 : length( ftrack_info{ fidx}.real_label) - 1
                        plot( app.TimeWin{ win_ind}.UIAxes, xlim(1) : xlim(2),...
                            ftrack_info{ fidx}.yrange( chnl_idx, 2) * ones( 1, diff(xlim) + 1),...
                            'Color', app.uidata.color_main_separator);
                    end
                end
                
                set( app.TimeWin{ win_ind}.UIAxes,...
                    'YTick', ftrack_info{ fidx}.plot_ytick,...
                    'YTickLabel', ftrack_info{ fidx}.plot_yticklabel,...
                    'YTickLabelRotation', app.uidata.time_ytick_rotation,...
                    'YDir','Reverse');
                
            end % floating bpfilter window
            
            
            %%#####################################################################################
            %% Start of spectrogram window plot update            
            if ~isempty( cur_chnl)
                win_nam = cur_chnl{ 1};
                cursor_loc = app.uidata.cursor_loc;

                nam = cellfun( @(x) x.UIFigure.Name, app.FreqWin, 'UniformOutput', false);
                [fw_exist, fw_ind] = ismember( win_nam, nam);
              
                % colormap
                eval( ['cmap = ', app.uidata.freq_colormap, ';']);
                
                % update spectrogram only when the window is open
                if fw_exist
                %for k = 1 : length( nam)
                %    fw_ind = k;
                %    win_nam = nam{ k};                 
                    tf_vec = [];
                    if strcmpi( app.uidata.entire_tf_plotted, 'yes') && strcmpi( app.uidata.tf_entire, 'yes')
                        [~, chnloc] = ismember( win_nam, app.data.tf.labels);
                        
                        if ~isempty( cursor_loc)
                            tf_vec = app.data.tf.mat{ chnloc}( :, cursor_loc);
                        end

                        freq_axis = app.data.tf.param{ chnloc}.freq;
                        %tf_clim = app.data.tf.param{ chnloc}.climit;

                    else
                        % calculate spectrogram for current window
                        [tf, freq_axis, freq_data_range] = PUpdateSpectrogram( app, win_nam);
                        tf = abs( tf);
                        %tf_clim = [min( tf, [], 'all'), max( tf, [], 'all')];                
                        ind = cursor_loc - freq_data_range(1) + 1;
                        if ~isempty( ind) && ind >= 1 && ind <= size( tf, 2)
                            tf_vec = tf( :, ind);
                        end
                                         
                        cla( app.FreqWin{ fw_ind}.UIAxes);
                        imagesc( app.FreqWin{ fw_ind}.UIAxes, freq_data_range, freq_axis, tf);
                    end

                    % y tick of the tf plot
                    tf_ytick = app.uidata.tf_ytick;
                    if isempty( tf_ytick)
                        freq_ytick = linspace( freq_axis(1), freq_axis(end), 8);
                    elseif isscalar( tf_ytick)
                        freq_ytick = linspace( freq_axis(1), freq_axis(end), tf_ytick);
                    else
                        freq_ytick = tf_ytick;
                    end
                    
                    % freq_ytick = round( 10 * freq_ytick) / 10;
                    set( app.FreqWin{ fw_ind}.UIAxes,...
                        'YDir', 'normal',...
                        'XLim', xlim,...
                        'YLim', freq_axis( [1, end]), ...
                        'YTick', freq_ytick,...
                        'XTick', xtick,...
                        'XTickLabel', xticklabel);
                    
                    ytickformat( app.FreqWin{ fw_ind}.UIAxes, '%,.1f');
    
                    colormap( app.FreqWin{ fw_ind}.UIAxes, cmap);  
                   
                    % spectrogram at cursor
                    cla( app.FreqWin{ fw_ind}.UIAxesAvg);
                    if ~isempty( tf_vec) 
                        [~, midx] = max( tf_vec);
                        pk_f = freq_axis( midx);

                        plot( app.FreqWin{ fw_ind}.UIAxesAvg,...
                            freq_axis, tf_vec,...
                            'linewidth', 1,...
                            'Color', [143, 30, 6]/255); 

                        tf_clim = [floor( min( tf_vec)), ceil( max( tf_vec))];
                        ytick = linspace( tf_clim(1), tf_clim(2), 4);                            
                                                
                        freq_ytick = linspace( freq_ytick(1), freq_ytick(end), 5);
                        freq_ytick = unique( round( freq_ytick), 'stable');
                        set( app.FreqWin{ fw_ind}.UIAxesAvg,...
                            'xlim', freq_axis( [1, end]),...
                            'xtick', freq_ytick,...
                            'fontsize', 10,...
                            'ylim', tf_clim,...
                            'ytick', ytick);
                        
                        %xtickformat( app.FreqWin{ fw_ind}.UIAxesAvg, '%,.1f');
                        ytickformat( app.FreqWin{ fw_ind}.UIAxesAvg, '%,.2f');
                        
                        tf_dect_off = app.FreqWin{ fw_ind}.TFAutoDetectCheckBox.Value == 0;                  
                        %if isempty( app.uidata.cur_event) && tf_dect_off 
                        %    pk_f = round( 10 * pk_f) / 10;
                        %    app.FreqWin{ fw_ind}.TFAutoPeakFreqLabel.Text = [num2str( pk_f), ' Hz'];
                        %    app.FreqWin{ fw_ind}.TFAutoNoCycleLabel.Text = '';
                        %end
                        
                        if tf_dect_off 
                            app.FreqWin{ fw_ind}.TFAutoPeakFreqLabel.Text = '';
                            app.FreqWin{ fw_ind}.TFAutoNoCycleLabel.Text = '';
                        end
                        
                        
                    end
                    
                end % spectrogram window exists
                
            end % current channel was set
            %% End of spectrogram window plot update            
            %%#####################################################################################
            
            % manual events            
            mev_start = app.uidata.manual_start;
            txt_yloc = min( main_ypnt) + 0.9 * abs( diff( main_ypnt));
            % text color
            mev_start_c = [0, 0.5, 0.9];
            if ~isempty( mev_start)
                pnt_in = mev_start >= data_range(1) && mev_start <= data_range(2);
                if pnt_in && app.ManualModeButton.Value == 1
                    start_str = 'Start';
                    xpnt = mev_start * [1; 1];
                    plot( app.UIAxes,...
                        xpnt,...
                        main_ypnt,...
                        'color', mev_start_c,...
                        'LineStyle', app.uidata.box_linestyle,...
                        'LineWidth', app.uidata.box_linewidth);
                    
                    th = text( app.UIAxes, mev_start, txt_yloc, start_str);
                    th.HorizontalAlignment = 'Right';
                    
                    % manual cluster on band-pass filter windows
                    for idx = 1 : length( app.TimeWin)
                        plot( app.TimeWin{ idx}.UIAxes,...
                            xpnt,...
                            main_ypnt,...
                            'color', mev_start_c,...
                            'LineStyle', app.uidata.box_linestyle,...
                            'LineWidth', app.uidata.box_linewidth);
                        
                        th = text( app.TimeWin{ idx}.UIAxes, mev_start, txt_yloc, start_str);
                        th.HorizontalAlignment = 'Right';
                    end                    
                    
                    % manual cluster on spectrogram window
                    for idx = 1 : length( app.FreqWin)
                        h = app.FreqWin{ idx}.uidata.mev_sh;
                        if ~isempty( h) && isvalid( h)
                            delete( h);
                        end
                        
                        th = app.FreqWin{ idx}.uidata.mev_stxth;
                        if ~isempty( th) && isvalid( th)
                            delete( th);
                        end
                        
                        freq_win_name = app.FreqWin{ idx}.UIFigure.Name;
                        [~, f_loc] = ismember( freq_win_name, app.data.tf.labels);
                        tmp_freq = app.data.tf.param{ f_loc}.freq( [1, end]);
                        hold( app.FreqWin{ idx}.UIAxes, 'on');
                        h = plot( app.FreqWin{ idx}.UIAxes,...
                            xpnt, tmp_freq,...
                            'color', 'y',...
                            'LineStyle', app.uidata.box_linestyle,...
                            'LineWidth', app.uidata.box_linewidth);
                        
                        app.FreqWin{ idx}.uidata.mev_sh = h;
                        
                        th = text( app.FreqWin{ idx}.UIAxes, mev_start, mean( tmp_freq), start_str);
                        th.HorizontalAlignment = 'Right';
                        app.FreqWin{ idx}.uidata.mev_stxth = th;
                    end
                    
                else
                    app.uidata.manual_start = [];
                    app.StartEventButton.FontColor = 'k';
                    try
                        for idx = 1 : length( app.FreqWin)
                            delete( app.FreqWin{ idx}.uidata.mev_sh);
                            delete( app.FreqWin{ idx}.uidata.mev_stxth);
                        end
                        
                    catch
                        % do nothing
                    end
                end
            end
            
            mev_end = app.uidata.manual_end;
            if ~isempty( mev_end)
                pnt_in = mev_end >= data_range(1) && mev_end <= data_range(2);
                
                if pnt_in && app.ManualModeButton.Value == 1
                    end_str = 'End';
                    xpnt = mev_end * [1; 1];
                    plot( app.UIAxes,...
                        xpnt, main_ypnt,...
                        'g',...
                        'LineStyle', app.uidata.box_linestyle,...
                        'LineWidth', app.uidata.box_linewidth);
                    
                    text( app.UIAxes, mev_end, txt_yloc, end_str);
                    
                    for idx = 1 : length( app.TimeWin)
                        plot( app.TimeWin{ idx}.UIAxes,...
                            xpnt, main_ypnt,...
                            'g', ...
                            'LineStyle', app.uidata.box_linestyle,...
                            'LineWidth', app.uidata.box_linewidth);
                        
                        text( app.TimeWin{ idx}.UIAxes, mev_end, txt_yloc, end_str);
                    end
                    
                    % manual cluster on spectrogram window
                    for idx = 1 : length( app.FreqWin)
                        h = app.FreqWin{ idx}.uidata.mev_eh;
                        if ~isempty( h) && isvalid( h)
                            delete( h);
                        end
                        
                        th = app.FreqWin{ idx}.uidata.mev_etxth;
                        if ~isempty( th) && isvalid( th)
                            delete( th);
                        end
                        
                        freq_win_name = app.FreqWin{ idx}.UIFigure.Name;
                        [~, f_loc] = ismember( freq_win_name, app.data.tf.labels);
                        tmp_freq = app.data.tf.param{ f_loc}.freq( [1, end]);
                        
                        hold( app.FreqWin{ idx}.UIAxes, 'on');
                        
                        h = plot( app.FreqWin{ idx}.UIAxes,...
                            xpnt, tmp_freq,...
                            'g',...
                            'LineStyle', app.uidata.box_linestyle,...
                            'LineWidth', app.uidata.box_linewidth);
                        
                        app.FreqWin{ idx}.uidata.mev_eh = h;
                        
                        th = text( app.FreqWin{ idx}.UIAxes, mev_end, mean( tmp_freq), end_str);
                        th.HorizontalAlignment = 'Right';
                        app.FreqWin{ idx}.uidata.mev_etxth = th;
                    end
                    
                else
                    app.uidata.manual_end = [];
                    app.EndEventButton.FontColor = 'k';
                    
                    try
                        for idx = 1 : length( app.FreqWin)
                            delete( app.FreqWin{ idx}.uidata.mev_eh);
                            delete( app.FreqWin{ idx}.uidata.mev_etxth);
                        end
                        
                    catch
                        % do nothing
                    end
                end
            end
                        
            %% ******************************************************************************
            %% clear events that are not fully within the current window
            cur_ev = app.uidata.cur_event;            
            if ~isempty( cur_ev) && isstruct( cur_ev)
                % chnl2rm = [];
                cnt = 0;
                chnl2rm = nan( length( cur_ev), 1);
                for cidx = 1 : length( cur_ev)
                    tmp_evs = app.uidata.cur_event( cidx).Location;
                    tmp = tmp_evs >= data_range(1) & tmp_evs <= data_range(2);
                    tmp = ~(tmp( :, 1) & tmp( :, 2));
                    app.uidata.cur_event( cidx).Location( tmp, :) = [];
                    if isempty( app.uidata.cur_event( cidx).Location)
                       % chnl2rm = [chnl2rm; cidx];
                        cnt = cnt + 1;
                        chnl2rm( cnt) = cidx;
                    end
                end
                
                chnl2rm( cnt+1 : end) = [];
                app.uidata.cur_event( chnl2rm) = [];
                
            elseif ~isempty( cur_ev)
                tmp = cur_ev >= data_range(1) & cur_ev <= data_range(2);
                tmp = ~(tmp( :, 1) & tmp( :, 2));
                app.uidata.cur_event( tmp, :) = [];
                
            else
                % do nothing
            end
            
            % clear spectrogram event outlines
            for win_idx = 1 : length( app.FreqWin)                
                box_h = app.FreqWin{ win_idx}.uidata.boxh;
                for k = 1 : length( box_h)
                    if ~isvalid( box_h)
                        continue;
                    end
                    
                    box_xdata = sort( box_h( k).XData);
                    box_xdata = box_xdata( [1, end]);
                    
                    tmp = box_xdata >= data_range(1) & box_xdata <= data_range(2);
                    if ~(tmp(1) && tmp(2)) || isempty( app.uidata.cur_event)
                        delete( box_h( k));
                    end
                end 
                
                if ~isempty( box_h)
                    box_h( ~isvalid( box_h)) = [];
                end
                app.FreqWin{ win_idx}.uidata.boxh = box_h;
            end
            %% End of clear events that are not fully within the current window
            %% ******************************************************************************
                        
            %% ******************************************************************************
            %% Navigation bar plot
            box_bound = [sum( app.LLabel.Position( [1, 3])),...
                app.RLabel.Position(1),...
                sum( app.BLabel.Position( [2, 4])),...
                app.TLabel.Position(2)];
            
            % indicator of the time window of interest
            xrange = app.uidata.xrange;
            nbsamples = app.data.nbsamples;
            box_width = diff( box_bound( [1, 2]));
            p = box_width * (xrange - 1) / (nbsamples - 1)  + box_bound(1);
            app.ToiTLabel.Position( [1, 3]) = [p(1), diff( p)];
            app.ToiBLabel.Position( [1, 3]) = [p(1), diff( p)];
            app.MLabel.Position(1) = box_width * (app.uidata.start_loc - 1) / (nbsamples - 1) + box_bound(1);
            app.MLabel.Position(3) = min( box_width, box_width * (win_len / ( nbsamples - 1)));
            
            % minimal width
            app.MLabel.Position(3) = max( [5, app.MLabel.Position(3)]);
            %% End of navigation bar plot
            %% ******************************************************************************
            
            % Do this last. X/Y ticks etc.
            set( app.UIAxes, 'XLim', xlim,...
                'XTick', xtick,...
                'XTickLabel', xticklabel,...
                'YLim', [0, 1],...
                'YTick', mtrack_info.plot_ytick,...
                'YTickLabel', mtrack_info.plot_yticklabel,...
                'YTickLabelRotation', app.uidata.time_ytick_rotation,...
                'TickLabelInterpreter', 'none',...
                'YDir', 'Reverse');
            
            PUpdateStatus( app, 'Plot updated.', app.uidata.msg_fc);            
            
            % event list cursor lcoation
            if ~isempty( cur_chnl) && ~isempty( app.uidata.cursor_loc) % && length( app.EventListBox.Value) == 1
                [ev_in_curlabel, dur, ev_type, ev_label] = PIsEventExist( app, app.uidata.cursor_loc, cur_chnl{1});
                
                if ev_in_curlabel
                    if length( dur) > 1
                        [chnl_name, ev_ind] = RetrieveChannel( app, ev_label);                        
                        s = cellfun( @(x, y) sprintf( '%s: %d', x, y),...
                            chnl_name, num2cell( ev_ind), 'uniformoutput', false);
                        
                        s = strjoin( s, ' | ');                        
                        txt = sprintf( '%d events at cursor.', s);
                        
                    else
                        txt = sprintf( 'Cursor at %s %0f ms. Type: %s',...
                            ev_label{1}, sum( dur), ev_type{1});
                        app.EventListBox.Value = ev_label{1};
                    end                       
                    
                    PUpdateStatus( app, txt, app.uidata.msg_fc);
                    
                else
                    % PUpdateStatus( app, ['No event at cursor location for ', cur_chnl{1}], app.uidata.msg_fc);
                    % do nothing
                end
            end
                
            % Change objects' value on the time windows
            SyncTimeWin( app);
            SyncFreqWin( app);
            
            PUpdateClustBox( app, mtrack_info);
        end % function Redraw, main plot function
        
        
        function [objs, lb_objs, btn_grp_obj_names, vis_objs] = GetSyncObjs(~)
            % Object names to be synchronized between main window and time
            % and frequency windows
            
            % Objects that have Value, FontColor, Enable properties
            objs = {'XScaleSpinner',...
                'StartClockEditField', ...
                'EndClockHEditField',...
                'JumpToClockEditField',...
                'LockCurrentChannelCheckBox',...
                'DurationLabel',...
                'StatusLabel', ...
                'AddEventButton',...
                'ManualEventAutoAdjCheckBox',...
                'ManualModeButton',...
                'StartEventButton',...
                'EndEventButton',...
                'MultipleDetectionCheckBox',...
                'ETGammaCheckBox',...
                'ETFastRippleCheckBox',...
                'ETRippleCheckBox', ...
                'ETSpikeCheckBox',...
                'ETUltraFastCheckBox', ...
                'ThreshInclusionEditField',...
                'ThreshNumberCyclesEditField', ...
                'ThreshOnsetEditField',...
                'BKSpecifiedEF',...
                'TrialLabel',...
                'PreTrialButton',...
                'NextTrialButton',...
                'CurTrialEditField',...
                'TotalTrialLabel'};
            
            % Listbox objects
            lb_objs = {'ChannelListBox',...
                'AllChannelListBox',...
                'EventListBox'};
            
            % button group
            btn_grp_obj_names = {'EventChanneltoDisplayButtonGroup',...
                'EventTypetoDisplayButtonGroup',...
                'BackgroundTimeWindowButtonGroup'};
            
            % visiblity 
            vis_objs = {'TrialLabel',...
                'PreTrialButton',...
                'NextTrialButton',...
                'CurTrialEditField',...
                'TotalTrialLabel'};            
        end
        
        
        % Update filtere time series window
        function SyncTimeWin(app)
            if app.uidata.debug
                fprintf( 'SyncTimeWin called.\n');
            end
            
            [objs, lb_objs, btn_grp_obj_names, vis_objs] = GetSyncObjs( app);
            
            % No redraw, do not call Redraw within this function
            for k = 1 : length( app.TimeWin)
                app.TimeWin{ k}.UIAxes.TickLabelInterpreter = 'none';
                
                %%%%%%%%%%%%% navigation bar
                box_bound = [sum( app.TimeWin{ k}.LLabel.Position( [1, 3])),...
                    app.TimeWin{ k}.RLabel.Position(1),...
                    sum( app.TimeWin{ k}.BLabel.Position( [2, 4])),...
                    app.TimeWin{ k}.TLabel.Position(2)];
                
                % indicator of the time window of interest
                xrange = app.uidata.xrange;
                nbsamples = app.data.nbsamples;
                box_width = diff( box_bound( [1, 2]));
                p = box_width * (xrange - 1) / (nbsamples - 1)  + box_bound(1);
                
                app.TimeWin{ k}.ToiTLabel.Position( [1, 3]) = [p(1), diff( p)];
                app.TimeWin{ k}.ToiBLabel.Position( [1, 3]) = [p(1), diff( p)];
                app.TimeWin{ k}.MLabel.Position(1) = box_width * (app.uidata.start_loc - 1) / (nbsamples - 1) + box_bound(1);
                
                win_len = floor( app.uidata.win_len * app.data.srate);
                app.TimeWin{ k}.MLabel.Position(3) = min( box_width, box_width * (win_len / (nbsamples - 1)));                
                app.TimeWin{ k}.MLabel.Position(3) = max( [5, app.TimeWin{ k}.MLabel.Position(3)]);
                
                for obj_idx = 1 : length( lb_objs)
                    obj_name = lb_objs{ obj_idx};
                    app.TimeWin{k}.( obj_name).Items = app.( obj_name).Items;
                    if ~isempty( app.( obj_name).Value)
                        app.TimeWin{k}.( obj_name).Value = app.( obj_name).Value;
                    else
                        app.TimeWin{k}.( obj_name).Value = {};
                    end
                end
                
                for obj_idx = 1 : length( objs)
                    obj_name = objs{ obj_idx};
                    if isprop( app.( obj_name), 'Value')
                        app.TimeWin{k}.( obj_name).Value = app.( obj_name).Value;
                    end
                    
                    if isprop( app.( obj_name), 'Text')
                        app.TimeWin{k}.( obj_name).Text = app.( obj_name).Text;
                    end
                    
                    if isprop( app.( obj_name), 'Fontcolor')
                        app.TimeWin{k}.( obj_name).FontColor = app.( obj_name).FontColor;
                    end
                    
                    if isprop( app.( obj_name), 'Enable')
                        app.TimeWin{k}.( obj_name).Enable = app.( obj_name).Enable;
                    end                    
                end
                
                for obj_idx = 1 : length( btn_grp_obj_names)
                    obj_name = btn_grp_obj_names{ obj_idx};
                    for idx = 1 : length( app.TimeWin{k}.( obj_name).Children)
                        if strcmpi( app.TimeWin{k}.( obj_name).Children( idx).Text, app.( obj_name).SelectedObject.Text)
                            app.TimeWin{k}.( obj_name).Children( idx).Value = 1;
                        end                        
                    end
                end
                
                for obj_idx = 1 : length( vis_objs)
                    obj_name = vis_objs{ obj_idx};
                    app.TimeWin{k}.( obj_name).Visible= app.( obj_name).Visible;
                end
                
                app.TimeWin{ k}.UIAxes.XLim = app.UIAxes.XLim;
                app.TimeWin{ k}.UIAxes.XTick = app.UIAxes.XTick;
                app.TimeWin{ k}.UIAxes.XTickLabel = app.UIAxes.XTickLabel;
                app.TimeWin{ k}.UIAxes.YLim = [0, 1];  
                
                if isprop( app.TimeWin{ k}, 'MergeEventButton')
                    app.TimeWin{ k}.MergeEventButton.Enable = app.MergeEventButton.Enable;
                end
                
            end % window loop

        end % function SyncTimeWin
        
        
        function SyncFreqWin( app)
            if app.uidata.debug
                fprintf( 'SyncFreqWin called.\n');
            end

            [objs, lb_objs, btn_grp_obj_names, vis_objs] = GetSyncObjs( app);
            
            % No redraw, do not call Redraw within this function
            for k = 1 : length( app.FreqWin)
                box_bound = [sum( app.FreqWin{ k}.LLabel.Position( [1, 3])),...
                    app.FreqWin{ k}.RLabel.Position(1),...
                    sum( app.FreqWin{ k}.BLabel.Position( [2, 4])),...
                    app.FreqWin{ k}.TLabel.Position(2)];
                
                % indicator of the time window of interest
                xrange = app.uidata.xrange;
                nbsamples = app.data.nbsamples;
                box_width = diff( box_bound( [1, 2]));
                p = box_width * (xrange - 1) / (nbsamples - 1)  + box_bound(1);
                app.FreqWin{ k}.ToiTLabel.Position( [1, 3]) = [p(1), diff( p)];
                app.FreqWin{ k}.ToiBLabel.Position( [1, 3]) = [p(1), diff( p)];
                app.FreqWin{ k}.MLabel.Position(1) = box_width * (app.uidata.start_loc - 1) / (nbsamples - 1) + box_bound(1);
                win_len = floor( app.uidata.win_len * app.data.srate);
                app.FreqWin{ k}.MLabel.Position(3) = min( box_width, box_width * (win_len / ( nbsamples - 1)));
                app.FreqWin{ k}.MLabel.Position(3) = max( [5, app.FreqWin{ k}.MLabel.Position(3)]);
                
                for obj_idx = 1 : length( lb_objs)
                    obj_name = lb_objs{ obj_idx};
                    app.FreqWin{k}.( obj_name).Items = app.( obj_name).Items;
                    if ~isempty( app.( obj_name).Value)
                        app.FreqWin{k}.( obj_name).Value = app.( obj_name).Value;
                    else
                        app.FreqWin{k}.( obj_name).Value = {};
                    end
                end
                
                for obj_idx = 1 : length( objs)
                    obj_name = objs{ obj_idx};
                    if isprop( app.( obj_name), 'Value')
                        app.FreqWin{k}.( obj_name).Value = app.( obj_name).Value;
                    end
                    
                    if isprop( app.( obj_name), 'Text')
                        app.FreqWin{k}.( obj_name).Text = app.( obj_name).Text;
                    end
                    
                    if isprop( app.( obj_name), 'Fontcolor')
                        app.FreqWin{k}.( obj_name).FontColor = app.( obj_name).FontColor;
                    end
                    
                    if isprop( app.( obj_name), 'Enable')
                        app.FreqWin{k}.( obj_name).Enable = app.( obj_name).Enable;
                    end
                end
                
                for obj_idx = 1 : length( btn_grp_obj_names)
                    obj_name = btn_grp_obj_names{ obj_idx};
                    for idx = 1 : length( app.FreqWin{k}.( obj_name).Children)
                        if strcmpi( app.FreqWin{k}.( obj_name).Children( idx).Text, app.( obj_name).SelectedObject.Text)
                            app.FreqWin{k}.( obj_name).Children( idx).Value = 1;
                        end                        
                    end
                    
                end
                
                for obj_idx = 1 : length( vis_objs)
                    obj_name = vis_objs{ obj_idx};
                    app.FreqWin{k}.( obj_name).Visible = app.( obj_name).Visible;                    
                end
                
                app.FreqWin{ k}.UIAxes.XLim = app.UIAxes.XLim;
                app.FreqWin{ k}.UIAxes.XTick = app.UIAxes.XTick;
                app.FreqWin{ k}.UIAxes.XTickLabel = app.UIAxes.XTickLabel;   
                
                if isprop( app.FreqWin{ k}, 'MergeEventButton')
                    app.FreqWin{ k}.MergeEventButton.Enable = app.MergeEventButton.Enable;
                end
            end % window loop
            
        end % function SyncFreqWin
        
        
        function [new_range, marg_range] = UpdateYScaleRange( ~, raw_range, margin, scale_fact)
            % raw_range, [lo, hi]
            % margin, [0, 1);
            % scale_fact, scalar            
            v = diff( raw_range);
            raw_range(1) = raw_range(1) + 0.5 * margin * v;
            raw_range(2) = raw_range(2) - 0.5 * margin * v;
            marg_range = raw_range;
            ct = raw_range(1) + mean( diff( raw_range))/2;
            new_range = ct + 0.5 * v * scale_fact * [-1, 1];
        end
        
        
        function UpdateXScale( app, value, previous_value)
            if app.uidata.debug
                fprintf( 'UpdateXScale called.\n');
            end
        
            if isempty( app.data)
                return;
            end

            if value <= 0
                PUpdateStatus( app, 'Invalid time window.', 'r');
                app.XScaleSpinner.FontColor = 'r';
                app.XScaleSpinner.Value = previous_value;
                app.uidata.win_len = previous_value;

            else
                max_val = (app.data.nbsamples - 1) / app.data.srate;
                % max_val = nbsamples / srate;
                if value >= max_val
                    app.uidata.start_loc = 1;
                end
                    
                app.StatusLabel.FontColor = 'k';
                app.XScaleSpinner.FontColor = 'k';
                app.XScaleSpinner.Value = value;
                app.uidata.win_len = value;
                Redraw( app);
            end

        end % Timewindow Change
        
        
        function time_d = TimeDiff( ~, d1, d2)
            x1 = datevec( datenum( d1));
            x2 = datevec( datenum( d2));
            time_d = etime( x2, x1);
        end % function TimeDiff
        
        
        % Retrieve channel label and event index from event listbox label
        function [label, ev_loc, new_ev_label] = RetrieveChannel( ~, ev_label, ev_idx)
            % Retrieve channel label and event index from ev_label
            % ev_label, 'label' or {'label1', 'label2'}
            % ev_idx, replace event index in ev_label with ev_idx
            
            if ~iscell( ev_label)
                ev_label = {ev_label};
            end
            
            N = length( ev_label);
            
            if nargin < 3
                ev_idx = [];
            else
                if isvector( ev_idx) && length( ev_idx) == N
                    % do nothing
                else
                    ev_idx = [];
                end
            end
            
            new_ev_label = ev_label;
            label = cell( N, 1);
            ev_loc = zeros( N, 1);
            for k = 1 : N
                s = ev_label{ k};
                
                sloc = strfind( s, '[');
                sloc = sloc( end);
                eloc = strfind( s, ']');
                eloc = eloc( end);                
                
                label{ k} = strtrim( s( 1 : sloc - 1));
                ev_loc( k) = str2num( s( sloc + 1 : eloc - 1));                %#ok<ST2NM> 
                if ~isempty( ev_idx)
                    tmp = new_ev_label{ k};
                    tmp = [tmp( 1:sloc), num2str( ev_idx(k)), tmp(eloc:end)];
                    new_ev_label{ k}  = tmp;
                end
            end
            
        end % retrive channel label from event label
        
        
        function ChangeVisibility( app, status)
            if app.uidata.debug
                fprintf( 'ChangeVisibility called.\n');
            end
            
            % Visible property
            obj_vis = {'UIAxes',...
                'TimesLabel',...
                'XScaleSpinner',...
                'StatusLabel', ...
                'DurationLabel',...
                'AmplitudeLabel',...
                'YScaleSpinner',...
                'FiltYScaleButton',...
                'StartClockLabel',...
                'StartClockEditField',...
                'EndClockLabel',...
                'EndClockHEditField',...
                'JumptoLabel',...
                'JumpToClockEditField',...
                'ResetTimeButton',...
                'ManualEventAutoAdjCheckBox',...
                'AddEventButton',...
                'ManualModeButton',...
                'StartEventButton',...
                'EndEventButton',...
                'TabGroup',...
                'StepBackButton',...
                'StepForwardButton',...
                'BackButton',...
                'ForwardButton',...
                'StartEventButton',...
                'EndEventButton',...
                'MultipleDetectionCheckBox',...
                'ManualModeButton',...
                'MLabel',...
                'TLabel',...
                'BLabel',...
                'LLabel',...
                'RLabel', ...
                'ToiTLabel', ...
                'ToiBLabel',...
                'TrialLabel', ...
                'PreTrialButton', ...
                'NextTrialButton', ...
                'CurTrialEditField', ...
                'TotalTrialLabel'};            
            
            % Enable property
            obj_enabl = { 'EventMenu',...
                'CloseMenu',...
                'SaveFileMenu',...
                'WindowsMenu',...
                'OrganizeWindowsMenu',...
                'SpectrogramMenu',...
                'MontageMenu',...
                'OptionsMenu',...
                'HelpMenu',...
                'ManualModeButton'};            
            
            for k = 1 : length( obj_vis)
                app.( obj_vis{ k}).Visible = status;
            end
            
            for k = 1 : length( obj_enabl)
                app.( obj_enabl{ k}).Enable = status;
            end                      
        end % function ChangeVisibility
        
        
        %% Save formatted results
        function SaveResults( app)
            if app.uidata.debug
                fprintf( 'SaveResults called.\n');
            end
            
            if isempty( app.data)
                return;
            end
            
            if isempty( app.data.savefile)
                SaveasMenuSelected( app, []);
                return;
            end
                        
            stats_fid = fopen( app.data.statsfile, 'w+');
            ev_fid = fopen( app.data.savefile, 'w+');
            
            srcfile = app.data.file;    
            % homedir = getenv('HOME');
            % if ~isempty( homedir) && length( srcfile) > length( homedir)
            %     s_len = length( homedir);
            %     if isequal( srcfile( 1:s_len), homedir)
            %         srcfile = srcfile( s_len : end);
            %         srcfile( 1) = '~';
            %     end
            % end
                
            [nb_chnls, nbsamples] = size( app.data.mat);
            data_len = nbsamples / app.data.srate;
            % nbtrls = 1 if trldata is []
            nbtrls = size( app.data.trldata, 3);
            
            if strcmpi( app.uidata.clock_type, 'clock')
                start_time = app.data.start_clock;
                end_time = app.data.end_clock;
            else
                start_time = app.data.time(1);
                end_time = app.data.time(end);
            end
            
            thresh_onset = num2str( app.ThreshOnsetEditField.Value);
            thresh_includ = num2str( app.ThreshInclusionEditField.Value);
            thresh_nc = num2str( app.ThreshNumberCyclesEditField.Value);
            if app.BKSpecifiedRB.Value == 1
                baseline = [num2str( app.BKSpecifiedEF.Value), ' s'];
            elseif app.BKWholeRB.Value == 1
                baseline = 'Whole time series';
            else
                baseline = 'Current window';
            end
            
            header_info = strjoin( ['File created by ', app.uidata.app_name, '. DO NOT EDIT.\n',...
                'Number of header lines:  11\n',...
                'Number of trials: ', num2str( nbtrls), '\n',...
                'Data file: ', srcfile, '\n',...
                'Time: ', string( start_time), ' - ', string( end_time), '\n',...
                'Total number of channels: ', num2str( nb_chnls), '\n',...
                'Length of data: ', num2str( data_len), ' s\n',...
                'Sampling rate: ', num2str( app.data.srate), ' Hz\n',...
                'Onset threshold: ', thresh_onset,...
                '; Inclusion threshold: ', thresh_includ,...
                '; Number of cycles: ', thresh_nc,...
                '; Baseline: ', baseline, '.\n',...
                repmat( '-', [1, 100]), '\n'], '');
            
            fprintf( stats_fid, header_info);
            fprintf( ev_fid, header_info);
            
            ev_header = {'Trial', 'Channel', 'EventLocation(sample)',...
                'EventType', 'AverageFreq(Hz)', 'PeakZscore', 'NoCycles', 'BPFreq'};
            
            stats_header = {'NoTrial',...
                'Channel',...
                'GammaDur(s)', 'RippleDur(s)', 'FastrippleDur(s)', 'UltrafastDur(s)', 'SpikeDur(s)',...
                'GammaNo', 'RippleNo', 'FastrippleNo', 'UltrafastNo', 'SpikeNo'};
            
            fprintf( stats_fid, '%7s, %7s, %11s, %12s, %16s, %15s, %11s, %7s, %8s, %12s, %11s, %7s\n',...
                stats_header{ 1}, stats_header{ 2}, stats_header{ 3}, stats_header{ 4},...
                stats_header{ 5}, stats_header{ 6}, stats_header{ 7}, stats_header{ 8},...
                stats_header{ 9}, stats_header{ 10}, stats_header{ 11}, stats_header{ 12});
            
            stats_format_str = '%7d, %7s, %11.3f, %12.3f, %16.3f, %15.3f, %11.3f, %7d, %8d, %12d, %11d, %7d\n';
            
            if nbtrls == 1
                raw_trlevs = {app.data.events};
            else
                raw_trlevs = app.data.trlevents;
            end
            
            % length of characters
            srate = app.data.srate;
            N = length( raw_trlevs);
            max_len = [];
            trl_evchnl = cell( N, 1);
            format_trlevs = cell( N, 1);
            for trl_idx = 1 : N
                if isempty( raw_trlevs{ trl_idx})
                    continue;
                end
                
                evs = PFormatEvents( app, raw_trlevs{ trl_idx}, start_time, srate);
                format_trlevs{ trl_idx} = evs;
                
                tmp = cellfun( @(x) strjoin( x, '; '), evs( :, 4), 'uniformoutput', false);
                tmp_max_len = cellfun( @(x) length( x), tmp, 'uniformoutput', false );
                tmp_max_len = max( cell2mat( tmp_max_len));
                max_len = max( [max_len, tmp_max_len]);
                
                trl_evchnl{ trl_idx} = arrayfun( @(x) x.label, raw_trlevs{ trl_idx}, 'Uniformoutput', false);
            end
            max_len = max( [max_len, 10]);    
            max_len = num2str( max_len);
            
            % all events of all channels and all trials
            ev_format_str = ['%5d, %7s, %21s, %', max_len,'s, %15.2f, %10.2f, %8.2f, %6.1f %6.1f\n'];
            
            fprintf( ev_fid, ['%5s, %7s, %21s, %', max_len  ,'s, %15s, %10s, %8s, %12s\n'],...
                ev_header{ 1}, ev_header{ 2}, ev_header{ 3}, ev_header{ 4},...
                ev_header{ 5}, ev_header{ 6}, ev_header{ 7},  ev_header{ 8});
            
            for trl_idx = 1 : N
                if isempty( format_trlevs{ trl_idx})
                    continue;
                end
                
                evs = format_trlevs{ trl_idx};
                tmp = cellfun( @(x) strjoin( x, '; '), evs( :, 4), 'uniformoutput', false);
                for k = 1 : size( evs, 1)
                    bpfreq = evs{ k, 8};
                    fprintf( ev_fid, ev_format_str,...
                        trl_idx, evs{ k, 2}, num2str( evs{ k, 3}), tmp{ k},...
                        evs{ k, 5}, evs{ k, 6}, evs{ k, 7}, bpfreq(1), bpfreq(2));
                end
            end            
            
            % stats for each channel
            all_chnls = app.data.labels;            
            all_evtype = app.uidata.all_event_types;
            nb_all_evtype = length( all_evtype);
            nb_chnls = length( all_chnls);
            dur = zeros( nb_chnls, nb_all_evtype);
            nb_evs = zeros( nb_chnls, nb_all_evtype);
            trl_cnt = zeros( nb_chnls, 1);
            for chnl_idx = 1 : nb_chnls
                for trl_idx = 1 : N
                    [is_exist, loc] = ismember( all_chnls{ chnl_idx}, trl_evchnl{ trl_idx});
                    if ~is_exist
                        continue;
                    end
                    
                    trl_cnt( chnl_idx) = trl_cnt( chnl_idx) + 1;
                    ev_info = raw_trlevs{ trl_idx}( loc).info;
                    for ev_idx  = 1 : size( ev_info, 1)
                        [~, ev_loc] = ismember( ev_info{ ev_idx, 2}, all_evtype);
                        ev_dur = diff( ev_info{ ev_idx, 1}) / srate;
                        dur( chnl_idx, ev_loc) = dur( chnl_idx, ev_loc) + ev_dur;
                        nb_evs( chnl_idx, ev_loc) = nb_evs( chnl_idx, ev_loc) + 1;
                    end
                end
            end
            
            loc2rm = all( trl_cnt == 0, 2);
            dur( loc2rm, :) = [];
            nb_evs( loc2rm, :) = [];
            trl_cnt( loc2rm, :) = [];
            all_chnls( loc2rm) = [];
            
            for k = 1 : length( all_chnls)
                fprintf( stats_fid, stats_format_str,...
                    trl_cnt( k), all_chnls{ k},...
                    dur( k, 1), dur( k, 2), dur( k, 3), dur( k, 4), dur( k, 5),...
                    nb_evs( k, 1), nb_evs( k, 2), nb_evs( k, 3), nb_evs( k, 4), nb_evs( k, 5));
            end
 
            fclose( ev_fid);
            fclose( stats_fid);
            
            app.data.results_saved = 'yes';
            
            txt = ['Results were successfully saved as: ', app.data.savefile];
            PUpdateStatus( app, txt, app.uidata.msg_fc);            
        end % function Save Results
        
        
        function InitializeUidata( app)        
            % Default configuration file
            p = fileparts( mfilename( 'fullpath'));
            
            % Load default configurations default_config.mat exists
            cfg_file = fullfile( p, 'default_config.mat');
            if exist( cfg_file, 'file')
                cfg = load( cfg_file);
                cfg = cfg.cfg;
            else
                cfg = [];
            end
                     
            
            %% Initialize UI data
            app.uidata = [];
            
            % Name of this app, to be appeared in the resulting text file
            app.uidata.app_name = 'HFOApp';
            
            % Prefiex to add to the saving file name
            app.uidata.savename_prefix = 'HFOEvents_';
            
            % Show xlabel as clock time or time
            % 'clock' | 'seconds'
            app.uidata.clock_type = 'clock';

            % number of x ticks
            app.uidata.nb_xticks = 4;        
            
            % ytick label rotation on time windows
            app.uidata.time_ytick_rotation = 0;
            
            % true | false, print debug information
            app.uidata.debug = false;
            
            % font color for event info and status bar
            app.uidata.msg_fc = 'b';
            
                        
            % event box and cursor line handles for detected events
            % array of gobjests
            app.uidata.boxh = [];
            app.uidata.cursor_h = [];
            
            %% line style and line width of the rectangle
            app.uidata.box_linecolor = 'r';
            app.uidata.box_linestyle = '--';
            app.uidata.box_linewidth = 0.75;
            
            % line color of the cursor
            app.uidata.cursor_linecolor = 'b';
            % cursor color on spectrogram plot
            app.uidata.cursor_linecolor_freq = 'k';
            app.uidata.cursor_linewidth = 0.75;
            
            app.uidata.ts_linewidth = 0.75;
            
            app.uidata.cursor_full_range = 'no';

            % event types
            app.uidata.all_event_types = {'gamma';...
                'ripple';...
                'fastripple';...
                'ultrafast';...
                'spike'};
            
            % default event color, color of the last will be used if
            % there's more than one event types for an event
            app.uidata.all_event_color = {[1 0 0],...
                [0 0 1],...
                [0 1 0],...
                [0.5, 0.7, 0.1],...
                [0.3, 0.1, 0]};
            
            % 'location' | 'channel' (default), how the events appear in
            % the event listbox
            app.uidata.event_sort_method = 'channel';
            
            % detection on multiple channels
            app.uidata.mult_chnl_detect = 'yes';
            
            % initial time window length, in seconds
            app.uidata.win_len = 1;
            
            % step size when the <</< and >>/> buttons are pressed
            %  relative to the window length, 0-1
            app.uidata.win_major_step = 1;
            app.uidata.win_minor_step = 0.25;
            
            % range of time series of interest, [lo, hi] in samples
            app.uidata.xrange = [];
            
            % sample location of the current time window
            app.uidata.data_range = [];
            
            % start location of plot range
            app.uidata.start_loc = 1;
            
            % current cursor location, scalar
            app.uidata.cursor_loc = [];
            
            % manual events start and end
            app.uidata.manual_start = [];
            app.uidata.manual_end = [];
            % length of extension for adjustment of manual events, in seconds
            app.uidata.manual_adj_win = 0.05;
            
            % data structure
            app.uidata.cur_event = [];
            
            % height of the cursor line and event box
            app.uidata.cursor_ypnt = [0, 1];
            app.uidata.clust_ypnt = [0, 0, 1, 1, 0];
            
            % lock selction of current channel, 'yes' | 'no'
            app.uidata.lock_current_channel = 'no';
            
            app.uidata.previous_file_closed = 'no';
            
            app.uidata.fontsize = 12;
            app.uidata.color_main = 0.05 * ones( 1, 3);
            app.uidata.color_current_channel = 0.2 * ones( 1, 3);
            
            % Line color between channels in main window
            app.uidata.color_main_separator = [0.8, 0, 0];
            % line color between filtered time series in main window
            app.uidata.color_minor_separator = [0.1, 0, 0];
            app.uidata.show_main_separator = 'yes';
            app.uidata.show_minor_separator = 'yes';
            app.uidata.color_current_background = 0.95 * ones( 1, 3);
            app.uidata.show_current_background = 'yes';
            
            % 1 x 2 cell, {label, index in bpfilt.mat}
            app.uidata.current_channel = {};            
            
            % If there's any data point within +/- 500 ms aroudn the cusrolor meet the criteria
            % cluster search time window
            %search_len = 2;
            app.uidata.autoev_search_len = 2;
            
            % cusor must be within the cluster, or within a cenrtain
            % distance from the cluster
            app.uidata.autoev_distance_thresh = 0.5;
            
            app.uidata.default_autoev_search_len = 1; % 1 s
            app.uidata.default_autoev_distance_thresh = 0.5; % 0.5s
            
            % merge detected events
            % If all z values between events are greater than 2
            % and the distance between them is less thann a threshold
            % is_merge = 'no';
            app.uidata.autoev_automerge = 'no';
            
            % in seconds, any clusters within this distance will be mreged
            % merge_durthresh = 0.05;
            app.uidata.autoev_automerge_durthresh = 0.05;
            % all samples between two clusters are greater than this threshold
            app.uidata.autoev_automerge_zthresh = 1.49999999;
            app.uidata.autoev_use_spec = false;
            
            
            %% Time-frequency plots
            % keep the spectrogram of all calculated channels
            app.uidata.tf_keepall = 'no';
            
            % calculate spectrogram for entire time series
            % 'yes' | 'no' (current window only)
            % wired showing image problem always yes maybe
            app.uidata.tf_entire = 'yes';
            
            % pad size for current window spectrogram calculation, in seconds
            app.uidata.tf_padsize = 1;
            
            % calculate spectrogram and save it to data structure
            % frequency range
            app.uidata.tf_foi_range = [50, 250];
            % number of linearly spaced of frequencies
            app.uidata.tf_foi_len = 100;
            % frequency tick
            app.uidata.tf_ytick = [];
            
            % multiple spectrogram windows 'yes'|'no'
            app.uidata.tf_multi_wins = 'no';
            
            app.uidata.tf_autoscale = 'yes';            
            app.uidata.freq_colormap = 'jet'; 
            
            app.uidata.entire_tf_plotted = 'yes';
            
            % HFO detection settings
            % Peak z score threshold
            app.uidata.thresh_inclusion = 5;
            % Onset threshold
            app.uidata.thresh_onset = 3;
            % Number of cycles threshold
            app.uidata.thresh_nbcycle = 3;
            % Baseline window
            % Use whole time series as baseline
            app.uidata.bk_whole = 'no';
            % Use current time window as baseline
            app.uidata.bk_current = 'no';
            % Use a specififed time window (centered at cursor) as baseline
            app.uidata.bk_specified = 'yes';
            app.uidata.bk_specifiy_val = 5;
            
            
            % Use default values
            if ~isempty( cfg)
                nam = fieldnames( cfg);
                for k = 1 : length( nam)
                    app.uidata.( nam{k}) = cfg.( nam{k});
                end
            end
            
            
            % Band-pass filter time window handles, cell array
            app.TimeWin = {};
            
            % Spectrogram window handles, cell array
            app.FreqWin = {};
            
            % handle to floating y-scale setup of bandpass filter windows
            app.HFloatYScale = [];
            
            % Perference setup window handle
            app.HMiscOption = [];
            
            % default time window X scale
            app.XScaleSpinner.Value = app.uidata.win_len;
            
            % Y scale
            app.YScaleSpinner.Value = 100;
            
            app.UIAxes.Toolbar.Visible = 'off';
            app.StatusLabel.Text = '';
            
            app.AllChannelListBox.Items = {};
            app.ChannelListBox.Items = {};
            app.EventListBox.Items = {};
            
            app.StartClockEditField.Value = '';
            app.EndClockHEditField.Value = '';
            
            
            % Default event detection thresholds
            app.ThreshInclusionEditField.Value = app.uidata.thresh_inclusion;
            app.ThreshOnsetEditField.Value = app.uidata.thresh_onset;
            app.ThreshNumberCyclesEditField.Value = app.uidata.thresh_nbcycle;
            
            if strcmpi( app.uidata.bk_whole, 'yes')
                app.BKWholeRB.Value = 1;
                app.BKSpecifiedEF.Enable = 'off';
                
            elseif strcmpi( app.uidata.bk_specified, 'yes')   
                app.BKSpecifiedRB.Value = 1;
                app.BKSpecifiedEF.Enable = 'on';
                
            else % current time window as baseline
               app.BKCurrentRB.Value = 1;
               app.BKSpecifiedEF.Enable = 'off';
            end
                        
            % Disable manual event detection
            app.StartEventButton.Enable = 'off';
            app.EndEventButton.Enable = 'off';
            app.ManualEventAutoAdjCheckBox.Enable = 'off';
                        
            app.StatusLabel.FontSize = app.uidata.fontsize;
            app.DurationLabel.FontSize = app.uidata.fontsize;            
            
            if strcmpi( app.uidata.mult_chnl_detect, 'yes')
                app.MultipleDetectionCheckBox.Value = 1;
            else
                app.MultipleDetectionCheckBox.Value = 0;
            end
            
            % Merge two events, disabled in current version
            app.MergeEventButton.Enable = 'off';
        end % Initialize UiData
                
        
        function [is_overlap, is_within, overlap] = RangeOverlap( ~, xrng, yrng)
            % Test is xrng is within the range of yrng
            %
            % Input
            %   xrng, [lo, hi] or scalar
            %   yrng, [lo, hi]
            %
            % Output
            %   is_overlap, logical value whether xrng and yrng overlaps
            %   is_within, whether xrng is within yrng
            %   overlap, overlap range
            
            if isscalar( xrng)
                xrng = xrng * ones( 1, 2);
            end
            
            x = xrng(1) >= yrng(1) && xrng(1) <= yrng(2);
            y = xrng(2) >= yrng(1) && xrng(2) <= yrng(2);
            x1 = yrng(1) >= xrng(1) && yrng(1) <= xrng(2);
            y1 = yrng(2) >= xrng(1) && yrng(2) <= xrng(2);
            is_overlap = x || y || x1 || y1;
            
            x = yrng(1) <= xrng(1) && yrng(2) >= xrng(1);
            y = yrng(1) <= xrng(2) && yrng(2) >= xrng(2);
            is_within = x && y;
            
            if is_overlap || is_within
                overlap = sort( [xrng(:); yrng(:)]);
                overlap = overlap( 2:3)';
            else
                overlap = [];
            end
            
        end % RangeOverlap
        
        
        function [pk_loc, pk_range] = FindPeakRange( app, v, loc, thresh, real_thresh)
            % v, binarize vector
            % loc, scalar, initial sample location
            % thresh, percentage of the peak 0-100 if real_thresh == 0
            %         thresh will be used if real_thresh ~= 0
            %
            % Usage
            %   pk_range = FindPeakRange( v, loc, thresh, 1);
            
            if app.uidata.debug
                fprintf( 'FindPeakRange called.\n');
            end
            
            [onset, offset] = FindOnset( app, v >= (v(loc) - 10*eps));
            clust_ind = find( onset <= loc & offset >= loc);
            
            [~, pk_loc] = max( v( onset( clust_ind) : offset( clust_ind)));
            pk_loc = pk_loc + onset( clust_ind) - 1;
            
            if real_thresh == 0
                new_thresh = thresh * v( pk_loc);
            else
                new_thresh = thresh;
            end
            
            [onset, offset] = FindOnset( app, v >= (new_thresh - eps));            
            if isempty( onset)
                pk_range = [];
            else
                clust_ind = find( onset <= pk_loc & offset >= pk_loc);                
                pk_range = [onset( clust_ind), offset( clust_ind)];
            end
            
        end % function find peak range
        
        
        %% Find onset and offset of a binarized vector
        function [onset, offset] = FindOnset( ~, vec_bin)            
            onset = [];
            offset = [];
            ind = find( vec_bin);
            if ~isempty( ind)
                ind = ind(:);
                clust_bd = find( diff( ind) > 1);
                clust_bd = clust_bd(:);
                onset = ind( [1; clust_bd+1]);
                offset = ind( [clust_bd; length( ind)]);
            end
        end
        
        
        %% time range of interest
        function UpdateClock( app)
            if app.uidata.debug
                fprintf( 'UpdateClock called.\n');
            end
            
            fc_s = app.StartClockEditField.FontColor;
            val_s = app.StartClockEditField.Value;
            fc_e = app.EndClockHEditField.FontColor;
            val_e = app.EndClockHEditField.Value;
            fc_j = app.JumpToClockEditField.FontColor;
            val_j = app.JumpToClockEditField.Value;
            
            % Bandpass filter windows
            cellfun( @(x)...
                set( x.StartClockEditField,...
                'FontColor', fc_s,...
                'Value', val_s),...
                app.TimeWin);
            
            cellfun( @(x)...
                set( x.EndClockHEditField,...
                'FontColor', fc_e,...
                'Value', val_e),...
                app.TimeWin);
            
            cellfun( @(x)...
                set( x.JumpToClockEditField,...
                'FontColor', fc_j,...
                'Value', val_j),...
                app.TimeWin);
            
            
            % Spectrogram windows
            cellfun( @(x)...
                set( x.StartClockEditField,...
                'FontColor', fc_s,...
                'Value', val_s),...
                app.FreqWin);
            
            cellfun( @(x)...
                set( x.EndClockHEditField,...
                'FontColor', fc_e,...
                'Value', val_e),...
                app.FreqWin);
            
            cellfun( @(x)...
                set( x.JumpToClockEditField,...
                'FontColor', fc_j,...
                'Value', val_j),...
                app.FreqWin);
        end % function update clock
        
        
        function [ev_types2disp, ev_select] = GetEventType2Disp( app)
            % ev_types2disp, which type of events to overlay on time series plots 
            % ev_select, currently selected event type            
            if app.uidata.debug
                fprintf( 'GetEventType2Disp called.\n');
            end
            
            ind = [app.ETGammaCheckBox.Value,...
                app.ETRippleCheckBox.Value,...
                app.ETFastRippleCheckBox.Value, ...
                app.ETUltraFastCheckBox.Value,...
                app.ETSpikeCheckBox.Value];

            ev_types2disp = app.uidata.all_event_types;
            ev_select = ev_types2disp( ind == 1);
            
            if app.ShowAllEventButton.Value ~= 1
                ev_types2disp = ev_types2disp( ind == 1);
            end            
        end % function Get Event Type2disp
        
        
        %% Change the checkboxes of the event type
        function SetEventType( app, evtype)
            if app.uidata.debug
                fprintf( 'SetEventType called.\n');
            end
            
            % Change the check box value of the event types.
            if ismember( app.uidata.all_event_types{1}, evtype)
                app.ETGammaCheckBox.Value = 1;
            else
                app.ETGammaCheckBox.Value = 0;
            end
            
            if ismember( app.uidata.all_event_types{2}, evtype)
                app.ETRippleCheckBox.Value = 1;
            else
                app.ETRippleCheckBox.Value = 0;
            end
            
            if ismember( app.uidata.all_event_types{3}, evtype)
                app.ETFastRippleCheckBox.Value = 1;
            else
                app.ETFastRippleCheckBox.Value = 0;
            end
            
            if ismember( app.uidata.all_event_types{4}, evtype)
                app.ETUltraFastCheckBox.Value = 1;
            else
                app.ETUltraFastCheckBox.Value = 0;
            end
            
            if ismember( app.uidata.all_event_types{5}, evtype)
                app.ETSpikeCheckBox.Value = 1;
            else
                app.ETSpikeCheckBox.Value = 0;
            end
        end %function Set EventType
        
        
        %% Estimate the number of cycles from bandpass filtered time series
        function [nb_cycle, avg_freq] = GetNbcycles( ~, v, srate)
            % Estimate average frequency and srate based on peaks
            % v, vector
            % srate, sampling rate
            
            warning('off', 'signal:findpeaks:largeMinPeakHeight');
            
            nb_cycle = 0;
            avg_freq = 0;
            threshold = 0;            
            if ~any( v > threshold) || length( v) < 3
                return;
            end
            
            try
                [~, pklocs] = findpeaks( v,...
                    'MinPeakHeight', threshold,...
                    'MinPeakDistance', 2/srate);
                
                if length( pklocs) > 1
                    cycle_dur = mean( diff( pklocs));
                    nb_cycle = length( v) / cycle_dur;
                    avg_freq = srate / cycle_dur;
                end
                
            catch
                return;
            end            
        end % estimating number of oscillatory cycles
        
        
        function surfix = GetClockSurfix( ~)
            % Function retrieves the current time which can be appended to file or folder
            % names to avoid overwriting existing ones            
            surfix = datestr( clock);
            surfix = strrep( surfix, ':', '-');
            surfix = strrep( surfix, ' ', '-');
        end
        
        
        function [evs, msg] = ValidateEvents( app, evs, dest_labels, ev_types, xrange)
            % evs has to be a structure array that contains fields: label and info
            % dest_labels, all channel labels
            % ev_types, all event types
            % xrange, sample range [1, nbsamples]
            % 
            % Channel will be removed if it does not exist in dest_labels
            % Event will be trimmed/removed if its location is out of xrange
            
            if app.uidata.debug
                fprintf( 'ValidateEvents called.\n');
            end
            
            if ~isstruct( evs)
                msg = 'evs must be a strucutre array contains following fields: label, info.';
                return;
            end
            
            try
                fnames = fieldnames( evs);                
            catch
                msg = 'evs must be a strucutre array contains following fields: label, info.';
                return;               
            end
            
            if ~all( ismember( fnames, {'label', 'info'}))
                msg = 'Invalid event data structure.';
                return;
            end
            
            chnl_modified = false;
            evs_modified = false;
            
            nb_chnls = length( evs);
            chnl2keep = ones( nb_chnls, 1);
            for chnl_idx = 1 : nb_chnls
                cur_chnl = evs( chnl_idx).label;
                
                if ~ismember( cur_chnl, dest_labels)
                    % remove the channel if it's not in dest_labels
                    chnl2keep( chnl_idx) = 0;
                    continue;
                end
                                
                nb_evs = size( evs( chnl_idx).info, 1);
                ev_ind2keep = ones( nb_evs, 1);
                for ev_idx = 1 : nb_evs
                    % 1 x 3 cell
                    ev_info = evs( chnl_idx).info( ev_idx, :);                    
                    if ~iscell( ev_info) || length( ev_info) ~= 3
                        ev_ind2keep( ev_idx) = 0;
                        evs_modified = true;
                        continue;
                    end
                    
                    [~, ~, overlap_rng] = RangeOverlap( app, ev_info{ 1}, xrange);
                    if isempty( overlap_rng)
                        evs_modified = true;
                        ev_ind2keep( ev_idx) = 0;
                        continue;
                    end
                    
                    cur_ev_type = cellfun( @(x) strtrim(x), ev_info{ 2}, 'UniformOutput', false);                    
                    if ~all( ismember( cur_ev_type, ev_types))
                        evs_modified = true;
                        ev_ind2keep( ev_idx) = 0;
                    end
                end
                
                evs( chnl_idx).info = evs( chnl_idx).info( ev_ind2keep == 1, :);
                
                if isempty( evs( chnl_idx).info )
                    chnl2keep( chnl_idx) = 0;
                end
            end % channel loop
            
            % remove channels
            evs( chnl2keep == 0, :) = [];
            
            if any( chnl2keep == 0)
                chnl_modified = true;
            end
            
            if chnl_modified && evs_modified
                msg = 'Events were loaded with channel and events modified.';
                
            elseif chnl_modified && ~evs_modified
                msg = 'Events were loaded with channel modified.';
                
            elseif ~chnl_modified && evs_modified
                msg = 'Events were loaded with events modified.';
                
            else
                msg = 'Events were loaded completely.';
            end
            
        end % function validate events
        
        
        function [msg, dur, peakz, nb_cycle] = EventInfo( ~, evs, srate)
            % summary infomation of evs, which is a structure array
            %  that contains fields: label, location, and info
            %
            % info is a data structure contains fields: location, avgfreq,
            % nbcycles, peakz           
            
            % number of channels
            nbchnls = length( evs);
            
            dur = [];
            peakz = [];
            nb_cycle = [];
            if nbchnls == 0
                msg = 'No event.';
                return;
            end

            % total number of events            
            ind = (1 : nbchnls)';
            nb_evs = arrayfun( @(x) size( evs( x).Location, 1), ind, 'UniformOutput', false);
            nb_evs = sum( cell2mat( nb_evs));
            
            % duration
            dur = arrayfun( @(x) diff( evs( x).Location, [], 2), ind, 'UniformOutput', false);
            dur = 1000 * cell2mat( dur) / srate;
            
            % peak z
            peakz = arrayfun( @(x) evs( x).info.PeakZScore, ind, 'UniformOutput', false);
            peakz = cell2mat( peakz);
            
            % number of cycles
            nb_cycle = arrayfun( @(x) evs(x).info.NoCycles, ind, 'UniformOutput', false);  
            nb_cycle = cell2mat( nb_cycle);
                        
            if nbchnls == 1
                chnl_s = evs(1).label;
            else
                chnl_s = [num2str( nbchnls), ' channels'];
            end
            
            if nb_evs == 1
                ev_s = 'event';
                d_s = 'Duration';
            else
                ev_s = 'events';
                d_s = 'Duration (total)';
            end
            
            msg = sprintf( '%d %s found for %s. %s: ', nb_evs, ev_s, chnl_s, d_s);
            
            msg = sprintf( '%s%.2f ms; PeakZ: %.2f',...
                msg,...
                sum( dur, 'all'),...
                max( peakz, [], 'all'));
            
        end % formatted uidata.current_event information

        
        function txt = EventInfoStr( ~, dur, peakz, nb_cycle)
            dur = dur(:);
            peakz = peakz(:);
            nb_cycle = nb_cycle(:);
            
            s1 = sprintf( '%.0f', dur(1));        
            s2 = sprintf( '%.1f', peakz(1));
            s3 = sprintf( '%.1f', nb_cycle(1));
            for k = 2 : length( dur)
                s1 = sprintf( '%s, %.0f', s1, dur(k));
                s2 = sprintf( '%s, %.1f', s2, peakz(k));
                s3 = sprintf( '%s, %.1f', s3, nb_cycle(k));
            end
       
            if length( dur) < 2
                txt = sprintf( 'Duration: %s ms. PeakZ: %s. NoCycle: %s.',...
                    s1, s2, s3);
            else
                txt = sprintf( 'Durations: %s ms. PeakZs: %s. NoCycles: %s.',...
                    s1, s2, s3);
            end                        
        end    
        
        
        %% Remove event from currently detected events
        function evinfo = CleanCurEvent( app, cur_chnl, evinfo, ev_type)
            % cur_chnl, channel label
            %
            % evinfo is a curently detected events, 
            %   a data strucutre: Location, PeakZ, AvgFreq, NoCycles, etc.
            %
            % ev_type, currently selected event types
        
            if app.uidata.debug
                fprintf( 'CleanCurEvent called.\n');
            end
            
            if isempty( cur_chnl) || isempty( evinfo)
                return;
            end
        
            nb_locs = size( evinfo.Location, 1);
            loc2rm = zeros( nb_locs, 1);
            for ev_idx = 1 : nb_locs
                tmp_loc = evinfo.Location( ev_idx, :);
                is_exist = PIsEventExist( app, tmp_loc, cur_chnl, ev_type);
                if is_exist
                    loc2rm( ev_idx) = 1;
                end
            end
            
            fnames = fieldnames( evinfo);
            for ind = 1 : length( fnames)
                evinfo.( fnames{ ind})( loc2rm == 1, :) = [];
            end        
        end % function remove existing events from detected 
        
        
        function ChangeClockType( app)
            % Change Start End Time Edits 
            xrange = (app.uidata.xrange - 1) / app.data.srate;
            
            if strcmpi( app.uidata.clock_type, 'clock')
                c = app.data.start_clock + seconds( xrange); 
            else          
                c = app.data.time( 1) + xrange;
            end        
            
            c = string( c);            
            app.StartClockEditField.Value = c(1);
            app.EndClockHEditField.Value = c(2);  
        end
        
        
        function TrialChanges( app)
            % app.data.mat, current trial, channel x sample
            % app.data.trldata, all trials, channel x sample x trial
            % app.uidata.trial_index
            
            % Bandpass filtered data
            % Spectrogram data
            % Events
            
            if app.uidata.debug
                fprintf( 'Change trial.\n');
            end
             
            trl_idx = app.uidata.trial_index;
            app.CurTrialEditField.Value = trl_idx;
            app.data.mat = app.data.trldata( :, :, trl_idx);
            
            cur_chnls = app.ChannelListBox.Items;
            if ~isempty( cur_chnls)
                [~, chl_loc] = ismember( cur_chnls, app.data.labels);
                lo = min( app.data.mat( chl_loc, :), [], 'all');
                hi = max( app.data.mat( chl_loc, :), [], 'all');
                app.data.raw_limit = [lo,  hi];
                app.data.raw_limit = [-1, 1] * max( abs( [lo, hi]));
            end
            
            % band-pass filtering
            nb_wins = length( app.data.bpfilt.mat);
            for win_idx = 1 : nb_wins
                filt_labels = app.data.bpfilt.labels{ win_idx};
                nb_chnls = length( filt_labels);
                for chnl_idx = 1 : nb_chnls                    
                    txt = sprintf( 'Updating band-pass filtering data for window: %d/%d; Channel: %d/%d.',...
                        win_idx, nb_wins, chnl_idx, nb_chnls);
                    
                    PUpdateStatus( app, txt, app.uidata.msg_fc);
                    
                    tmp_label = filt_labels{ chnl_idx};
                    [~, loc] = ismember( tmp_label, app.data.labels);
                    
                    [app.data.bpfilt.mat{ win_idx}( chnl_idx, :),...
                        app.data.bpfilt.amp{ win_idx}( chnl_idx, :)] = ...
                        HFOFiltData( app.data.mat( loc, :),...
                        app.data.srate,...
                        app.data.bpfilt.param{ win_idx});
    
                    if exist( 'zscore', 'file')
                        app.data.bpfilt.z{ win_idx}( chnl_idx, :) = zscore( app.data.bpfilt.amp{ win_idx}( chnl_idx, :));
                    else
                        app.data.bpfilt.z{ win_idx}( chnl_idx, :) = ...
                            ( app.data.bpfilt.amp{ win_idx}( chnl_idx, :) - ...
                             mean( app.data.bpfilt.amp{ win_idx}( chnl_idx, :)) ) ...
                            / std( app.data.bpfilt.amp{ win_idx}( chnl_idx, :));
                    end
                end
                
                % update data range
                [~, chnl_loc] = ismember( cur_chnls, filt_labels);
                min_val = min( app.data.bpfilt.mat{ win_idx}( chnl_loc, :), [], 'all');
                max_val = max( app.data.bpfilt.mat{ win_idx}( chnl_loc, :), [], 'all');
                app.data.bpfilt.param{ win_idx}.raw_limit = [-1, 1] * max( abs([ min_val, max_val]));
                app.data.bpfilt.param{ win_idx}.limit = [-1, 1] * max( abs([ min_val, max_val]));
            end
            
            %% spectrogram, channel x 1
            % app.data.tf.mat{ N} = tf;
            % app.data.tf.param{ N}.freq = freq_axis;
            % app.data.tf.param{ N}.climit = [0, prctile( tf(:), 99.9)];
            % app.data.tf.param{ N}.Name = cur_chnl;
            % app.data.tf.param{ N}.xrange = freq_xrange;
            % app.data.tf.labels{ N} = cur_chnl;
            
            nb_wins = length( app.data.tf.mat);
            PUpdateClustInfo( app, 'Updating spectrogram ...', app.uidata.msg_fc);
            PUpdateStatus( app, 'Updating spectrogram ...', app.uidata.msg_fc);
            drawnow;
            
            for win_idx = 1 : nb_wins
                PUpdateSpectrogram( app, app.data.tf.labels{ win_idx}, true);
            end
            
            for win_idx = 1 : length( app.FreqWin)
                if strcmpi( app.uidata.entire_tf_plotted, 'yes')
                    [~, chnloc] = ismember( app.FreqWin{ win_idx}.UIFigure.Name, app.data.tf.labels);                    
                    cla( app.FreqWin{ win_idx}.UIAxes);
                    imagesc( app.FreqWin{ win_idx}.UIAxes,...
                        1 : app.data.nbsamples,...
                       app.data.tf.param{ chnloc}.freq,...
                       app.data.tf.mat{ chnloc});
                end
            end
            
            % events
            if length( app.data.trlevents) >= app.uidata.trial_index
                app.data.events = app.data.trlevents{ app.uidata.trial_index};                
            else
                app.data.events = [];
            end
            
            % current detected events
            app.uidata.cur_event = [];
            
            PUpdateEventList( app);
            Redraw( app);
        end % switch between trials
        
    end % private methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath( genpath( fileparts( mfilename( 'fullpath'))));
            
            % Tooltip of these objects will be passed to Time and Freq wins
            app.tooltip_objs = {'OpenMenu',...
                'CloseMenu',...
                'SaveFileMenu',...
                'QuitMenu',...
                'SaveMenu',...
                'SaveasMenu',...
                'ExportasMatlabFileMenu',...
                'LoadMenu',...
                'AddbandpassfilterwindowMenu',...
                'AddtimefrequencywindowMenu',...
                'OrganizeWindowsMenu',...
                'ChangeMontageMenu',...
                'ConfigureMenu',...
                'SetpreferencesMenu',...
                'AutomatedHFOdetectionMenu',...
                'SavesettingsasdefaultMenu',...
                'HelpMenu',...
                'XScaleSpinner',...
                'YScaleSpinner',...
                'FiltYScaleButton',...
                'StartClockEditField',...
                'EndClockHEditField',...
                'ResetTimeButton',...
                'AddEventButton',...
                'ManualEventAutoAdjCheckBox',...
                'ManualModeButton',...
                'StartEventButton',...
                'EndEventButton',...
                'ClearButton',...
                'AddAllChannel',...
                'AddChannel',...
                'RemoveChannel',...
                'RemoveAllChannel',...
                'RemoveChannelButton',...
                'AddButton',...
                'PreTrialButton',...
                'NextTrialButton',...
                'CurTrialEditField',...
                'JumpToClockEditField',...
                'EventListBox',...
                'TypePanel',...
                'RemoveEventButton',...
                'UpdateEventButton',...
                'MergeEventButton',...
                'EventTypetoDisplayButtonGroup',...
                'EventChanneltoDisplayButtonGroup',...
                'ThreshOnsetEditField',...
                'ThreshInclusionEditField',...
                'ThreshNumberCyclesEditField',...
                'BackgroundTimeWindowButtonGroup',...
                'BKWholeRB',...
                'BKCurrentRB',...
                'BKSpecifiedRB',...
                'BKSpecifiedEF',...
                'MultipleDetectionCheckBox',...
                'StepBackButton',...
                'BackButton',...
                'ForwardButton',...
                'StepForwardButton',...
                'StatusLabel'};
                        
            % Consider to add a default configuration file to the toolbox folder.
            InitializeUidata( app);
            
            srn_sz = get( 0, 'ScreenSize');
            fig_pos = [0, 0, 1000, 900];
            if srn_sz(3) < 1200
                fig_pos( 3) = 700;
            end
            
            if srn_sz(4) < 900
                fig_pos(4) = 600;
            end
            
            fig_pos(1) = max( [0, (srn_sz(3) - fig_pos(3)) / 6]);
            fig_pos(2) = max( [0, (srn_sz(4) - fig_pos(4)) / 6]);
            
            app.HFOAppUIFigure.Position = fig_pos;
            PResizeWindows( app, app.HFOAppUIFigure.Position, 'main');
                        
            % Disable all objects, except file open menu and status label
            ChangeVisibility( app, 'off');
            
            % Dispaly welcome message
            cla( app.UIAxes);
            
            t = text( app.UIAxes, 0.3, 0.2,...
                'Welcome to HFOApp',...
                'HorizontalAlignment', 'center',...
                'VerticalAlignment', 'middle');
            
            set( t,...
                'Color', app.uidata.msg_fc,...
                'FontSize', 24,...
                'FontWeight', 'bold');
            
            txt = {'HFOApp is free software: you can redistribute it and/or modify it',...
                'under the terms of the GNU General Public License as published by',...
                'the Free Software Foundation, either version 3 of the license, or',...
                '(at your option) any later version.'};
            
            t = text( app.UIAxes,...
                0.3, -0.2,...
                txt,...
                'HorizontalAlignment', 'center',...
                'VerticalAlignment', 'middle');
            
            set( t,...
                'Color', 'k',...
                'FontSize', 14);
            
            set( app.UIAxes, ...
                'XLim', [-1, 1],...
                'Xtick', [],...
                'YLim', [-1, 1],...
                'YTick', []);
            
            disableDefaultInteractivity( app.UIAxes);
                        
            % 'on'/'off' Enable merge events button
            app.MergeEventButton.Visible = 'off';
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            POpenFileMenuSelected( app, event, app.HFOAppUIFigure);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            PCloseFileMenuSelected( app, event, app.HFOAppUIFigure);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            PQuitAppMenuSelected( app, event, app.HFOAppUIFigure);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            PSaveMenuSelected( app, event);
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            PSaveasMenuSelected( app, event);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            PLoadeEventMenuSelected( app, event, app.HFOAppUIFigure);
        end

        % Menu selected function: OrganizeWindowsMenu
        function OrganizeWindowsMenuSelected(app, event)
            POrganizeWindowsMenuSelected( app, event);
        end

        % Menu selected function: AddtimefrequencywindowMenu
        function AddtimefrequencywindowMenuSelected(app, event)
            PAddFreqWinPushed( app);
        end

        % Menu selected function: ChangeMontageMenu
        function ChangeMontageMenuSelected(app, event)
            PChangeMontage( app, event);
        end

        % Menu selected function: AddbandpassfilterwindowMenu
        function AddbandpassfilterwindowMenuSelected(app, event)
            PAddTimeWinButtonPushed( app);
        end

        % Menu selected function: ConfigureMenu
        function ConfigureMenuSelected(app, event)
            PSpectrogramMenu( app);
        end

        % Menu selected function: SetpreferencesMenu
        function SetpreferencesMenuSelected(app, event)
            PWinOptionsMenuSelected( app, event);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            if ~isempty( app.data)
                [~, filename] = fileparts( app.data.file);
                txt = sprintf( '%s; Sampling rate: %d Hz; Length: %.1f s.', ...
                    filename,...
                    app.data.srate,...
                    size( app.data.mat, 2) / app.data.srate);
                
                PUpdateStatus( app, txt, app.uidata.msg_fc);
                
            else
                PUpdateStatus( app, 'No data were loaded', app.uidata.msg_fc);
            end
        end

        % Value changed function: XScaleSpinner
        function XScaleSpinnerValueChanged(app, event)
            UpdateXScale( app, app.XScaleSpinner.Value, event.PreviousValue);
        end

        % Value changed function: YScaleSpinner
        function YScaleSpinnerValueChanged(app, event)
            Redraw(app);
        end

        % Button pushed function: FiltYScaleButton
        function FiltYScaleButtonPushed(app, event)
            PFiltYScaleButtonPushed( app);
        end

        % Value changed function: StartClockEditField
        function StartClockEditFieldValueChanged(app, event)
            value = app.StartClockEditField.Value;
            end_clock = app.EndClockHEditField.Value;
            PUpdateDataRange( app, value, event.PreviousValue, end_clock, end_clock);
        end

        % Value changed function: EndClockHEditField
        function EndClockHEditFieldValueChanged(app, event)
            value = app.EndClockHEditField.Value;
            start_clock = app.StartClockEditField.Value;
            PUpdateDataRange( app, start_clock, start_clock, value, event.PreviousValue);
        end

        % Value changed function: JumpToClockEditField
        function JumpToClockEditFieldValueChanged(app, event)
            value = app.JumpToClockEditField.Value;
            is_valid = false;
            srate = app.data.srate;            
            if strcmpi( app.uidata.clock_type, 'clock')            
                try
                    d = datetime( value, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');
                    d1 = TimeDiff( app, app.data.start_clock, d);
                    d2 = TimeDiff(app, d, app.data.end_clock);
                    if d1 > -eps && d2 > -eps
                        N = TimeDiff( app, app.data.start_clock, d);
                        start_loc = floor( N * srate);
                        is_valid = true;
                    end
                    
                catch
                    start_loc = [];
                end
                
            else % seconds         
                value = str2num( value);       %#ok<ST2NM> 
                t_lim = app.data.time([1, end]);
                t_tol = 0.1 / srate;
                t_lim = t_lim + [-1, 1] * t_tol;
                start_loc = [];
                if isscalar( value) && value >= t_lim(1) && value <= t_lim(2)
                    start_loc = floor( srate * (value - t_lim(1)) ) + 1;
                    is_valid = true;
                end
            end % clock time or seconds
           
            start_loc = max( [1, start_loc]);
            xlim = app.uidata.xrange( [1, end]) + [-1, 1] * eps;
            is_sloc_ok = start_loc >= xlim(1) && start_loc <= xlim(2);
            if ~is_valid || ~is_sloc_ok
                app.JumpToClockEditField.FontColor = 'r';
                Redraw( app);
                PUpdateStatus( app, 'Invalid time', 'r');
                return;
            end

            win_len = floor( srate * app.XScaleSpinner.Value);
            start_loc = min( [start_loc, xlim(2) - win_len]);
            
            app.JumpToClockEditField.FontColor = 'k';
            app.uidata.start_loc = start_loc;
            UpdateClock( app);
            Redraw( app);            
        end

        % Button pushed function: ResetTimeButton
        function ResetTimeButtonPushed(app, event)
            if isempty( app.data)
                return;
            end
            
            if strcmpi( app.uidata.clock_type, 'clock')
                sclock = string( app.data.start_clock);
                eclock = string( app.data.end_clock);
            else
                sclock = num2str( app.data.time( 1));
                eclock = num2str( app.data.time( end));
            end
            
            app.StartClockEditField.Value = sclock;
            app.EndClockHEditField.Value = eclock;
            PUpdateDataRange( app, sclock, sclock, eclock, eclock);            
        end

        % Button pushed function: StepBackButton
        function StepBackButtonPushed(app, event)
            if isempty( app.data) || isempty( app.ChannelListBox.Items)
                return;
            end
            
            win_len = app.uidata.win_len * app.data.srate;
            tmp = floor( app.uidata.win_major_step * win_len);
            app.uidata.start_loc = max( [app.uidata.xrange(1), app.uidata.start_loc - tmp]);
            Redraw( app);   
            
            PUpdateStatus( app,...
                'Plot updated. Click on time series or press "d" to detect HFO.',...
                app.uidata.msg_fc);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            if isempty( app.data) || isempty( app.ChannelListBox.Items)
                return;
            end
            
            win_len = app.uidata.win_len * app.data.srate;
            tmp = floor( app.uidata.win_minor_step * win_len);
            app.uidata.start_loc = max( [app.uidata.xrange(1), app.uidata.start_loc - tmp]);
            Redraw( app);
            
            PUpdateStatus( app,...
                'Plot updated. Click on time series or press "d" to detect HFO.',...
                app.uidata.msg_fc);
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if isempty( app.data) || isempty( app.ChannelListBox.Items)
                return;
            end
                        
            win_len = app.uidata.win_len * app.data.srate;
            N = floor( app.uidata.win_minor_step * win_len);
            tmp = max( [app.uidata.xrange(1), app.uidata.xrange(2) - N - 1]);
            app.uidata.start_loc = min( [tmp, app.uidata.start_loc + N]);
            Redraw( app);   
            
            PUpdateStatus( app,...
                'Plot updated. Click on time series or press "d" to detect HFO.',...
                app.uidata.msg_fc);
        end

        % Button pushed function: StepForwardButton
        function StepForwardButtonPushed(app, event)
            if isempty( app.data) || isempty( app.ChannelListBox.Items)
                return;
            end

            win_len = app.uidata.win_len * app.data.srate;
            N = floor( app.uidata.win_major_step * win_len);
            tmp = max( [app.uidata.xrange(1), app.uidata.xrange(2) - N - 1]);
            app.uidata.start_loc = min( [tmp, app.uidata.start_loc + N]);
            Redraw( app);
            
            PUpdateStatus( app,...
                'Plot updated. Click on time series or press "d" to detect HFO.',...
                app.uidata.msg_fc);
        end

        % Value changed function: ManualEventAutoAdjCheckBox
        function ManualEventAutoAdjCheckBoxValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Button pushed function: AddEventButton
        function AddEventButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.EventsTab;
            [~, evtype] = GetEventType2Disp( app);
            PAddEvent( app, app.uidata.cur_event, evtype);
        end

        % Callback function
        function ManualModeButtonPushed(app, event)
            value = app.ManualModeButton.Value;
            if value == 1
                app.uidata.manual_start = [];
                app.StartEventButton.FontColor = 'k';
                app.EndEventButton.FontColor = 'k';
                app.uidata.manual_end = [];
                app.uidata.cur_event = [];
                app.EndEventButton.Enable = 'on';
                app.StartEventButton.Enable = 'on';
                app.ManualEventAutoAdjCheckBox.Enable = 'on';
                
            else
                app.StartEventButton.Enable = 'off';
                app.EndEventButton.Enable = 'off';
                app.ManualEventAutoAdjCheckBox.Enable = 'off';
            end
            
            PChangeObjectValue( app);
            Redraw( app);
        end

        % Button pushed function: StartEventButton
        function StartEventButtonPushed(app, event)
            PSetManualEventRange( app, 'start');
        end

        % Button pushed function: EndEventButton
        function EndEventButtonPushed(app, event)
            PSetManualEventRange( app, 'end');
        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)
            value = app.ChannelListBox.Value;
            if length( value) == 1 && app.LockCurrentChannelCheckBox.Value == 0
                if ~isempty( app.uidata.current_channel)
                    app.uidata.current_channel{1} = value;
                else
                    app.uidata.current_channel = {value, 0};
                end
                
                Redraw( app);
            end
        end

        % Button pushed function: AddAllChannel
        function AddAllChannelButtonPushed(app, event)
            app.AllChannelListBox.Value = app.AllChannelListBox.Items;
            AddChannelButtonPushed( app);
        end

        % Button pushed function: AddChannel
        function AddChannelButtonPushed(app, event)
            % Add selected channels to Display            
            if app.uidata.debug
                fprintf( 'AddChannelButtonPushed called.\n');
            end            
            
            if isempty( app.AllChannelListBox.Value)
                return;
            end
            
            all_chnls = app.AllChannelListBox.Items;
            cur_chnl2plot = app.ChannelListBox.Items;
            
            % Channels to add
            all_chnl2plot = app.AllChannelListBox.Value;
            all_chnl2plot = cat( 2, all_chnl2plot, cur_chnl2plot);
            all_chnl2plot = unique( all_chnl2plot, 'stable');
            [~, loc] = ismember( all_chnl2plot, all_chnls);
            all_chnl2plot = all_chnls( sort( loc));
            
            chnl2add = all_chnl2plot( ~ismember( all_chnl2plot, cur_chnl2plot));
            
            app.ChannelListBox.Items = all_chnl2plot;
            app.ChannelListBox.Value = all_chnl2plot{1};
            
            if isempty( chnl2add)
                [~, new_item_loc] = ismember( app.AllChannelListBox.Value, all_chnls);
                new_item_loc = new_item_loc( end);
                
            else
                % band-pass filtering
                nb_wins = length( app.data.bpfilt.mat);
                xrng = app.uidata.xrange( [1, end]);
                for win_idx = 1 : nb_wins
                    cur_chnl2add = chnl2add( ~ismember( chnl2add, app.data.bpfilt.labels{ win_idx}));
                    
                    for chnl_idx = 1 : length( cur_chnl2add)
                        % Show status
                        txt = sprintf( 'Bandpass filtering for window: %d/%d; Channel %d/%d.',...
                            win_idx, nb_wins, chnl_idx, length( cur_chnl2add));
                        
                        PUpdateStatus( app, txt, app.uidata.msg_fc);
                        drawnow;
                        
                        tmp_label = cur_chnl2add{ chnl_idx};
                        [~, loc] = ismember( tmp_label, app.data.labels);
                        [vec, amp] = HFOFiltData( app.data.mat( loc, :),...
                            app.data.srate,...
                            app.data.bpfilt.param{ win_idx});
                        
                        app.data.bpfilt.mat{ win_idx} = cat( 1, app.data.bpfilt.mat{ win_idx}, vec);
                        app.data.bpfilt.amp{ win_idx} = cat( 1, app.data.bpfilt.amp{ win_idx}, amp);
                        
                        if exist( 'zscore', 'file')
                            app.data.bpfilt.z{ win_idx} = cat( 1, app.data.bpfilt.z{ win_idx}, zscore( amp));
                        else
                            app.data.bpfilt.z{ win_idx} = cat( 1, app.data.bpfilt.z{ win_idx}, ...
                                (amp - mean( amp)) / std( amp));
                        end
                        
                        app.data.bpfilt.labels{ win_idx} = cat( 1, app.data.bpfilt.labels{ win_idx}, tmp_label);
                        
                        clear vec amp
                    end
                    
                    % update data range
                    min_val = min( app.data.bpfilt.mat{ win_idx}( :, xrng(1) : xrng(2)), [], 'all');
                    max_val = max( app.data.bpfilt.mat{ win_idx}( :, xrng(1) : xrng(2)), [], 'all');
                    app.data.bpfilt.param{ win_idx}.raw_limit = [-1, 1] * max( abs([ min_val, max_val]));
                    app.data.bpfilt.param{ win_idx}.limit = [-1, 1] * max( abs([ min_val, max_val]));
                end
                
                lo = min( app.data.mat( :, xrng(1) : xrng(2)), [], 'all');
                hi = max( app.data.mat( :, xrng(1) : xrng(2)), [], 'all');                
                app.data.raw_limit = [lo,  hi];
                app.data.raw_limit = [-1, 1] * max( abs( [lo, hi]));
                
                [~, new_item_loc] = ismember( chnl2add, all_chnls);
            end
            
            new_item_loc = min( [length( all_chnls), max( new_item_loc) + 1]);
            app.AllChannelListBox.Value = all_chnls( new_item_loc);
            
            PUpdateEventList( app);
            Redraw( app);
        end

        % Button pushed function: RemoveChannel
        function RemoveChannelButtonPushed(app, event)
            if app.uidata.debug
                fprintf( 'RemoveChannelButtonPushed called.\n');
            end

            if isempty( app.ChannelListBox.Value)
                return;
            end
            
            all_items = app.ChannelListBox.Items;
            chnl2rm = app.ChannelListBox.Value;
            if length( chnl2rm) < length( app.ChannelListBox.Items)
                [~, orig_loc] = ismember( chnl2rm, app.ChannelListBox.Items);
                
                % band-pass filter
                for idx = 1 : length( app.data.bpfilt.mat)
                    cur_labels = app.data.bpfilt.labels{ idx};
                    [~, loc] = ismember( chnl2rm, cur_labels);
                    
                    app.data.bpfilt.mat{ idx}( loc, :) = [];
                    app.data.bpfilt.amp{ idx}( loc, :) = [];
                    app.data.bpfilt.z{ idx}( loc, :) = [];
                    app.data.bpfilt.labels{ idx}( loc) = [];
                    
                    % update data range
                    xrng = app.uidata.xrange( [1, end]);
                    min_val = min( app.data.bpfilt.mat{ idx}( :, xrng(1) : xrng(2)), [], 'all');
                    max_val = max( app.data.bpfilt.mat{ idx}( :, xrng(1) : xrng(2)), [], 'all');
                    app.data.bpfilt.param{ idx}.raw_limit = [-1, 1] * max( abs( [min_val, max_val]));
                    app.data.bpfilt.param{ idx}.limit = [-1, 1] * max( abs( [min_val, max_val]));
                end
                
                % spectrogram
                for idx = 1 : length( app.data.tf.mat)
                    lbl_exist = ismember( chnl2rm, app.data.tf.labels{ idx});
                    if ~lbl_exist
                        continue;
                    end
                    
                    freq_win_names = cellfun( @(x) x.UIFigure.Name, app.FreqWin, 'UniformOutput', false);
                    [~, win_loc] = ismember( app.data.tf.labels{ idx}, freq_win_names);
                    if win_loc ~= 0
                        delete( app.FreqWin{ win_loc});
                        app.FreqWin( win_loc) = [];
                    end
                    
                    app.data.tf.mat( idx) = [];
                    app.data.tf.param( idx) = [];
                    app.data.tf.labels( idx) = [];
                end
                
                remain_items = all_items( ~ismember( all_items, chnl2rm));
                app.ChannelListBox.Items = remain_items;
                loc = dsearchn( (1 : length( remain_items))', max( orig_loc));
                app.ChannelListBox.Value = remain_items{ loc};
                                
            else
                % Clear bandpass filter data and spectrograms
                app.ChannelListBox.Items = {};
                app.ChannelListBox.Value = {};
                
                app.data.bpfilt.mat = {};
                app.data.bpfilt.amp = {};
                app.data.bpfilt.z = {};
                app.data.bpfilt.param = {};
                app.data.bpfilt.labels = {};
                app.data.bpfilt.update_plot = 'no';
                
                app.data.tf.mat = {};
                app.data.tf.param = {};
                app.data.tf.labels = {};
                    
                DeleteAllWins( app);
            end
            
            % reset locked channel to empty if it's removed
            if ~isempty( app.uidata.current_channel)
                if ismember( app.uidata.current_channel{1}, chnl2rm)
                    app.uidata.current_channel = {};
                    app.LockCurrentChannelCheckBox.Value = 0;
                end
            end
            
            % remove currently detected events
            if ~isempty( app.uidata.cur_event)
                nbevs = length( app.uidata.cur_event);
                loc2rm = zeros( nbevs, 1);
                for k = 1 : length( app.uidata.cur_event)
                    if ismember( app.uidata.cur_event(k).label, chnl2rm)
                        loc2rm( k) = 1;
                    end
                end
                app.uidata.cur_event( loc2rm == 1) = [];
            end
            
            PUpdateEventList( app);
            PUpdateClustInfo( app, '', app.uidata.msg_fc);
            Redraw( app);
        end

        % Button pushed function: RemoveAllChannel
        function RemoveAllChannelButtonPushed(app, event)
            app.ChannelListBox.Value = app.ChannelListBox.Items;
            RemoveChannelButtonPushed2( app, []);
        end

        % Button pushed function: RemoveChannelButton
        function RemoveChannelButtonPushed2(app, event)
            RemoveChannelButtonPushed( app, event);
        end

        % Value changed function: LockCurrentChannelCheckBox
        function LockCurrentChannelCheckBoxValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            AddChannelButtonPushed(app, []);
        end

        % Value changed function: EventListBox
        function EventListBoxValueChanged(app, event)
            if app.uidata.debug
                fprintf( 'EventListBoxValueChanged called.\n');
            end
            
            value = app.EventListBox.Value;
            if isempty( value)
                return;
            end
            
            app.RemoveEventButton.Enable = 'on';
            
            % disable merge button
            app.MergeEventButton.Enable = 'off';
            
            % { event_label, channel_name, [start, end], event_type}
            [~, loc] = ismember( value, app.data.disp_events( :, 1));
            cur_ev_loc = cell2mat( app.data.disp_events( loc, 3));
            
            % duration in milliseconds
            dur = diff( cur_ev_loc, [], 2) / app.data.srate;
            dur = 1000 * sum( dur, 1);
            
            fontcolor = app.uidata.msg_fc;
            nb_select_evs = length( value);
            if nb_select_evs == 1                
                cur_label = app.data.disp_events{ loc, 2};
                ev_type = strjoin( app.data.disp_events{ loc, 4}, ', ');
                
                txt = sprintf( '%s: 1 event. Duration: %.0f ms. Type: %s; Peak z: %.2f',...
                    cur_label,...
                    dur,...
                    ev_type,...
                    app.data.disp_events{ loc, 6});
                
               % set current channel so it will be highlighted
               if app.LockCurrentChannelCheckBox.Value == 0
                   if ~isempty( app.uidata.current_channel)
                       app.uidata.current_channel = {cur_label, app.uidata.current_channel{ 2}};
                   else
                       app.uidata.current_channel = {cur_label, 0};
                   end                    
               end                
                
                [~, is_within] = RangeOverlap( app, cur_ev_loc, app.uidata.xrange);
                if is_within
                    % set cursor location to the center of the event
                    app.uidata.cursor_loc = cur_ev_loc( 1) + round( diff( cur_ev_loc) / 2);
                    
                    % Change the start location of the current window
                    half_win_len = floor( app.XScaleSpinner.Value * app.data.srate / 2);
                    app.uidata.start_loc = max( [app.uidata.xrange(1), app.uidata.cursor_loc - half_win_len]);
                    
                else
                    txt = 'Can not got to event because it is out of range.';
                    fontcolor = 'r';
                    app.uidata.cursor_loc = [];
                end
                
                %%% Change event type checkbox
                %% SetEventType( app, app.data.disp_events{ loc, 4});
                
            else                
                % Two or more events are selected
                unique_chnl_label = unique( app.data.disp_events( loc, 2), 'stable');
                nb_chnls = length( unique_chnl_label);                 
                ev_type = app.data.disp_events( loc, 4);
                ev_type = ReorderEventType( app, ev_type);
                nb_evtypes = length( ev_type);
                
                txt = sprintf( '%s: %d events. Duration: %.0f ms. Type: %s.',...
                    strjoin( unique_chnl_label, ', '),...
                    nb_select_evs,...
                    dur,...
                    strjoin( ev_type, ', '));
                
                if app.LockCurrentChannelCheckBox.Value == 0
                    app.uidata.current_channel = {};
                end  
                
                % Enable MergeEvent button if
                % 1). Events of the same type are selected.
                % 2). Events are from the same channel
                % 3). Two events are selected
                if nb_select_evs == 2 && nb_evtypes == 1 && nb_chnls == 1
                    [~, ev1_within] = RangeOverlap( app, cur_ev_loc( 1, :), app.uidata.xrange);
                    [~, ev2_within] = RangeOverlap( app, cur_ev_loc( 2, :), app.uidata.xrange);
                    
                    d = 1000 * (cur_ev_loc(2, 1) - cur_ev_loc( 1, 2)) / app.data.srate;
                    txt = sprintf( '%s Distance: %.0f ms.', txt, d);
                        
                    if ev1_within && ev2_within
                        app.MergeEventButton.Enable = 'on';
                        
                    elseif ev1_within && ~ev2_within
                         txt = sprintf( '%s The second event is out of range.', txt);
                    else      
                         txt = sprintf( '%s The first event is out of range.', txt);
                    end
                end
                
            end % one or more events are selected
            
            Redraw( app);            
            PUpdateClustInfo( app, txt, fontcolor);            
        end

        % Value changed function: ETGammaCheckBox
        function ETGammaCheckBoxValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Value changed function: ETRippleCheckBox
        function ETRippleCheckBoxValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Value changed function: ETFastRippleCheckBox
        function ETFastRippleCheckBoxValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Value changed function: ETUltraFastCheckBox
        function ETUltraFastCheckBoxValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Value changed function: ETSpikeCheckBox
        function ETSpikeCheckBoxValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Selection changed function: EventTypetoDisplayButtonGroup
        function EventTypetoDisplayButtonGroupSelectionChanged(app, event)
            Redraw( app);
        end

        % Selection changed function: EventChanneltoDisplayButtonGroup
        function EventChanneltoDisplayButtonGroupSelectionChanged(app, event)
            Redraw( app);
        end

        % Button pushed function: MergeEventButton
        function MergeEventButtonPushed(app, event)
            PMergeEvent( app, app.EventListBox.Value);
        end

        % Button pushed function: RemoveEventButton
        function RemoveEventButtonPushed(app, event)
            PRemoveEvent( app, app.EventListBox.Value);
        end

        % Button pushed function: UpdateEventButton
        function UpdateEventButtonPushed(app, event)
            PUpdateCurrentEvent( app, app.EventListBox.Value);
        end

        % Value changed function: ThreshOnsetEditField
        function ThreshOnsetEditFieldValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Value changed function: ThreshInclusionEditField
        function ThreshInclusionEditFieldValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Value changed function: ThreshNumberCyclesEditField
        function ThreshNumberCyclesEditFieldValueChanged(app, event)
            PChangeObjectValue( app);
        end

        % Value changed function: MultipleDetectionCheckBox
        function MultipleDetectionCheckBoxValueChanged(app, event)
            value = app.MultipleDetectionCheckBox.Value;
            PChangeObjectValue( app, [], 'MultipleDetectionCheckBox', value);
            if value == 1
                app.uidata.mult_chnl_detect = 'yes';
            else
                app.uidata.mult_chnl_detect = 'no';
            end
        end

        % Window button down function: HFOAppUIFigure
        function HFOAppUIFigureWindowButtonDown(app, event)
            % no data loaded
            if isempty( app.data) || isempty( app.ChannelListBox.Items)
                return;
            end
            
            PWinButtonDown( app,...
                'main',...
                'Main',...
                app.HFOAppUIFigure.SelectionType,...
                app.UIAxes.CurrentPoint( 1, 1:2),...
                app.uidata.data_range,...
                [0, 1]);
            
            box_bound = [sum( app.LLabel.Position( [1, 3])),...
                app.RLabel.Position(1),...
                sum( app.BLabel.Position( [2, 4])),...
                app.TLabel.Position(2)];
            
            PShiftPlots( app,...
                app.HFOAppUIFigure.CurrentPoint( 1, [1, 2]),...
                box_bound);
        end

        % Close request function: HFOAppUIFigure
        function HFOAppUIFigureCloseRequest(app, event)
            PQuitAppMenuSelected( app, [], app.HFOAppUIFigure);
        end

        % Window key press function: HFOAppUIFigure
        function HFOAppUIFigureWindowKeyPress(app, event)
            PWindowsKeyPress( app, event);
        end

        % Value changed function: ManualModeButton
        function ManualModeButtonValueChanged(app, event)
            if app.ManualModeButton.Value == 1
                app.uidata.manual_start = [];
                app.StartEventButton.FontColor = 'k';
                app.EndEventButton.FontColor = 'k';
                
                app.uidata.manual_end = [];
                app.uidata.cur_event = [];
                app.EndEventButton.Enable = 'on';
                app.StartEventButton.Enable = 'on';
                app.ManualEventAutoAdjCheckBox.Enable = 'on';
                
            else
                app.StartEventButton.Enable = 'off';
                app.EndEventButton.Enable = 'off';
                app.ManualEventAutoAdjCheckBox.Enable = 'off';
            end
            
            PChangeObjectValue( app);
            Redraw( app);
        end

        % Size changed function: HFOAppUIFigure
        function HFOAppUIFigureSizeChanged(app, event)
            PResizeWindows( app, app.HFOAppUIFigure.Position, 'main');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            PClearButtonPushed( app, event, app.HFOAppUIFigure);
        end

        % Menu selected function: ExportasMatlabFileMenu
        function ExportasMatlabFileMenuSelected(app, event)
            if isempty( app.data.savefile)
                [~, fname] = fileparts( app.data.file);
                fname = [app.uidata.savename_prefix, fname]; 
            else
                [~, fname] = fileparts( app.data.savefile);
            end
            
            [filename, path] = uiputfile( ...
                {'.mat'},...
                'Save results as Matlab file.',...
                [fname, '.mat']);

            if isequal( filename, 0) || isequal( path, 0)
                PUpdateStatus( app, 'Result exporting was cancelled.', 'r');
                drawnow;
                return;
            end
            
            PUpdateStatus( app,...
                'Exporting events as Matlab file, please wait ...',...
                app.uidata.msg_fc);
            
            drawnow;
            
            if isempty( app.data.trldata)
                nbtrls = 1;
                evs = app.data.events;
            else
                nbtrls = size( app.data.trldata, 3);
                evs = app.data.trlevents;
            end
            
            savefile = fullfile( path, filename);
            
            tmp = whos( 'evs');
            if tmp.bytes / 1e9 < 1.5
                save( savefile, 'evs', 'nbtrls');
            else
                save( savefile, 'evs', 'nbtrls', '-v7.3');
            end
            
            PUpdateStatus( app,...
                sprintf( 'Events were exported as %s', savefile),...
                app.uidata.msg_fc);
            
            drawnow;            
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            PNextTrial(app);
        end

        % Button pushed function: PreTrialButton
        function PreTrialButtonPushed(app, event)
            PPrevTrial( app);
        end

        % Value changed function: CurTrialEditField
        function CurTrialEditFieldValueChanged(app, event)
            value = round( app.CurTrialEditField.Value);
            PSetTrial( app, value, event.PreviousValue);
        end

        % Callback function
        function RunMenuSelected(app, event)
            % Open automatic detector window, and wait until the operation
            % is done.
            app.autodetect.labels = app.ChannelListBox.Items;
            waitfor( HFOAutoDetector( app));
        end

        % Menu selected function: SaveFileMenu
        function SaveFileMenuSelected(app, event)
            PSaveFileMenu(app);
        end

        % Menu selected function: AutomatedHFOdetectionMenu
        function AutomatedHFOdetectionMenuSelected(app, event)
            PAutoDetect( app);
        end

        % Menu selected function: SavesettingsasdefaultMenu
        function SavesettingsasdefaultMenuSelected(app, event)
            PSaveDefaultConfig( app);
        end

        % Selection changed function: BackgroundTimeWindowButtonGroup
        function BackgroundTimeWindowButtonGroupSelectionChanged(app, event)
            app.uidata.bk_whole = 'no';
            app.uidata.bk_current = 'no';
            app.uidata.bk_specified = 'no';
            app.BKSpecifiedEF.Enable = 'off';
            app.uidata.bk_specifiy_val = app.BKSpecifiedEF.Value;
            
            if app.BKWholeRB.Value == 1
                app.uidata.bk_whole = 'yes';
                
            elseif app.BKCurrentRB.Value == 1
                app.uidata.bk_current = 'yes';
                
            elseif app.BKSpecifiedRB.Value == 1
                app.uidata.bk_specified = 'yes';
                app.BKSpecifiedEF.Enable = 'on';
                
            else
                % do nothing
            end
            
            PChangeObjectValue( app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HFOAppUIFigure = uifigure('Visible', 'off');
            app.HFOAppUIFigure.Name = 'HFO App';
            app.HFOAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @HFOAppUIFigureCloseRequest, true);
            app.HFOAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @HFOAppUIFigureSizeChanged, true);
            app.HFOAppUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @HFOAppUIFigureWindowButtonDown, true);
            app.HFOAppUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @HFOAppUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.HFOAppUIFigure);
            app.FileMenu.Tooltip = {'Load dataset.'};
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'Open a file.'};
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Tooltip = {'Close current file.'};
            app.CloseMenu.Text = 'Close';
            app.SaveFileMenu = uimenu(app.FileMenu);
            app.SaveFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFileMenuSelected, true);
            app.SaveFileMenu.Tooltip = {'Save data as .mat file.'};
            app.SaveFileMenu.Text = 'Save';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Tooltip = {'Quit HFOApp.'};
            app.QuitMenu.Text = 'Quit';
            app.EventMenu = uimenu(app.HFOAppUIFigure);
            app.EventMenu.Text = 'Event';
            app.SaveMenu = uimenu(app.EventMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Tooltip = {'Save HFO events.'};
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.EventMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Tooltip = {'Save HFO events to specified file.'};
            app.SaveasMenu.Text = 'Save as';
            app.ExportasMatlabFileMenu = uimenu(app.EventMenu);
            app.ExportasMatlabFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportasMatlabFileMenuSelected, true);
            app.ExportasMatlabFileMenu.Tooltip = {'Save HFO events as .mat file.'};
            app.ExportasMatlabFileMenu.Text = 'Export as Matlab File';
            app.LoadMenu = uimenu(app.EventMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Tooltip = {'Load existing HFO events in HFOApp format.'};
            app.LoadMenu.Text = 'Load';
            app.WindowsMenu = uimenu(app.HFOAppUIFigure);
            app.WindowsMenu.Text = 'Windows';
            app.AddbandpassfilterwindowMenu = uimenu(app.WindowsMenu);
            app.AddbandpassfilterwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddbandpassfilterwindowMenuSelected, true);
            app.AddbandpassfilterwindowMenu.Tooltip = {'Open a new bandpass filter window.'};
            app.AddbandpassfilterwindowMenu.Text = 'Add bandpass filter window';
            app.AddtimefrequencywindowMenu = uimenu(app.WindowsMenu);
            app.AddtimefrequencywindowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddtimefrequencywindowMenuSelected, true);
            app.AddtimefrequencywindowMenu.Tooltip = {'Open a new spectrogrm window for selected channel.'};
            app.AddtimefrequencywindowMenu.Text = 'Add time-frequency window';
            app.OrganizeWindowsMenu = uimenu(app.WindowsMenu);
            app.OrganizeWindowsMenu.MenuSelectedFcn = createCallbackFcn(app, @OrganizeWindowsMenuSelected, true);
            app.OrganizeWindowsMenu.Tooltip = {'Reorganize all bandpass filter windows.'};
            app.OrganizeWindowsMenu.Text = 'Organize Windows';
            app.MontageMenu = uimenu(app.HFOAppUIFigure);
            app.MontageMenu.Text = 'Montage';
            app.ChangeMontageMenu = uimenu(app.MontageMenu);
            app.ChangeMontageMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeMontageMenuSelected, true);
            app.ChangeMontageMenu.Tooltip = {'Change the montage.'};
            app.ChangeMontageMenu.Text = 'Change Montage';
            app.SpectrogramMenu = uimenu(app.HFOAppUIFigure);
            app.SpectrogramMenu.Text = 'Spectrogram';
            app.ConfigureMenu = uimenu(app.SpectrogramMenu);
            app.ConfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigureMenuSelected, true);
            app.ConfigureMenu.Tooltip = {'Spectrogram settings.'};
            app.ConfigureMenu.Text = 'Configure';
            app.OptionsMenu = uimenu(app.HFOAppUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.SetpreferencesMenu = uimenu(app.OptionsMenu);
            app.SetpreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @SetpreferencesMenuSelected, true);
            app.SetpreferencesMenu.Tooltip = {'HFOApp preference.'};
            app.SetpreferencesMenu.Text = 'Set preferences';
            app.AutomatedHFOdetectionMenu = uimenu(app.OptionsMenu);
            app.AutomatedHFOdetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomatedHFOdetectionMenuSelected, true);
            app.AutomatedHFOdetectionMenu.Tooltip = {'Automated HFO detectors.'};
            app.AutomatedHFOdetectionMenu.Text = 'Automated HFO detection';
            app.SavesettingsasdefaultMenu = uimenu(app.OptionsMenu);
            app.SavesettingsasdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesettingsasdefaultMenuSelected, true);
            app.SavesettingsasdefaultMenu.Tooltip = {'Save current settings as default.'};
            app.SavesettingsasdefaultMenu.Text = 'Save settings as default';
            app.HelpMenu = uimenu(app.HFOAppUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Show help.'};
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.HFOAppUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.XScaleSpinner = uispinner(app.HFOAppUIFigure);
            app.XScaleSpinner.Step = 0.2;
            app.XScaleSpinner.Limits = [2.22044604925031e-16 Inf];
            app.XScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @XScaleSpinnerValueChanged, true);
            app.XScaleSpinner.Tooltip = {'Time window length (in seconds).'};
            app.XScaleSpinner.Value = 2.22044604925031e-16;
            app.YScaleSpinner = uispinner(app.HFOAppUIFigure);
            app.YScaleSpinner.Step = 20;
            app.YScaleSpinner.Limits = [2.22044604925031e-16 Inf];
            app.YScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @YScaleSpinnerValueChanged, true);
            app.YScaleSpinner.Tooltip = {'Amplitude scale.'};
            app.YScaleSpinner.Value = 2.22044604925031e-16;
            app.FiltYScaleButton = uibutton(app.HFOAppUIFigure, 'push');
            app.FiltYScaleButton.ButtonPushedFcn = createCallbackFcn(app, @FiltYScaleButtonPushed, true);
            app.FiltYScaleButton.Tooltip = {'Amplitude scale of bandpass filtered data.'};
            app.FiltYScaleButton.Text = 'Embed Amp';
            app.StartClockEditField = uieditfield(app.HFOAppUIFigure, 'text');
            app.StartClockEditField.ValueChangedFcn = createCallbackFcn(app, @StartClockEditFieldValueChanged, true);
            app.StartClockEditField.Tooltip = {'Format: yyyy-MM-dd HH:mm:ss Or Scalar of sceonds'};
            app.EndClockHEditField = uieditfield(app.HFOAppUIFigure, 'text');
            app.EndClockHEditField.ValueChangedFcn = createCallbackFcn(app, @EndClockHEditFieldValueChanged, true);
            app.EndClockHEditField.Tooltip = {'Format: yyyy-MM-dd HH:mm:ss Or Scalar of sceonds'};
            app.ResetTimeButton = uibutton(app.HFOAppUIFigure, 'push');
            app.ResetTimeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimeButtonPushed, true);
            app.ResetTimeButton.Tooltip = {'Reset to full length of time series.'};
            app.ResetTimeButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.HFOAppUIFigure);
            app.ChannelTab = uitab(app.TabGroup);
            app.ChannelTab.Tooltip = {'List of all channels.'};
            app.ChannelTab.Title = 'Channel';
            app.ChannelListBox = uilistbox(app.ChannelTab);
            app.ChannelListBox.Multiselect = 'on';
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Value = {'Item 1'};
            app.AllChannelListBox = uilistbox(app.ChannelTab);
            app.AllChannelListBox.Multiselect = 'on';
            app.AllChannelListBox.Value = {'Item 1'};
            app.LockCurrentChannelCheckBox = uicheckbox(app.ChannelTab);
            app.LockCurrentChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCurrentChannelCheckBoxValueChanged, true);
            app.LockCurrentChannelCheckBox.Tooltip = {'Lock working channel.'};
            app.LockCurrentChannelCheckBox.Text = 'Lock channel';
            app.RemoveChannelButton = uibutton(app.ChannelTab, 'push');
            app.RemoveChannelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelButtonPushed2, true);
            app.RemoveChannelButton.Tooltip = {'Hide selected channel.'};
            app.RemoveChannelButton.Text = 'Remove';
            app.AddButton = uibutton(app.ChannelTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Tooltip = {'Display selected channel.'};
            app.AddButton.Text = 'Add';
            app.AddAllChannel = uibutton(app.ChannelTab, 'push');
            app.AddAllChannel.ButtonPushedFcn = createCallbackFcn(app, @AddAllChannelButtonPushed, true);
            app.AddAllChannel.Tooltip = {'Display all channels.'};
            app.AddAllChannel.Text = '<<';
            app.AddChannel = uibutton(app.ChannelTab, 'push');
            app.AddChannel.ButtonPushedFcn = createCallbackFcn(app, @AddChannelButtonPushed, true);
            app.AddChannel.Tooltip = {'Display selected channel.'};
            app.AddChannel.Text = '<';
            app.RemoveChannel = uibutton(app.ChannelTab, 'push');
            app.RemoveChannel.ButtonPushedFcn = createCallbackFcn(app, @RemoveChannelButtonPushed, true);
            app.RemoveChannel.Tooltip = {'Hide selected channel.'};
            app.RemoveChannel.Text = '>';
            app.RemoveAllChannel = uibutton(app.ChannelTab, 'push');
            app.RemoveAllChannel.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllChannelButtonPushed, true);
            app.RemoveAllChannel.Tooltip = {'Hide all channels.'};
            app.RemoveAllChannel.Text = '>>';
            app.DisplayLabel = uilabel(app.ChannelTab);
            app.DisplayLabel.Text = 'Display';
            app.AllLabel = uilabel(app.ChannelTab);
            app.AllLabel.Text = 'All';
            app.ClearButton = uibutton(app.ChannelTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear selected channel from memory.'};
            app.ClearButton.Text = 'Clear';
            app.EventsTab = uitab(app.TabGroup);
            app.EventsTab.Title = 'Events';
            app.EventListBox = uilistbox(app.EventsTab);
            app.EventListBox.Multiselect = 'on';
            app.EventListBox.ValueChangedFcn = createCallbackFcn(app, @EventListBoxValueChanged, true);
            app.EventListBox.Tooltip = {'List of HFOs.'};
            app.EventListBox.Value = {'Item 1'};
            app.RemoveEventButton = uibutton(app.EventsTab, 'push');
            app.RemoveEventButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEventButtonPushed, true);
            app.RemoveEventButton.Tooltip = {'Remove selected HFOs.'};
            app.RemoveEventButton.Text = 'Remove';
            app.UpdateEventButton = uibutton(app.EventsTab, 'push');
            app.UpdateEventButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventButtonPushed, true);
            app.UpdateEventButton.Tooltip = {'Update the event type of selected HFOs.'};
            app.UpdateEventButton.Text = 'Update';
            app.TypePanel = uipanel(app.EventsTab);
            app.TypePanel.Tooltip = {'Set the type of HFO.'};
            app.TypePanel.Title = 'Type';
            app.ETGammaCheckBox = uicheckbox(app.TypePanel);
            app.ETGammaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETGammaCheckBoxValueChanged, true);
            app.ETGammaCheckBox.Text = 'Gamma';
            app.ETRippleCheckBox = uicheckbox(app.TypePanel);
            app.ETRippleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETRippleCheckBoxValueChanged, true);
            app.ETRippleCheckBox.Text = 'Ripple';
            app.ETFastRippleCheckBox = uicheckbox(app.TypePanel);
            app.ETFastRippleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETFastRippleCheckBoxValueChanged, true);
            app.ETFastRippleCheckBox.Text = 'Fast ripple';
            app.ETUltraFastCheckBox = uicheckbox(app.TypePanel);
            app.ETUltraFastCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETUltraFastCheckBoxValueChanged, true);
            app.ETUltraFastCheckBox.Text = 'Ultra fast';
            app.ETSpikeCheckBox = uicheckbox(app.TypePanel);
            app.ETSpikeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ETSpikeCheckBoxValueChanged, true);
            app.ETSpikeCheckBox.Text = 'Spike';
            app.EventTypetoDisplayButtonGroup = uibuttongroup(app.EventsTab);
            app.EventTypetoDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventTypetoDisplayButtonGroupSelectionChanged, true);
            app.EventTypetoDisplayButtonGroup.Tooltip = {'Specify the type of HFO to display.'};
            app.EventTypetoDisplayButtonGroup.Title = 'Type to display';
            app.ShowAllEventButton = uiradiobutton(app.EventTypetoDisplayButtonGroup);
            app.ShowAllEventButton.Text = 'All';
            app.ShowAllEventButton.Value = true;
            app.ShowSelectedEventButton = uiradiobutton(app.EventTypetoDisplayButtonGroup);
            app.ShowSelectedEventButton.Text = 'Selected';
            app.EventChanneltoDisplayButtonGroup = uibuttongroup(app.EventsTab);
            app.EventChanneltoDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventChanneltoDisplayButtonGroupSelectionChanged, true);
            app.EventChanneltoDisplayButtonGroup.Tooltip = {'Specify the channels which the HFOs will be overlaied.'};
            app.EventChanneltoDisplayButtonGroup.Title = 'Channel to display';
            app.ShowEventOnAllChannelButton = uiradiobutton(app.EventChanneltoDisplayButtonGroup);
            app.ShowEventOnAllChannelButton.Text = 'All';
            app.ShowEventOnAllChannelButton.Value = true;
            app.ShowEventOnSelectedChanelButton = uiradiobutton(app.EventChanneltoDisplayButtonGroup);
            app.ShowEventOnSelectedChanelButton.Text = 'Selected';
            app.MergeEventButton = uibutton(app.EventsTab, 'push');
            app.MergeEventButton.ButtonPushedFcn = createCallbackFcn(app, @MergeEventButtonPushed, true);
            app.MergeEventButton.Text = 'Merge';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MultipleDetectionCheckBox = uicheckbox(app.SettingsTab);
            app.MultipleDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultipleDetectionCheckBoxValueChanged, true);
            app.MultipleDetectionCheckBox.Tooltip = {'Enable/Disable multi-channel HFO detection. Press "d" to perform detection.'};
            app.MultipleDetectionCheckBox.Text = 'Enable multiple channel detection.';
            app.MultipleDetectionCheckBox.Value = true;
            app.EventDetectionOptionsPanel = uipanel(app.SettingsTab);
            app.EventDetectionOptionsPanel.Tooltip = {'HFOApp Hilbert detector parameters.'};
            app.EventDetectionOptionsPanel.Title = 'Event Detection Options';
            app.OnsetthresholdSDEditFieldLabel = uilabel(app.EventDetectionOptionsPanel);
            app.OnsetthresholdSDEditFieldLabel.Text = 'Onset threshold (SD):';
            app.ThreshOnsetEditField = uieditfield(app.EventDetectionOptionsPanel, 'numeric');
            app.ThreshOnsetEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshOnsetEditFieldValueChanged, true);
            app.ThreshOnsetEditField.Tooltip = {'Onset of HFO.'};
            app.InclusionCriteriaSDEditFieldLabel = uilabel(app.EventDetectionOptionsPanel);
            app.InclusionCriteriaSDEditFieldLabel.Text = 'Inclusion Criteria (SD):';
            app.ThreshInclusionEditField = uieditfield(app.EventDetectionOptionsPanel, 'numeric');
            app.ThreshInclusionEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshInclusionEditFieldValueChanged, true);
            app.ThreshInclusionEditField.Tooltip = {'Peak z score theshold.'};
            app.NumberofCyclesEditFieldLabel = uilabel(app.EventDetectionOptionsPanel);
            app.NumberofCyclesEditFieldLabel.Text = 'Number of Cycles:';
            app.ThreshNumberCyclesEditField = uieditfield(app.EventDetectionOptionsPanel, 'numeric');
            app.ThreshNumberCyclesEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshNumberCyclesEditFieldValueChanged, true);
            app.ThreshNumberCyclesEditField.Tooltip = {'Number of cycles threshold.'};
            app.BackgroundTimeWindowButtonGroup = uibuttongroup(app.SettingsTab);
            app.BackgroundTimeWindowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundTimeWindowButtonGroupSelectionChanged, true);
            app.BackgroundTimeWindowButtonGroup.Tooltip = {'Specifiy the time window that will be used for z score calculation.'};
            app.BackgroundTimeWindowButtonGroup.Title = 'Background Time Window';
            app.BKWholeRB = uiradiobutton(app.BackgroundTimeWindowButtonGroup);
            app.BKWholeRB.Tooltip = {'Use whole time series for z score calculation.'};
            app.BKWholeRB.Text = 'Whole time series';
            app.BKWholeRB.Value = true;
            app.BKCurrentRB = uiradiobutton(app.BackgroundTimeWindowButtonGroup);
            app.BKCurrentRB.Tooltip = {'Use current time window for z score calculation.'};
            app.BKCurrentRB.Text = 'Current Window';
            app.BKSpecifiedRB = uiradiobutton(app.BackgroundTimeWindowButtonGroup);
            app.BKSpecifiedRB.Tooltip = {'Use a specified time window (centered at cursor) for z score calculation.'};
            app.BKSpecifiedRB.Text = 'Specified Window (s):';
            app.BKSpecifiedEF = uieditfield(app.SettingsTab, 'numeric');
            app.BKSpecifiedEF.Limits = [0.1 Inf];
            app.BKSpecifiedEF.Value = 5;
            app.DurationLabel = uilabel(app.HFOAppUIFigure);
            app.StepBackButton = uibutton(app.HFOAppUIFigure, 'push');
            app.StepBackButton.ButtonPushedFcn = createCallbackFcn(app, @StepBackButtonPushed, true);
            app.StepBackButton.Tooltip = {'Move time series backword by a full time window. Keyboard shutcut: "b"'};
            app.StepBackButton.Text = '<<';
            app.BackButton = uibutton(app.HFOAppUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Tooltip = {'Move time series backword by a short time window. Keyboard shutcut: "b"'};
            app.BackButton.Text = '<';
            app.ForwardButton = uibutton(app.HFOAppUIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tooltip = {'Move time series forward by a short time window. Keyboard shutcut: "f"'};
            app.ForwardButton.Text = '>';
            app.StepForwardButton = uibutton(app.HFOAppUIFigure, 'push');
            app.StepForwardButton.ButtonPushedFcn = createCallbackFcn(app, @StepForwardButtonPushed, true);
            app.StepForwardButton.Tooltip = {'Move time series forward by a full time window. Keyboard shutcut: "f"'};
            app.StepForwardButton.Text = '>>';
            app.StatusLabel = uilabel(app.HFOAppUIFigure);
            app.StatusLabel.Tooltip = {'Status bar.'};
            app.StatusLabel.Text = 'Label2';
            app.JumpToClockEditField = uieditfield(app.HFOAppUIFigure, 'text');
            app.JumpToClockEditField.ValueChangedFcn = createCallbackFcn(app, @JumpToClockEditFieldValueChanged, true);
            app.JumpToClockEditField.Tooltip = {'Format: yyyy-MM-dd HH:mm:ss Or Scalar'};
            app.AddEventButton = uibutton(app.HFOAppUIFigure, 'push');
            app.AddEventButton.ButtonPushedFcn = createCallbackFcn(app, @AddEventButtonPushed, true);
            app.AddEventButton.Tooltip = {'Add detected HFO. Keyboard shutcut: "a"'};
            app.AddEventButton.Text = 'Add event';
            app.StartEventButton = uibutton(app.HFOAppUIFigure, 'push');
            app.StartEventButton.ButtonPushedFcn = createCallbackFcn(app, @StartEventButtonPushed, true);
            app.StartEventButton.Enable = 'off';
            app.StartEventButton.Tooltip = {'Mark the start of an HFO. Keyboard shortcut: "s".'};
            app.StartEventButton.Text = 'Start';
            app.EndEventButton = uibutton(app.HFOAppUIFigure, 'push');
            app.EndEventButton.ButtonPushedFcn = createCallbackFcn(app, @EndEventButtonPushed, true);
            app.EndEventButton.Enable = 'off';
            app.EndEventButton.Tooltip = {'Mark the end of an HFO. Keyboard shortcut: "e".'};
            app.EndEventButton.Text = 'End';
            app.ManualEventAutoAdjCheckBox = uicheckbox(app.HFOAppUIFigure);
            app.ManualEventAutoAdjCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualEventAutoAdjCheckBoxValueChanged, true);
            app.ManualEventAutoAdjCheckBox.Enable = 'off';
            app.ManualEventAutoAdjCheckBox.Tooltip = {'Enable/Disable automatic adjustment of manual marking.'};
            app.ManualEventAutoAdjCheckBox.Text = 'Manual auto adjustment';
            app.ManualEventAutoAdjCheckBox.Value = true;
            app.ManualModeButton = uibutton(app.HFOAppUIFigure, 'state');
            app.ManualModeButton.ValueChangedFcn = createCallbackFcn(app, @ManualModeButtonValueChanged, true);
            app.ManualModeButton.Tooltip = {'Enable/Disable manual marking. Keyboard shortcut: "w".'};
            app.ManualModeButton.Text = 'Manual';
            app.TimesLabel = uilabel(app.HFOAppUIFigure);
            app.TimesLabel.Text = 'Time (s):';
            app.AmplitudeLabel = uilabel(app.HFOAppUIFigure);
            app.AmplitudeLabel.Text = 'Amplitude (%):';
            app.StartClockLabel = uilabel(app.HFOAppUIFigure);
            app.StartClockLabel.Text = 'Start Time:';
            app.EndClockLabel = uilabel(app.HFOAppUIFigure);
            app.EndClockLabel.Text = 'End Time:';
            app.JumptoLabel = uilabel(app.HFOAppUIFigure);
            app.JumptoLabel.Text = 'Go to time:';
            app.TLabel = uilabel(app.HFOAppUIFigure);
            app.TLabel.Text = '';
            app.BLabel = uilabel(app.HFOAppUIFigure);
            app.BLabel.Text = '';
            app.MLabel = uilabel(app.HFOAppUIFigure);
            app.MLabel.Text = '';
            app.LLabel = uilabel(app.HFOAppUIFigure);
            app.LLabel.Text = '';
            app.RLabel = uilabel(app.HFOAppUIFigure);
            app.RLabel.Text = '';
            app.ToiTLabel = uilabel(app.HFOAppUIFigure);
            app.ToiBLabel = uilabel(app.HFOAppUIFigure);
            app.ToiBLabel.Text = '';
            app.TrialLabel = uilabel(app.HFOAppUIFigure);
            app.TrialLabel.Text = 'Trial:';
            app.CurTrialEditField = uieditfield(app.HFOAppUIFigure, 'numeric');
            app.CurTrialEditField.ValueChangedFcn = createCallbackFcn(app, @CurTrialEditFieldValueChanged, true);
            app.CurTrialEditField.Tooltip = {'Go to trial.'};
            app.CurTrialEditField.Value = 1;
            app.PreTrialButton = uibutton(app.HFOAppUIFigure, 'push');
            app.PreTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreTrialButtonPushed, true);
            app.PreTrialButton.Tooltip = {'Previous trial.'};
            app.PreTrialButton.Text = '<';
            app.NextTrialButton = uibutton(app.HFOAppUIFigure, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Tooltip = {'Next trial.'};
            app.NextTrialButton.Text = '>';
            app.TotalTrialLabel = uilabel(app.HFOAppUIFigure);
            app.TotalTrialLabel.Text = 'Label3';
            % Show the figure after all components are created
            app.HFOAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HFOApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HFOAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HFOAppUIFigure)
        end
    end
end